classdef GUI_v4_0_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPOT40GUIUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LabLinksMenu                    matlab.ui.container.Menu
        GitHubMenu                      matlab.ui.container.Menu
        GooglePhotosMenu                matlab.ui.container.Menu
        YoutubeChannelMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        YouTubeTutorialsMenu            matlab.ui.container.Menu
        RunningaSimulationandExperimentMenu  matlab.ui.container.Menu
        ConnectingtoaRaspberryPiRemotelyMenu  matlab.ui.container.Menu
        RemovingtheTableCoverMenu       matlab.ui.container.Menu
        RetrievingtheIsopropanolMenu    matlab.ui.container.Menu
        TableCleaningProceduresMenu     matlab.ui.container.Menu
        SavingExperimentDataMenu        matlab.ui.container.Menu
        RemovingtheRoboticArmMenu       matlab.ui.container.Menu
        ChargingaLEDBatteryPackMenu     matlab.ui.container.Menu
        ChargingaPlatformMenu           matlab.ui.container.Menu
        InstallinganAirTankMenu         matlab.ui.container.Menu
        InstallingtheRoboticArmMenu     matlab.ui.container.Menu
        ForkingtheSPOTRepositoryMenu    matlab.ui.container.Menu
        PlacingtheTableCoverMenu        matlab.ui.container.Menu
        FillinganAirTankMenu            matlab.ui.container.Menu
        ExportingVideoswithQCViewerMenu  matlab.ui.container.Menu
        CleaningthePucksandMovingaPlatformMenu  matlab.ui.container.Menu
        SaveGUIStateMenu                matlab.ui.container.Menu
        LoadGUIStateMenu                matlab.ui.container.Menu
        ResetGUIParameterstoDefaultMenu  matlab.ui.container.Menu
        OverrideCurrentDefaultParametersMenu  matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        AddPathtoCustomLibraryMenu      matlab.ui.container.Menu
        GridLayout16                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        InitializeParametersTab         matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        SPOTGUIVersion40Label           matlab.ui.control.Label
        Step5SetDurationsPanel          matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Phase0Label                     matlab.ui.control.Label
        SampRateUnits_2                 matlab.ui.control.Label
        TotalTimeEditField              matlab.ui.control.NumericEditField
        TotalTimeLabel_2                matlab.ui.control.Label
        TotalTimeLabel                  matlab.ui.control.Label
        StartupPhasesLabel              matlab.ui.control.Label
        DurPhase0Units                  matlab.ui.control.Label
        DurPhase0EditField              matlab.ui.control.NumericEditField
        TerminationPhasesLabel          matlab.ui.control.Label
        SubPhase4Units                  matlab.ui.control.Label
        SubPhase4EditField              matlab.ui.control.NumericEditField
        SubPhase4Label                  matlab.ui.control.Label
        SubPhase3Units                  matlab.ui.control.Label
        SubPhase3EditField              matlab.ui.control.NumericEditField
        SubPhase3Label                  matlab.ui.control.Label
        SubPhase2Units                  matlab.ui.control.Label
        SubPhase2EditField              matlab.ui.control.NumericEditField
        SubPhase2Label                  matlab.ui.control.Label
        SubPhase1Units                  matlab.ui.control.Label
        SubPhase1EditField              matlab.ui.control.NumericEditField
        SubPhase1Label                  matlab.ui.control.Label
        Phase3SubPhasesLabel            matlab.ui.control.Label
        DurPhase5Units                  matlab.ui.control.Label
        DurPhase5EditField              matlab.ui.control.NumericEditField
        Phase5Label                     matlab.ui.control.Label
        DurPhase4Units                  matlab.ui.control.Label
        DurPhase4EditField              matlab.ui.control.NumericEditField
        Phase4Label                     matlab.ui.control.Label
        DurPhase3Units                  matlab.ui.control.Label
        DurPhase3EditField              matlab.ui.control.NumericEditField
        Phase3Label                     matlab.ui.control.Label
        DurPhase2Units                  matlab.ui.control.Label
        DurPhase2EditField              matlab.ui.control.NumericEditField
        Phase2Label                     matlab.ui.control.Label
        DurPhase1Units                  matlab.ui.control.Label
        DurPhase1EditField              matlab.ui.control.NumericEditField
        Phase1Label                     matlab.ui.control.Label
        Step4InitialConditionsPanel     matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        OpenInitialConditionAppButton   matlab.ui.control.Button
        Step2MassPropertiesPanel_2      matlab.ui.container.Panel
        GridLayout35                    matlab.ui.container.GridLayout
        OpenMassPropertiesAppButton     matlab.ui.control.Button
        Step1LoadSimulinkDiagramPanel   matlab.ui.container.Panel
        GridLayout3_3                   matlab.ui.container.GridLayout
        AvailableDiagramsDropDown       matlab.ui.control.DropDown
        Step6AuxiliaryParametersPanel   matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        DurPhase3Units_8                matlab.ui.control.Label
        ThursterCountThresholdEditField  matlab.ui.control.NumericEditField
        ThrusterCountThresholdLabel     matlab.ui.control.Label
        BLUEFYNominal                   matlab.ui.control.NumericEditField
        BLACKFYNominal                  matlab.ui.control.NumericEditField
        REDFYNominal                    matlab.ui.control.NumericEditField
        DurPhase3Units_7                matlab.ui.control.Label
        mathrmF_Y_NominalLabel_3        matlab.ui.control.Label
        DurPhase3Units_6                matlab.ui.control.Label
        mathrmF_Y_NominalLabel_2        matlab.ui.control.Label
        DurPhase3Units_5                matlab.ui.control.Label
        mathrmF_Y_NominalLabel          matlab.ui.control.Label
        DurPhase3Units_4                matlab.ui.control.Label
        BLUEFXNominal                   matlab.ui.control.NumericEditField
        mathrmF_X_NominalLabel_3        matlab.ui.control.Label
        DurPhase3Units_3                matlab.ui.control.Label
        BLACKFXNominal                  matlab.ui.control.NumericEditField
        mathrmF_X_NominalLabel_2        matlab.ui.control.Label
        DurPhase3Units_2                matlab.ui.control.Label
        REDFXNominal                    matlab.ui.control.NumericEditField
        mathrmF_X_NominalLabel          matlab.ui.control.Label
        ThrusterParametersLabel         matlab.ui.control.Label
        BlueDYGainEditField             matlab.ui.control.NumericEditField
        mathrmK_dYLabel_3               matlab.ui.control.Label
        BlackDYGainEditField            matlab.ui.control.NumericEditField
        mathrmK_dYLabel_2               matlab.ui.control.Label
        RedDYGainEditField              matlab.ui.control.NumericEditField
        mathrmK_dYLabel                 matlab.ui.control.Label
        BluePYGainEditField             matlab.ui.control.NumericEditField
        BlackPYGainEditField            matlab.ui.control.NumericEditField
        RedPYGainEditField              matlab.ui.control.NumericEditField
        mathrmK_pYLabel                 matlab.ui.control.Label
        mathrmK_pYLabel_3               matlab.ui.control.Label
        mathrmK_pYLabel_2               matlab.ui.control.Label
        GridLayout31                    matlab.ui.container.GridLayout
        DurPhase0Units_51               matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        mathrmK_pXLabel                 matlab.ui.control.Label
        SamplingRateLabel               matlab.ui.control.Label
        BlueDGainThetaEditField         matlab.ui.control.NumericEditField
        mathrmK_dthetaLabel_3           matlab.ui.control.Label
        BlackDGainThetaEditField        matlab.ui.control.NumericEditField
        mathrmK_dthetaLabel             matlab.ui.control.Label
        RedDGainThetaEditField          matlab.ui.control.NumericEditField
        mathrmK_dthetaLabel_2           matlab.ui.control.Label
        BluePGainThetaEditField         matlab.ui.control.NumericEditField
        mathrmK_pthetaLabel_3           matlab.ui.control.Label
        BlackPGainThetaEditField        matlab.ui.control.NumericEditField
        mathrmK_pthetaLabel_2           matlab.ui.control.Label
        RedPGainThetaEditField          matlab.ui.control.NumericEditField
        mathrmK_pthetaLabel             matlab.ui.control.Label
        BlueDXGainEditField             matlab.ui.control.NumericEditField
        mathrmK_dXLabel_3               matlab.ui.control.Label
        BlackDXGainEditField            matlab.ui.control.NumericEditField
        mathrmK_dXLabel_2               matlab.ui.control.Label
        RedDXGainEditField              matlab.ui.control.NumericEditField
        mathrmK_dXLabel                 matlab.ui.control.Label
        DerivativeGainsLabel            matlab.ui.control.Label
        BluePXGainEditField             matlab.ui.control.NumericEditField
        mathrmK_pXLabel_3               matlab.ui.control.Label
        BlackPXGainEditField            matlab.ui.control.NumericEditField
        mathrmK_pXLabel_2               matlab.ui.control.Label
        RedPXGainEditField              matlab.ui.control.NumericEditField
        ProportionalGainsLabel          matlab.ui.control.Label
        Step3ActiveHardwarePanel        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        BLACKCheckBox                   matlab.ui.control.CheckBox
        ARMCheckBox                     matlab.ui.control.CheckBox
        BLUECheckBox                    matlab.ui.control.CheckBox
        REDCheckBox                     matlab.ui.control.CheckBox
        SetupRunSimulationsTab          matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        SimulationSettingsPanel         matlab.ui.container.Panel
        GridLayout3_4                   matlab.ui.container.GridLayout
        SolverNameVarStepDropDown       matlab.ui.control.DropDown
        VarStepAbsTolEditField          matlab.ui.control.EditField
        VarStepRelTolEditField          matlab.ui.control.EditField
        VarStepInitStepSizeEditField    matlab.ui.control.EditField
        VarStepMinStepSizeEditField     matlab.ui.control.EditField
        VarStepMaxStepSizeEditField     matlab.ui.control.EditField
        AnimationOptionsLabel           matlab.ui.control.Label
        CreateVideoCheckBox             matlab.ui.control.CheckBox
        NoiseOptionsLabel               matlab.ui.control.Label
        DurPhase0Units_35               matlab.ui.control.Label
        DurPhase0Units_34               matlab.ui.control.Label
        DurPhase0Units_33               matlab.ui.control.Label
        DurPhase0Units_32               matlab.ui.control.Label
        DurPhase0Units_31               matlab.ui.control.Label
        SolverTypeDropDownLabel_7       matlab.ui.control.Label
        SolverTypeDropDownLabel_6       matlab.ui.control.Label
        SolverTypeDropDownLabel_5       matlab.ui.control.Label
        SolverTypeDropDownLabel_4       matlab.ui.control.Label
        VariableStepOptionsLabel        matlab.ui.control.Label
        SolverOptionsLabel              matlab.ui.control.Label
        SolverTypeDropDownLabel_3       matlab.ui.control.Label
        DurPhase0Units_30               matlab.ui.control.Label
        NoiseVarianceBlueEditField      matlab.ui.control.NumericEditField
        NoiseVarBLUELabel               matlab.ui.control.Label
        DurPhase0Units_29               matlab.ui.control.Label
        NoiseVarianceBlackEditField     matlab.ui.control.NumericEditField
        NoiseVarBLACKLabel              matlab.ui.control.Label
        DurPhase0Units_28               matlab.ui.control.Label
        NoiseVarianceRedEditField       matlab.ui.control.NumericEditField
        NoiseVarREDLabel                matlab.ui.control.Label
        SolverNameDropDown              matlab.ui.control.DropDown
        SolverTypeDropDownLabel_2       matlab.ui.control.Label
        SolverTypeDropDown              matlab.ui.control.DropDown
        SolverTypeDropDownLabel         matlab.ui.control.Label
        Step7RunSimulationPanel         matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout32                    matlab.ui.container.GridLayout
        SkipToLabel                     matlab.ui.control.Label
        DurPhase0Units_52               matlab.ui.control.Label
        SkipToTimeEditField             matlab.ui.control.Spinner
        PauseAnimationButton            matlab.ui.control.StateButton
        GridLayout26                    matlab.ui.container.GridLayout
        AnimationSpeedSlider            matlab.ui.control.Slider
        AnimationSpeedLabel             matlab.ui.control.Label
        GridLayout25                    matlab.ui.container.GridLayout
        AnimationScrubberSlider         matlab.ui.control.Slider
        AnimationScrubberLabel          matlab.ui.control.Label
        SaveSimulationDataButton        matlab.ui.control.Button
        StopAnimationButton             matlab.ui.control.StateButton
        PlayAnimationButton             matlab.ui.control.Button
        StartSimulationButton           matlab.ui.control.Button
        ExperimentPreview               matlab.ui.control.UIAxes
        SetupRunExperimentsTab          matlab.ui.container.Tab
        GridLayout19_2                  matlab.ui.container.GridLayout
        GridLayout33                    matlab.ui.container.GridLayout
        ConnectionStatusLabel           matlab.ui.control.Label
        ConnectionStatus_1              matlab.ui.control.Lamp
        Step8EstablishConnectionwithSelectedPlatformsButtonRepeat  matlab.ui.control.Button
        RealTimePhaseSpaceDataPanel     matlab.ui.container.Panel
        GridLayout9_4                   matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        GridLayout27                    matlab.ui.container.GridLayout
        RealTimeTelOnLamp               matlab.ui.control.Lamp
        ToggleRealTimeTelemetrySwitch   matlab.ui.control.Switch
        EnableRealTimeTelemetryLabel    matlab.ui.control.Label
        DurPhase0Units_44               matlab.ui.control.Label
        DurPhase0Units_43               matlab.ui.control.Label
        DurPhase0Units_42               matlab.ui.control.Label
        DurPhase0Units_41               matlab.ui.control.Label
        DurPhase0Units_40               matlab.ui.control.Label
        DurPhase0Units_39               matlab.ui.control.Label
        DurPhase0Units_38               matlab.ui.control.Label
        DurPhase0Units_37               matlab.ui.control.Label
        DurPhase0Units_36               matlab.ui.control.Label
        mathrmtheta_BLUEEditField       matlab.ui.control.NumericEditField
        mathrmtheta_BLUEEditFieldLabel  matlab.ui.control.Label
        mathrmY_BLUEEditField           matlab.ui.control.NumericEditField
        mathrmY_BLUEEditFieldLabel      matlab.ui.control.Label
        mathrmX_BLUEEditField           matlab.ui.control.NumericEditField
        mathrmX_BLUEEditFieldLabel      matlab.ui.control.Label
        mathrmtheta_BLACKEditField      matlab.ui.control.NumericEditField
        mathrmtheta_BLACKEditFieldLabel  matlab.ui.control.Label
        mathrmY_BLACKEditField          matlab.ui.control.NumericEditField
        mathrmY_BLACKEditFieldLabel     matlab.ui.control.Label
        mathrmX_BLACKEditField          matlab.ui.control.NumericEditField
        mathrmX_BLACKEditFieldLabel     matlab.ui.control.Label
        mathrmtheta_REDEditField        matlab.ui.control.NumericEditField
        mathrmtheta_REDEditFieldLabel   matlab.ui.control.Label
        mathrmY_REDEditField            matlab.ui.control.NumericEditField
        mathrmY_REDEditFieldLabel       matlab.ui.control.Label
        mathrmX_REDEditField            matlab.ui.control.NumericEditField
        mathrmX_REDEditFieldLabel       matlab.ui.control.Label
        RealTimeVisualizationPanel      matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        ExperimentPreviewLive           matlab.ui.control.UIAxes
        ExperimentControlsPanel         matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout28                    matlab.ui.container.GridLayout
        CamerasOnLamp                   matlab.ui.control.Lamp
        PhaseSpaceSwitch                matlab.ui.control.Switch
        PhaseSpaceCameraLabel           matlab.ui.control.Label
        BuildCode                       matlab.ui.control.Button
        SaveExperimentDataButton        matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        StartExperimentButton           matlab.ui.control.Button
        DirectHardwareControlTab        matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        GridLayout33_2                  matlab.ui.container.GridLayout
        ConnectionStatusLabel_2         matlab.ui.control.Label
        ConnectionStatus_2              matlab.ui.control.Lamp
        ExtrasPanel                     matlab.ui.container.Panel
        GridLayout20_4                  matlab.ui.container.GridLayout
        RefreshSoftwareonPlatformsButton  matlab.ui.control.Button
        Step8EstablishConnectionwithSelectedPlatformsButton  matlab.ui.control.Button
        BLUEPlatformLiveControlsPanel   matlab.ui.container.Panel
        GridLayout20_3                  matlab.ui.container.GridLayout
        OpenBLUECMDShell                matlab.ui.control.Button
        CyclePinsButtonBLUE             matlab.ui.control.Button
        FireBLUEThrustersButton         matlab.ui.control.Button
        BLUETextArea                    matlab.ui.control.TextArea
        BLUEConnectedLamp               matlab.ui.control.Lamp
        PingBLUEButton                  matlab.ui.control.Button
        BLUEPucksOffButton              matlab.ui.control.Button
        BLUEPucksOnButton               matlab.ui.control.Button
        BLACKPlatformLiveControlsPanel  matlab.ui.container.Panel
        GridLayout20_2                  matlab.ui.container.GridLayout
        BLACKTextArea                   matlab.ui.control.TextArea
        OpenBLACKCMDShell               matlab.ui.control.Button
        CyclePinsButtonBLACK            matlab.ui.control.Button
        FireBLACKThrustersButton        matlab.ui.control.Button
        BLACKConnectedLamp              matlab.ui.control.Lamp
        PingBLACKButton                 matlab.ui.control.Button
        BLACKPucksOffButton             matlab.ui.control.Button
        BLACKPucksOnButton              matlab.ui.control.Button
        REDPlatformLiveControlsPanel    matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        OpenREDCMDShell                 matlab.ui.control.Button
        CyclePinsButtonRED              matlab.ui.control.Button
        FireREDThrustersButton          matlab.ui.control.Button
        REDConnectedLamp                matlab.ui.control.Lamp
        REDTextArea                     matlab.ui.control.TextArea
        PingREDButton                   matlab.ui.control.Button
        REDPucksOffButton               matlab.ui.control.Button
        REDPucksOnButton                matlab.ui.control.Button
        DataInspectorTab                matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        InspectSimulationandExperimentalDataPanel  matlab.ui.container.Panel
        GridLayout9_3                   matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        Image_3                         matlab.ui.control.Image
        DataViewer1PDFName_3            matlab.ui.control.EditField
        DataViewer2PDFName              matlab.ui.control.EditField
        ExporttoPDFButton2              matlab.ui.control.Button
        SelectYDataPlt2                 matlab.ui.control.DropDown
        SelectXDataPlt2                 matlab.ui.control.DropDown
        LoadDataButtonPlt2              matlab.ui.control.Button
        SelectYAxisLabel_2              matlab.ui.control.Label
        SelectXAxisLabel_2              matlab.ui.control.Label
        SelectDataFileLabel_2           matlab.ui.control.Label
        DataViewerPlt2                  matlab.ui.control.UIAxes
        GridLayout29                    matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.Image
        DataViewer1PDFName_2            matlab.ui.control.EditField
        DataViewer1PDFName              matlab.ui.control.EditField
        SelectYAxisLabel                matlab.ui.control.Label
        SelectYDataPlt1                 matlab.ui.control.DropDown
        SelectXDataPlt1                 matlab.ui.control.DropDown
        SelectXAxisLabel                matlab.ui.control.Label
        ExporttoPDFButton               matlab.ui.control.Button
        LoadDataButtonPlt1              matlab.ui.control.Button
        SelectDataFileLabel             matlab.ui.control.Label
        DataViewerPlt1                  matlab.ui.control.UIAxes
    end

    
properties (Access = public)
        REDConnection;
        BLACKConnection;
        BLUEConnection;
        RED_Process_ID;
        BLUE_Process_ID;
        BLACK_Process_ID;
        REDFigHdl;
        BLACKFigHdl;
        BLUEFigHdl;
        REDFigLine;
        BLACKFigLine;
        BLUEFigLine;
        REDFigLink1;
        REDFigLink2;
        REDFigLink3;
        REDFigJoin0;
        REDFigJoin1;
        REDFigJoin2;
        REDFigJoin3;
        REDFigLiveHdl;
        BLACKFigLiveHdl;
        BLUEFigLiveHdl;
        REDFigLiveLine;
        BLACKFigLiveLine;
        BLUEFigLiveLine;
        REDFigLiveLink1;
        REDFigLiveLink2;
        REDFigLiveLink3;
        REDFigLiveJoin0;
        REDFigLiveJoin1;
        REDFigLiveJoin2;
        REDFigLiveJoin3;
        Interruptor;
        CompileDir;
        DataPacketUp;
            
        Phase3_SubPhase1_Duration;
        Phase3_SubPhase2_Duration;
        Phase3_SubPhase3_Duration;
        Phase3_SubPhase4_Duration;

        Phase0_Duration;
        Phase1_Duration;
        Phase2_Duration;
        Phase3_Duration;
        Phase4_Duration;
        Phase5_Duration;

        Phase3_SubPhase1_End;
        Phase3_SubPhase2_End;
        Phase3_SubPhase3_End;
        Phase3_SubPhase4_End;

        Phase0_End;
        Phase1_End;
        Phase2_End;
        Phase3_End;
        Phase4_End;
        Phase5_End;

        tsim;

        Kp_xr;
        Kd_xr;
        Kp_yr;
        Kd_yr;
        Kp_tr;
        Kd_tr;
        Kp_xb;
        Kd_xb;
        Kp_yb;
        Kd_yb;
        Kp_tb;
        Kd_tb;
        Kp_xblue;
        Kd_xblue;
        Kp_yblue;
        Kd_yblue;
        Kp_tblue;
        Kd_tblue;   

        F_red_X_nominal;
        F_red_Y_nominal; 
        F_thrusters_RED;      
        F_black_X_nominal;
        F_black_Y_nominal; 
        F_thrusters_BLACK;   
        F_blue_X_nominal;
        F_blue_Y_nominal; 
        F_thrusters_BLUE;   

        thruster_count_threshold;

        baseRate;
        PreviousSamplingRate;

        mRED;
        mBLACK;
        mBLUE;
        mPROX;
        mDIST;
        mEE;
        IRED;
        IBLACK;
        IBLUE;
        IPROX;
        IDIST;
        IEE;

        Phi;
        b0;
        a1;
        b1;
        a2;
        b2;
        a3;
        b3;

        xLength;
        yLength;

        noise_variance_RED;
        noise_variance_BLACK;
        noise_variance_BLUE;

        varStepMaxStepSize;
        varStepMinStepSize;
        varStepInitStepSize;
        varStepRelTol;
        varStepAbsTol;

        LoadedData1;
        LoadedData2;

        DataPacket;

        SubAppMassProperties;
        SubAppInitialConditions;

        isExperimentRunning;
        hudpr;

        ExternalAppState;
                   
    end

    methods (Access = public)

        % This function checks if an SLX file is already open in MATLAB        
        function is_open = isSLXOpen(~,file_name)
            
            % Remove the '.slx'
            file_name = strrep(file_name,".slx","");    
            
            % Get a list of all open models
            open_models = find_system('Type', 'block_diagram');
            
            % Loop through the open models and check if the file path matches
            for i = 1:length(open_models)
                [~, open_filename, ~] = fileparts(get_param(open_models{i}, 'FileName'));
                if strcmp(string(open_filename), file_name)
                    % If the file path matches, the file is already open
                    is_open = true;
                    return;
                end
            end
            
            % If we reach this point, the file is not open
            is_open = false;
            
        end

        % This function draws the platforms based on the
        % 'platformSelection' variable.
        function [] = AddPlatformSim(app,platformSelection)
            
            cla(app.ExperimentPreview);
            app.REDFigHdl = []; 
            app.BLACKFigHdl = [];
            app.BLUEFigHdl = [];
            app.REDFigLine  = [];
            app.BLACKFigLine= [];
            app.BLUEFigLine = [];
            app.REDFigLink1 = [];
            app.REDFigLink2 = [];
            app.REDFigLink3 = [];
            app.REDFigJoin0 = [];
            app.REDFigJoin1 = [];
            app.REDFigJoin2 = [];
            app.REDFigJoin3 = [];

            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreview,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreview.XAxisLocation = "top";
            app.ExperimentPreview.XLim          = [-0.65 3];
            app.ExperimentPreview.YLim          = [-0.5 4];
            
            % Plot the table:
            line(app.ExperimentPreview,[0 app.yLength],[0 0],"Color","black","LineWidth",2);
            hold(app.ExperimentPreview,"on");
            line(app.ExperimentPreview,[app.yLength app.yLength],[0 app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[app.yLength 0],[app.xLength app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[0 0],[app.xLength 0],"Color","black","LineWidth",2);
            grid(app.ExperimentPreview,"on");    
            
            % Get the drop states for all three platforms:
            drop_states_RED   = evalin("base","drop_states_RED");
            drop_states_ARM   = evalin("base","drop_states_ARM");
            drop_states_BLACK = evalin("base","drop_states_BLACK");
            drop_states_BLUE  = evalin("base","drop_states_BLUE");
            
            % For animations purposes, invert the angles:
            drop_states_RED(3)  = -drop_states_RED(3);
            drop_states_BLACK(3)= -drop_states_BLACK(3);
            drop_states_BLUE(3) = -drop_states_BLUE(3);
            
            % Draw the line showing the direction of the front face:
            C_bI_RED = [  cos(drop_states_RED(3)) sin(drop_states_RED(3))
                         -sin(drop_states_RED(3)) cos(drop_states_RED(3)) ];
                     
            Vec_RED  = C_bI_RED*[ 0.15
                                  0.0 ];
                              
            C_bI_BLACK = [  cos(drop_states_BLACK(3)) sin(drop_states_BLACK(3))
                           -sin(drop_states_BLACK(3)) cos(drop_states_BLACK(3)) ];
                     
            Vec_BLACK  = C_bI_BLACK*[ 0.15
                                      0.0 ];    
                              
            C_bI_BLUE = [  cos(drop_states_BLUE(3)) sin(drop_states_BLUE(3))
                          -sin(drop_states_BLUE(3)) cos(drop_states_BLUE(3)) ];
                     
            Vec_BLUE  = C_bI_BLUE*[ 0.15
                                    0.0 ];                              
            x0 = drop_states_RED(2);
            y0 = drop_states_RED(1);
            q0 = drop_states_RED(3);
            q1 = (-drop_states_ARM(1) + 3*pi/2);
            q2 = -drop_states_ARM(2);
            q3 = -drop_states_ARM(3);         
            
            rs                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) ];                        
                        
            re                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) ];

            rw                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) ];

            ree                     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                                
                                
            % Draw the relevant platforms:
            if (platformSelection == 1)
                
                app.REDFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                
            elseif (platformSelection == 2)
                
                app.BLACKFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k");   
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 3)
                
                app.REDFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLACKFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 4)
                
                app.REDFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLACKFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 5)
                
                app.REDFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k');
                
            elseif (platformSelection == 6)
                
                app.BLUEFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 7)
                
                app.BLUEFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");
                app.REDFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 8)
                
                app.REDFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLUEFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 9)
                
                app.BLUEFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                                   
            elseif (platformSelection == 10)
                
                app.BLUEFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b"); 
                app.REDFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 11)
                
                app.REDFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLUEFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivateSimulation(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k');
                
            end


        end

        % This function draws the platforms based on the
        % 'platformSelection' variable.
        function [ax] = AddPlatformExternalFig(app,platformSelection)
            
            fig = figure();
            app.REDFigHdl = []; 
            app.BLACKFigHdl = [];
            app.BLUEFigHdl = [];
            app.REDFigLine  = [];
            app.BLACKFigLine= [];
            app.BLUEFigLine = [];
            app.REDFigLink1 = [];
            app.REDFigLink2 = [];
            app.REDFigLink3 = [];
            app.REDFigJoin0 = [];
            app.REDFigJoin1 = [];
            app.REDFigJoin2 = [];
            app.REDFigJoin3 = [];

            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            ax = gca;
            axis(ax,"ij");
            
            % Set the remaining axes properties:
            ax.XAxisLocation = "top";
            ax.XLim          = [-0.65 3];
            ax.YLim          = [-0.5 4];
            set(gcf,'Position',[1000         262         560         640]);
            xlabel(ax,'Y-Position [m]');
            ylabel(ax,'X-Position [m]');
            
            % Plot the table:
            line(ax,[0 app.yLength],[0 0],"Color","black","LineWidth",2);
            hold(ax,"on");
            line(ax,[app.yLength app.yLength],[0 app.xLength],"Color","black","LineWidth",2);
            line(ax,[app.yLength 0],[app.xLength app.xLength],"Color","black","LineWidth",2);
            line(ax,[0 0],[app.xLength 0],"Color","black","LineWidth",2);
            grid(ax,"on");    
            
            % Get the drop states for all three platforms:
            drop_states_RED   = evalin("base","drop_states_RED");
            drop_states_ARM   = evalin("base","drop_states_ARM");
            drop_states_BLACK = evalin("base","drop_states_BLACK");
            drop_states_BLUE  = evalin("base","drop_states_BLUE");
            
            % For animations purposes, invert the angles:
            drop_states_RED(3)  = -drop_states_RED(3);
            drop_states_BLACK(3)= -drop_states_BLACK(3);
            drop_states_BLUE(3) = -drop_states_BLUE(3);
            
            % Draw the line showing the direction of the front face:
            C_bI_RED = [  cos(drop_states_RED(3)) sin(drop_states_RED(3))
                         -sin(drop_states_RED(3)) cos(drop_states_RED(3)) ];
                     
            Vec_RED  = C_bI_RED*[ 0.15
                                  0.0 ];
                              
            C_bI_BLACK = [  cos(drop_states_BLACK(3)) sin(drop_states_BLACK(3))
                           -sin(drop_states_BLACK(3)) cos(drop_states_BLACK(3)) ];
                     
            Vec_BLACK  = C_bI_BLACK*[ 0.15
                                      0.0 ];    
                              
            C_bI_BLUE = [  cos(drop_states_BLUE(3)) sin(drop_states_BLUE(3))
                          -sin(drop_states_BLUE(3)) cos(drop_states_BLUE(3)) ];
                     
            Vec_BLUE  = C_bI_BLUE*[ 0.15
                                    0.0 ];                              
            x0 = drop_states_RED(2);
            y0 = drop_states_RED(1);
            q0 = drop_states_RED(3);
            q1 = (-drop_states_ARM(1) + 3*pi/2);
            q2 = -drop_states_ARM(2);
            q3 = -drop_states_ARM(3);        
            
            rs                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) ];                        
                        
            re                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) ];

            rw                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) ];

            ree                     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                                
                                
            % Draw the relevant platforms:
            if (platformSelection == 1)
                
                app.REDFigHdl = DrawRectanglePrivateExternal(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r",ax);
                app.REDFigLine = line(ax,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                
            elseif (platformSelection == 2)
                
                app.BLACKFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k",ax);   
                app.BLACKFigLine = line(ax,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 3)
                
                app.REDFigHdl = DrawRectanglePrivateExternal(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r",ax);
                app.BLACKFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k",ax); 
                app.REDFigLine = line(ax,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(ax,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 4)
                
                app.REDFigHdl = DrawRectanglePrivateExternal(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r",ax);
                app.BLACKFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k",ax); 
                app.REDFigLine = line(ax,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(ax,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.REDFigLink1 = line(ax,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(ax,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(ax,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(ax,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(ax,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(ax,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(ax,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 5)
                
                app.REDFigHdl = DrawRectanglePrivateExternal(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r",ax);
                app.REDFigLine = line(ax,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.REDFigLink1 = line(ax,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(ax,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(ax,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(ax,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(ax,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(ax,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(ax,ree(1,1),ree(1,2),'filled','k');
                
            elseif (platformSelection == 6)
                
                app.BLUEFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b",ax);
                app.BLUEFigLine = line(ax,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 7)
                
                app.BLUEFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b",ax);
                app.REDFigHdl = DrawRectanglePrivateExternal(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r",ax);
                app.REDFigLine = line(ax,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(ax,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 8)
                
                app.REDFigHdl = DrawRectanglePrivateExternal(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r",ax);
                app.BLUEFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b",ax);         
                app.BLACKFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k",ax); 
                app.REDFigLine = line(ax,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(ax,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(ax,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 9)
                
                app.BLUEFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b",ax);         
                app.BLACKFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k",ax); 
                app.BLACKFigLine = line(ax,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(ax,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                                   
            elseif (platformSelection == 10)
                
                app.BLUEFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b",ax); 
                app.REDFigHdl = DrawRectanglePrivateExternal(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r",ax); 
                app.REDFigLine = line(ax,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(ax,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLink1 = line(ax,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(ax,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(ax,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(ax,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(ax,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(ax,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(ax,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 11)
                
                app.REDFigHdl = DrawRectanglePrivateExternal(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r",ax);
                app.BLUEFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b",ax);         
                app.BLACKFigHdl = DrawRectanglePrivateExternal(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k",ax); 
                app.REDFigLine = line(ax,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(ax,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(ax,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLink1 = line(ax,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(ax,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(ax,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(ax,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(ax,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(ax,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(ax,ree(1,1),ree(1,2),'filled','k');
                
            end


        end

        function [] = AddPlatformLive(app,platformSelection)
            
            cla(app.ExperimentPreviewLive);
            app.REDFigLiveHdl = []; 
            app.BLACKFigLiveHdl = [];
            app.BLUEFigLiveHdl = [];
            app.REDFigLiveLine  = [];
            app.BLACKFigLiveLine= [];
            app.BLUEFigLiveLine = [];
            app.REDFigLiveLink1 = [];
            app.REDFigLiveLink2 = [];
            app.REDFigLiveLink3 = [];
            app.REDFigLiveJoin0 = [];
            app.REDFigLiveJoin1 = [];
            app.REDFigLiveJoin2 = [];
            app.REDFigLiveJoin3 = [];

            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreviewLive,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreviewLive.XAxisLocation = "top";
            app.ExperimentPreviewLive.XLim          = [-0.65 3];
            app.ExperimentPreviewLive.YLim          = [-0.5 4];
            
            % Plot the table:
            line(app.ExperimentPreviewLive,[0 app.yLength],[0 0],"Color","black","LineWidth",2);
            hold(app.ExperimentPreviewLive,"on");
            line(app.ExperimentPreviewLive,[app.yLength app.yLength],[0 app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreviewLive,[app.yLength 0],[app.xLength app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreviewLive,[0 0],[app.xLength 0],"Color","black","LineWidth",2);
            grid(app.ExperimentPreviewLive,"on");    
            
            % Get the drop states for all three platforms:
            drop_states_RED   = evalin("base","drop_states_RED");
            drop_states_ARM   = evalin("base","drop_states_ARM");
            drop_states_BLACK = evalin("base","drop_states_BLACK");
            drop_states_BLUE  = evalin("base","drop_states_BLUE");
            
            % For animations purposes, invert the angles:
            drop_states_RED(3)  = -drop_states_RED(3);
            drop_states_BLACK(3)= -drop_states_BLACK(3);
            drop_states_BLUE(3) = -drop_states_BLUE(3);
            
            % Draw the line showing the direction of the front face:
            C_bI_RED = [  cos(drop_states_RED(3)) sin(drop_states_RED(3))
                         -sin(drop_states_RED(3)) cos(drop_states_RED(3)) ];
                     
            Vec_RED  = C_bI_RED*[ 0.15
                                  0.0 ];
                              
            C_bI_BLACK = [  cos(drop_states_BLACK(3)) sin(drop_states_BLACK(3))
                           -sin(drop_states_BLACK(3)) cos(drop_states_BLACK(3)) ];
                     
            Vec_BLACK  = C_bI_BLACK*[ 0.15
                                      0.0 ];    
                              
            C_bI_BLUE = [  cos(drop_states_BLUE(3)) sin(drop_states_BLUE(3))
                          -sin(drop_states_BLUE(3)) cos(drop_states_BLUE(3)) ];
                     
            Vec_BLUE  = C_bI_BLUE*[ 0.15
                                    0.0 ];                              
            x0 = drop_states_RED(2);
            y0 = drop_states_RED(1);
            q0 = drop_states_RED(3);
            q1 = (-drop_states_ARM(1) + 3*pi/2);
            q2 = -drop_states_ARM(2);
            q3 = -drop_states_ARM(3);       
            
            rs                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) ];                        
                        
            re                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) ];

            rw                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) ];

            ree                     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                                
                                
            % Draw the relevant platforms:
            if (platformSelection == 1)
                
                app.REDFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                
            elseif (platformSelection == 2)
                
                app.BLACKFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k");   
                app.BLACKFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 3)
                
                app.REDFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLACKFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 4)
                
                app.REDFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLACKFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.REDFigLiveLink1 = line(app.ExperimentPreviewLive,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLiveLink2 = line(app.ExperimentPreviewLive,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLiveLink3 = line(app.ExperimentPreviewLive,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigLiveJoin0 = scatter(app.ExperimentPreviewLive,rs(1,1),rs(1,2),'filled','k');
                app.REDFigLiveJoin1 = scatter(app.ExperimentPreviewLive,re(1,1),re(1,2),'filled','k');
                app.REDFigLiveJoin2 = scatter(app.ExperimentPreviewLive,rw(1,1),rw(1,2),'filled','k');
                app.REDFigLiveJoin3 = scatter(app.ExperimentPreviewLive,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 5)
                
                app.REDFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.REDFigLiveLink1 = line(app.ExperimentPreviewLive,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLiveLink2 = line(app.ExperimentPreviewLive,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLiveLink3 = line(app.ExperimentPreviewLive,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigLiveJoin0 = scatter(app.ExperimentPreviewLive,rs(1,1),rs(1,2),'filled','k');
                app.REDFigLiveJoin1 = scatter(app.ExperimentPreviewLive,re(1,1),re(1,2),'filled','k');
                app.REDFigLiveJoin2 = scatter(app.ExperimentPreviewLive,rw(1,1),rw(1,2),'filled','k');
                app.REDFigLiveJoin3 = scatter(app.ExperimentPreviewLive,ree(1,1),ree(1,2),'filled','k');
                
            elseif (platformSelection == 6)
                
                app.BLUEFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");
                app.BLUEFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 7)
                
                app.BLUEFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");
                app.REDFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 8)
                
                app.REDFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLUEFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 9)
                
                app.BLUEFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.BLACKFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                                   
            elseif (platformSelection == 10)
                
                app.BLUEFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b"); 
                app.REDFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r"); 
                app.REDFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLiveLink1 = line(app.ExperimentPreviewLive,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLiveLink2 = line(app.ExperimentPreviewLive,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLiveLink3 = line(app.ExperimentPreviewLive,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigLiveJoin0 = scatter(app.ExperimentPreviewLive,rs(1,1),rs(1,2),'filled','k');
                app.REDFigLiveJoin1 = scatter(app.ExperimentPreviewLive,re(1,1),re(1,2),'filled','k');
                app.REDFigLiveJoin2 = scatter(app.ExperimentPreviewLive,rw(1,1),rw(1,2),'filled','k');
                app.REDFigLiveJoin3 = scatter(app.ExperimentPreviewLive,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 11)
                
                app.REDFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLUEFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigLiveHdl = DrawRectanglePrivateLive(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLiveLine = line(app.ExperimentPreviewLive,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLiveLink1 = line(app.ExperimentPreviewLive,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLiveLink2 = line(app.ExperimentPreviewLive,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLiveLink3 = line(app.ExperimentPreviewLive,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigLiveJoin0 = scatter(app.ExperimentPreviewLive,rs(1,1),rs(1,2),'filled','k');
                app.REDFigLiveJoin1 = scatter(app.ExperimentPreviewLive,re(1,1),re(1,2),'filled','k');
                app.REDFigLiveJoin2 = scatter(app.ExperimentPreviewLive,rw(1,1),rw(1,2),'filled','k');
                app.REDFigLiveJoin3 = scatter(app.ExperimentPreviewLive,ree(1,1),ree(1,2),'filled','k');
                
            end


        end 

        % This function creates a rectangle in a plot. 
        function [FigHdl] = DrawRectanglePrivateSimulation(app,param,style)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            
            [m,n] = size(param);
            
            if(m ~= 1 || n ~= 5)
                error("param should be an 1x5 array.");
            end
            
            if(param(3) <=0 || param(4) <=0)
                error("width and height must be positive values.");
            end
            
            a = param(1);
            b = param(2);
            w = param(3);
            h = param(4);
            theta = param(5);
            
            X = [-w/2 w/2 w/2 -w/2 -w/2];
            Y = [h/2 h/2 -h/2 -h/2 h/2];
            P = [X;Y];
            ct = cos(theta);
            st = sin(theta);
            R = [ct -st;st ct];
            P = R * P;
            
            FigHdl = plot(app.ExperimentPreview,P(1,:)+a,P(2,:)+b,style);  
            
        end

        % This function creates a rectangle in a plot. 
        function [FigHdl] = DrawRectanglePrivateExternal(app,param,style,fig)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            
            [m,n] = size(param);
            
            if(m ~= 1 || n ~= 5)
                error("param should be an 1x5 array.");
            end
            
            if(param(3) <=0 || param(4) <=0)
                error("width and height must be positive values.");
            end
            
            a = param(1);
            b = param(2);
            w = param(3);
            h = param(4);
            theta = param(5);
            
            X = [-w/2 w/2 w/2 -w/2 -w/2];
            Y = [h/2 h/2 -h/2 -h/2 h/2];
            P = [X;Y];
            ct = cos(theta);
            st = sin(theta);
            R = [ct -st;st ct];
            P = R * P;
            
            FigHdl = plot(fig,P(1,:)+a,P(2,:)+b,style);  
            
        end


        % This function creates a rectangle in a plot. 
        function [FigHdl] = DrawRectanglePrivateLive(app,param,style)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            
            [m,n] = size(param);
            
            if(m ~= 1 || n ~= 5)
                error("param should be an 1x5 array.");
            end
            
            if(param(3) <=0 || param(4) <=0)
                error("width and height must be positive values.");
            end
            
            a = param(1);
            b = param(2);
            w = param(3);
            h = param(4);
            theta = param(5);
            
            X = [-w/2 w/2 w/2 -w/2 -w/2];
            Y = [h/2 h/2 -h/2 -h/2 h/2];
            P = [X;Y];
            ct = cos(theta);
            st = sin(theta);
            R = [ct -st;st ct];
            P = R * P;
            
            FigHdl = plot(app.ExperimentPreviewLive,P(1,:)+a,P(2,:)+b,style);  
            
        end       
        
        % This function takes a data packet and then creates a dictionary.
        % The keys in the dictionary are fixed, but if the packet contains
        % more data then there are keys, the last keys will have the name
        % 'CustomUserData' with the index appended to the key. 
        function [dataClass] = ApplyDataDictionary(~,dataPacket)

            dataClass = [];
            
            dataLabels = {'Time_s'...
                      'RED_Fx_Sat_N' ...
                      'RED_Fy_Sat_N' ...
                      'RED_Tz_Sat_Nm' ...
                      'RED_Px_m' ...
                      'RED_Py_m' ...
                      'RED_Rz_rad' ...
                      'RED_Vx_mpers' ...
                      'RED_Vy_mpers' ...
                      'RED_RzD_radpers' ...
                      'RED_Ax_mpers2' ...
                      'RED_Ay_mpers2' ...
                      'RED_RzDD_radpers2' ...
                      'BLACK_Fx_Sat_N' ...
                      'BLACK_Fy_Sat_N' ...
                      'BLACK_Tz_Sat_Nm' ...
                      'BLACK_Px_m' ...
                      'BLACK_Py_m' ...
                      'BLACK_Rz_rad' ...
                      'BLACK_Vx_mpers' ...
                      'BLACK_Vy_mpers' ...
                      'BLACK_RzD_radpers' ...
                      'BLACK_Ax_mpers2' ...
                      'BLACK_Ay_mpers2' ...
                      'BLACK_RzDD_radpers2' ...
                      'BLUE_Fx_Sat_N' ...
                      'BLUE_Fy_Sat_N' ...
                      'BLUE_Tz_Sat_Nm' ...
                      'BLUE_Px_m' ...
                      'BLUE_Py_m' ...
                      'BLUE_Rz_rad' ...
                      'BLUE_Vx_mpers' ...
                      'BLUE_Vy_mpers' ...
                      'BLUE_RzD_radpers' ...
                      'ARM_Shoulder_Rz_rad' ...
                      'ARM_Elbow_Rz_rad' ...
                      'ARM_Wrist_Rz_rad' ...
                      'ARM_Shoulder_RzD_radpers' ...
                      'ARM_Elbow_RzD_radpers' ...
                      'ARM_Wrist_RzD_radpers' ...
                      'ARM_Command_Mode' ...
                      'ARM_Shoulder_Command' ...
                      'ARM_Elbow_Command'...
                      'ARM_Wrist_Command'...
                      'RED_Thruster_ON_Count'...
                      'BLACK_Thruster_ON_Count'...
                      'BLUE_Thruster_ON_Count'};
                                  
                for sizePacket = 1:size(dataPacket,2)
                    try
                        eval(['dataClass.' dataLabels{sizePacket} ' = dataPacket(:,sizePacket);']) %#ok<EVLDOT>
                    catch
                        eval(['dataClass.CustomUserData' num2str(sizePacket) ' = dataPacket(:,sizePacket);']) %#ok<EVLDOT>
                    end
                end
                
                
                %assignin('base','dataClass',dataClass); 
                            
        end
        
        % This function is used for debugging. It enables all pins and then
        % cycles through them in 2 second intervals.
        function CycleAllThrusters(~, Platform)

            % Start a timer
            tic;

            loopThroughTruster = 1;

            while(loopThroughTruster == 1)
                if toc < 2
                    % Turn on channel 0
                    system(Platform,'i2cset -y 8 0x40 0x06 0x00 && i2cset -y 8 0x40 0x07 0x00 && i2cset -y 8 0x40 0x08 0xFF && i2cset -y 8 0x40 0x09 0x0F');
                elseif toc >=2 && toc < 4
                    % Turn off channel 0 and turn on channel 1
                    system(Platform,'i2cset -y 8 0x40 0x08 0x00 && i2cset -y 8 0x40 0x09 0x00 && i2cset -y 8 0x40 0x0C 0xFF && i2cset -y 8 0x40 0x0D 0x0F');
                elseif toc >=4 && toc < 6
                    % Turn off channel 1 and turn on channel 2
                    system(Platform,'i2cset -y 8 0x40 0x0C 0x00 && i2cset -y 8 0x40 0x0D 0x00 && i2cset -y 8 0x40 0x10 0xFF && i2cset -y 8 0x40 0x11 0x0F');
                elseif toc >=6 && toc < 8
                    % Turn off channel 2 and turn on channel 3
                    system(Platform,'i2cset -y 8 0x40 0x10 0x00 && i2cset -y 8 0x40 0x11 0x00 && i2cset -y 8 0x40 0x14 0xFF && i2cset -y 8 0x40 0x15 0x0F');
                elseif toc >=8 && toc < 10
                    % Turn off channel 3 and turn on channel 4
                    system(Platform,'i2cset -y 8 0x40 0x14 0x00 && i2cset -y 8 0x40 0x15 0x00 && i2cset -y 8 0x40 0x18 0xFF && i2cset -y 8 0x40 0x19 0x0F');
                elseif toc >=10 && toc < 12
                    % Turn off channel 4 and turn on channel 5
                    system(Platform,'i2cset -y 8 0x40 0x18 0x00 && i2cset -y 8 0x40 0x19 0x00 && i2cset -y 8 0x40 0x1C 0xFF && i2cset -y 8 0x40 0x1D 0x0F');
                elseif toc >=12 && toc < 14
                    % Turn off channel 5 and turn on channel 6
                    system(Platform,'i2cset -y 8 0x40 0x1C 0x00 && i2cset -y 8 0x40 0x1D 0x00 && i2cset -y 8 0x40 0x20 0xFF && i2cset -y 8 0x40 0x21 0x0F');
                elseif toc >=14 && toc < 16
                    % Turn off channel 6 and turn on channel 7
                    system(Platform,'i2cset -y 8 0x40 0x20 0x00 && i2cset -y 8 0x40 0x21 0x00 && i2cset -y 8 0x40 0x24 0xFF && i2cset -y 8 0x40 0x25 0x0F');
                else
                    % Turn off the last channel 7
                    system(Platform,'i2cset -y 8 0x40 0x24 0x00 && i2cset -y 8 0x40 0x25 0x00'); 
                    loopThroughTruster = 0;
                end
            end
           
        end
        
        % This function disables all key buttons in the application.
        function [] = DisableAllKeyButtons(app)
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.SaveSimulationDataButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.AnimationSpeedSlider,'enable','off');
            set(app.AnimationScrubberSlider,'enable','off');
            set(app.ToggleRealTimeTelemetrySwitch,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off');
            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off');
            set(app.RefreshSoftwareonPlatformsButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off');
            set(app.REDCheckBox,'enable','off');
            set(app.BLACKCheckBox,'enable','off');
            set(app.BLUECheckBox,'enable','off');
            set(app.ARMCheckBox,'enable','off');
            set(app.SolverNameVarStepDropDown,'enable','off');
            set(app.SolverNameDropDown,'enable','off');
            set(app.VarStepAbsTolEditField,'enable','off');
            set(app.VarStepRelTolEditField,'enable','off');
            set(app.VarStepInitStepSizeEditField,'enable','off');
            set(app.VarStepMinStepSizeEditField,'enable','off');
            set(app.VarStepMaxStepSizeEditField,'enable','off');
            set(app.SolverTypeDropDown,'enable','off');
            set(app.PauseAnimationButton, 'enable','off');
            set(app.SkipToTimeEditField,'enable','off');
            set(app.OpenInitialConditionAppButton,'enable','off');
        end
        
        % This function is used to perform a clean shutdown. It manually
        % sets all GPIO pins to low and then unexports them.
        function CleanShutdown(~, Platform)

            try
                system(Platform,'echo 428 > /sys/class/gpio/unexport');
            catch
            end

            try
                system(Platform,'echo 428 > /sys/class/gpio/export');
            catch
            end

            try
                system(Platform,'echo out > /sys/class/gpio/gpio428/direction');
            catch
            end

            try
                system(Platform,'echo 0 > /sys/class/gpio/gpio428/value');
            catch
            end

            try
                system(Platform,'i2cset -y 8 0x40 0x06 0x00 && i2cset -y 8 0x40 0x07 0x00'); % Channel 0 off
            catch
            end
            
            try
                system(Platform,'i2cset -y 8 0x40 0x08 0x00 && i2cset -y 8 0x40 0x09 0x00'); % Channel 1 off
            catch
            end
            
            try
                system(Platform,'i2cset -y 8 0x40 0x0A 0x00 && i2cset -y 8 0x40 0x0B 0x00'); % Channel 2 off
            catch
            end
            
            try
                system(Platform,'i2cset -y 8 0x40 0x0C 0x00 && i2cset -y 8 0x40 0x0D 0x00'); % Channel 3 off
            catch
            end
            
            try
                system(Platform,'i2cset -y 8 0x40 0x0E 0x00 && i2cset -y 8 0x40 0x0F 0x00'); % Channel 4 off
            catch
            end
            
            try
                system(Platform,'i2cset -y 8 0x40 0x10 0x00 && i2cset -y 8 0x40 0x11 0x00'); % Channel 5 off
            catch
            end
            
            try
                system(Platform,'i2cset -y 8 0x40 0x12 0x00 && i2cset -y 8 0x40 0x13 0x00'); % Channel 6 off
            catch
            end
            
            try
                system(Platform,'i2cset -y 8 0x40 0x14 0x00 && i2cset -y 8 0x40 0x15 0x00'); % Channel 7 off
            catch
            end


        end
        
        % This function creates a custom waitbox to force the app to pause
        % while it is processing a user request.
        function d = CustomWaitBox(app,Message)
            fig = app.SPOT40GUIUIFigure;
            d = uiprogressdlg(fig,'Title',Message,...
                'Indeterminate','on');
            drawnow
                   
        end
       
        
        function [] = UpdateTimes(app)
            % Set the duration of each major phase in the experiment, in seconds:
            
            % Set the duration of the experiment sub-phases first. Sub-phases occur during the
            % experiment phase (Phase3_Duration). 
            
            app.Phase3_SubPhase1_Duration      = app.SubPhase1EditField.Value;        % [s]
            app.Phase3_SubPhase2_Duration      = app.SubPhase2EditField.Value;        % [s]
            app.Phase3_SubPhase3_Duration      = app.SubPhase3EditField.Value;        % [s]
            app.Phase3_SubPhase4_Duration      = app.SubPhase4EditField.Value;        % [s]
            
            app.Phase0_Duration                = app.DurPhase0EditField.Value;        % [s]
            app.Phase1_Duration                = app.DurPhase1EditField.Value;         % [s]
            app.Phase2_Duration                = app.DurPhase2EditField.Value;        % [s]
            app.Phase3_Duration                = app.Phase3_SubPhase1_Duration+...
                                                 app.Phase3_SubPhase2_Duration+...
                                                 app.Phase3_SubPhase3_Duration+...
                                                 app.Phase3_SubPhase4_Duration;        % [s]
            app.Phase4_Duration                = app.DurPhase4EditField.Value;         % [s]
            app.Phase5_Duration                = app.DurPhase5EditField.Value;         % [s]
            
            
            % Calculate other time related parameters. USERS SHOULD NOT EDIT THIS.
            
            % Determine the total experiment time from the durations:
            
            app.tsim                           = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration + app.Phase3_Duration + ...
                                                    app.Phase4_Duration + app.Phase5_Duration;        
            
            % Determine the start time of each phase based on the duration:
            
            app.Phase0_End                     = app.Phase0_Duration;
            app.Phase1_End                     = app.Phase0_Duration + app.Phase1_Duration;           
            app.Phase2_End                     = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration;         
            app.Phase3_End                     = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration + app.Phase3_Duration;      
            app.Phase4_End                     = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration + app.Phase3_Duration + ...
                                                    app.Phase4_Duration; 
            app.Phase5_End                     = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration + app.Phase3_Duration + ...
                                                    app.Phase4_Duration + app.Phase5_Duration;                              
                                         
            % Determine the start time of each sub-phase based on the duration:  
            
            app.Phase3_SubPhase1_End           = app.Phase2_End + app.Phase3_SubPhase1_Duration;
            app.Phase3_SubPhase2_End           = app.Phase2_End + app.Phase3_SubPhase1_Duration + ...
                                                    app.Phase3_SubPhase2_Duration;
            app.Phase3_SubPhase3_End           = app.Phase2_End + app.Phase3_SubPhase1_Duration + ...
                                                    app.Phase3_SubPhase2_Duration +...
                                                    app.Phase3_SubPhase3_Duration;
            app.Phase3_SubPhase4_End           = app.Phase2_End + app.Phase3_SubPhase1_Duration + ...
                                                    app.Phase3_SubPhase2_Duration +...
                                                    app.Phase3_SubPhase3_Duration +...
                                                    app.Phase3_SubPhase4_Duration;

            assignin('base','Phase0_End',app.Phase0_End);
            assignin('base','Phase1_End',app.Phase1_End);
            assignin('base','Phase2_End',app.Phase2_End);
            assignin('base','Phase3_End',app.Phase3_End);
            assignin('base','Phase4_End',app.Phase4_End);
            assignin('base','Phase5_End',app.Phase5_End);
            assignin('base','Phase3_SubPhase1_End',app.Phase3_SubPhase1_End);
            assignin('base','Phase3_SubPhase2_End',app.Phase3_SubPhase2_End);
            assignin('base','Phase3_SubPhase3_End',app.Phase3_SubPhase3_End);
            assignin('base','Phase3_SubPhase4_End',app.Phase3_SubPhase4_End);       

            assignin('base','Phase0_Duration',app.Phase0_Duration);
            assignin('base','Phase1_Duration',app.Phase1_Duration);
            assignin('base','Phase2_Duration',app.Phase2_Duration);
            assignin('base','Phase3_Duration',app.Phase3_Duration);
            assignin('base','Phase4_Duration',app.Phase4_Duration);
            assignin('base','Phase5_Duration',app.Phase5_Duration);
            assignin('base','Phase3_SubPhase1_Duration',app.Phase3_SubPhase1_Duration);
            assignin('base','Phase3_SubPhase2_Duration',app.Phase3_SubPhase2_Duration);
            assignin('base','Phase3_SubPhase3_Duration',app.Phase3_SubPhase3_Duration);
            assignin('base','Phase3_SubPhase4_Duration',app.Phase3_SubPhase4_Duration);    
            assignin('base','tsim', app.tsim);

            app.DurPhase3EditField.Value = app.Phase3_Duration;
            app.TotalTimeEditField.Value = app.tsim;

        end
        
        function [] = UpdateAuxParameters(app)

            % Initialize the PID gains for the RED platform:
            
            app.Kp_xr                          = app.RedPXGainEditField.Value;
            app.Kd_xr                          = app.RedDXGainEditField.Value;
            
            app.Kp_yr                          = app.RedPYGainEditField.Value;
            app.Kd_yr                          = app.RedDYGainEditField.Value;
            
            app.Kp_tr                          = app.RedPGainThetaEditField.Value;
            app.Kd_tr                          = app.RedDGainThetaEditField.Value;
            
            % Initialize the PID gains for the BLACK platform:
            
            app.Kp_xb                          = app.BlackPXGainEditField.Value;
            app.Kd_xb                          = app.BlackDXGainEditField.Value;
                                               
            app.Kp_yb                          = app.BlackPYGainEditField.Value;
            app.Kd_yb                          = app.BlackDYGainEditField.Value;
                                               
            app.Kp_tb                          = app.BlackPGainThetaEditField.Value;
            app.Kd_tb                          = app.BlackDGainThetaEditField.Value;
            
            % Initialize the PID gains for the BLUE platform:
            
            app.Kp_xblue                       = app.BluePXGainEditField.Value;
            app.Kd_xblue                       = app.BlueDXGainEditField.Value;
                                               
            app.Kp_yblue                       = app.BluePYGainEditField.Value;
            app.Kd_yblue                       = app.BlueDYGainEditField.Value;
                                               
            app.Kp_tblue                       = app.BluePGainThetaEditField.Value;
            app.Kd_tblue                       = app.BlueDGainThetaEditField.Value;  

            % Update the nominal force values

            app.F_red_X_nominal                = app.REDFXNominal.Value;
            app.F_red_Y_nominal                = app.REDFYNominal.Value; 
            app.F_thrusters_RED                = [app.F_red_X_nominal; app.F_red_X_nominal;...
                            app.F_red_Y_nominal; app.F_red_Y_nominal; app.F_red_X_nominal; ...
                            app.F_red_X_nominal; app.F_red_Y_nominal; app.F_red_Y_nominal];      
            app.F_black_X_nominal              = app.BLACKFXNominal.Value;
            app.F_black_Y_nominal              = app.BLACKFYNominal.Value; 
            app.F_thrusters_BLACK              = [app.F_black_X_nominal; app.F_black_X_nominal;...
                            app.F_black_Y_nominal; app.F_black_Y_nominal; app.F_black_X_nominal; ...
                            app.F_black_X_nominal; app.F_black_Y_nominal; app.F_black_Y_nominal];   
            app.F_blue_X_nominal               = app.BLUEFXNominal.Value;
            app.F_blue_Y_nominal               = app.BLUEFYNominal.Value; 
            app.F_thrusters_BLUE               = [app.F_blue_X_nominal; app.F_blue_X_nominal;...
                            app.F_blue_Y_nominal; app.F_blue_Y_nominal; app.F_blue_X_nominal; ...
                            app.F_blue_X_nominal; app.F_blue_Y_nominal; app.F_blue_Y_nominal];
            
            % Update thruster count threshold
            app.thruster_count_threshold       = app.ThursterCountThresholdEditField.Value;

            % Initialize baseRate 

            app.baseRate                      = 1/app.SampleRateEditField.Value;

            assignin('base','Kp_xr',app.Kp_xr);
            assignin('base','Kd_xr',app.Kd_xr);
            assignin('base','Kp_yr',app.Kp_yr);
            assignin('base','Kd_yr',app.Kd_yr);
            assignin('base','Kp_tr',app.Kp_tr);
            assignin('base','Kd_tr',app.Kd_tr);
            assignin('base','Kp_xb',app.Kp_xb);
            assignin('base','Kd_xb',app.Kd_xb);
            assignin('base','Kp_yb',app.Kp_yb);
            assignin('base','Kd_yb',app.Kd_yb);
            assignin('base','Kp_tb',app.Kp_tb);
            assignin('base','Kd_tb',app.Kd_tb);
            assignin('base','Kp_xblue',app.Kp_xblue);
            assignin('base','Kd_xblue',app.Kd_xblue);
            assignin('base','Kp_yblue',app.Kp_yblue);
            assignin('base','Kd_yblue',app.Kd_yblue);
            assignin('base','Kp_tblue',app.Kp_tblue);
            assignin('base','Kd_tblue',app.Kd_tblue);   
            assignin('base','F_red_X_nominal',app.F_red_X_nominal);
            assignin('base','F_red_Y_nominal',app.F_red_Y_nominal);
            assignin('base','F_Black_X_nominal',app.F_black_X_nominal);
            assignin('base','F_Black_Y_nominal',app.F_black_Y_nominal);
            assignin('base','F_Blue_X_nominal',app.F_blue_X_nominal);
            assignin('base','F_Blue_Y_nominal',app.F_blue_Y_nominal);
            assignin('base','F_thrusters_RED',app.F_thrusters_RED);
            assignin('base','F_thrusters_BLACK',app.F_thrusters_BLACK);
            assignin('base','F_thrusters_BLUE',app.F_thrusters_BLUE);
            assignin('base','baseRate',app.baseRate);
            assignin('base','thruster_count_threshold',app.thruster_count_threshold);

        end

        function [] = UpdateSimulationParameters(app, FileName)

            % Set the noise variance levels
            app.noise_variance_RED   = app.NoiseVarianceRedEditField.Value/1000;
            app.noise_variance_BLACK = app.NoiseVarianceBlackEditField.Value/1000;
            app.noise_variance_BLUE  = app.NoiseVarianceBlueEditField.Value/1000;

            app.varStepMaxStepSize = app.VarStepMaxStepSizeEditField.Value;
            app.varStepMinStepSize = app.VarStepMinStepSizeEditField.Value;
            app.varStepInitStepSize = app.VarStepInitStepSizeEditField.Value;
            app.varStepRelTol = app.VarStepRelTolEditField.Value;
            app.varStepAbsTol = app.VarStepAbsTolEditField.Value;

            if app.SolverTypeDropDown.Value == "Variable-step"
                set_param(FileName,"MaxStep",app.varStepMaxStepSize);
                set_param(FileName,"MinStep",app.varStepMinStepSize);
                set_param(FileName,"InitialStep",app.varStepInitStepSize);
                set_param(FileName,"AbsTol",app.varStepAbsTol);
                set_param(FileName,"RelTol",app.varStepRelTol);
            end

            assignin('base','noise_variance_RED',app.noise_variance_RED);
            assignin('base','noise_variance_BLACK',app.noise_variance_BLACK);
            assignin('base','noise_variance_BLUE',app.noise_variance_BLUE); 
            %app.ConfirmSettings();
        end        
        
        function [] = ConfirmSettings(app)

            FileName = app.AvailableDiagramsDropDown.Value;

            if strcmp(FileName,'Select a Diagram') == 0
                          
                % Based on user selection in the GUI, assign variables to the workspace:
                % First, load in the selections:
                RED         = app.REDCheckBox.Value;
                BLACK       = app.BLACKCheckBox.Value;
                BLUE        = app.BLUECheckBox.Value;
                ARM         = app.ARMCheckBox.Value;
                ErrorMsg    = 0;
                
                % Assign the results to the platformSelection variable:        
                if RED == 1 && BLACK == 0 && BLUE == 0 && ARM == 0
                    assignin("base","platformSelection",1);     % RED
                elseif RED == 0 && BLACK == 1 && BLUE == 0 && ARM == 0
                    assignin("base","platformSelection",2);     % BLACK
                elseif RED == 1 && BLACK == 1 && BLUE == 0 && ARM == 0
                    assignin("base","platformSelection",3);     % RED + BLACK
                elseif RED == 1 && BLACK == 1 && BLUE == 0 && ARM == 1
                    assignin("base","platformSelection",4);     % RED + BLACK + ARM
                elseif RED == 1 && BLACK == 0 && BLUE == 0 && ARM == 1
                    assignin("base","platformSelection",5);     % RED + ARM
                elseif RED == 0 && BLACK == 0 && BLUE == 1 && ARM == 0
                    assignin("base","platformSelection",6);     % BLUE
                elseif RED == 1 && BLACK == 0 && BLUE == 1 && ARM == 0
                    assignin("base","platformSelection",7);     % BLUE + RED
                elseif RED == 1 && BLACK == 1 && BLUE == 1 && ARM == 0
                    assignin("base","platformSelection",8);     % BLUE + RED + BLACK
                elseif RED == 0 && BLACK == 1 && BLUE == 1 && ARM == 0
                    assignin("base","platformSelection",9);     % BLUE + BLACK
                elseif RED == 1 && BLACK == 0 && BLUE == 1 && ARM == 1
                    assignin("base","platformSelection",10);    % BLUE + RED + ARM
                elseif RED == 1 && BLACK == 1 && BLUE == 1 && ARM == 1
                    assignin("base","platformSelection",11);    % BLUE + RED + BLACK + ARM
                elseif RED == 0 && BLACK == 0 && BLUE == 0 && ARM == 0
                    assignin("base","platformSelection",0);    % Nothing
                else
                    ed = errordlg("The combination of platforms selected has not been implemented yet.","Error");
                    set(ed, "WindowStyle", "modal");
                    uiwait(ed);
                    app.REDCheckBox.Value = 0;
                    app.BLACKCheckBox.Value = 0;
                    app.BLUECheckBox.Value = 0;
                    app.ARMCheckBox.Value = 0;
                    ErrorMsg = 1;
                end
                
                if (ErrorMsg~=1)
                    platformSelection = evalin("base","platformSelection");
                    if platformSelection ~= 0
                        set(app.OpenInitialConditionAppButton,'enable','on');
                        AddPlatformSim(app,platformSelection);
                        AddPlatformLive(app,platformSelection);
                        set(app.StartSimulationButton,'enable','on');
                        set(app.Step8EstablishConnectionwithSelectedPlatformsButton,'enable','on');
                        set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','on');
                        app.AnimationScrubberSlider.MajorTicks = [0,20,40,60,80,100];
                        app.AnimationScrubberSlider.MajorTickLabels = {'0 s',...
                            [num2str(0.2*app.tsim) ' s'],[num2str(0.4*app.tsim) ' s'],...
                            [num2str(0.6*app.tsim) ' s'],[num2str(0.8*app.tsim) ' s'],[num2str(app.tsim) ' s']};
                        set(app.SolverNameVarStepDropDown,'enable','on');
                        set(app.SolverNameDropDown,'enable','on');
                        set(app.SolverTypeDropDown,'enable','on');
                        set(app.VarStepAbsTolEditField,'enable','on');
                        set(app.VarStepRelTolEditField,'enable','on');
                        set(app.VarStepInitStepSizeEditField,'enable','on');
                        set(app.VarStepMinStepSizeEditField,'enable','on');
                        set(app.VarStepMaxStepSizeEditField,'enable','on');
            
                        SolverName = get_param(FileName(1:end-4),"SolverName");
                        SolverType = get_param(FileName(1:end-4),"SolverType");
                        
                        app.SolverTypeDropDown.Value = SolverType;
            
                        if SolverType == "Variable-step"
                            app.SolverNameVarStepDropDown.Enable = "on";
                            app.SolverNameVarStepDropDown.Visible = "on";
                            app.SolverNameDropDown.Enable = "off";
                            app.SolverNameDropDown.Visible = "off";
                            app.SolverNameVarStepDropDown.Value = SolverName;
                            MaxStep = get_param(FileName(1:end-4),"MaxStep");
                            MinStep = get_param(FileName(1:end-4),"MinStep");
                            InitStep = get_param(FileName(1:end-4),"InitialStep");
                            AbsTol = get_param(FileName(1:end-4),"AbsTol");
                            RelTol = get_param(FileName(1:end-4),"RelTol");
                            app.VarStepMaxStepSizeEditField.Value = MaxStep;
                            app.VarStepMinStepSizeEditField.Value = MinStep;
                            app.VarStepInitStepSizeEditField.Value = InitStep;
                            app.VarStepAbsTolEditField.Value = AbsTol;
                            app.VarStepRelTolEditField.Value = RelTol;
                            set(app.VarStepAbsTolEditField,'enable','on');
                            set(app.VarStepRelTolEditField,'enable','on');
                            set(app.VarStepInitStepSizeEditField,'enable','on');
                            set(app.VarStepMinStepSizeEditField,'enable','on');
                            set(app.VarStepMaxStepSizeEditField,'enable','on');
                        else
                            app.SolverNameVarStepDropDown.Enable = "off";
                            app.SolverNameVarStepDropDown.Visible = "off";
                            app.SolverNameDropDown.Enable = "on";
                            app.SolverNameDropDown.Visible = "on";
                            app.SolverNameDropDown.Value = SolverName;
                            set(app.VarStepAbsTolEditField,'enable','off');
                            set(app.VarStepRelTolEditField,'enable','off');
                            set(app.VarStepInitStepSizeEditField,'enable','off');
                            set(app.VarStepMinStepSizeEditField,'enable','off');
                            set(app.VarStepMaxStepSizeEditField,'enable','off');
                        end
                        
                        app.UpdateSimulationParameters(FileName(1:end-4));
                    else
                        set(app.OpenInitialConditionAppButton,'enable','off');
                        AddPlatformSim(app,platformSelection);
                        AddPlatformLive(app,platformSelection);
                        set(app.StartSimulationButton,'enable','off');
                        set(app.Step8EstablishConnectionwithSelectedPlatformsButton,'enable','off');
                        set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');  
                        app.SolverNameVarStepDropDown.Enable = "off";
                        app.SolverNameVarStepDropDown.Visible = "off";
                        app.SolverNameDropDown.Enable = "off";
                        app.SolverNameDropDown.Visible = "on";
                        app.SolverTypeDropDown.Enable = "off";
                        set(app.VarStepAbsTolEditField,'enable','off');
                        set(app.VarStepRelTolEditField,'enable','off');
                        set(app.VarStepInitStepSizeEditField,'enable','off');
                        set(app.VarStepMinStepSizeEditField,'enable','off');
                        set(app.VarStepMaxStepSizeEditField,'enable','off');
                    end
                else
                    set(app.OpenInitialConditionAppButton,'enable','off');
                    set(app.StartSimulationButton,'enable','off');
                    set(app.Step8EstablishConnectionwithSelectedPlatformsButton,'enable','off');
                    set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');  
                    app.SolverNameVarStepDropDown.Enable = "off";
                    app.SolverNameVarStepDropDown.Visible = "off";
                    app.SolverNameDropDown.Enable = "off";
                    app.SolverNameDropDown.Visible = "on";
                    app.SolverTypeDropDown.Enable = "off";
                    set(app.VarStepAbsTolEditField,'enable','off');
                    set(app.VarStepRelTolEditField,'enable','off');
                    set(app.VarStepInitStepSizeEditField,'enable','off');
                    set(app.VarStepMinStepSizeEditField,'enable','off');
                    set(app.VarStepMaxStepSizeEditField,'enable','off');
                end            
            end

        end
       
    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PopulateGUI(app)
            
            % Find any instances of the main app already open and close
            % them
            
            f = findall(groot,'Type','figure','Name','SPOT 4.0 GUI');
            close(f(2:end))

            evalin('base','clear');
            evalin('base','clc');

            VersionData = ver;
            if (strcmp(VersionData(1).Release,'(R2023a)') == 0 )
                 waitfor(errordlg({'This version (4.0) of the GUI was built in MATLAB 2023a. Backwards compatibility is possible, but things may not work as expected.',...
                     'Please use the correct version of MALTAB if possible.'},'Version Error','Understood'));
            end

            % Try and add the custom library
            try
                addpath(genpath('../../Custom_Library'));
            catch
                waitfor(warndlg('Adding the custom library to the path failed. You may have to add the directory manually (under Tools).','Failed to Add Path'));
            end

            app.SubAppMassProperties = GUI_v4_0_MassProperties;     
            app.SubAppInitialConditions = GUI_v4_0_InitialConditions;   
            app.SubAppMassProperties.SPOT40MassPropertiesUIFigure.Visible = "off";
            app.SubAppInitialConditions.SPOT40InitialConditionsUIFigure.Visible = "off";

            app.SolverNameVarStepDropDown.Enable = "off";
            app.SolverNameVarStepDropDown.Visible = "off";
                
            % Obtain a list of all .slx files present in the working directory, and populate the dropdown menu:
            MyFolderInfo = dir([cd '\*.slx']);
            FileNames    = {'Select a Diagram',MyFolderInfo.name};
            app.AvailableDiagramsDropDown.Items = FileNames;
            
            app.ResetGUIParameterstoDefaultMenuSelected();

            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreview,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreview.XAxisLocation = "top";
            app.ExperimentPreview.XLim          = [-0.65 3];
            app.ExperimentPreview.YLim          = [-0.5 4];
            
            % Plot the table:
            line(app.ExperimentPreview,[0 app.yLength],[0 0],"Color","black","LineWidth",2);
            hold(app.ExperimentPreview,"on");
            line(app.ExperimentPreview,[app.yLength app.yLength],[0 app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[app.yLength 0],[app.xLength app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[0 0],[app.xLength 0],"Color","black","LineWidth",2);
            grid(app.ExperimentPreview,"on");

            % Flip the axis to match the table:
            axis(app.ExperimentPreviewLive,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreviewLive.XAxisLocation = "top";
            app.ExperimentPreviewLive.XLim          = [-0.65 3];
            app.ExperimentPreviewLive.YLim          = [-0.5 4];
            
            % Plot the table:
            line(app.ExperimentPreviewLive,[0 app.yLength],[0 0],"Color","black","LineWidth",2);
            hold(app.ExperimentPreviewLive,"on");
            line(app.ExperimentPreviewLive,[app.yLength app.yLength],[0 app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreviewLive,[app.yLength 0],[app.xLength app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreviewLive,[0 0],[app.xLength 0],"Color","black","LineWidth",2);
            grid(app.ExperimentPreviewLive,"on");           
            
            % Assign the default value to be 0; this only changes temporarily when the
            % user clicks on the Run Simulation button.
            assignin("base","simMode",1);      
            assignin("base","xLength",app.xLength);
            assignin("base","yLength",app.yLength);

            % Set appropriate enables and disables:
            app.DisableAllKeyButtons();
            
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
        % Get the selected files from the drop down menu:
        waitbox = app.CustomWaitBox('Running simulation...');
        selectedFile = get(app.AvailableDiagramsDropDown,'Value');
        
        if (bdIsLoaded(selectedFile(1:(end-4)))==1)
                  
            % Set the simulation mode in Simulink:
            set_param(selectedFile(1:(end-4)),'SimulationMode','normal');
        
            % Disable hardware mode:
            activeConfigObj = getActiveConfigSet(selectedFile(1:(end-4)));
            set_param(activeConfigObj,'HardwareBoard','None');

            app.UpdateSimulationParameters(selectedFile(1:end-4));

            % Set the simMode to 1 to indicate that a simulation is being
            % performed:
            assignin('base','simMode',1);
            assignin('base','WhoAmI',0);
                
            % Open a dialog indicating tht a simulation is being performed:
                       
            options = simset('SrcWorkspace','base','DstWorkspace','base');
            if strcmp(get_param(selectedFile(1:(end-4)),'Dirty'),'on')
            	save_system(selectedFile(1:(end-4)));
            end
            simOut = sim(selectedFile(1:(end-4)),[],options);
            %set_param(selectedFile(1:(end-4)),'SimulationCommand','start')
            assignin('base','simOut',simOut);
            %SimStatus = "running";
            %while SimStatus ~= "stopped"
            %    SimStatus = get_param(selectedFile(1:(end-4)),'SimulationStatus');
                %drawnow;
            %end
            
            close(waitbox);

            set_param(activeConfigObj,'HardwareBoard','NVIDIA Jetson');
            
            assignin('base','simMode',0);
                
            set(app.PlayAnimationButton,'enable','on');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','on');
            set(app.SaveSimulationDataButton,'enable','on');
            set(app.AnimationSpeedSlider,'enable','on');
            set(app.AnimationScrubberSlider,'enable','on');
            set(app.SkipToTimeEditField,'enable','on');
            
            %simOut = evalin("base","out");
            dataPacket = simOut.dataPacket;
            assignin('base','dataPacket',dataPacket);
            dataClass = app.ApplyDataDictionary(dataPacket); 
            assignin('base','dataClass',dataClass);

            % Downsample the data to reduce the number of points:
            [UniqueTime, UniqueIndex, ~] = unique(dataPacket(:,1));

            NewTimeSlow = linspace(0,UniqueTime(end),UniqueTime(end)*(app.AnimationSpeedSlider.Value/10+1))';

            app.DataPacket = zeros(length(NewTimeSlow),size(dataPacket,2));
            
            for j = 1:size(dataPacket,2)
                app.DataPacket(:,j) = interp1(UniqueTime,dataPacket(UniqueIndex,j),NewTimeSlow);       
            end
            
        else
            ed = errordlg("The selected Simulink diagram has not been loaded. Try selecting it again from the dropdown.","Error");
            set(ed, "WindowStyle", "modal");
            uiwait(ed);            
        end
        
        end

        % Button pushed function: PlayAnimationButton
        function PlayAnimationButtonPushed(app, event)
            W = evalin("base","whos"); 
            doesPacketExist = ismember('dataPacket',[W(:).name]);

            app.PlayAnimationButton.Enable = "off";
            app.SaveSimulationDataButton.Enable = "off";
            app.AnimationScrubberSlider.Enable = "off";
            app.AnimationSpeedSlider.Enable = "off";
            app.SkipToTimeEditField.Enable = "off";
            app.PauseAnimationButton.Enable = 'on';
            app.StopAnimationButton.Enable = "on";
                        
            if (sum(doesPacketExist) == 10)
                
                if app.CreateVideoCheckBox.Value == 1
                    fig = AddPlatformExternalFig(app,evalin("base","platformSelection"));
                else
                    AddPlatformSim(app,evalin("base","platformSelection")); 
                    fig = app.ExperimentPreview;
                end
                
                % Print time text:
                txtT = '';
                text(fig,0.1,3.75,txtT); 
                
                % Load the data packet into the GUI workspace:
                dataPacketUpWS = evalin("base","dataPacket");
                
                % Downsample the data to reduce the number of points:
                [UniqueTime, UniqueIndex, ~] = unique(dataPacketUpWS(:,1));

                NewTimeSlow = linspace(0,UniqueTime(end),UniqueTime(end)*(app.AnimationSpeedSlider.Value/10+1))';

                dataPacket = zeros(length(NewTimeSlow),size(dataPacketUpWS,2));
                
                for j = 1:size(dataPacketUpWS,2)
                    dataPacket(:,j) = interp1(UniqueTime,dataPacketUpWS(UniqueIndex,j),NewTimeSlow);       
                end
                
                app.DataPacketUp = dataPacket;
                app.DataPacket = dataPacketUpWS;

                if app.CreateVideoCheckBox.Value == 1

                    waitbox = app.CustomWaitBox('Saving animation to SimulationVideo.avi...');

                end
                                
                for i = 1:size(dataPacket,1)
                               
                    a = dataPacket(i,6);
                    b = dataPacket(i,5);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,7);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.REDFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_RED = [  cos(theta) sin(theta)
                                     -sin(theta) cos(theta) ];
                                 
                        Vec_RED  = C_bI_RED*[ 0.15
                                              0.0 ];
                                                      
                        app.REDFigLine.XData = [a,a+Vec_RED(2)]; 
                        app.REDFigLine.YData = [b,b+Vec_RED(1)];
                        app.REDFigHdl.XData = P(1,:)+a;
                        app.REDFigHdl.YData = P(2,:)+b;
                    end
                    
                    x0 = dataPacket(i,6);
                    y0 = dataPacket(i,5);
                    q0 = -dataPacket(i,7);
                      
                    q1 = (-dataPacket(i,35) + 3*pi/2);
                    q2 = - dataPacket(i,36);
                    q3 = - dataPacket(i,37);
                    
                    rs                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) ];                        
                                
                    re                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) ];
        
                    rw                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) ];
        
                    ree                     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                    
                    if (isempty(app.REDFigLink1) ~= 1) 
                        
                        app.REDFigLink1.XData = [rs(1,1)  re(1,1)];
                        app.REDFigLink1.YData = [rs(1,2)  re(1,2)];
                        app.REDFigLink2.XData = [re(1,1)  rw(1,1)];
                        app.REDFigLink2.YData = [re(1,2)  rw(1,2)];
                        app.REDFigLink3.XData = [rw(1,1)  ree(1,1)];
                        app.REDFigLink3.YData = [rw(1,2)  ree(1,2)];
                        app.REDFigJoin0.XData = rs(1,1);
                        app.REDFigJoin0.YData = rs(1,2);
                        app.REDFigJoin1.XData = re(1,1);
                        app.REDFigJoin1.YData = re(1,2);
                        app.REDFigJoin2.XData = rw(1,1);
                        app.REDFigJoin2.YData = rw(1,2);
                        app.REDFigJoin3.XData = ree(1,1);
                        app.REDFigJoin3.YData = ree(1,2);
                                                
                    end
                    
                    a = dataPacket(i,18);
                    b = dataPacket(i,17);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,19);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.BLACKFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_BLACK = [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];
                                 
                        Vec_BLACK  = C_bI_BLACK*[ 0.15
                                                  0.0 ];
                                                      
                        app.BLACKFigLine.XData = [a,a+Vec_BLACK(2)]; 
                        app.BLACKFigLine.YData = [b,b+Vec_BLACK(1)];
                        app.BLACKFigHdl.XData = P(1,:)+a;
                        app.BLACKFigHdl.YData = P(2,:)+b;
                        
                    end
                    
                    a = dataPacket(i,30);
                    b = dataPacket(i,29);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,31);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.BLUEFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_BLUE =  [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];
                                 
                        Vec_BLUE  = C_bI_BLUE*[ 0.15
                                                0.0 ];
                                                      
                        app.BLUEFigLine.XData = [a,a+Vec_BLUE(2)]; 
                        app.BLUEFigLine.YData = [b,b+Vec_BLUE(1)];  
                        app.BLUEFigHdl.XData = P(1,:)+a;
                        app.BLUEFigHdl.YData = P(2,:)+b;
                        
                    end                    
          
                    fig.Children(1).String = strcat('Time: ',num2str(dataPacket(i,1)),' s');
                    
                    if (app.StopAnimationButton.Value == 1)
                        app.StopAnimationButton.Value = 0;
                        break;
                    end

                    while app.PauseAnimationButton.Value == 1
                        app.PauseAnimationButton.Text = "Resume Animation";
                        app.StopAnimationButton.Enable = "off";
                        drawnow;
                    end

                    app.PauseAnimationButton.Text = "Pause Animation";
                    app.StopAnimationButton.Enable = "on";
                                        
                    if app.CreateVideoCheckBox.Value == 1

                           frames{i} = getframe(fig); %#ok<AGROW>
                           %writeVideo(v,frame);
                           %exportgraphics(app.ExperimentPreview,[num2str(i) '.png'],'Resolution',100)
                    else
                           pause(0.01)
                           drawnow limitrate
                    end

                    
                end
                
                set(app.AnimationScrubberSlider,'enable','on');
                   
            else
                
                ed = errordlg("The variable dataPacket does not exist in the base workspace. Check that the simulation has been run, or ensure the variable is loaded.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                      
            end
            

            % After the animation has played, write all the frames to the video file
            if app.CreateVideoCheckBox.Value == 1
                v = VideoWriter('SimulationVideo.avi','Motion JPEG AVI');
                v.Quality = 95;
                open(v);
                for i = 1:length(frames)
                    writeVideo(v, frames{i});
                end
                close(v);
                close(waitbox);
                close(gcf);
            end

            app.PlayAnimationButton.Enable = "on";
            app.SaveSimulationDataButton.Enable = "on";
            app.AnimationScrubberSlider.Enable = "on";
            app.AnimationSpeedSlider.Enable = "on";
            app.PlayAnimationButton.Enable = "on";
            app.StopAnimationButton.Enable = "off";
            app.PauseAnimationButton.Enable = "off";
            app.SkipToTimeEditField.Enable = "on";

                       
        end

        % Callback function
        function StopAnimationButtonPushed(app, event)
            app.Interruptor = 1;
        end

        % Button pushed function: REDPucksOnButton
        function REDPucksOnButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Enabling pucks on RED...');
            [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                    
                RED         = app.REDConnection;
                app.CleanShutdown(RED);
                try
                    system(RED,'echo 428 > /sys/class/gpio/unexport');
                catch
                end
                system(RED,'echo 428 > /sys/class/gpio/export');
                system(RED,'echo out > /sys/class/gpio/gpio428/direction');
                system(RED,'echo 1 > /sys/class/gpio/gpio428/value');
                
                app.REDPucksOnButton.Enable = "off";
                app.REDPucksOffButton.Enable = "on";
                app.FireREDThrustersButton.Enable = "off";

            else 
                waitfor(warndlg('There was a problem connecting to RED, could not start floating. Check that the IP address for RED is still accessible using !ping 192.168.1.110 in the MATLAB Command Window.','Connection Failed'));
            end
            close(waitbox);

        end

        % Button pushed function: REDPucksOffButton
        function REDPucksOffButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Stopping pucks on RED...');
            [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                RED = app.REDConnection;
                
                try
                    system(RED,'echo 0 > /sys/class/gpio/gpio428/value');
                    system(RED,'echo 428 > /sys/class/gpio/unexport');
                    app.REDPucksOnButton.Enable = "on";
                    app.REDPucksOffButton.Enable = "off";
                    app.FireREDThrustersButton.Enable = "on";
                catch
                    waitfor(warndlg('There was an error stopping the float, check that the platform is currently floating. If it is, turn off the airtank and cycle the power.','Float OFF Error'));
                end
            else 
                waitfor(warndlg('There was a problem connecting to RED, could not stop floating. Check that the IP address for RED is still accessible using !ping 192.168.1.110 in the MATLAB Command Window.','Floating Failed'));
            end 
            close(waitbox);

        end

        % Button pushed function: BLACKPucksOnButton
        function BLACKPucksOnButtonPushed(app, event)
            
            waitbox = app.CustomWaitBox('Enabling pucks on BLACK...');
            [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                    
                BLACK         = app.BLACKConnection;

                app.CleanShutdown(BLACK);
                
                try
                    system(BLACK,'echo 428 > /sys/class/gpio/unexport');
                catch
                end
                system(BLACK,'echo 428 > /sys/class/gpio/export');
                system(BLACK,'echo out > /sys/class/gpio/gpio428/direction');
                system(BLACK,'echo 1 > /sys/class/gpio/gpio428/value');

                app.BLACKPucksOnButton.Enable = "off";
                app.BLACKPucksOffButton.Enable = "on";
                app.FireBLACKThrustersButton.Enable = "off";
                
            else 
                waitfor(warndlg('There was a problem connecting to BLACK, could not start floating. Check that the IP address for BLUE is still accessible using !ping 192.168.1.111 in the MATLAB Command Window.','Connection Failed'));
            end

            close(waitbox);

        end

        % Button pushed function: BLACKPucksOffButton
        function BLACKPucksOffButtonPushed(app, event)
            
            waitbox = app.CustomWaitBox('Stopping pucks on BLACK...');
            [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                    
                BLACK = app.BLACKConnection;
                
                try
                    system(BLACK,'echo 0 > /sys/class/gpio/gpio428/value');
                    system(BLACK,'echo 428 > /sys/class/gpio/unexport');

                    app.BLACKPucksOnButton.Enable = "on";
                    app.BLACKPucksOffButton.Enable = "off";
                    app.FireBLACKThrustersButton.Enable = "on";

                catch
                    waitfor(warndlg('There was an error stopping the float, check that the platform is currently floating. If it is, turn off the airtank and cycle the power.','Float OFF Error'));
                end

            else 
                waitfor(warndlg('There was a problem connecting to BLACK, could not stop floating. Check that the IP address for BLACK is still accessible using !ping 192.168.1.111 in the MATLAB Command Window.','Floating Failed'));
            end 
            close(waitbox);
                     
        end

        % Button pushed function: BLUEPucksOnButton
        function BLUEPucksOnButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Enabling pucks on BLUE...');
            [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                BLUE = app.BLUEConnection;
                app.CleanShutdown(BLUE);

                try
                    system(BLUE,'echo 428 > /sys/class/gpio/unexport');
                catch
                end
                system(BLUE,'echo 428 > /sys/class/gpio/export');
                system(BLUE,'echo out > /sys/class/gpio/gpio428/direction');
                system(BLUE,'echo 1 > /sys/class/gpio/gpio428/value');

                app.BLUEPucksOffButton.Enable = "on";
                app.BLUEPucksOnButton.Enable = "off";
                app.FireBLUEThrustersButton.Enable = "off";
                
            else 
                waitfor(warndlg('There was a problem connecting to BLUE, could not start floating. Check that the IP address for BLUE is still accessible using !ping 192.168.1.112 in the MATLAB Command Window.','Connection Failed'));
            end
            
            close(waitbox);
        end

        % Button pushed function: BLUEPucksOffButton
        function BLUEPucksOffButtonPushed(app, event)
            
            waitbox = app.CustomWaitBox('Stopping pucks on BLUE...');
            [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                BLUE = app.BLUEConnection;
                try
                    system(BLUE,'echo 0 > /sys/class/gpio/gpio428/value');
                    system(BLUE,'echo 428 > /sys/class/gpio/unexport');
                    app.BLUEPucksOffButton.Enable = "off";
                    app.BLUEPucksOnButton.Enable = "on";
                    app.FireBLUEThrustersButton.Enable = "on";
                catch
                    waitfor(warndlg('There was an error stopping the float, check that the platform is currently floating. If it is, turn off the airtank and cycle the power.','Float OFF Error'));
                end
                
            else 
                waitfor(warndlg('There was a problem connecting to BLUE, could not stop floating. Check that the IP address for BLUE is still accessible using !ping 192.168.1.112 in the MATLAB Command Window.','Floating Failed'));
            end 
            close(waitbox);
                  
        end

        % Button pushed function: BuildCode
        function BuildCodePushed(app, event)
        waitbox = app.CustomWaitBox('Compiling code, please be patient...');
        selectedFile = get(app.AvailableDiagramsDropDown,'Value');
        
        if (bdIsLoaded(selectedFile(1:(end-4)))==1)

            set_param(selectedFile(1:(end-4)),'SolverType',"Fixed-step");
            set_param(selectedFile(1:(end-4)),'SolverName',"ode4");

            app.CompileDir = cd;
            app.CompileDir = strrep(app.CompileDir,":","");
            app.CompileDir = strrep(app.CompileDir," ","_");
            app.CompileDir = strrep(app.CompileDir,"\","/");

            FileName = app.AvailableDiagramsDropDown.Value;

            assignin('base','simMode',0);

            activeConfigObj = getActiveConfigSet(selectedFile(1:(end-4)));
            set_param(activeConfigObj,'HardwareBoard','NVIDIA Jetson');

            % Locate a master
            if app.REDCheckBox.Value == 1
                fprintf('RED appears to be MASTER. building to RED...\n')
                [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                    RED = app.REDConnection; 

                    prefDir = prefdir;
                    prefFilePath = fullfile(prefDir, 'matlabprefs.mat');
                    load(prefFilePath); %#ok<LOAD>
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultIpAddress = '192.168.1.110';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultUserName = 'spot-red';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultBuildDirPref = '/home/spot-red/ExperimentSoftware/';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaulttargetwsdirPref = ['/home/spot-red/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/'];
                    save(prefFilePath,"Preferences",'-mat');

                    if strcmp(get_param(FileName(1:(end-4)),'Dirty'),'on')
                        save_system(selectedFile);
                    end
                    slbuild(FileName(1:(end-4)));
                    if (isModelRunning(RED,FileName(1:(end-4))) == 1)
                        stopModel(RED,FileName(1:(end-4)));
                        app.CleanShutdown(RED);
                        DataDir = ['/home/spot-red/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*']; 
                        deleteFile(RED,DataDir);
                    end
                else 
                    
                    fprintf('There was a problem connecting to the designated master platform (RED), build stopped.\n')
                    
                end                
            elseif app.BLACKCheckBox.Value == 1
                fprintf('BLACK appears to be MASTER. building to BLACK...\n')
                [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                    BLACK = app.BLACKConnection; 
                    prefDir = prefdir;
                    prefFilePath = fullfile(prefDir, 'matlabprefs.mat');
                    load(prefFilePath); %#ok<LOAD>
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultIpAddress = '192.168.1.111';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultUserName = 'spot-black';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultBuildDirPref = '/home/spot-black/ExperimentSoftware/';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaulttargetwsdirPref = ['/home/spot-black/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/'];
                    save(prefFilePath,"Preferences",'-mat');
                    
                    if strcmp(get_param(FileName(1:(end-4)),'Dirty'),'on')
                        save_system(selectedFile);
                    end
                    slbuild(FileName(1:(end-4)));
                    if (isModelRunning(BLACK,FileName(1:(end-4))) == 1)
                        stopModel(BLACK,FileName(1:(end-4)));
                        app.CleanShutdown(BLACK);
                        DataDir = ['/home/spot-black/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*']; 
                        deleteFile(BLACK,DataDir);
                    end
                    
                else 
                    
                    fprintf('There was a problem connecting to the designated master platform (BLACK), build stopped.\n')
                    
                end   
            elseif app.BLUECheckBox.Value == 1
                fprintf('BLUE appears to be MASTER. building to BLUE...\n')
                [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                    BLUE = app.BLUEConnection;

                    prefDir = prefdir;
                    prefFilePath = fullfile(prefDir, 'matlabprefs.mat');
                    load(prefFilePath); %#ok<LOAD>
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultIpAddress = '192.168.1.112';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultUserName = 'spot-blue';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultBuildDirPref = '/home/spot-blue/ExperimentSoftware/';
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaulttargetwsdirPref = ['/home/spot-blue/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/'];
                    save(prefFilePath,"Preferences",'-mat');
                    
                    if strcmp(get_param(FileName(1:(end-4)),'Dirty'),'on')
                        save_system(selectedFile);
                    end
                    slbuild(FileName(1:(end-4)));
                    if (isModelRunning(BLUE,FileName(1:(end-4))) == 1)
                        stopModel(BLUE,FileName(1:(end-4)));
                        app.CleanShutdown(BLUE);
                        DataDir = ['/home/spot-blue/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*']; 
                        deleteFile(BLUE,DataDir);
                    end
                    
                else 
                    
                    fprintf('There was a problem connecting to the designated master platform (BLUE), build stopped.\n')
                    
                end
            else
                [~] = questdlg('Warning: You have not selected any platforms for code deployment! Check at least one platform.','Deployment Warning','Understood','Cancel','Cancel');
            end

            assignin('base','simMode',1);
            
            % Use the system command to copy files around as needed
            % If RED is MASTER
            if (app.REDCheckBox.Value == 1)

                if (app.BLACKCheckBox.Value == 1)

                    fprintf('Checking connection to BLACK platform...\n')
                    [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        BLACK = app.BLACKConnection;

                        fprintf('Connection successful!\n');
                        
                        fprintf('Copying files from RED to BLACK...')
                        system(RED,'scp -r /home/spot-red/ExperimentSoftware/ spot-black@192.168.1.111:/home/spot-black/');                        
                        system(BLACK,'echo "srcl2023" | sudo -S chmod -R 755 ExperimentSoftware');
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLACK, files not copied.\n')
                    end

                end

                if (app.BLUECheckBox.Value == 1)

                    fprintf('Checking connection to BLUE platform...\n')
                    [~,results] = system('ping 192.168.1.112 -w 2000 -n  1');
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        BLUE = app.BLUEConnection;

                        fprintf('Connection successful!\n');
                        
                        fprintf('Copying files from RED to BLUE...')
                        system(RED,'scp -r /home/spot-red/ExperimentSoftware/ spot-blue@192.168.1.112:/home/spot-blue/');                        
                        system(BLUE,'echo "srcl2023" | sudo -S chmod -R 755 ExperimentSoftware');
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLUE, files not copied.\n')
                    end

                end

                close(waitbox);
                return

            end
            
            % If RED is NOT MASTER, then ONLY BLACK AND BLUE ARE USED
            if (app.BLACKCheckBox.Value == 1)

                if (app.BLUECheckBox.Value == 1)

                    fprintf('Checking connection to BLUE platform...\n')
                    [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        BLUE = app.BLUEConnection;

                        fprintf('Connection successful!\n');
                        
                        fprintf('Copying files from BLACK to BLUE...')
                        system(BLACK,'scp -r /home/spot-black/ExperimentSoftware/ spot-blue@192.168.1.112:/home/spot-blue/');     
                        system(BLUE,'echo "srcl2023" | sudo -S chmod -R 755 ExperimentSoftware');
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLUE, files not copied.\n')
                    end

                end
                
                close(waitbox);
                return

            end
        else
            close(waitbox);
            ed = errordlg("The selected Simulink diagram has not been loaded. Try selecting it again from the dropdown.","Error");
            set(ed, "WindowStyle", "modal");
            uiwait(ed);          
        end

        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            FailedFlag = false;
            
            if isempty(app.CompileDir)
                app.CompileDir = cd;
                app.CompileDir = strrep(app.CompileDir,":","");
                app.CompileDir = strrep(app.CompileDir," ","_");
                app.CompileDir = strrep(app.CompileDir,"\","/");
            end
               
            if app.REDCheckBox.Value == 1
            
                fprintf('Checking connection to RED platform...\n')
                [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                    RED         = app.REDConnection;  

                    try
                        system(RED,'echo "srcl2023" | sudo -S  chmod 666 /dev/ttyUSB0');
                    catch
                    end
                                                  
                    if isModelRunning(RED,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on RED!','Model Already Running'));
                    end

                    app.CleanShutdown(RED);
                    app.RED_Process_ID = runExecutable(RED,['/home/spot-red/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/' FileName(1:end-4) '.elf']);
                    
                else 
                    waitfor(warndlg('There was a problem connecting to RED, could not start experiment. Check that the IP address for RED is still accessible using !ping 192.168.1.110 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end        
                
            end

            if app.BLACKCheckBox.Value == 1
            
                fprintf('Checking connection to BLACK platform...\n')
                [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                           
                    BLACK         = app.BLACKConnection;     
                    
                    try
                        system(BLACK,'echo "srcl2023" | sudo -S  chmod 666 /dev/ttyUSB0');
                    catch
                    end
                                                  
                    if isModelRunning(BLACK,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on BLACK!','Model Already Running'));
                    end

                    app.CleanShutdown(BLACK);
                    app.BLACK_Process_ID = runExecutable(BLACK,['/home/spot-black/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/' FileName(1:end-4) '.elf']);
                    
                else 
                    waitfor(warndlg('There was a problem connecting to BLACK, could not start experiment. Check that the IP address for BLACK is still accessible using !ping 192.168.1.111 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end        
                
            end            

            if app.BLUECheckBox.Value == 1
            
                fprintf('Checking connection to BLUE platform...\n')
                [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                            
                    BLUE         = app.BLUEConnection;

                    try
                        system(BLUE,'echo "srcl2023" | sudo -S  chmod 666 /dev/ttyUSB0');
                    catch
                    end
                            
                    if isModelRunning(BLUE,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on BLUE!','Model Already Running'));
                    end
                    
                    app.CleanShutdown(BLUE);
                    app.BLUE_Process_ID = runExecutable(BLUE,['/home/spot-blue/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/' FileName(1:end-4) '.elf']);

                else 
                    waitfor(warndlg('There was a problem connecting to BLUE, could not start experiment. Check that the IP address for BLUE is still accessible using !ping 192.168.1.112 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end        
                
            end
     
            if FailedFlag == false
                
                app.StopExperimentButton.Enable = 'on';
                app.PhaseSpaceSwitch.Enable = 'off';
                app.StartExperimentButton.Enable = 'off';
                app.isExperimentRunning = true;
                
            else
                try
                    app.StopExperimentButtonPushed();
                catch
                    errordlg('UNABLE TO STOP EXPERIMENT VIA SOFTWARE. HIT THE E-STOP.','Critical Software Failure');
                end
            end

        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            FailedFlag = false;
            if app.REDCheckBox.Value == 1
            
                fprintf('Checking connection to RED platform...\n')
                [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                            
                    RED         = app.REDConnection;
                    try
                        system(RED,['kill -15 ' num2str(app.RED_Process_ID)]);
                        app.CleanShutdown(RED);
                    catch
                    end
                    
                else 
                    waitfor(warndlg('There was a problem connecting to RED, could not stop experiment. Check that the IP address for RED is still accessible using !ping 192.168.1.110 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end        
                
            end

            if app.BLACKCheckBox.Value == 1
                
                fprintf('Checking connection to BLACK platform...\n')
                [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                            
                    BLACK         = app.BLACKConnection;
                    try
                        system(BLACK,['kill -15 ' num2str(app.BLACK_Process_ID)]);
                        app.CleanShutdown(BLACK);
                    catch
                    end
                    
                else 
                    waitfor(warndlg('There was a problem connecting to BLACK, could not stop experiment. Check that the IP address for BLACK is still accessible using !ping 192.168.1.111 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end         
                
            end            

            if app.BLUECheckBox.Value == 1
            
                fprintf('Checking connection to BLUE platform...\n')
                [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                           
                    BLUE         = app.BLUEConnection;
                    try
                        system(BLUE,['kill -15 ' num2str(app.BLUE_Process_ID)]);
                        app.CleanShutdown(BLUE);
                    catch
                    end
                else 
                    waitfor(warndlg('There was a problem connecting to BLUE, could not stop experiment. Check that the IP address for BLUE is still accessible using !ping 192.168.1.112 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end     
                
            end

            if FailedFlag == false
                app.StopExperimentButton.Enable = 'off';
                app.StartExperimentButton.Enable = 'on';
                app.PhaseSpaceSwitch.Enable = 'on';
                app.isExperimentRunning = false;
                app.PhaseSpaceSwitch.Value = "Off";
                app.PhaseSpaceSwitchValueChanged();
            else
                app.StopExperimentButton.Enable = 'on';
                errordlg('UNABLE TO STOP EXPERIMENT VIA SOFTWARE. HIT THE E-STOP.','Critical Software Failure');
            end
            
            
        end

        % Button pushed function: SaveSimulationDataButton
        function SaveSimulationDataButtonPushed(app, event)
            % Grab the file name from the drop down menu:
            waitbox = app.CustomWaitBox('Saving simulation data...');
            
            try
                
                dataClass      = evalin('base', 'dataClass');
                
                % Move the file to a folder for storage:
                
                DateTime       = datetime("now");
                
                foldername = ['SimulationData_' num2str(DateTime.Year) '_' ...
                    num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                
                save('dataPacket_SIM.mat','dataClass');
                
                mkdir([cd '\Saved Data\' foldername '\']);
                
                movefile([cd '\dataPacket_SIM.mat'],...
                [cd '\Saved Data\' foldername '\dataPacket_SIM.mat']);

                waitfor(warndlg(['The simulation data has been successfully saved to: ' newline ...
                cd '\Saved Data\' foldername '\dataPacket_SIM.mat'],'Simulation Data Saved!'));
                       
            catch 
                
                ed = errordlg("The variable 'dataClass' does not exist in the base workspace. Cannot save data.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                
            end

            close(waitbox);
        end

        % Button pushed function: SaveExperimentDataButton
        function SaveExperimentDataButtonPushed(app, event)
            
            if app.isExperimentRunning == true
                errordlg("Cannot save data while an experiment is running.","Error");
            else

                % Get dateTime:
                DateTime       = datetime("now");
    
                % Grab the file name from the drop down menu:
                Choice = questdlg('Warning: The experiment must be terminated before exporting any data. If you have not ended the experiment, select "Cancel", otherwise select "Proceed".','Data Export Warning','Proceed','Cancel','Proceed');
                switch Choice
                    case 'Proceed'
                        try
                            waitbox = app.CustomWaitBox('Saving experimental data for selected platforms...');
                            if app.REDCheckBox.Value == 1
                    
                                [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
                                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                                    
                                    RED         = app.REDConnection;
                                    
                                    DataDir = ['/home/spot-red/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*'];
                                    
                                    getFile(RED,DataDir);
        
                                    deleteFile(RED,DataDir);
                        
                                    foldername_RED = ['ExperimentData_RED_' num2str(DateTime.Year) '_' ...
                                        num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                    num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                                    
                                    AllLoadedFiles = dir('ExpLog*');
        
                                    for index = 1:length(AllLoadedFiles)
                                        
                                        dataPacket_rt = importdata(AllLoadedFiles(index).name);
                                        dataClass_rt = app.ApplyDataDictionary(dataPacket_rt);
                                        save([foldername_RED '_' num2str(index) '.mat'],"dataClass_rt",'-mat');
                                        
                                    end
        
                                    % Move all data to new folder:
                                    movefile([cd '\ExpLog*'],[cd '\Saved Data\' foldername_RED]);
                                    movefile([cd '\' foldername_RED '_*'],[cd '\Saved Data\' foldername_RED]);
                
                                else 
                                    waitfor(warndlg('There was a problem connecting to RED, could not copy over data. Check that the IP address for RED is still accessible using !ping 192.168.1.110 in the MATLAB Command Window.','Connection Failed'));
                                end        
                                
                            end
                
                            if app.BLACKCheckBox.Value == 1
                                
                                [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
                                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                    
                                    BLACK         = app.BLACKConnection;
                                    
                                    DataDir = ['/home/spot-black/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*'];
                                    
                                    getFile(BLACK,DataDir);
        
                                    deleteFile(BLACK,DataDir);
        
                                    foldername_BLACK = ['ExperimentData_BLACK_' num2str(DateTime.Year) '_' ...
                                        num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                    num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')]; 
             
                                    AllLoadedFiles = dir('ExpLog*');
        
                                    for index = 1:length(AllLoadedFiles)
                                        
                                        dataPacket_rt = importdata(AllLoadedFiles(index).name);
                                        dataClass_rt = app.ApplyDataDictionary(dataPacket_rt);
                                        save([foldername_BLACK '_' num2str(index) '.mat'],"dataClass_rt",'-mat');
                                        
                                    end
                                    
                                    % Move all data to new folder:
                                    movefile([cd '\ExpLog*'],[cd '\Saved Data\' foldername_BLACK]);
                                    movefile([cd '\' foldername_BLACK '_*'],[cd '\Saved Data\' foldername_BLACK]);
        
                                else 
                                    waitfor(warndlg('There was a problem connecting to BLACK, could not copy over data. Check that the IP address for BLACK is still accessible using !ping 192.168.1.111 in the MATLAB Command Window.','Connection Failed'));
                                end         
                                
                            end            
                
                            if app.BLUECheckBox.Value == 1
                            
                                [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
                                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                                    
                                    BLUE         = app.BLUEConnection;
                                    
                                    DataDir = ['/home/spot-blue/ExperimentSoftware/MATLAB_ws/R2023a/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*'];
                                    
                                    getFile(BLUE,DataDir);
        
                                    deleteFile(BLUE,DataDir);
        
                                    foldername_BLUE = ['ExperimentData_BLUE_' num2str(DateTime.Year) '_' ...
                                        num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                    num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];     
        
                                    AllLoadedFiles = dir('ExpLog*');
        
                                    for index = 1:length(AllLoadedFiles)
                                        
                                        dataPacket_rt = importdata(AllLoadedFiles(index).name);
                                        dataClass_rt = app.ApplyDataDictionary(dataPacket_rt);
                                        save([foldername_BLUE '_' num2str(index) '.mat'],"dataClass_rt",'-mat');
                                        
                                    end
        
                                    % Move all data to new folder:
                                    movefile([cd '\ExpLog*'],[cd '\Saved Data\' foldername_BLUE]);
                                    movefile([cd '\' foldername_BLUE '_*'],[cd '\Saved Data\' foldername_BLUE]);                           
        
                                    
                                else 
                                    waitfor(warndlg('There was a problem connecting to BLUE, could not copy over data. Check that the IP address for BLUE is still accessible using !ping 192.168.1.112 in the MATLAB Command Window.','Connection Failed'));
                                end     
                                
                            end
                            close(waitbox);
                        catch
                            waitbox = app.CustomWaitBox('Saving experimental data for selected platforms...');
                            waitfor(warndlg('There was a problem saving the data. Remote into the platforms and check that the data file was correctly generated. You are looking for files which start with "ExpLog".','Failed to Save Data'));
                            close(waitbox);
                        end
    
                    case 'Cancel'
                        % Do nothing.
                end
            end

            
        end

        % Button pushed function: PingREDButton
        function PingREDButtonPushed(app, event)
         
            trytime = char(datetime("now"));

            [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               app.REDConnectedLamp.Color = [0.00,1.00,0.00];
               app.REDTextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP: 192.168.1.110'];
            else 
               app.REDConnectedLamp.Color = [1.00,0.00,0.00];
               app.REDTextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end

        end

        % Button pushed function: PingBLACKButton
        function PingBLACKButtonPushed(app, event)
            
            trytime = char(datetime("now"));

            [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               app.BLACKConnectedLamp.Color = [0.00,1.00,0.00];
               app.BLACKTextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP: 192.168.1.111'];
            else 
               app.BLACKConnectedLamp.Color = [1.00,0.00,0.00];
               app.BLACKTextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end

        end

        % Button pushed function: PingBLUEButton
        function PingBLUEButtonPushed(app, event)
            
            trytime = char(datetime("now"));

            [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               app.BLUEConnectedLamp.Color = [0.00,1.00,0.00];
               app.BLUETextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP: 192.168.1.112'];
            else 
               app.BLUEConnectedLamp.Color = [1.00,0.00,0.00];
               app.BLUETextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end
            
        end

        % Callback function
        function SoftwareInstallationMenuSelected(app, event)
            web('https://youtu.be/d77dXB41VeI','-browser' );
        end

        % Menu selected function: ConnectingtoaRaspberryPiRemotelyMenu
        function ConnectingtoaRaspberryPiRemotelyMenuSelected(app, event)
            web('https://youtu.be/1Ly6PqwiL1w','-browser' );
        end

        % Menu selected function: RemovingtheTableCoverMenu
        function RemovingtheTableCoverMenuSelected(app, event)
            web('https://youtu.be/0DmQvlUzcDc','-browser' );
        end

        % Menu selected function: RetrievingtheIsopropanolMenu
        function RetrievingtheIsopropanolMenuSelected(app, event)
            web('https://youtu.be/nVwOGg06n6c','-browser' );
        end

        % Menu selected function: TableCleaningProceduresMenu
        function TableCleaningProceduresMenuSelected(app, event)
            web('https://youtu.be/xqzN2uYfsFM','-browser' );
        end

        % Menu selected function: SavingExperimentDataMenu
        function SavingExperimentDataMenuSelected(app, event)
            web('https://youtu.be/kHlCK_5decQ','-browser' );
        end

        % Menu selected function: RemovingtheRoboticArmMenu
        function RemovingtheRoboticArmMenuSelected(app, event)
            web('https://youtu.be/KlgUwF5Puzk','-browser' );
        end

        % Button pushed function: FireREDThrustersButton
        function FireREDThrustersButtonPushed(app, event)
            fprintf('Checking connection to RED platform...\n')
            [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run turn on thrusters in order...\n');

                RED         = app.REDConnection;
                app.CleanShutdown(RED);
                
                waitbox = app.CustomWaitBox('Cycling all thrusters...');
                app.CycleAllThrusters(RED);
                close(waitbox);
                       
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to RED, could not fire thrusters. Check that the IP address for RED is still accessible using !ping 192.168.1.110 in the MATLAB Command Window.','Connection Failed'));
            end  
        end

        % Button pushed function: OpenREDCMDShell
        function OpenREDCMDShellButtonPushed(app, event)
            RED = app.REDConnection;
            openShell(RED);             
        end

        % Button pushed function: FireBLACKThrustersButton
        function FireBLACKThrustersButtonPushed(app, event)
            fprintf('Checking connection to BLACK platform...\n')
            [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run turn on thrusters in order...\n');

                BLACK         = app.BLACKConnection;
                app.CleanShutdown(BLACK);

                waitbox = app.CustomWaitBox('Cycling all thrusters...');
                app.CycleAllThrusters(BLACK);
                close(waitbox);
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to BLACK, could not start floating. Check that the IP address for BLACK is still accessible using !ping 192.168.1.111 in the MATLAB Command Window.','Connection Failed'));
            end             
        end

        % Button pushed function: OpenBLACKCMDShell
        function OpenBLACKCMDShellButtonPushed(app, event)
            BLACK = app.BLACKConnection;
            openShell(BLACK);              
        end

        % Button pushed function: OpenBLUECMDShell
        function OpenBLUECMDShellButtonPushed(app, event)
            BLUE = app.BLUEConnection;
            openShell(BLUE);              
        end

        % Button pushed function: FireBLUEThrustersButton
        function FireBLUEThrustersButtonPushed(app, event)
            fprintf('Checking connection to BLUE platform...\n')
            [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run turn on thrusters in order...\n');

                BLUE = app.BLUEConnection;
                app.CleanShutdown(BLUE);

                waitbox = app.CustomWaitBox('Cycling all thrusters...');
                app.CycleAllThrusters(BLUE);
                close(waitbox);
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to BLUE, could not start floating. Check that the IP address for BLUE is still accessible using !ping 192.168.1.112 in the MATLAB Command Window.','Connection Failed'));
            end            
        end

        % Value changed function: REDCheckBox
        function REDCheckBoxValueChanged(app, event)
                  
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');             
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off');    

            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off');

            set(app.RefreshSoftwareonPlatformsButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off');
            

            app.ConfirmSettings();
        end

        % Value changed function: BLACKCheckBox
        function BLACKCheckBoxValueChanged(app, event)
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');             
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off'); 

            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off'); 

            set(app.RefreshSoftwareonPlatformsButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off'); 
            

            app.ConfirmSettings();
        end

        % Value changed function: BLUECheckBox
        function BLUECheckBoxValueChanged(app, event)
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');             
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off');       

            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off'); 

            set(app.RefreshSoftwareonPlatformsButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off');   
            

            app.ConfirmSettings();
        end

        % Value changed function: ARMCheckBox
        function ARMCheckBoxValueChanged(app, event)
            
            % Can't have the arm without RED
            if app.REDCheckBox.Value == 0 && app.ARMCheckBox.Value == 1
                app.REDCheckBox.Value = 1;
            end
            
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');             
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off');    

            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off');  

            set(app.RefreshSoftwareonPlatformsButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off');  
            

            app.ConfirmSettings();
        end

        % Menu selected function: GitHubMenu
        function GitHubMenuSelected(app, event)
            web('https://github.com/Carleton-SRCL/SPOT/wiki','-browser' );
        end

        % Menu selected function: GooglePhotosMenu
        function GooglePhotosMenuSelected(app, event)
           web('https://photos.google.com/share/AF1QipNfJT6JaXArg4jsdNeEDJND8l6JiP75N8nS3p4yscl8leAFTLd5wW7gAhIgfqgyiQ?key=SmpJNUhQNE05OGh2NUhSWXc0UmFkZnJVbHdGZmJ3','-browser' );
        end

        % Menu selected function: YoutubeChannelMenu
        function YoutubeChannelMenuSelected(app, event)
            web('https://www.youtube.com/user/SRCLabCarleton','-browser' );
        end

        % Menu selected function: RunningaSimulationandExperimentMenu
        function RunningaSimulationandExperimentMenuSelected(app, event)
            web('https://youtu.be/N2YYbz6UxEw','-browser');
        end

        % Menu selected function: ChargingaLEDBatteryPackMenu
        function ChargingaLEDBatteryPackMenuSelected(app, event)
            web('https://youtu.be/_GZzT4T4Xq8','-browser');
        end

        % Menu selected function: ChargingaPlatformMenu
        function ChargingaPlatformMenuSelected(app, event)
            web('https://youtu.be/wDpUcpgEBgA','-browser');
        end

        % Menu selected function: InstallinganAirTankMenu
        function InstallinganAirTankMenuSelected(app, event)
            web('https://youtu.be/4xL3vRoF7FE','-browser');
        end

        % Menu selected function: InstallingtheRoboticArmMenu
        function InstallingtheRoboticArmMenuSelected(app, event)
            web('https://youtu.be/ZjW5lB-b85M','-browser');
        end

        % Menu selected function: ForkingtheSPOTRepositoryMenu
        function ForkingtheSPOTRepositoryMenuSelected(app, event)
            web('https://youtu.be/Vu77edOiv6o','-browser');
        end

        % Menu selected function: PlacingtheTableCoverMenu
        function PlacingtheTableCoverMenuSelected(app, event)
            web('https://youtu.be/UVv9MAwmUHA','-browser');
        end

        % Menu selected function: FillinganAirTankMenu
        function FillinganAirTankMenuSelected(app, event)
            web('https://youtu.be/2G6YD_iBmaA','-browser');
        end

        % Menu selected function: ExportingVideoswithQCViewerMenu
        function ExportingVideoswithQCViewerMenuSelected(app, event)
            web('https://youtu.be/H8Ht0rh1U1E','-browser');
        end

        % Menu selected function: CleaningthePucksandMovingaPlatformMenu
        function CleaningthePucksandMovingaPlatformMenuSelected(app, event)
            web('https://youtu.be/eQQ0q6dXufQ','-browser');
        end

        % Value changing function: AnimationScrubberSlider
        function AnimationScrubberSliderValueChanging(app, event)
            changingValue = event.Value/100*app.tsim;
            changingValue(changingValue<1)=1;

            if (isempty(app.DataPacket)~=1)

                TimeVec = app.DataPacket(:,1);

                closestIndex = find(abs(TimeVec - changingValue) == min(abs(TimeVec - changingValue)), 1);

                AddPlatformSim(app,evalin("base","platformSelection")); 
                
                % Print time text:
                txtT = '';
                text(app.ExperimentPreview,0.1,3.75,txtT); 
                                        
                a = app.DataPacket(closestIndex,6);
                b = app.DataPacket(closestIndex,5);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacket(closestIndex,7);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.REDFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_RED = [  cos(theta) sin(theta)
                                 -sin(theta) cos(theta) ];
                             
                    Vec_RED  = C_bI_RED*[ 0.15
                                          0.0 ];
                                                  
                    app.REDFigLine.XData = [a,a+Vec_RED(2)]; 
                    app.REDFigLine.YData = [b,b+Vec_RED(1)];
                    app.REDFigHdl.XData = P(1,:)+a;
                    app.REDFigHdl.YData = P(2,:)+b;
                end
                
                x0 = app.DataPacket(closestIndex,6);
                y0 = app.DataPacket(closestIndex,5);
                q0 = -app.DataPacket(closestIndex,7);
                  
                q1 = (-app.DataPacket(closestIndex,35)+3*pi/2);
                q2 = -app.DataPacket(closestIndex,36);
                q3 = -(app.DataPacket(closestIndex,37));  
      
                
                rs                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) ];                        
                            
                re                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) ];
    
                rw                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) ];
    
                ree                     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                
                if (isempty(app.REDFigLink1) ~= 1) 
                    
                    app.REDFigLink1.XData = [rs(1,1)  re(1,1)];
                    app.REDFigLink1.YData = [rs(1,2)  re(1,2)];
                    app.REDFigLink2.XData = [re(1,1)  rw(1,1)];
                    app.REDFigLink2.YData = [re(1,2)  rw(1,2)];
                    app.REDFigLink3.XData = [rw(1,1)  ree(1,1)];
                    app.REDFigLink3.YData = [rw(1,2)  ree(1,2)];
                    app.REDFigJoin0.XData = rs(1,1);
                    app.REDFigJoin0.YData = rs(1,2);
                    app.REDFigJoin1.XData = re(1,1);
                    app.REDFigJoin1.YData = re(1,2);
                    app.REDFigJoin2.XData = rw(1,1);
                    app.REDFigJoin2.YData = rw(1,2);
                    app.REDFigJoin3.XData = ree(1,1);
                    app.REDFigJoin3.YData = ree(1,2);
                                            
                end
                
                a = app.DataPacket(closestIndex,18);
                b = app.DataPacket(closestIndex,17);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacket(closestIndex,19);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.BLACKFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_BLACK = [  cos(theta) sin(theta)
                                   -sin(theta) cos(theta) ];
                             
                    Vec_BLACK  = C_bI_BLACK*[ 0.15
                                              0.0 ];
                                                  
                    app.BLACKFigLine.XData = [a,a+Vec_BLACK(2)]; 
                    app.BLACKFigLine.YData = [b,b+Vec_BLACK(1)];
                    app.BLACKFigHdl.XData = P(1,:)+a;
                    app.BLACKFigHdl.YData = P(2,:)+b;
                    
                end
                
                a = app.DataPacket(closestIndex,30);
                b = app.DataPacket(closestIndex,29);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacket(closestIndex,31);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.BLUEFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_BLUE =  [  cos(theta) sin(theta)
                                   -sin(theta) cos(theta) ];
                             
                    Vec_BLUE  = C_bI_BLUE*[ 0.15
                                            0.0 ];
                                                  
                    app.BLUEFigLine.XData = [a,a+Vec_BLUE(2)]; 
                    app.BLUEFigLine.YData = [b,b+Vec_BLUE(1)];  
                    app.BLUEFigHdl.XData = P(1,:)+a;
                    app.BLUEFigHdl.YData = P(2,:)+b;
                    
                end                    
      
                app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(app.DataPacket(closestIndex,1)),' s');
            end

        end

        % Button pushed function: RefreshSoftwareonPlatformsButton
        function RefreshSoftwareonPlatformsButtonPushed(app, event)
             
            answer = questdlg('Warning: You are about to PERMANENTLY DELETE all files under /home/pi/ExpeirmentSoftware/MATLAB_ws/. This action cannot be undone','Raspberry Pi Cleanup Warning','Understood','Cancel','Cancel');
            
            if answer ~= "Cancel"
                            
                if (app.REDCheckBox.Value == 1)
                    
                    fprintf('Checking connection to RED platform...\n')
                    [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on RED...')
                        
                        try
                            RED = app.REDConnection;                    
                            system(RED, 'rm -r ExperimentSoftware/MATLAB_ws/');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to RED, files not deleted.\n')
                    end
               
                end
                
                if (app.BLACKCheckBox.Value == 1)
                    
                    fprintf('Checking connection to BLACK platform...\n')
                    [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on BLACK...')
                        
                        try
                            BLACK = app.BLACKConnection;                  
                            system(BLACK, 'rm -r ExperimentSoftware/MATLAB_ws/');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLACK, files not deleted.\n')
                    end
               
                end
    
                if (app.BLUECheckBox.Value == 1)
                    
                    fprintf('Checking connection to BLUE platform...\n')
                    [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on BLUE...')
                        try
                            BLUE = app.BLUEConnection;                    
                            system(BLUE, 'rm -r ExperimentSoftware/MATLAB_ws/');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLUE, files not deleted.\n')
                    end
               
                end
                
                try
                    rmdir([cd '\Pi_Files\MATLAB_ws\'],'s');
                catch
                        %Do Nothing
                end
                                
            else
                
                %Do Nothing
                
            end
            
        end

        % Button pushed function: 
        % Step8EstablishConnectionwithSelectedPlatformsButton, 
        % ...and 1 other component
        function Step8EstablishConnectionwithSelectedPlatformsButtonPushed(app, event)
            %waitbox = app.CustomDialogBox('Establishing connection with selected platform. Wait for dialog to close before proceeding to any hardware control.', 'Connecting to hardware...');
            waitbox = app.CustomWaitBox('Connecting to hardware...');
            errFlag = 0;
            if ((app.REDCheckBox.Value == 1) && ~isempty(app.REDConnection)) || ((app.BLACKCheckBox.Value == 1) && ~isempty(app.BLACKConnection)) || ((app.BLUECheckBox.Value == 1) && ~isempty(app.BLUEConnection))
                Choice = questdlg('Warning: A connection between the groundstation and the platforms already exists. If you are attempting a re-connection, select "Proceed".','Connection Warning','Proceed','Cancel','Proceed');
            else
                Choice = 'Proceed';
            end
            
            switch Choice
                case 'Proceed'

                    app.REDConnection = [];
                    app.BLACKConnection = [];
                    app.BLUEConnection = [];
                    try
                        if (app.REDCheckBox.Value == 1)

                            [~,results] = system('ping 192.168.1.110 -w 2000 -n 1');
                            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                    
                                app.REDConnection = jetson('192.168.1.110','spot-red','srcl2023');
                
                                set(app.REDPucksOnButton,'enable','on');
                                set(app.REDPucksOffButton,'enable','off');
                                set(app.FireREDThrustersButton,'enable','on');
                                set(app.OpenREDCMDShell,'enable','on');
                                set(app.CyclePinsButtonRED,'enable','on');
                
                            else
                                errFlag = 1;
                            end
 
                        end

                        if (app.BLACKCheckBox.Value == 1)

                            [~,results] = system('ping 192.168.1.111 -w 2000 -n 1');
                            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                   
                                app.BLACKConnection = jetson('192.168.1.111','spot-black','srcl2023');
                                
                                set(app.BLACKPucksOnButton,'enable','on');
                                set(app.BLACKPucksOffButton,'enable','off');
                                set(app.FireBLACKThrustersButton,'enable','on');
                                set(app.OpenBLACKCMDShell,'enable','on');
                                set(app.CyclePinsButtonBLACK,'enable','on');
                            else
                                errFlag = 1;
                            end
                            
                        end

                        if (app.BLUECheckBox.Value == 1)
                            [~,results] = system('ping 192.168.1.112 -w 2000 -n 1');
                            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                   
                                app.BLUEConnection = jetson('192.168.1.112','spot-blue','srcl2023');
                
                                set(app.BLUEPucksOnButton,'enable','on');
                                set(app.BLUEPucksOffButton,'enable','off');
                                set(app.FireBLUEThrustersButton,'enable','on');
                                set(app.OpenBLUECMDShell,'enable','on');
                                set(app.CyclePinsButtonBLUE,'enable','on'); 
                            else
                                errFlag = 1;
                            end
                             
                        end 

                        if errFlag == 0
                            app.ConnectionStatus_1.Color = [0.00,1.00,0.00];
                            app.ConnectionStatus_2.Color = [0.00,1.00,0.00];
                            set(app.BuildCode,'enable','on');
                            set(app.PhaseSpaceSwitch,'enable','on');
                            set(app.RefreshSoftwareonPlatformsButton,'enable','on');
                            set(app.SaveExperimentDataButton,'enable','on');
                        else
                            app.ConnectionStatus_1.Color = [1.00,0.00,0.00];
                            app.ConnectionStatus_2.Color = [1.00,0.00,0.00];
                            set(app.BuildCode,'enable','off');
                            set(app.RefreshSoftwareonPlatformsButton,'enable','off');
                            set(app.SaveExperimentDataButton,'enable','off');
                            ed = errordlg("There was a problem connecting to one of the selected platforms. Ensure the platforms are all ON.","Connection Error");
                            set(ed, "WindowStyle", "modal");
                            uiwait(ed);
                        end
                    catch
                        ed = errordlg("There was a problem connecting to one of the selected platforms. Ensure the platforms are all ON.","Connection Error");
                        set(ed, "WindowStyle", "modal");
                        uiwait(ed);
                    end
                case 'Cancel'
                    % Do Nothing
            end

            close(waitbox); 
        end

        % Value changed function: PhaseSpaceSwitch
        function PhaseSpaceSwitchValueChanged(app, event)
            value = app.PhaseSpaceSwitch.Value;
            
            if value == "On"
                
                try
                    app.baseRate = evalin("base","baseRate");
                    CurrentDir = cd();
                    system(['cd "' CurrentDir '\Resources\' '" & start StreamData.exe ' num2str(round((1/app.baseRate)*1))]);
                    app.ToggleRealTimeTelemetrySwitch.Enable = 'On';
                    app.ToggleRealTimeTelemetrySwitch.Value = 'On';
                    if (app.REDCheckBox.Value == 1 && isempty(app.REDConnection)) || (app.BLACKCheckBox.Value == 1 && isempty(app.BLACKConnection)) || (app.BLUECheckBox.Value == 1 && isempty(app.BLUEConnection)) || (app.REDCheckBox.Value == 0 && app.BLACKCheckBox.Value == 0 && app.BLUECheckBox.Value == 0)
                        waitfor(warndlg('Cameras were started, but you cannot start an experiment unless you are connected to the active hardware.','No Hardware Connection'));
                    else
                        app.StartExperimentButton.Enable = 'On';
                    end
                    %app.StopExperimentButton.Enable = 'On';
                    AddPlatformLive(app,evalin("base","platformSelection"));
                    app.CamerasOnLamp.Color = [0.00,1.00,0.00];

                    app.ToggleRealTimeTelemetrySwitchValueChanged();
                catch
                    waitfor(warndlg('Could not start cameras.','Cameras Failed'));
                    app.ToggleRealTimeTelemetrySwitch.Value = 'Off';
                    app.ToggleRealTimeTelemetrySwitch.Enable = 'Off';
                    app.StartExperimentButton.Enable = 'Off';
                    %app.StopExperimentButton.Enable = 'Off';

                    app.ToggleRealTimeTelemetrySwitchValueChanged();
                end
                
            else
                app.CamerasOnLamp.Color = [1.00,0.00,0.00];
                app.ToggleRealTimeTelemetrySwitch.Value = 'Off';
                app.ToggleRealTimeTelemetrySwitch.Enable = 'Off';
                system("taskkill/im StreamData.exe /F");
                app.StartExperimentButton.Enable = 'Off';
                %app.StopExperimentButton.Enable = 'Off';
                
                app.ToggleRealTimeTelemetrySwitchValueChanged();

            end

        end

        % Button pushed function: CyclePinsButtonBLACK
        function CyclePinsButtonBLACKPushed(app, event)
            BLACK = app.BLACKConnection;
            waitbox = app.CustomWaitBox('Closing all GPIO Pins...');
            app.CleanShutdown(BLACK);
            close(waitbox);
        end

        % Button pushed function: CyclePinsButtonBLUE
        function CyclePinsButtonBLUEPushed(app, event)
            BLUE = app.BLUEConnection;
            waitbox = app.CustomWaitBox('Closing all GPIO Pins...');
            app.CleanShutdown(BLUE);
            close(waitbox);
        end

        % Button pushed function: CyclePinsButtonRED
        function CyclePinsButtonREDPushed(app, event)
            RED = app.REDConnection;
            waitbox = app.CustomWaitBox('Closing all GPIO Pins...');
            app.CleanShutdown(RED);   
            close(waitbox);
        end

        % Value changed function: AvailableDiagramsDropDown
        function AvailableDiagramsDropDownValueChanged(app, event)
            if app.AvailableDiagramsDropDown.Value == "Select a Diagram"
                return;
            end
            
            waitbox = app.CustomWaitBox('Loading selected Simulink diagram...');

            FileName = app.AvailableDiagramsDropDown.Value;

            open(FileName); 

            is_open = app.isSLXOpen(app.AvailableDiagramsDropDown.Value);

            while is_open == 0
                is_open = app.isSLXOpen(app.AvailableDiagramsDropDown.Value);
            end
            
            %app.VarStepMaxStepSizeEditField.Value = MaxStep;

            set(app.REDCheckBox,'enable','on');
            set(app.BLACKCheckBox,'enable','on');
            set(app.BLUECheckBox,'enable','on');
            set(app.ARMCheckBox,'enable','on');

            close(waitbox);

                        
        end

        % Value changed function: DurPhase0EditField
        function DurPhase0EditFieldValueChanged(app, event)

            app.Phase0_Duration = app.DurPhase0EditField.Value;
            app.UpdateTimes();

        end

        % Value changed function: DurPhase1EditField
        function DurPhase1EditFieldValueChanged(app, event)
            
            app.Phase1_Duration = app.DurPhase1EditField.Value;
            app.UpdateTimes();
            
        end

        % Value changed function: DurPhase2EditField
        function DurPhase2EditFieldValueChanged(app, event)

            app.Phase2_Duration = app.DurPhase2EditField.Value;
            app.UpdateTimes();

        end

        % Value changed function: SubPhase1EditField
        function SubPhase1EditFieldValueChanged(app, event)
            
            app.Phase3_SubPhase1_Duration = app.SubPhase1EditField.Value;
            app.UpdateTimes();
            
        end

        % Value changed function: SubPhase2EditField
        function SubPhase2EditFieldValueChanged(app, event)
            app.Phase3_SubPhase2_Duration = app.SubPhase2EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: SubPhase3EditField
        function SubPhase3EditFieldValueChanged(app, event)
            app.Phase3_SubPhase3_Duration = app.SubPhase3EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: SubPhase4EditField
        function SubPhase4EditFieldValueChanged(app, event)
            app.Phase3_SubPhase4_Duration = app.SubPhase4EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: DurPhase4EditField
        function DurPhase4EditFieldValueChanged(app, event)
            app.Phase4_Duration = app.DurPhase4EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: DurPhase5EditField
        function DurPhase5EditFieldValueChanged(app, event)
            app.Phase5_Duration = app.DurPhase5EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: RedPXGainEditField
        function RedPXGainEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: BlackPXGainEditField
        function BlackPXGainEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: BluePXGainEditField
        function BluePXGainEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: RedDXGainEditField
        function RedDXGainEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: BlackDXGainEditField
        function BlackDXGainEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: BlueDXGainEditField
        function BlueDXGainEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: RedPGainThetaEditField
        function RedPGainThetaEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: BlackPGainThetaEditField
        function BlackPGainThetaEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: BluePGainThetaEditField
        function BluePGainThetaEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: RedDGainThetaEditField
        function RedDGainThetaEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: BlackDGainThetaEditField
        function BlackDGainThetaEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: BlueDGainThetaEditField
        function BlueDGainThetaEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: SolverTypeDropDown
        function SolverTypeDropDownValueChanged(app, event)

            FileName = app.AvailableDiagramsDropDown.Value;
            set_param(FileName(1:(end-4)),'SolverType',app.SolverTypeDropDown.Value);

            if app.SolverTypeDropDown.Value == "Variable-step"
                app.SolverNameVarStepDropDown.Enable = "on";
                app.SolverNameVarStepDropDown.Visible = "on";
                app.SolverNameDropDown.Enable = "off";
                app.SolverNameDropDown.Visible = "off";
                set(app.VarStepAbsTolEditField,'enable','on');
                set(app.VarStepRelTolEditField,'enable','on');
                set(app.VarStepInitStepSizeEditField,'enable','on');
                set(app.VarStepMinStepSizeEditField,'enable','on');
                set(app.VarStepMaxStepSizeEditField,'enable','on');
            else
                app.SolverNameVarStepDropDown.Enable = "off";
                app.SolverNameVarStepDropDown.Visible = "off";
                app.SolverNameDropDown.Enable = "on";
                app.SolverNameDropDown.Visible = "on";
                set(app.VarStepAbsTolEditField,'enable','off');
                set(app.VarStepRelTolEditField,'enable','off');
                set(app.VarStepInitStepSizeEditField,'enable','off');
                set(app.VarStepMinStepSizeEditField,'enable','off');
                set(app.VarStepMaxStepSizeEditField,'enable','off');
            end            

            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Menu selected function: SaveGUIStateMenu
        function SaveGUIStateMenuSelected(app, event)
            waitbox = app.CustomWaitBox('Saving GUI Parameters...');
           
            [filename, pathname] = uiputfile('*.mat', 'Save As');

            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)

                return

            else

                % Initialize an empty struct
                appState = struct();
                              
                % Get the list of properties from the app object
                properties = fieldnames(app);
                
                % Loop through the properties
                for i = 1:length(properties)
                     % Try to save the 'Value' property to the struct
                    try
                        appState.(properties{i}) = app.(properties{i}).Value;
                    catch
                        % If the 'Value' property cannot be saved, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end
                
                subAppState = struct();
                try
                    propertiesSubApp = fieldnames(app.SubAppMassProperties);
    
                    % Loop through the properties
                    for i = 1:length(propertiesSubApp)
                         % Try to save the 'Value' property to the struct
                        try
                            subAppState.(propertiesSubApp{i}) = app.SubAppMassProperties.(propertiesSubApp{i}).Value;
                        catch
                            % If the 'Value' property cannot be saved, skip it
                            %fprintf('Skipping property %s\n', properties{i});
                        end
                    end
                catch
                end

                subAppStateInit = struct();
                try
                    propertiesSubApp = fieldnames(app.SubAppInitialConditions);
    
                    % Loop through the properties
                    for i = 1:length(propertiesSubApp)
                         % Try to save the 'Value' property to the struct
                        try
                            subAppStateInit.(propertiesSubApp{i}) = app.SubAppInitialConditions.(propertiesSubApp{i}).Value;
                        catch
                            % If the 'Value' property cannot be saved, skip it
                            %fprintf('Skipping property %s\n', properties{i});
                        end
                    end
                catch
                end
                % Save the appState variable to a .mat file
                save(fullfile(pathname, filename), 'appState', 'subAppState', 'subAppStateInit');

            end

            close(waitbox);

        end

        % Menu selected function: LoadGUIStateMenu
        function LoadGUIStateMenuSelected(app, event)
            waitbox = app.CustomWaitBox('Loading GUI Parameters...');

            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file');
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else

                % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'appState');
                    
                    % Get the list of properties from the appState struct
                    properties = fieldnames(appState);
                catch
                    ed = errordlg("The file you just tried to load does not contain the variable 'appState'. Confirm you are loading a proper GUI state file.","Error");
                    set(ed, "WindowStyle", "modal");
                    uiwait(ed);
                    return
                end

                 % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'subAppState');
                    
                    % Get the list of properties from the appState struct
                    propertiesSubApp = fieldnames(subAppState);
                catch
                    subAppState = struct();
                    propertiesSubApp = fieldnames(subAppState);
                end

                 % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'subAppStateInit');
                    
                    % Get the list of properties from the appState struct
                    propertiesSubAppInit = fieldnames(subAppStateInit);
                catch
                    subAppStateInit = struct();
                    propertiesSubAppInit = fieldnames(subAppStateInit);
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(properties)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.(properties{i}).Value = appState.(properties{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(propertiesSubApp)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.SubAppMassProperties.(propertiesSubApp{i}).Value = subAppState.(propertiesSubApp{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(propertiesSubAppInit)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.SubAppInitialConditions.(propertiesSubAppInit{i}).Value = subAppStateInit.(propertiesSubAppInit{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                app.AvailableDiagramsDropDownValueChanged();
                app.UpdateTimes();
                app.UpdateAuxParameters();
                app.UpdateSimulationParameters();
                app.SubAppMassProperties.UpdateMassProperties();
                app.SubAppInitialConditions.UpdateInitialConditions();
                app.REDCheckBoxValueChanged();
                app.BLACKCheckBoxValueChanged();
                app.BLUECheckBoxValueChanged();
                app.ARMCheckBoxValueChanged();

                close(waitbox);
            end

        end

        % Menu selected function: ResetGUIParameterstoDefaultMenu
        function ResetGUIParameterstoDefaultMenuSelected(app, event)
            
            waitbox = app.CustomWaitBox('Loading default GUI parameters...');

            % Open a file browser and let the user select a .mat file
            filename = 'SPOTGUI_DEFAULT.mat';
            pathname = [cd '/Resources/'];
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else

                % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'appState');
                    
                    % Get the list of properties from the appState struct
                    properties = fieldnames(appState);
                catch
                    ed = errordlg("The file you just tried to load does not contain the variable 'appState'. Confirm you are loading a proper GUI state file.","Error");
                    set(ed, "WindowStyle", "modal");
                    uiwait(ed);
                    return
                end

                 % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'subAppState');
                    
                    % Get the list of properties from the appState struct
                    propertiesSubApp = fieldnames(subAppState);
                catch
                    subAppState = struct();
                    propertiesSubApp = fieldnames(subAppState);
                end

                 % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'subAppStateInit');
                    
                    % Get the list of properties from the appState struct
                    propertiesSubAppInit = fieldnames(subAppStateInit);
                catch
                    subAppStateInit = struct();
                    propertiesSubAppInit = fieldnames(subAppStateInit);
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(properties)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.(properties{i}).Value = appState.(properties{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(propertiesSubApp)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.SubAppMassProperties.(propertiesSubApp{i}).Value = subAppState.(propertiesSubApp{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(propertiesSubAppInit)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.SubAppInitialConditions.(propertiesSubAppInit{i}).Value = subAppStateInit.(propertiesSubAppInit{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                app.AvailableDiagramsDropDownValueChanged();
                app.UpdateTimes();
                app.UpdateAuxParameters();
                app.UpdateSimulationParameters();
                app.SubAppMassProperties.UpdateMassProperties();
                app.SubAppInitialConditions.UpdateInitialConditions();
                app.REDCheckBoxValueChanged();
                app.BLACKCheckBoxValueChanged();
                app.BLUECheckBoxValueChanged();
                app.ARMCheckBoxValueChanged();
                app.DisableAllKeyButtons();

                close(waitbox);
            end
        end

        % Value changed function: NoiseVarianceRedEditField
        function NoiseVarianceRedEditFieldValueChanged(app, event)
            app.noise_variance_RED = app.NoiseVarianceRedEditField.Value;
            app.UpdateSimulationParameters();
        end

        % Value changed function: NoiseVarianceBlackEditField
        function NoiseVarianceBlackEditFieldValueChanged(app, event)
            app.noise_variance_BLACK = app.NoiseVarianceBlackEditField.Value;
            app.UpdateSimulationParameters();
        end

        % Value changed function: NoiseVarianceBlueEditField
        function NoiseVarianceBlueEditFieldValueChanged(app, event)
            app.noise_variance_BLUE = app.NoiseVarianceBlueEditField.Value;
            app.UpdateSimulationParameters();
        end

        % Value changed function: SolverNameDropDown
        function SolverNameDropDownValueChanged(app, event)
            value = app.SolverNameDropDown.Value;
            FileName = app.AvailableDiagramsDropDown.Value;
            set_param(FileName(1:(end-4)),'SolverName',value);
        end

        % Callback function
        function StopSimulationButtonPushed(app, event)
            selectedFile = get(app.AvailableDiagramsDropDown,'Value');
            set_param(selectedFile(1:(end-4)),'SimulationCommand','stop')
            %assignin('base','simOut',simOut);
        end

        % Button pushed function: LoadDataButtonPlt1
        function LoadDataButtonPlt1Pushed(app, event)
            % Check if there's an existing figure (your app's main window)
            currentFig = app.SPOT40GUIUIFigure;
            
            % Create a waitbar for the data loading
            waitbox = app.CustomWaitBox('Loading Data...');
            
            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file');
            
            % Bring your app's window back to the forefront only if currentFig is not empty
            if ~isempty(currentFig)
                figure(currentFig);
            end
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                tmp   = load([pathname filename]);
                tmpfn = fieldnames(tmp);
                app.LoadedData1 = tmp.(tmpfn{1});
            
                if isstruct(app.LoadedData1)
                    app.SelectXDataPlt1.Items = fieldnames(app.LoadedData1);
                    app.SelectYDataPlt1.Items = fieldnames(app.LoadedData1);
                else
                    waitfor(warndlg('Could not load data file, please ensure the data file is a struct.','Load Failed'));
                end
            end
            
            close(waitbox);

        end

        % Value changed function: SelectXDataPlt1
        function SelectXDataPlt1ValueChanged(app, event)
            dataX = app.LoadedData1.(app.SelectXDataPlt1.Value);
            dataY = app.LoadedData1.(app.SelectYDataPlt1.Value);
            plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0), 'k');
            grid(app.DataViewerPlt1,"on");
            %xaxis(app.DataViewerPlt1,"xy");
            axis(app.DataViewerPlt1,"tight");
            xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1.Value,'_',' '));
            ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1.Value,'_',' '));
        end

        % Value changed function: SelectYDataPlt1
        function SelectYDataPlt1ValueChanged(app, event)
           dataX = app.LoadedData1.(app.SelectXDataPlt1.Value);
           dataY = app.LoadedData1.(app.SelectYDataPlt1.Value);
           plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0), 'k');
           grid(app.DataViewerPlt1,"on");
           %axis(app.DataViewerPlt1,"xy");
           axis(app.DataViewerPlt1,"tight");
           xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1.Value,'_',' '));
           ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1.Value,'_',' '));
        end

        % Button pushed function: LoadDataButtonPlt2
        function LoadDataButtonPlt2Pushed(app, event)
            % Check if there's an existing figure (your app's main window)
            currentFig = app.SPOT40GUIUIFigure;
            
            % Create a waitbar for the data loading
            waitbox = app.CustomWaitBox('Loading Data...');
            
            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file');
            
            % Bring your app's window back to the forefront only if currentFig is not empty
            if ~isempty(currentFig)
                figure(currentFig);
            end
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                tmp   = load([pathname filename]);
                tmpfn = fieldnames(tmp);
                app.LoadedData2 = tmp.(tmpfn{1});
                
                if isstruct(app.LoadedData2)

                    app.SelectXDataPlt2.Items = fieldnames(app.LoadedData2);
                    app.SelectYDataPlt2.Items = fieldnames(app.LoadedData2);
                
                else

                    waitfor(warndlg('Could not load data file, please ensure the data file is a struct.','Load Failed'));

                end


            end     
            close(waitbox);
        end

        % Value changed function: SelectXDataPlt2
        function SelectXDataPlt2ValueChanged(app, event)
            dataX = app.LoadedData2.(app.SelectXDataPlt2.Value);
            dataY = app.LoadedData2.(app.SelectYDataPlt2.Value);
            plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0), 'k');
            grid(app.DataViewerPlt2,"on");
            %xaxis(app.DataViewerPlt2,"xy");
            axis(app.DataViewerPlt2,"tight");
            xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2.Value,'_',' '));
            ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2.Value,'_',' ')); 
        end

        % Value changed function: SelectYDataPlt2
        function SelectYDataPlt2ValueChanged(app, event)
            dataX = app.LoadedData2.(app.SelectXDataPlt2.Value);
            dataY = app.LoadedData2.(app.SelectYDataPlt2.Value);
            plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0), 'k');
            grid(app.DataViewerPlt2,"on");
            %xaxis(app.DataViewerPlt2,"xy");
            axis(app.DataViewerPlt2,"tight");
            xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2.Value,'_',' '));
            ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2.Value,'_',' ')); 
        end

        % Button pushed function: ExporttoPDFButton
        function ExporttoPDFButtonPushed(app, event)

            waitbox = app.CustomWaitBox(['Saving figure to ' app.DataViewer1PDFName.Value '...']);
            try
                exportgraphics(app.DataViewerPlt1,[app.DataViewer1PDFName.Value '.pdf'],'ContentType','vector');
            catch
                waitfor(warndlg('Could not export to PDF. Please confirm a file with the same name is not currently open.','Save to PDF Failed'));
            end
            close(waitbox);
        end

        % Button pushed function: ExporttoPDFButton2
        function ExporttoPDFButton2Pushed(app, event)
            waitbox = app.CustomWaitBox(['Saving figure to ' app.DataViewer2PDFName.Value '...']);
            try
                exportgraphics(app.DataViewerPlt2,[app.DataViewer2PDFName.Value '.pdf'],'ContentType','vector');
            catch
                waitfor(warndlg('Could not export to PDF. Please confirm a file with the same name is not currently open.','Save to PDF Failed'));
            end            
            close(waitbox);
        end

        % Value changed function: VarStepMaxStepSizeEditField
        function VarStepMaxStepSizeEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepMaxStepSize = app.VarStepMaxStepSizeEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: VarStepMinStepSizeEditField
        function VarStepMinStepSizeEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepMinStepSize = app.VarStepMinStepSizeEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: VarStepInitStepSizeEditField
        function VarStepInitStepSizeEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepInitStepSize = app.VarStepInitStepSizeEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: VarStepRelTolEditField
        function VarStepRelTolEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepRelTol = app.VarStepRelTolEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: VarStepAbsTolEditField
        function VarStepAbsTolEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepAbsTol = app.VarStepAbsTolEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: SolverNameVarStepDropDown
        function SolverNameVarStepDropDownValueChanged(app, event)
            value = app.SolverNameVarStepDropDown.Value;
            FileName = app.AvailableDiagramsDropDown.Value;
            set_param(FileName(1:(end-4)),'SolverName',value);
        end

        % Value changed function: ToggleRealTimeTelemetrySwitch
        function ToggleRealTimeTelemetrySwitchValueChanged(app, event)
            value = app.ToggleRealTimeTelemetrySwitch.Value;
            
            if value == "On"
                app.hudpr = dsp.UDPReceiver('LocalIPPort',31534,'ReceiveBufferSize' ,8192,'MessageDataType','double');
            end

            while value == "On"  

                value = app.ToggleRealTimeTelemetrySwitch.Value;
                dataR = app.hudpr();
                                
                % Get latest data packet:
               
                if isempty(dataR)

                    continue;

                end

                if size(dataR) ~= 10

                    continue;

                end

                % Update the indicator color               
                app.RealTimeTelOnLamp.Color = [0.00,1.00,0.00];

                % Update the text boxes
                app.mathrmX_REDEditField.Value = dataR(2)/1000;
                app.mathrmY_REDEditField.Value = dataR(3)/1000;
                app.mathrmtheta_REDEditField.Value = dataR(4)*180/pi;

                app.mathrmX_BLACKEditField.Value = dataR(5)/1000;
                app.mathrmY_BLACKEditField.Value = dataR(6)/1000;
                app.mathrmtheta_BLACKEditField.Value = dataR(7)*180/pi;

                app.mathrmX_BLUEEditField.Value = dataR(8)/1000;
                app.mathrmY_BLUEEditField.Value = dataR(9)/1000;
                app.mathrmtheta_BLUEEditField.Value = dataR(10)*180/pi;

                if app.REDCheckBox.Value

                    % Draw the platforms:
                    a = dataR(3)/1000;
                    b = dataR(2)/1000;
                    w = 0.3;
                    h = 0.3;
                    theta = -dataR(4);

                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;

                    if (isempty(app.REDFigLiveHdl) ~= 1) 

                       % Draw the line showing the direction of the front face:
                        C_bI_RED = [  cos(theta) sin(theta)
                                     -sin(theta) cos(theta) ];

                        Vec_RED  = C_bI_RED*[ 0.15
                                              0.0 ];

                        app.REDFigLiveLine.XData = [a,a+Vec_RED(2)]; 
                        app.REDFigLiveLine.YData = [b,b+Vec_RED(1)];
                        app.REDFigLiveHdl.XData = P(1,:)+a;
                        app.REDFigLiveHdl.YData = P(2,:)+b;
                    end

                end

                if app.BLACKCheckBox.Value == 1

                    a = dataR(6)/1000;
                    b = dataR(5)/1000;
                    w = 0.3;
                    h = 0.3;
                    theta = -dataR(7);

                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;

                    if (isempty(app.BLACKFigLiveHdl) ~= 1) 

                       % Draw the line showing the direction of the front face:
                        C_bI_BLACK = [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];

                        Vec_BLACK  = C_bI_BLACK*[ 0.15
                                                  0.0 ];

                        app.BLACKFigLiveLine.XData = [a,a+Vec_BLACK(2)]; 
                        app.BLACKFigLiveLine.YData = [b,b+Vec_BLACK(1)];
                        app.BLACKFigLiveHdl.XData = P(1,:)+a;
                        app.BLACKFigLiveHdl.YData = P(2,:)+b;

                    end

                end

                if app.BLUECheckBox.Value == 1

                    a = dataR(9)/1000;
                    b = dataR(8)/1000;
                    w = 0.3;
                    h = 0.3;
                    theta = -dataR(10);

                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;

                    if (isempty(app.BLUEFigLiveHdl) ~= 1) 

                       % Draw the line showing the direction of the front face:
                        C_bI_BLUE =  [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];

                        Vec_BLUE  = C_bI_BLUE*[ 0.15
                                                0.0 ];

                        app.BLUEFigLiveLine.XData = [a,a+Vec_BLUE(2)]; 
                        app.BLUEFigLiveLine.YData = [b,b+Vec_BLUE(1)];  
                        app.BLUEFigLiveHdl.XData = P(1,:)+a;
                        app.BLUEFigLiveHdl.YData = P(2,:)+b;

                    end       
                end

                %app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(dataR(1)),' s');

                %pause(0.01)
                drawnow limitrate

            end   

            if value == "Off"
                release(app.hudpr);
                app.RealTimeTelOnLamp.Color = [1.00,0.00,0.00];
            end
            
        end

        % Value changed function: SkipToTimeEditField
        function SkipToTimeEditFieldValueChanged(app, event)

            changingValue = app.SkipToTimeEditField.Value;
            changingValue(changingValue<1)=1;

            if (isempty(app.DataPacket)~=1)

                TimeVec = app.DataPacket(:,1);

                closestIndex = find(abs(TimeVec - changingValue) == min(abs(TimeVec - changingValue)), 1);

                AddPlatformSim(app,evalin("base","platformSelection")); 
                
                % Print time text:
                txtT = '';
                text(app.ExperimentPreview,0.1,3.75,txtT); 
                                        
                a = app.DataPacket(closestIndex,6);
                b = app.DataPacket(closestIndex,5);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacket(closestIndex,7);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.REDFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_RED = [  cos(theta) sin(theta)
                                 -sin(theta) cos(theta) ];
                             
                    Vec_RED  = C_bI_RED*[ 0.15
                                          0.0 ];
                                                  
                    app.REDFigLine.XData = [a,a+Vec_RED(2)]; 
                    app.REDFigLine.YData = [b,b+Vec_RED(1)];
                    app.REDFigHdl.XData = P(1,:)+a;
                    app.REDFigHdl.YData = P(2,:)+b;
                end
                
                x0 = app.DataPacket(closestIndex,6);
                y0 = app.DataPacket(closestIndex,5);
                q0 = -app.DataPacket(closestIndex,7);
                  
                q1 = -(pi/2+app.DataPacket(closestIndex,35));
                q2 = -app.DataPacket(closestIndex,36);
                q3 = -(app.DataPacket(closestIndex,37));         
                
                rs                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) ];                        
                            
                re                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) ];
    
                rw                      = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) ];
    
                ree                     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                
                if (isempty(app.REDFigLink1) ~= 1) 
                    
                    app.REDFigLink1.XData = [rs(1,1)  re(1,1)];
                    app.REDFigLink1.YData = [rs(1,2)  re(1,2)];
                    app.REDFigLink2.XData = [re(1,1)  rw(1,1)];
                    app.REDFigLink2.YData = [re(1,2)  rw(1,2)];
                    app.REDFigLink3.XData = [rw(1,1)  ree(1,1)];
                    app.REDFigLink3.YData = [rw(1,2)  ree(1,2)];
                    app.REDFigJoin0.XData = rs(1,1);
                    app.REDFigJoin0.YData = rs(1,2);
                    app.REDFigJoin1.XData = re(1,1);
                    app.REDFigJoin1.YData = re(1,2);
                    app.REDFigJoin2.XData = rw(1,1);
                    app.REDFigJoin2.YData = rw(1,2);
                    app.REDFigJoin3.XData = ree(1,1);
                    app.REDFigJoin3.YData = ree(1,2);
                                            
                end
                
                a = app.DataPacket(closestIndex,18);
                b = app.DataPacket(closestIndex,17);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacket(closestIndex,19);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.BLACKFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_BLACK = [  cos(theta) sin(theta)
                                   -sin(theta) cos(theta) ];
                             
                    Vec_BLACK  = C_bI_BLACK*[ 0.15
                                              0.0 ];
                                                  
                    app.BLACKFigLine.XData = [a,a+Vec_BLACK(2)]; 
                    app.BLACKFigLine.YData = [b,b+Vec_BLACK(1)];
                    app.BLACKFigHdl.XData = P(1,:)+a;
                    app.BLACKFigHdl.YData = P(2,:)+b;
                    
                end
                
                a = app.DataPacket(closestIndex,30);
                b = app.DataPacket(closestIndex,29);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacket(closestIndex,31);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.BLUEFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_BLUE =  [  cos(theta) sin(theta)
                                   -sin(theta) cos(theta) ];
                             
                    Vec_BLUE  = C_bI_BLUE*[ 0.15
                                            0.0 ];
                                                  
                    app.BLUEFigLine.XData = [a,a+Vec_BLUE(2)]; 
                    app.BLUEFigLine.YData = [b,b+Vec_BLUE(1)];  
                    app.BLUEFigHdl.XData = P(1,:)+a;
                    app.BLUEFigHdl.YData = P(2,:)+b;
                    
                end                    
      
                app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(app.DataPacket(closestIndex,1)),' s');
            end
        end

        % Callback function
        function EstablishConnectionwithSelectedPlatformsButtonValueChanged(app, event)
            value = app.Step8EstablishConnectionwithSelectedPlatformsButton.Value;
            if value == 1 && isempty(app.REDConnection)
            end
            
        end

        % Button pushed function: OpenMassPropertiesAppButton
        function OpenMassPropertiesAppButtonPushed(app, event)
            % Instantiate the sub-application
            app.SubAppMassProperties.SPOT40MassPropertiesUIFigure.Visible = "on";
        end

        % Close request function: SPOT40GUIUIFigure
        function SPOT40GUIUIFigureCloseRequest(app, event)
            if isempty(app.SubAppMassProperties) && isempty(app.SubAppInitialConditions)
                delete(app);
            elseif ~isempty(app.SubAppMassProperties) && isempty(app.SubAppInitialConditions)
                delete(app.SubAppMassProperties);
                delete(app);
            elseif isempty(app.SubAppMassProperties) && ~isempty(app.SubAppInitialConditions)
                delete(app.SubAppInitialConditions);
                delete(app);
            else
                delete(app.SubAppInitialConditions);
                delete(app.SubAppMassProperties);
                delete(app);
            end
            
        end

        % Value changed function: RedPYGainEditField
        function RedPYGainEditFieldValueChanged(app, event)
            app.Kp_yr = app.RedPYGainEditField.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: BlackPYGainEditField
        function BlackPYGainEditFieldValueChanged(app, event)
            app.Kp_yb = app.BlackPYGainEditField.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: BluePYGainEditField
        function BluePYGainEditFieldValueChanged(app, event)
            app.Kp_yblue = app.BluePYGainEditField.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: RedDYGainEditField
        function RedDYGainEditFieldValueChanged(app, event)
            app.Kd_yr = app.RedDYGainEditField.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: BlackDYGainEditField
        function BlackDYGainEditFieldValueChanged(app, event)
            app.Kd_yb = app.BlackDYGainEditField.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: BlueDYGainEditField
        function BlueDYGainEditFieldValueChanged(app, event)
            app.Kd_yblue = app.BlueDYGainEditField.Value;
            app.UpdateAuxParameters();
        end

        % Menu selected function: AddPathtoCustomLibraryMenu
        function AddPathtoCustomLibraryMenuSelected(app, event)
            % Warn the user about adding custom libraries:
            Choice = questdlg('Warning: You are about to add a new custom library to your path. If you have more then one custom library on the path, you will encounter problems. If you want to proceed, select "proceed".','Adding Custom Library','Proceed','Cancel','Proceed');
            switch Choice
                case 'Proceed'
                    
                    % Get user to select a folder 
                    selpath = uigetdir();
        
                    pathCell = regexp(path, pathsep, 'split');
                    if ispc  % Windows is not case-sensitive
                      onPath = any(strcmpi(selpath, pathCell));
                    else
                      onPath = any(strcmp(selpath, pathCell));
                    end
        
                    if onPath == 1
                        ed = errordlg("The path you selected is already on the MATLAB path.","Error");
                        set(ed, "WindowStyle", "modal");
                        uiwait(ed);
                    else
                        addpath(genpath(selpath));
                        savepath matlab/myfiles/pathdef.m
                    end

                case 'Cancel'

            end
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            app.PreviousSamplingRate      = app.SampleRateEditField.Value;
            app.baseRate                  = 1/app.SampleRateEditField.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: REDFXNominal
        function REDFXNominalValueChanged(app, event)
            app.F_red_X_nominal = app.REDFXNominal.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: BLACKFXNominal
        function BLACKFXNominalValueChanged(app, event)
            app.F_black_X_nominal = app.BLACKFXNominal.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: BLUEFXNominal
        function BLUEFXNominalValueChanged(app, event)
            app.F_blue_X_nominal = app.BLUEFXNominal.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: REDFYNominal
        function REDFYNominalValueChanged(app, event)
            app.F_red_Y_nominal = app.REDFYNominal.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: BLACKFYNominal
        function BLACKFYNominalValueChanged(app, event)
            app.F_black_Y_nominal = app.BLACKFYNominal.Value;
            app.UpdateAuxParameters();
        end

        % Value changed function: BLUEFYNominal
        function BLUEFYNominalValueChanged(app, event)
            app.F_blue_Y_nominal = app.BLUEFYNominal.Value;
            app.UpdateAuxParameters();
        end

        % Button pushed function: OpenInitialConditionAppButton
        function OpenInitialConditionAppButtonPushed(app, event)
            % Instantiate the sub-application
            app.SubAppInitialConditions.SPOT40InitialConditionsUIFigure.Visible = "on";
        end

        % Value changed function: ThursterCountThresholdEditField
        function ThursterCountThresholdEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();     
        end

        % Menu selected function: OverrideCurrentDefaultParametersMenu
        function OverrideCurrentDefaultParametersMenuSelected(app, event)
                % Grab the file name from the drop down menu:
                Choice = questdlg('Warning: If you overwrite the default values you will not be able to revert back to the original defaults. If you want to proceed, select "proceed".','Overwritting Defaults','Proceed','Cancel','Proceed');
                switch Choice
                    case 'Proceed'

                        waitbox = app.CustomWaitBox('Overwriting Defaults Parameters...');
                       
                        filename = 'SPOTGUI_DEFAULT';
                        pathname = [cd '/Resources/'];
            
                        % Check if the user clicked 'Cancel'
                        if isequal(filename, 0) || isequal(pathname, 0)
            
                            return
            
                        else
            
                            % Initialize an empty struct
                            appState = struct();
                                          
                            % Get the list of properties from the app object
                            properties = fieldnames(app);
                            
                            % Loop through the properties
                            for i = 1:length(properties)
                                 % Try to save the 'Value' property to the struct
                                try
                                    appState.(properties{i}) = app.(properties{i}).Value;
                                catch
                                    % If the 'Value' property cannot be saved, skip it
                                    %fprintf('Skipping property %s\n', properties{i});
                                end
                            end
                            
                            subAppState = struct();
                            try
                                propertiesSubApp = fieldnames(app.SubAppMassProperties);
                
                                % Loop through the properties
                                for i = 1:length(propertiesSubApp)
                                     % Try to save the 'Value' property to the struct
                                    try
                                        subAppState.(propertiesSubApp{i}) = app.SubAppMassProperties.(propertiesSubApp{i}).Value;
                                    catch
                                        % If the 'Value' property cannot be saved, skip it
                                        %fprintf('Skipping property %s\n', properties{i});
                                    end
                                end
                            catch
                            end
            
                            subAppStateInit = struct();
                            try
                                propertiesSubApp = fieldnames(app.SubAppInitialConditions);
                
                                % Loop through the properties
                                for i = 1:length(propertiesSubApp)
                                     % Try to save the 'Value' property to the struct
                                    try
                                        subAppStateInit.(propertiesSubApp{i}) = app.SubAppInitialConditions.(propertiesSubApp{i}).Value;
                                    catch
                                        % If the 'Value' property cannot be saved, skip it
                                        %fprintf('Skipping property %s\n', properties{i});
                                    end
                                end
                            catch
                            end
                            % Save the appState variable to a .mat file
                            save(fullfile(pathname, filename), 'appState', 'subAppState', 'subAppStateInit');
            
                        end
            
                        close(waitbox);

                    case 'Cancel'

                end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SPOT40GUIUIFigure = uifigure('Visible', 'off');
            app.SPOT40GUIUIFigure.Name = 'SPOT 4.0 GUI';
            app.SPOT40GUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @SPOT40GUIUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.SPOT40GUIUIFigure);
            app.FileMenu.Text = 'File';
            app.LabLinksMenu = uimenu(app.FileMenu);
            app.LabLinksMenu.Text = 'Lab Links';
            app.GitHubMenu = uimenu(app.LabLinksMenu);
            app.GitHubMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubMenuSelected, true);
            app.GitHubMenu.Text = 'GitHub';
            app.GooglePhotosMenu = uimenu(app.LabLinksMenu);
            app.GooglePhotosMenu.MenuSelectedFcn = createCallbackFcn(app, @GooglePhotosMenuSelected, true);
            app.GooglePhotosMenu.Text = 'Google Photos';
            app.YoutubeChannelMenu = uimenu(app.LabLinksMenu);
            app.YoutubeChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @YoutubeChannelMenuSelected, true);
            app.YoutubeChannelMenu.Text = 'Youtube Channel';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.Text = 'Help';
            app.YouTubeTutorialsMenu = uimenu(app.HelpMenu);
            app.YouTubeTutorialsMenu.Text = 'YouTube Tutorials';
            app.RunningaSimulationandExperimentMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RunningaSimulationandExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @RunningaSimulationandExperimentMenuSelected, true);
            app.RunningaSimulationandExperimentMenu.Text = 'Running a Simulation and Experiment';
            app.ConnectingtoaRaspberryPiRemotelyMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ConnectingtoaRaspberryPiRemotelyMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectingtoaRaspberryPiRemotelyMenuSelected, true);
            app.ConnectingtoaRaspberryPiRemotelyMenu.Text = 'Connecting to a Raspberry Pi Remotely';
            app.RemovingtheTableCoverMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RemovingtheTableCoverMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovingtheTableCoverMenuSelected, true);
            app.RemovingtheTableCoverMenu.Text = 'Removing the Table Cover';
            app.RetrievingtheIsopropanolMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RetrievingtheIsopropanolMenu.MenuSelectedFcn = createCallbackFcn(app, @RetrievingtheIsopropanolMenuSelected, true);
            app.RetrievingtheIsopropanolMenu.Text = 'Retrieving the Isopropanol';
            app.TableCleaningProceduresMenu = uimenu(app.YouTubeTutorialsMenu);
            app.TableCleaningProceduresMenu.MenuSelectedFcn = createCallbackFcn(app, @TableCleaningProceduresMenuSelected, true);
            app.TableCleaningProceduresMenu.Text = 'Table Cleaning Procedures';
            app.SavingExperimentDataMenu = uimenu(app.YouTubeTutorialsMenu);
            app.SavingExperimentDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavingExperimentDataMenuSelected, true);
            app.SavingExperimentDataMenu.Text = 'Saving Experiment Data';
            app.RemovingtheRoboticArmMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RemovingtheRoboticArmMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovingtheRoboticArmMenuSelected, true);
            app.RemovingtheRoboticArmMenu.Text = 'Removing the Robotic Arm';
            app.ChargingaLEDBatteryPackMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ChargingaLEDBatteryPackMenu.MenuSelectedFcn = createCallbackFcn(app, @ChargingaLEDBatteryPackMenuSelected, true);
            app.ChargingaLEDBatteryPackMenu.Text = 'Charging a LED Battery Pack';
            app.ChargingaPlatformMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ChargingaPlatformMenu.MenuSelectedFcn = createCallbackFcn(app, @ChargingaPlatformMenuSelected, true);
            app.ChargingaPlatformMenu.Text = 'Charging a Platform';
            app.InstallinganAirTankMenu = uimenu(app.YouTubeTutorialsMenu);
            app.InstallinganAirTankMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallinganAirTankMenuSelected, true);
            app.InstallinganAirTankMenu.Text = 'Installing an Air Tank';
            app.InstallingtheRoboticArmMenu = uimenu(app.YouTubeTutorialsMenu);
            app.InstallingtheRoboticArmMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallingtheRoboticArmMenuSelected, true);
            app.InstallingtheRoboticArmMenu.Text = 'Installing the Robotic Arm';
            app.ForkingtheSPOTRepositoryMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ForkingtheSPOTRepositoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ForkingtheSPOTRepositoryMenuSelected, true);
            app.ForkingtheSPOTRepositoryMenu.Text = 'Forking the SPOT Repository';
            app.PlacingtheTableCoverMenu = uimenu(app.YouTubeTutorialsMenu);
            app.PlacingtheTableCoverMenu.MenuSelectedFcn = createCallbackFcn(app, @PlacingtheTableCoverMenuSelected, true);
            app.PlacingtheTableCoverMenu.Text = 'Placing the Table Cover';
            app.FillinganAirTankMenu = uimenu(app.YouTubeTutorialsMenu);
            app.FillinganAirTankMenu.MenuSelectedFcn = createCallbackFcn(app, @FillinganAirTankMenuSelected, true);
            app.FillinganAirTankMenu.Text = 'Filling an Air Tank';
            app.ExportingVideoswithQCViewerMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ExportingVideoswithQCViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportingVideoswithQCViewerMenuSelected, true);
            app.ExportingVideoswithQCViewerMenu.Text = 'Exporting Videos with QC Viewer';
            app.CleaningthePucksandMovingaPlatformMenu = uimenu(app.YouTubeTutorialsMenu);
            app.CleaningthePucksandMovingaPlatformMenu.MenuSelectedFcn = createCallbackFcn(app, @CleaningthePucksandMovingaPlatformMenuSelected, true);
            app.CleaningthePucksandMovingaPlatformMenu.Text = 'Cleaning the Pucks and Moving a Platform';
            app.SaveGUIStateMenu = uimenu(app.FileMenu);
            app.SaveGUIStateMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveGUIStateMenuSelected, true);
            app.SaveGUIStateMenu.Tooltip = {'Save the current state of the GUI to a MAT file so that you can load it later.'};
            app.SaveGUIStateMenu.Text = 'Save GUI State';
            app.LoadGUIStateMenu = uimenu(app.FileMenu);
            app.LoadGUIStateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadGUIStateMenuSelected, true);
            app.LoadGUIStateMenu.Tooltip = {'Load a MAT file containing a saved GUI state. '};
            app.LoadGUIStateMenu.Text = 'Load GUI State';
            app.ResetGUIParameterstoDefaultMenu = uimenu(app.FileMenu);
            app.ResetGUIParameterstoDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetGUIParameterstoDefaultMenuSelected, true);
            app.ResetGUIParameterstoDefaultMenu.Tooltip = {'Reset the GUI to it''s default state. Default states are saved in the Resources folder.'};
            app.ResetGUIParameterstoDefaultMenu.Text = 'Reset GUI Parameters to Default';
            app.OverrideCurrentDefaultParametersMenu = uimenu(app.FileMenu);
            app.OverrideCurrentDefaultParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @OverrideCurrentDefaultParametersMenuSelected, true);
            app.OverrideCurrentDefaultParametersMenu.Tooltip = {'Allows you to override the default GUI states (stored in the Resources folder) with the current GUI state. This is permanent.'};
            app.OverrideCurrentDefaultParametersMenu.Text = 'Override Current Default Parameters';
            app.ToolsMenu = uimenu(app.SPOT40GUIUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.AddPathtoCustomLibraryMenu = uimenu(app.ToolsMenu);
            app.AddPathtoCustomLibraryMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPathtoCustomLibraryMenuSelected, true);
            app.AddPathtoCustomLibraryMenu.Tooltip = {'Allows you to add any folder to the matlab path. It''s designed to be used to add a new custom library, but you could add any path you want.'};
            app.AddPathtoCustomLibraryMenu.Text = 'Add Path to Custom Library';
            app.GridLayout16 = uigridlayout(app.SPOT40GUIUIFigure);
            app.TabGroup2 = uitabgroup(app.GridLayout16);
            app.InitializeParametersTab = uitab(app.TabGroup2);
            app.InitializeParametersTab.Title = 'Initialize Parameters';
            app.GridLayout17 = uigridlayout(app.InitializeParametersTab);
            app.Step3ActiveHardwarePanel = uipanel(app.GridLayout17);
            app.Step3ActiveHardwarePanel.Title = 'Step #3: Active Hardware';
            app.GridLayout4 = uigridlayout(app.Step3ActiveHardwarePanel);
            app.REDCheckBox = uicheckbox(app.GridLayout4);
            app.REDCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDCheckBoxValueChanged, true);
            app.REDCheckBox.Tooltip = {'Select to enable RED for the simulation and experiment.'};
            app.REDCheckBox.Text = 'RED ';
            app.BLUECheckBox = uicheckbox(app.GridLayout4);
            app.BLUECheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUECheckBoxValueChanged, true);
            app.BLUECheckBox.Tooltip = {'Select to enable BLUE for the simulation and experiment.'};
            app.BLUECheckBox.Text = 'BLUE ';
            app.ARMCheckBox = uicheckbox(app.GridLayout4);
            app.ARMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ARMCheckBoxValueChanged, true);
            app.ARMCheckBox.Tooltip = {'Select to enable the ARM for the simulation and experiment.'};
            app.ARMCheckBox.Text = 'ARM';
            app.BLACKCheckBox = uicheckbox(app.GridLayout4);
            app.BLACKCheckBox.ValueChangedFcn = createCallbackFcn(app, @BLACKCheckBoxValueChanged, true);
            app.BLACKCheckBox.Tooltip = {'Select to enable BLACK for the simulation and experiment.'};
            app.BLACKCheckBox.Text = 'BLACK ';
            app.Step6AuxiliaryParametersPanel = uipanel(app.GridLayout17);
            app.Step6AuxiliaryParametersPanel.Title = 'Step #6: Auxiliary Parameters';
            app.GridLayout3_2 = uigridlayout(app.Step6AuxiliaryParametersPanel);
            app.ProportionalGainsLabel = uilabel(app.GridLayout3_2);
            app.ProportionalGainsLabel.Text = 'Proportional Gains';
            app.RedPXGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.RedPXGainEditField.ValueDisplayFormat = '%.1f';
            app.RedPXGainEditField.ValueChangedFcn = createCallbackFcn(app, @RedPXGainEditFieldValueChanged, true);
            app.RedPXGainEditField.Tooltip = {'Set the proportial gain for RED (x-position error),'};
            app.RedPXGainEditField.Value = 1;
            app.mathrmK_pXLabel_2 = uilabel(app.GridLayout3_2);
            app.mathrmK_pXLabel_2.Interpreter = 'latex';
            app.mathrmK_pXLabel_2.Text = '$\mathrm{K_{pX}}$';
            app.BlackPXGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlackPXGainEditField.ValueDisplayFormat = '%.1f';
            app.BlackPXGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlackPXGainEditFieldValueChanged, true);
            app.BlackPXGainEditField.Tooltip = {'Set the proportial gain for BLACK (x-position error),'};
            app.BlackPXGainEditField.Value = 1;
            app.mathrmK_pXLabel_3 = uilabel(app.GridLayout3_2);
            app.mathrmK_pXLabel_3.Interpreter = 'latex';
            app.mathrmK_pXLabel_3.Text = '$\mathrm{K_{pX}}$';
            app.BluePXGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BluePXGainEditField.ValueDisplayFormat = '%.1f';
            app.BluePXGainEditField.ValueChangedFcn = createCallbackFcn(app, @BluePXGainEditFieldValueChanged, true);
            app.BluePXGainEditField.Tooltip = {'Set the proportial gain for BLUE (x-position error),'};
            app.BluePXGainEditField.Value = 1;
            app.DerivativeGainsLabel = uilabel(app.GridLayout3_2);
            app.DerivativeGainsLabel.Text = 'Derivative Gains';
            app.mathrmK_dXLabel = uilabel(app.GridLayout3_2);
            app.mathrmK_dXLabel.Interpreter = 'latex';
            app.mathrmK_dXLabel.Text = '$\mathrm{K_{dX}}$';
            app.RedDXGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.RedDXGainEditField.ValueDisplayFormat = '%.1f';
            app.RedDXGainEditField.ValueChangedFcn = createCallbackFcn(app, @RedDXGainEditFieldValueChanged, true);
            app.RedDXGainEditField.Tooltip = {'Set the derivative gain for RED (x-position error),'};
            app.RedDXGainEditField.Value = 10;
            app.mathrmK_dXLabel_2 = uilabel(app.GridLayout3_2);
            app.mathrmK_dXLabel_2.Interpreter = 'latex';
            app.mathrmK_dXLabel_2.Text = '$\mathrm{K_{dX}}$';
            app.BlackDXGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlackDXGainEditField.ValueDisplayFormat = '%.1f';
            app.BlackDXGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlackDXGainEditFieldValueChanged, true);
            app.BlackDXGainEditField.Tooltip = {'Set the derivative gain for BLACK (x-position error),'};
            app.BlackDXGainEditField.Value = 10;
            app.mathrmK_dXLabel_3 = uilabel(app.GridLayout3_2);
            app.mathrmK_dXLabel_3.Interpreter = 'latex';
            app.mathrmK_dXLabel_3.Text = '$\mathrm{K_{dX}}$';
            app.BlueDXGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlueDXGainEditField.ValueDisplayFormat = '%.1f';
            app.BlueDXGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlueDXGainEditFieldValueChanged, true);
            app.BlueDXGainEditField.Tooltip = {'Set the derivative gain for BLUE (x-position error),'};
            app.BlueDXGainEditField.Value = 10;
            app.mathrmK_pthetaLabel = uilabel(app.GridLayout3_2);
            app.mathrmK_pthetaLabel.Interpreter = 'latex';
            app.mathrmK_pthetaLabel.Text = '$\mathrm{K_{p\theta}}$';
            app.RedPGainThetaEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.RedPGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.RedPGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @RedPGainThetaEditFieldValueChanged, true);
            app.RedPGainThetaEditField.Tooltip = {'Set the proportial gain for RED (attitude error),'};
            app.RedPGainThetaEditField.Value = 0.3;
            app.mathrmK_pthetaLabel_2 = uilabel(app.GridLayout3_2);
            app.mathrmK_pthetaLabel_2.Interpreter = 'latex';
            app.mathrmK_pthetaLabel_2.Text = '$\mathrm{K_{p\theta}}$';
            app.BlackPGainThetaEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlackPGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.BlackPGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @BlackPGainThetaEditFieldValueChanged, true);
            app.BlackPGainThetaEditField.Tooltip = {'Set the proportial gain for BLACK (attitude error),'};
            app.BlackPGainThetaEditField.Value = 0.3;
            app.mathrmK_pthetaLabel_3 = uilabel(app.GridLayout3_2);
            app.mathrmK_pthetaLabel_3.Interpreter = 'latex';
            app.mathrmK_pthetaLabel_3.Text = '$\mathrm{K_{p\theta}}$';
            app.BluePGainThetaEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BluePGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.BluePGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @BluePGainThetaEditFieldValueChanged, true);
            app.BluePGainThetaEditField.Tooltip = {'Set the proportial gain for BLUE (attitude error),'};
            app.BluePGainThetaEditField.Value = 0.3;
            app.mathrmK_dthetaLabel_2 = uilabel(app.GridLayout3_2);
            app.mathrmK_dthetaLabel_2.Interpreter = 'latex';
            app.mathrmK_dthetaLabel_2.Text = '$\mathrm{K_{d\theta}}$';
            app.RedDGainThetaEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.RedDGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.RedDGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @RedDGainThetaEditFieldValueChanged, true);
            app.RedDGainThetaEditField.Tooltip = {'Set the derivative gain for RED (attitude error),'};
            app.RedDGainThetaEditField.Value = 3;
            app.mathrmK_dthetaLabel = uilabel(app.GridLayout3_2);
            app.mathrmK_dthetaLabel.Interpreter = 'latex';
            app.mathrmK_dthetaLabel.Text = '$\mathrm{K_{d\theta}}$';
            app.BlackDGainThetaEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlackDGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.BlackDGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @BlackDGainThetaEditFieldValueChanged, true);
            app.BlackDGainThetaEditField.Tooltip = {'Set the derivative gain for BLACK (attitude error),'};
            app.BlackDGainThetaEditField.Value = 3;
            app.mathrmK_dthetaLabel_3 = uilabel(app.GridLayout3_2);
            app.mathrmK_dthetaLabel_3.Interpreter = 'latex';
            app.mathrmK_dthetaLabel_3.Text = '$\mathrm{K_{d\theta}}$';
            app.BlueDGainThetaEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlueDGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.BlueDGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @BlueDGainThetaEditFieldValueChanged, true);
            app.BlueDGainThetaEditField.Tooltip = {'Set the derivative gain for BLUE (attitude error),'};
            app.BlueDGainThetaEditField.Value = 3;
            app.SamplingRateLabel = uilabel(app.GridLayout3_2);
            app.SamplingRateLabel.Text = 'Sampling Rate ';
            app.mathrmK_pXLabel = uilabel(app.GridLayout3_2);
            app.mathrmK_pXLabel.Interpreter = 'latex';
            app.mathrmK_pXLabel.Text = '$\mathrm{K_{pX}}$';
            app.GridLayout31 = uigridlayout(app.GridLayout3_2);
            app.SampleRateEditField = uieditfield(app.GridLayout31, 'numeric');
            app.SampleRateEditField.ValueDisplayFormat = '%.1f';
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.SampleRateEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. If you change this value, you will need to retune the gain and possibly change the PWM period values.'};
            app.SampleRateEditField.Value = 100;
            app.DurPhase0Units_51 = uilabel(app.GridLayout31);
            app.DurPhase0Units_51.Interpreter = 'latex';
            app.DurPhase0Units_51.Text = 'Hz';
            app.mathrmK_pYLabel_2 = uilabel(app.GridLayout3_2);
            app.mathrmK_pYLabel_2.Interpreter = 'latex';
            app.mathrmK_pYLabel_2.Text = '$\mathrm{K_{pY}}$';
            app.mathrmK_pYLabel_3 = uilabel(app.GridLayout3_2);
            app.mathrmK_pYLabel_3.Interpreter = 'latex';
            app.mathrmK_pYLabel_3.Text = '$\mathrm{K_{pY}}$';
            app.mathrmK_pYLabel = uilabel(app.GridLayout3_2);
            app.mathrmK_pYLabel.Interpreter = 'latex';
            app.mathrmK_pYLabel.Text = '$\mathrm{K_{pY}}$';
            app.RedPYGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.RedPYGainEditField.ValueDisplayFormat = '%.1f';
            app.RedPYGainEditField.ValueChangedFcn = createCallbackFcn(app, @RedPYGainEditFieldValueChanged, true);
            app.RedPYGainEditField.Tooltip = {'Set the proportial gain for RED (y-position error),'};
            app.RedPYGainEditField.Value = 1;
            app.BlackPYGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlackPYGainEditField.ValueDisplayFormat = '%.1f';
            app.BlackPYGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlackPYGainEditFieldValueChanged, true);
            app.BlackPYGainEditField.Tooltip = {'Set the proportial gain for BLACK (y-position  error),'};
            app.BlackPYGainEditField.Value = 1;
            app.BluePYGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BluePYGainEditField.ValueDisplayFormat = '%.1f';
            app.BluePYGainEditField.ValueChangedFcn = createCallbackFcn(app, @BluePYGainEditFieldValueChanged, true);
            app.BluePYGainEditField.Tooltip = {'Set the proportial gain for BLUE (y-position error),'};
            app.BluePYGainEditField.Value = 1;
            app.mathrmK_dYLabel = uilabel(app.GridLayout3_2);
            app.mathrmK_dYLabel.Interpreter = 'latex';
            app.mathrmK_dYLabel.Text = '$\mathrm{K_{dY}}$';
            app.RedDYGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.RedDYGainEditField.ValueDisplayFormat = '%.1f';
            app.RedDYGainEditField.ValueChangedFcn = createCallbackFcn(app, @RedDYGainEditFieldValueChanged, true);
            app.RedDYGainEditField.Tooltip = {'Set the derivative gain for RED (y-position error),'};
            app.RedDYGainEditField.Value = 10;
            app.mathrmK_dYLabel_2 = uilabel(app.GridLayout3_2);
            app.mathrmK_dYLabel_2.Interpreter = 'latex';
            app.mathrmK_dYLabel_2.Text = '$\mathrm{K_{dY}}$';
            app.BlackDYGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlackDYGainEditField.ValueDisplayFormat = '%.1f';
            app.BlackDYGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlackDYGainEditFieldValueChanged, true);
            app.BlackDYGainEditField.Tooltip = {'Set the derivative gain for BLACK (y-position error),'};
            app.BlackDYGainEditField.Value = 10;
            app.mathrmK_dYLabel_3 = uilabel(app.GridLayout3_2);
            app.mathrmK_dYLabel_3.Interpreter = 'latex';
            app.mathrmK_dYLabel_3.Text = '$\mathrm{K_{dY}}$';
            app.BlueDYGainEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.BlueDYGainEditField.ValueDisplayFormat = '%.1f';
            app.BlueDYGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlueDYGainEditFieldValueChanged, true);
            app.BlueDYGainEditField.Tooltip = {'Set the derivative gain for BLUE (y-position error),'};
            app.BlueDYGainEditField.Value = 10;
            app.ThrusterParametersLabel = uilabel(app.GridLayout3_2);
            app.ThrusterParametersLabel.Text = 'Thruster Parameters';
            app.mathrmF_X_NominalLabel = uilabel(app.GridLayout3_2);
            app.mathrmF_X_NominalLabel.Interpreter = 'latex';
            app.mathrmF_X_NominalLabel.Text = '$\mathrm{F_{X_{Nominal}}}$';
            app.REDFXNominal = uieditfield(app.GridLayout3_2, 'numeric');
            app.REDFXNominal.ValueDisplayFormat = '%.4f';
            app.REDFXNominal.ValueChangedFcn = createCallbackFcn(app, @REDFXNominalValueChanged, true);
            app.REDFXNominal.Tooltip = {'Set the nominal thrust value for RED (x-direction).'};
            app.REDFXNominal.Value = 0.2196;
            app.DurPhase3Units_2 = uilabel(app.GridLayout3_2);
            app.DurPhase3Units_2.Text = 'N';
            app.mathrmF_X_NominalLabel_2 = uilabel(app.GridLayout3_2);
            app.mathrmF_X_NominalLabel_2.Interpreter = 'latex';
            app.mathrmF_X_NominalLabel_2.Text = '$\mathrm{F_{X_{Nominal}}}$';
            app.BLACKFXNominal = uieditfield(app.GridLayout3_2, 'numeric');
            app.BLACKFXNominal.ValueDisplayFormat = '%.4f';
            app.BLACKFXNominal.ValueChangedFcn = createCallbackFcn(app, @BLACKFXNominalValueChanged, true);
            app.BLACKFXNominal.Tooltip = {'Set the nominal thrust value for BLACK (x-direction).'};
            app.BLACKFXNominal.Value = 0.2196;
            app.DurPhase3Units_3 = uilabel(app.GridLayout3_2);
            app.DurPhase3Units_3.Text = 'N';
            app.mathrmF_X_NominalLabel_3 = uilabel(app.GridLayout3_2);
            app.mathrmF_X_NominalLabel_3.Interpreter = 'latex';
            app.mathrmF_X_NominalLabel_3.Text = '$\mathrm{F_{X_{Nominal}}}$';
            app.BLUEFXNominal = uieditfield(app.GridLayout3_2, 'numeric');
            app.BLUEFXNominal.ValueDisplayFormat = '%.4f';
            app.BLUEFXNominal.ValueChangedFcn = createCallbackFcn(app, @BLUEFXNominalValueChanged, true);
            app.BLUEFXNominal.Tooltip = {'Set the nominal thrust value for BLUE (x-direction).'};
            app.BLUEFXNominal.Value = 0.2196;
            app.DurPhase3Units_4 = uilabel(app.GridLayout3_2);
            app.DurPhase3Units_4.Text = 'N';
            app.mathrmF_Y_NominalLabel = uilabel(app.GridLayout3_2);
            app.mathrmF_Y_NominalLabel.Interpreter = 'latex';
            app.mathrmF_Y_NominalLabel.Text = '$\mathrm{F_{Y_{Nominal}}}$';
            app.DurPhase3Units_5 = uilabel(app.GridLayout3_2);
            app.DurPhase3Units_5.Text = 'N';
            app.mathrmF_Y_NominalLabel_2 = uilabel(app.GridLayout3_2);
            app.mathrmF_Y_NominalLabel_2.Interpreter = 'latex';
            app.mathrmF_Y_NominalLabel_2.Text = '$\mathrm{F_{Y_{Nominal}}}$';
            app.DurPhase3Units_6 = uilabel(app.GridLayout3_2);
            app.DurPhase3Units_6.Text = 'N';
            app.mathrmF_Y_NominalLabel_3 = uilabel(app.GridLayout3_2);
            app.mathrmF_Y_NominalLabel_3.Interpreter = 'latex';
            app.mathrmF_Y_NominalLabel_3.Text = '$\mathrm{F_{Y_{Nominal}}}$';
            app.DurPhase3Units_7 = uilabel(app.GridLayout3_2);
            app.DurPhase3Units_7.Text = 'N';
            app.REDFYNominal = uieditfield(app.GridLayout3_2, 'numeric');
            app.REDFYNominal.ValueDisplayFormat = '%.4f';
            app.REDFYNominal.ValueChangedFcn = createCallbackFcn(app, @REDFYNominalValueChanged, true);
            app.REDFYNominal.Tooltip = {'Set the nominal thrust value for RED (y-direction).'};
            app.REDFYNominal.Value = 0.2825;
            app.BLACKFYNominal = uieditfield(app.GridLayout3_2, 'numeric');
            app.BLACKFYNominal.ValueDisplayFormat = '%.4f';
            app.BLACKFYNominal.ValueChangedFcn = createCallbackFcn(app, @BLACKFYNominalValueChanged, true);
            app.BLACKFYNominal.Tooltip = {'Set the nominal thrust value for BLACK (y-direction).'};
            app.BLACKFYNominal.Value = 0.2825;
            app.BLUEFYNominal = uieditfield(app.GridLayout3_2, 'numeric');
            app.BLUEFYNominal.ValueDisplayFormat = '%.4f';
            app.BLUEFYNominal.ValueChangedFcn = createCallbackFcn(app, @BLUEFYNominalValueChanged, true);
            app.BLUEFYNominal.Tooltip = {'Set the nominal thrust value for BLUE (y-direction).'};
            app.BLUEFYNominal.Value = 0.2825;
            app.ThrusterCountThresholdLabel = uilabel(app.GridLayout3_2);
            app.ThrusterCountThresholdLabel.Interpreter = 'latex';
            app.ThrusterCountThresholdLabel.Text = 'Thruster Count Threshold';
            app.ThursterCountThresholdEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.ThursterCountThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThursterCountThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThursterCountThresholdEditFieldValueChanged, true);
            app.ThursterCountThresholdEditField.Tooltip = {'Set the number of thrusters that need to be open '};
            app.DurPhase3Units_8 = uilabel(app.GridLayout3_2);
            app.DurPhase3Units_8.Text = '-';
            app.Step1LoadSimulinkDiagramPanel = uipanel(app.GridLayout17);
            app.Step1LoadSimulinkDiagramPanel.Title = 'Step #1: Load Simulink Diagram ';
            app.GridLayout3_3 = uigridlayout(app.Step1LoadSimulinkDiagramPanel);
            app.AvailableDiagramsDropDown = uidropdown(app.GridLayout3_3);
            app.AvailableDiagramsDropDown.Items = {'Select a Diagram'};
            app.AvailableDiagramsDropDown.ValueChangedFcn = createCallbackFcn(app, @AvailableDiagramsDropDownValueChanged, true);
            app.AvailableDiagramsDropDown.Tooltip = {'Select and load the Simulink diagram to run.'};
            app.AvailableDiagramsDropDown.Value = 'Select a Diagram';
            app.Step2MassPropertiesPanel_2 = uipanel(app.GridLayout17);
            app.Step2MassPropertiesPanel_2.Title = 'Step #2: Mass Properties';
            app.GridLayout35 = uigridlayout(app.Step2MassPropertiesPanel_2);
            app.OpenMassPropertiesAppButton = uibutton(app.GridLayout35, 'push');
            app.OpenMassPropertiesAppButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMassPropertiesAppButtonPushed, true);
            app.OpenMassPropertiesAppButton.Tooltip = {'Open a sub-application containing the mass properties (and geometric properties) of the platforms. Allows you to calculate new COG and inertia values.'};
            app.OpenMassPropertiesAppButton.Text = {'Open Mass '; 'Properties App'};
            app.Step4InitialConditionsPanel = uipanel(app.GridLayout17);
            app.Step4InitialConditionsPanel.Title = 'Step #4: Initial Conditions';
            app.GridLayout34 = uigridlayout(app.Step4InitialConditionsPanel);
            app.OpenInitialConditionAppButton = uibutton(app.GridLayout34, 'push');
            app.OpenInitialConditionAppButton.ButtonPushedFcn = createCallbackFcn(app, @OpenInitialConditionAppButtonPushed, true);
            app.OpenInitialConditionAppButton.Tooltip = {'Opens a sub-application to tune the initial conditions for any simulation or experiment. Allows you to quickly see what the drop, initial, and home locations are for the simulation and experiment.'};
            app.OpenInitialConditionAppButton.Text = {'Open Initial '; 'Condition App'};
            app.Step5SetDurationsPanel = uipanel(app.GridLayout17);
            app.Step5SetDurationsPanel.Title = 'Step #5: Set Durations';
            app.GridLayout3 = uigridlayout(app.Step5SetDurationsPanel);
            app.Phase1Label = uilabel(app.GridLayout3);
            app.Phase1Label.Text = 'Phase #1:';
            app.DurPhase1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase1EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase1EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase1EditFieldValueChanged, true);
            app.DurPhase1EditField.Tooltip = {'Set the duration for Phase #1 (start floating).'};
            app.DurPhase1EditField.Value = 5;
            app.DurPhase1Units = uilabel(app.GridLayout3);
            app.DurPhase1Units.Text = 's';
            app.Phase2Label = uilabel(app.GridLayout3);
            app.Phase2Label.Text = 'Phase #2:';
            app.DurPhase2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase2EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase2EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase2EditFieldValueChanged, true);
            app.DurPhase2EditField.Tooltip = {'Set the duration for Phase #2 (go to initial location as defined in the Initial Conditions App).'};
            app.DurPhase2EditField.Value = 20;
            app.DurPhase2Units = uilabel(app.GridLayout3);
            app.DurPhase2Units.Text = 's';
            app.Phase3Label = uilabel(app.GridLayout3);
            app.Phase3Label.Text = 'Phase #3:';
            app.DurPhase3EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase3EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase3EditField.Editable = 'off';
            app.DurPhase3EditField.Tooltip = {'Displays the duration for Phase #3 (run experiment).'};
            app.DurPhase3EditField.Value = 170;
            app.DurPhase3Units = uilabel(app.GridLayout3);
            app.DurPhase3Units.Text = 's';
            app.Phase4Label = uilabel(app.GridLayout3);
            app.Phase4Label.Text = 'Phase #4:';
            app.DurPhase4EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase4EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase4EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase4EditFieldValueChanged, true);
            app.DurPhase4EditField.Tooltip = {'Set the duration for Phase #4 (head to home location as defined in the Initial Conditions App).'};
            app.DurPhase4EditField.Value = 30;
            app.DurPhase4Units = uilabel(app.GridLayout3);
            app.DurPhase4Units.Text = 's';
            app.Phase5Label = uilabel(app.GridLayout3);
            app.Phase5Label.Text = 'Phase #5:';
            app.DurPhase5EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase5EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase5EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase5EditFieldValueChanged, true);
            app.DurPhase5EditField.Tooltip = {'Set the duration for Phase #5 (hold the home position until the end of the experiment).'};
            app.DurPhase5EditField.Value = 20;
            app.DurPhase5Units = uilabel(app.GridLayout3);
            app.DurPhase5Units.Text = 's';
            app.Phase3SubPhasesLabel = uilabel(app.GridLayout3);
            app.Phase3SubPhasesLabel.Text = 'Phase #3 - Sub-Phases';
            app.SubPhase1Label = uilabel(app.GridLayout3);
            app.SubPhase1Label.Text = 'Sub-Phase #1:';
            app.SubPhase1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubPhase1EditField.ValueDisplayFormat = '%.1f';
            app.SubPhase1EditField.ValueChangedFcn = createCallbackFcn(app, @SubPhase1EditFieldValueChanged, true);
            app.SubPhase1EditField.Tooltip = {'Set the duration for Phase #3 - Sub-Phase #1.'};
            app.SubPhase1EditField.Value = 10;
            app.SubPhase1Units = uilabel(app.GridLayout3);
            app.SubPhase1Units.Text = 's';
            app.SubPhase2Label = uilabel(app.GridLayout3);
            app.SubPhase2Label.Text = 'Sub-Phase #2:';
            app.SubPhase2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubPhase2EditField.ValueDisplayFormat = '%.1f';
            app.SubPhase2EditField.ValueChangedFcn = createCallbackFcn(app, @SubPhase2EditFieldValueChanged, true);
            app.SubPhase2EditField.Tooltip = {'Set the duration for Phase #3 - Sub-Phase #2.'};
            app.SubPhase2EditField.Value = 10;
            app.SubPhase2Units = uilabel(app.GridLayout3);
            app.SubPhase2Units.Text = 's';
            app.SubPhase3Label = uilabel(app.GridLayout3);
            app.SubPhase3Label.Text = 'Sub-Phase #3:';
            app.SubPhase3EditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubPhase3EditField.ValueDisplayFormat = '%.1f';
            app.SubPhase3EditField.ValueChangedFcn = createCallbackFcn(app, @SubPhase3EditFieldValueChanged, true);
            app.SubPhase3EditField.Tooltip = {'Set the duration for Phase #3 - Sub-Phase #3.'};
            app.SubPhase3EditField.Value = 30;
            app.SubPhase3Units = uilabel(app.GridLayout3);
            app.SubPhase3Units.Text = 's';
            app.SubPhase4Label = uilabel(app.GridLayout3);
            app.SubPhase4Label.Text = 'Sub-Phase #4:';
            app.SubPhase4EditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubPhase4EditField.ValueDisplayFormat = '%.1f';
            app.SubPhase4EditField.ValueChangedFcn = createCallbackFcn(app, @SubPhase4EditFieldValueChanged, true);
            app.SubPhase4EditField.Tooltip = {'Set the duration for Phase #3 - Sub-Phase #4.'};
            app.SubPhase4EditField.Value = 120;
            app.SubPhase4Units = uilabel(app.GridLayout3);
            app.SubPhase4Units.Text = 's';
            app.TerminationPhasesLabel = uilabel(app.GridLayout3);
            app.TerminationPhasesLabel.Text = 'Termination Phases ';
            app.DurPhase0EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase0EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase0EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase0EditFieldValueChanged, true);
            app.DurPhase0EditField.Tooltip = {'Set the duration for Phase #0 (waiting for data).'};
            app.DurPhase0EditField.Value = 10;
            app.DurPhase0Units = uilabel(app.GridLayout3);
            app.DurPhase0Units.Text = 's';
            app.StartupPhasesLabel = uilabel(app.GridLayout3);
            app.StartupPhasesLabel.Text = 'Startup Phases';
            app.TotalTimeLabel = uilabel(app.GridLayout3);
            app.TotalTimeLabel.Text = 'Total Time';
            app.TotalTimeLabel_2 = uilabel(app.GridLayout3);
            app.TotalTimeLabel_2.Text = 'Total Time:';
            app.TotalTimeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TotalTimeEditField.ValueDisplayFormat = '%.1f';
            app.TotalTimeEditField.Editable = 'off';
            app.TotalTimeEditField.Tooltip = {'Display the total time for the experiment. This is automatically updated based on the phase durations.'};
            app.TotalTimeEditField.Value = 255;
            app.SampRateUnits_2 = uilabel(app.GridLayout3);
            app.SampRateUnits_2.Text = 's';
            app.Phase0Label = uilabel(app.GridLayout3);
            app.Phase0Label.Text = 'Phase #0:';
            app.SPOTGUIVersion40Label = uilabel(app.GridLayout17);
            app.SPOTGUIVersion40Label.Text = 'SPOT GUI Version 4.0';
            app.SetupRunSimulationsTab = uitab(app.TabGroup2);
            app.SetupRunSimulationsTab.Title = 'Setup & Run Simulations';
            app.GridLayout18 = uigridlayout(app.SetupRunSimulationsTab);
            app.Step7RunSimulationPanel = uipanel(app.GridLayout18);
            app.Step7RunSimulationPanel.Title = 'Step #7: Run Simulation';
            app.GridLayout7 = uigridlayout(app.Step7RunSimulationPanel);
            app.ExperimentPreview = uiaxes(app.GridLayout7);
            xlabel(app.ExperimentPreview, 'Y-Coordinate (m)')
            ylabel(app.ExperimentPreview, 'X-Coordinate (m)')
            app.StartSimulationButton = uibutton(app.GridLayout7, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Tooltip = {'Click to run the simulation for the currently loaded (and selected) diagram in the Available Diagrams dropdown menu.'};
            app.StartSimulationButton.Text = 'Start Simulation';
            app.PlayAnimationButton = uibutton(app.GridLayout7, 'push');
            app.PlayAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAnimationButtonPushed, true);
            app.PlayAnimationButton.Tooltip = {'Play the animation for the simulation.'};
            app.PlayAnimationButton.Text = 'Play Animation';
            app.StopAnimationButton = uibutton(app.GridLayout7, 'state');
            app.StopAnimationButton.Tooltip = {'Stop the animation.'};
            app.StopAnimationButton.Text = 'Stop Animation';
            app.SaveSimulationDataButton = uibutton(app.GridLayout7, 'push');
            app.SaveSimulationDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSimulationDataButtonPushed, true);
            app.SaveSimulationDataButton.Tooltip = {'Save the simulation data to the Saved Data folder.'};
            app.SaveSimulationDataButton.Text = 'Save Simulation Data';
            app.GridLayout25 = uigridlayout(app.GridLayout7);
            app.AnimationScrubberLabel = uilabel(app.GridLayout25);
            app.AnimationScrubberLabel.Text = 'Animation Scrubber';
            app.AnimationScrubberSlider = uislider(app.GridLayout25);
            app.AnimationScrubberSlider.MajorTicks = [0 50 100];
            app.AnimationScrubberSlider.MajorTickLabels = {''};
            app.AnimationScrubberSlider.ValueChangingFcn = createCallbackFcn(app, @AnimationScrubberSliderValueChanging, true);
            app.AnimationScrubberSlider.MinorTicks = [];
            app.AnimationScrubberSlider.Tooltip = {'After a simulation has been performed, use this to scrub to a specific point in time.'};
            app.AnimationScrubberSlider.Value = 46.4919793801229;
            app.GridLayout26 = uigridlayout(app.GridLayout7);
            app.AnimationSpeedLabel = uilabel(app.GridLayout26);
            app.AnimationSpeedLabel.Text = 'Animation Speed';
            app.AnimationSpeedSlider = uislider(app.GridLayout26);
            app.AnimationSpeedSlider.MajorTicks = [0 100];
            app.AnimationSpeedSlider.MajorTickLabels = {'Fast', 'Slow'};
            app.AnimationSpeedSlider.Tooltip = {'Change the speed of the animation. '};
            app.PauseAnimationButton = uibutton(app.GridLayout7, 'state');
            app.PauseAnimationButton.Tooltip = {'While the animation is playing, pause the animation.'};
            app.PauseAnimationButton.Text = 'Pause Animation';
            app.GridLayout32 = uigridlayout(app.GridLayout7);
            app.SkipToTimeEditField = uispinner(app.GridLayout32);
            app.SkipToTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SkipToTimeEditFieldValueChanged, true);
            app.SkipToTimeEditField.Tooltip = {'After a simulation has been performed, use this to jump to a specific point in time.'};
            app.DurPhase0Units_52 = uilabel(app.GridLayout32);
            app.DurPhase0Units_52.Interpreter = 'latex';
            app.DurPhase0Units_52.Text = 's';
            app.SkipToLabel = uilabel(app.GridLayout32);
            app.SkipToLabel.Text = 'Skip To:';
            app.SimulationSettingsPanel = uipanel(app.GridLayout18);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.GridLayout3_4 = uigridlayout(app.SimulationSettingsPanel);
            app.SolverTypeDropDownLabel = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel.Text = 'Solver Type:';
            app.SolverTypeDropDown = uidropdown(app.GridLayout3_4);
            app.SolverTypeDropDown.Items = {'Fixed-step', 'Variable-step'};
            app.SolverTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverTypeDropDownValueChanged, true);
            app.SolverTypeDropDown.Tooltip = {'Set the solver type for the simulation. Note that when running an experiment, the type is set to be fixed-step.'};
            app.SolverTypeDropDown.Value = 'Fixed-step';
            app.SolverTypeDropDownLabel_2 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_2.Text = 'Solver Name:';
            app.SolverNameDropDown = uidropdown(app.GridLayout3_4);
            app.SolverNameDropDown.Items = {'FixedStepAuto', 'FixedStepDiscrete', 'ode8', 'ode5', 'ode4', 'ode3', 'ode2', 'ode1', 'ode14x', 'ode1be'};
            app.SolverNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverNameDropDownValueChanged, true);
            app.SolverNameDropDown.Tooltip = {'Choose the solver algorithm for the fixed-step solver. In experiment, this will be forced to be ODE4.'};
            app.SolverNameDropDown.Value = 'FixedStepAuto';
            app.NoiseVarREDLabel = uilabel(app.GridLayout3_4);
            app.NoiseVarREDLabel.Text = 'Noise Var. (RED):';
            app.NoiseVarianceRedEditField = uieditfield(app.GridLayout3_4, 'numeric');
            app.NoiseVarianceRedEditField.ValueDisplayFormat = '%.3f';
            app.NoiseVarianceRedEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseVarianceRedEditFieldValueChanged, true);
            app.NoiseVarianceRedEditField.Tooltip = {'If desired, add noise to the RED positions in simulation.'};
            app.DurPhase0Units_28 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_28.Interpreter = 'latex';
            app.DurPhase0Units_28.Text = '\times $10^{-3}$';
            app.NoiseVarBLACKLabel = uilabel(app.GridLayout3_4);
            app.NoiseVarBLACKLabel.Text = 'Noise Var. (BLACK):';
            app.NoiseVarianceBlackEditField = uieditfield(app.GridLayout3_4, 'numeric');
            app.NoiseVarianceBlackEditField.ValueDisplayFormat = '%.3f';
            app.NoiseVarianceBlackEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseVarianceBlackEditFieldValueChanged, true);
            app.NoiseVarianceBlackEditField.Tooltip = {'If desired, add noise to the BLACK positions in simulation.'};
            app.DurPhase0Units_29 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_29.Interpreter = 'latex';
            app.DurPhase0Units_29.Text = '\times $10^{-3}$';
            app.NoiseVarBLUELabel = uilabel(app.GridLayout3_4);
            app.NoiseVarBLUELabel.Text = 'Noise Var. (BLUE):';
            app.NoiseVarianceBlueEditField = uieditfield(app.GridLayout3_4, 'numeric');
            app.NoiseVarianceBlueEditField.ValueDisplayFormat = '%.3f';
            app.NoiseVarianceBlueEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseVarianceBlueEditFieldValueChanged, true);
            app.NoiseVarianceBlueEditField.Tooltip = {'If desired, add noise to the BLUE positions in simulation.'};
            app.DurPhase0Units_30 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_30.Interpreter = 'latex';
            app.DurPhase0Units_30.Text = '\times $10^{-3}$';
            app.SolverTypeDropDownLabel_3 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_3.Text = 'Max Step Size:';
            app.SolverOptionsLabel = uilabel(app.GridLayout3_4);
            app.SolverOptionsLabel.Text = 'Solver Options';
            app.VariableStepOptionsLabel = uilabel(app.GridLayout3_4);
            app.VariableStepOptionsLabel.Text = 'Variable-Step Options';
            app.SolverTypeDropDownLabel_4 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_4.Text = 'Min Step Size:';
            app.SolverTypeDropDownLabel_5 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_5.Text = 'Init. Step Size:';
            app.SolverTypeDropDownLabel_6 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_6.Text = 'Rel. Tolerance:';
            app.SolverTypeDropDownLabel_7 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_7.Text = 'Abs. Tolerance:';
            app.DurPhase0Units_31 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_31.Interpreter = 'latex';
            app.DurPhase0Units_31.Text = 's';
            app.DurPhase0Units_32 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_32.Interpreter = 'latex';
            app.DurPhase0Units_32.Text = 's';
            app.DurPhase0Units_33 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_33.Interpreter = 'latex';
            app.DurPhase0Units_33.Text = 's';
            app.DurPhase0Units_34 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_34.Interpreter = 'latex';
            app.DurPhase0Units_34.Text = '-';
            app.DurPhase0Units_35 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_35.Interpreter = 'latex';
            app.DurPhase0Units_35.Text = '-';
            app.NoiseOptionsLabel = uilabel(app.GridLayout3_4);
            app.NoiseOptionsLabel.Text = 'Noise Options';
            app.CreateVideoCheckBox = uicheckbox(app.GridLayout3_4);
            app.CreateVideoCheckBox.Tooltip = {'If checked when you click on Play Animation, simulation animation is saved to as a video. '};
            app.CreateVideoCheckBox.Text = 'Create Video';
            app.AnimationOptionsLabel = uilabel(app.GridLayout3_4);
            app.AnimationOptionsLabel.Text = 'Animation Options';
            app.VarStepMaxStepSizeEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepMaxStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepMaxStepSizeEditFieldValueChanged, true);
            app.VarStepMaxStepSizeEditField.Tooltip = {'If using a variable-step solver, set the max step size.'};
            app.VarStepMinStepSizeEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepMinStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepMinStepSizeEditFieldValueChanged, true);
            app.VarStepMinStepSizeEditField.Tooltip = {'If using a variable-step solver, set the min step size.'};
            app.VarStepInitStepSizeEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepInitStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepInitStepSizeEditFieldValueChanged, true);
            app.VarStepInitStepSizeEditField.Tooltip = {'If using a variable-step solver, set the initial step size.'};
            app.VarStepRelTolEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepRelTolEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepRelTolEditFieldValueChanged, true);
            app.VarStepRelTolEditField.Tooltip = {'If using a variable-step solver, set the relative tolerance.'};
            app.VarStepAbsTolEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepAbsTolEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepAbsTolEditFieldValueChanged, true);
            app.VarStepAbsTolEditField.Tooltip = {'If using a variable-step solver, set the absolute tolerance.'};
            app.SolverNameVarStepDropDown = uidropdown(app.GridLayout3_4);
            app.SolverNameVarStepDropDown.Items = {'VariableStepAuto', 'VariableStepDiscrete', 'ode45', 'ode23', 'ode113', 'ode15s', 'ode23s', 'ode23t', 'ode23tb', 'odeN', 'daessc'};
            app.SolverNameVarStepDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverNameVarStepDropDownValueChanged, true);
            app.SolverNameVarStepDropDown.Tooltip = {'Choose the solver algorithm for the variable-step solver. In experiment, this will be forced to be ODE4.'};
            app.SolverNameVarStepDropDown.Value = 'VariableStepAuto';
            app.SetupRunExperimentsTab = uitab(app.TabGroup2);
            app.SetupRunExperimentsTab.Title = 'Setup & Run Experiments';
            app.GridLayout19_2 = uigridlayout(app.SetupRunExperimentsTab);
            app.ExperimentControlsPanel = uipanel(app.GridLayout19_2);
            app.ExperimentControlsPanel.Title = 'Experiment Controls';
            app.GridLayout9 = uigridlayout(app.ExperimentControlsPanel);
            app.StartExperimentButton = uibutton(app.GridLayout9, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Tooltip = {'Start the selected experiment (cameras must be on before starting).'};
            app.StartExperimentButton.Text = {'Start '; 'Experiment'};
            app.StopExperimentButton = uibutton(app.GridLayout9, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Tooltip = {'Stop the selected experiment. This is only enabled after you have started an experiment.'};
            app.StopExperimentButton.Text = {'Stop '; 'Experiment'};
            app.SaveExperimentDataButton = uibutton(app.GridLayout9, 'push');
            app.SaveExperimentDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExperimentDataButtonPushed, true);
            app.SaveExperimentDataButton.Tooltip = {'Copy the experiment data from all active hardware and save it to the Saved Data folder.'};
            app.SaveExperimentDataButton.Text = {'Save Experiment'; 'Data'};
            app.BuildCode = uibutton(app.GridLayout9, 'push');
            app.BuildCode.ButtonPushedFcn = createCallbackFcn(app, @BuildCodePushed, true);
            app.BuildCode.Tooltip = {'Build the code for the selected diagram to the designated "Master Platform". Then, copy the built code to the other active platforms.'};
            app.BuildCode.Text = 'Build Code';
            app.GridLayout28 = uigridlayout(app.GridLayout9);
            app.PhaseSpaceCameraLabel = uilabel(app.GridLayout28);
            app.PhaseSpaceCameraLabel.Text = {'PhaseSpace'; 'Camera:'};
            app.PhaseSpaceSwitch = uiswitch(app.GridLayout28, 'slider');
            app.PhaseSpaceSwitch.ValueChangedFcn = createCallbackFcn(app, @PhaseSpaceSwitchValueChanged, true);
            app.PhaseSpaceSwitch.Tooltip = {'Toggle to ON to turn on the PhaseSpace cameras. This relies on streamdata.exe located in the Resources folder.'};
            app.CamerasOnLamp = uilamp(app.GridLayout28);
            app.RealTimeVisualizationPanel = uipanel(app.GridLayout19_2);
            app.RealTimeVisualizationPanel.Title = 'Real-Time Visualization';
            app.GridLayout9_2 = uigridlayout(app.RealTimeVisualizationPanel);
            app.ExperimentPreviewLive = uiaxes(app.GridLayout9_2);
            xlabel(app.ExperimentPreviewLive, 'Y-Coordinate (m)')
            ylabel(app.ExperimentPreviewLive, 'X-Coordinate (m)')
            app.RealTimePhaseSpaceDataPanel = uipanel(app.GridLayout19_2);
            app.RealTimePhaseSpaceDataPanel.Title = 'Real-Time PhaseSpace Data';
            app.GridLayout9_4 = uigridlayout(app.RealTimePhaseSpaceDataPanel);
            app.mathrmX_REDEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmX_REDEditFieldLabel.Interpreter = 'latex';
            app.mathrmX_REDEditFieldLabel.Text = '$\mathrm{X_{RED}}$';
            app.mathrmX_REDEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmX_REDEditField.Editable = 'off';
            app.mathrmX_REDEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the RED platform (x-position).'};
            app.mathrmY_REDEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmY_REDEditFieldLabel.Interpreter = 'latex';
            app.mathrmY_REDEditFieldLabel.Text = '$\mathrm{Y_{RED}}$';
            app.mathrmY_REDEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmY_REDEditField.Editable = 'off';
            app.mathrmY_REDEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the RED platform (y-position).'};
            app.mathrmtheta_REDEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmtheta_REDEditFieldLabel.Interpreter = 'latex';
            app.mathrmtheta_REDEditFieldLabel.Text = '$\mathrm{\theta_{RED}}$';
            app.mathrmtheta_REDEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmtheta_REDEditField.Editable = 'off';
            app.mathrmtheta_REDEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time attitude of the RED platform.'};
            app.mathrmX_BLACKEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmX_BLACKEditFieldLabel.Interpreter = 'latex';
            app.mathrmX_BLACKEditFieldLabel.Text = '$\mathrm{X_{BLACK}}$';
            app.mathrmX_BLACKEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmX_BLACKEditField.Editable = 'off';
            app.mathrmX_BLACKEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the BLACK platform (x-position).'};
            app.mathrmY_BLACKEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmY_BLACKEditFieldLabel.Interpreter = 'latex';
            app.mathrmY_BLACKEditFieldLabel.Text = '$\mathrm{Y_{BLACK}}$';
            app.mathrmY_BLACKEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmY_BLACKEditField.Editable = 'off';
            app.mathrmY_BLACKEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the BLACK platform (y-position).'};
            app.mathrmtheta_BLACKEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmtheta_BLACKEditFieldLabel.Interpreter = 'latex';
            app.mathrmtheta_BLACKEditFieldLabel.Text = '$\mathrm{\theta_{BLACK}}$';
            app.mathrmtheta_BLACKEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmtheta_BLACKEditField.Editable = 'off';
            app.mathrmtheta_BLACKEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time attitude of the BLACK platform.'};
            app.mathrmX_BLUEEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmX_BLUEEditFieldLabel.Interpreter = 'latex';
            app.mathrmX_BLUEEditFieldLabel.Text = '$\mathrm{X_{BLUE}}$';
            app.mathrmX_BLUEEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmX_BLUEEditField.Editable = 'off';
            app.mathrmX_BLUEEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the BLUE platform (x-position).'};
            app.mathrmY_BLUEEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmY_BLUEEditFieldLabel.Interpreter = 'latex';
            app.mathrmY_BLUEEditFieldLabel.Text = '$\mathrm{Y_{BLUE}}$';
            app.mathrmY_BLUEEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmY_BLUEEditField.Editable = 'off';
            app.mathrmY_BLUEEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the BLUE platform (y-position).'};
            app.mathrmtheta_BLUEEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmtheta_BLUEEditFieldLabel.Interpreter = 'latex';
            app.mathrmtheta_BLUEEditFieldLabel.Text = '$\mathrm{\theta_{BLUE}}$';
            app.mathrmtheta_BLUEEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmtheta_BLUEEditField.Editable = 'off';
            app.mathrmtheta_BLUEEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time attitude of the BLUE platform.'};
            app.DurPhase0Units_36 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_36.Interpreter = 'latex';
            app.DurPhase0Units_36.Text = 'm';
            app.DurPhase0Units_37 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_37.Interpreter = 'latex';
            app.DurPhase0Units_37.Text = 'm';
            app.DurPhase0Units_38 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_38.Interpreter = 'latex';
            app.DurPhase0Units_38.Text = 'm';
            app.DurPhase0Units_39 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_39.Interpreter = 'latex';
            app.DurPhase0Units_39.Text = 'm';
            app.DurPhase0Units_40 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_40.Interpreter = 'latex';
            app.DurPhase0Units_40.Text = 'm';
            app.DurPhase0Units_41 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_41.Interpreter = 'latex';
            app.DurPhase0Units_41.Text = 'm';
            app.DurPhase0Units_42 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_42.Interpreter = 'latex';
            app.DurPhase0Units_42.Text = '$\degree$';
            app.DurPhase0Units_43 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_43.Interpreter = 'latex';
            app.DurPhase0Units_43.Text = '$\degree$';
            app.DurPhase0Units_44 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_44.Interpreter = 'latex';
            app.DurPhase0Units_44.Text = '$\degree$';
            app.GridLayout27 = uigridlayout(app.GridLayout9_4);
            app.EnableRealTimeTelemetryLabel = uilabel(app.GridLayout27);
            app.EnableRealTimeTelemetryLabel.Text = 'Enable Real-Time Telemetry';
            app.ToggleRealTimeTelemetrySwitch = uiswitch(app.GridLayout27, 'slider');
            app.ToggleRealTimeTelemetrySwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleRealTimeTelemetrySwitchValueChanged, true);
            app.ToggleRealTimeTelemetrySwitch.Tooltip = {'Toggle to ON to enable real-time telemetry. This will automatically turn on when you turn on the cameras, but it can be independently turned off.'};
            app.RealTimeTelOnLamp = uilamp(app.GridLayout27);
            app.Image = uiimage(app.GridLayout9_4);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat = uibutton(app.GridLayout19_2, 'push');
            app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat.ButtonPushedFcn = createCallbackFcn(app, @Step8EstablishConnectionwithSelectedPlatformsButtonPushed, true);
            app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat.Tooltip = {'If you have selected your active hardware, click this to establish a connection with them. If problems occur, try pinging the platforms from the DIrect Hardware Control tab.'};
            app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat.Text = 'Step #8: Establish Connection with Selected Platforms';
            app.GridLayout33 = uigridlayout(app.GridLayout19_2);
            app.ConnectionStatus_1 = uilamp(app.GridLayout33);
            app.ConnectionStatus_1.Tooltip = {'Indicates the connection status of the hardware. Does not update continuously, so if you lose connection without resetting the GUI, this may be a false indication.'};
            app.ConnectionStatusLabel = uilabel(app.GridLayout33);
            app.ConnectionStatusLabel.Text = {'Connection'; 'Status'};
            app.DirectHardwareControlTab = uitab(app.TabGroup2);
            app.DirectHardwareControlTab.Title = 'Direct Hardware Control';
            app.GridLayout19 = uigridlayout(app.DirectHardwareControlTab);
            app.REDPlatformLiveControlsPanel = uipanel(app.GridLayout19);
            app.REDPlatformLiveControlsPanel.Title = 'RED Platform Live Controls';
            app.GridLayout20 = uigridlayout(app.REDPlatformLiveControlsPanel);
            app.REDPucksOnButton = uibutton(app.GridLayout20, 'push');
            app.REDPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @REDPucksOnButtonPushed, true);
            app.REDPucksOnButton.Tooltip = {'Turn on the pucks to float the RED platform.'};
            app.REDPucksOnButton.Text = 'Pucks On';
            app.REDPucksOffButton = uibutton(app.GridLayout20, 'push');
            app.REDPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @REDPucksOffButtonPushed, true);
            app.REDPucksOffButton.Tooltip = {'Turn off the pucks for RED.'};
            app.REDPucksOffButton.Text = 'Pucks Off';
            app.PingREDButton = uibutton(app.GridLayout20, 'push');
            app.PingREDButton.ButtonPushedFcn = createCallbackFcn(app, @PingREDButtonPushed, true);
            app.PingREDButton.Tooltip = {'Send a ping to RED. If RED''s Xavier is powered on, it will indicate a successful ping.'};
            app.PingREDButton.Text = 'Ping Jetson Xavier';
            app.REDTextArea = uitextarea(app.GridLayout20);
            app.REDTextArea.Editable = 'off';
            app.REDConnectedLamp = uilamp(app.GridLayout20);
            app.FireREDThrustersButton = uibutton(app.GridLayout20, 'push');
            app.FireREDThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireREDThrustersButtonPushed, true);
            app.FireREDThrustersButton.Tooltip = {'Fire the thrusters for RED in sequential order (1,2,3...).'};
            app.FireREDThrustersButton.Text = 'Fire Thrusters in Sequence';
            app.CyclePinsButtonRED = uibutton(app.GridLayout20, 'push');
            app.CyclePinsButtonRED.ButtonPushedFcn = createCallbackFcn(app, @CyclePinsButtonREDPushed, true);
            app.CyclePinsButtonRED.Tooltip = {'Turns ON then OFF all GPIO pins on RED. This can be useful for debugging.'};
            app.CyclePinsButtonRED.Text = 'Cycle GPIOs ON/OFF';
            app.OpenREDCMDShell = uibutton(app.GridLayout20, 'push');
            app.OpenREDCMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenREDCMDShellButtonPushed, true);
            app.OpenREDCMDShell.Tooltip = {'Open a command shell connected to the Xavier on RED.'};
            app.OpenREDCMDShell.Text = 'Open Remote SSH Command Shell';
            app.BLACKPlatformLiveControlsPanel = uipanel(app.GridLayout19);
            app.BLACKPlatformLiveControlsPanel.Title = 'BLACK Platform Live Controls';
            app.GridLayout20_2 = uigridlayout(app.BLACKPlatformLiveControlsPanel);
            app.BLACKPucksOnButton = uibutton(app.GridLayout20_2, 'push');
            app.BLACKPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKPucksOnButtonPushed, true);
            app.BLACKPucksOnButton.Tooltip = {'Turn on the pucks to float the BLACK platform.'};
            app.BLACKPucksOnButton.Text = 'Pucks On';
            app.BLACKPucksOffButton = uibutton(app.GridLayout20_2, 'push');
            app.BLACKPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKPucksOffButtonPushed, true);
            app.BLACKPucksOffButton.Tooltip = {'Turn off the pucks for BLACK.'};
            app.BLACKPucksOffButton.Text = 'Pucks Off';
            app.PingBLACKButton = uibutton(app.GridLayout20_2, 'push');
            app.PingBLACKButton.ButtonPushedFcn = createCallbackFcn(app, @PingBLACKButtonPushed, true);
            app.PingBLACKButton.Tooltip = {'Send a ping to BLACK. If BLACK''s Xavier is powered on, it will indicate a successful ping.'};
            app.PingBLACKButton.Text = 'Ping Jetson Xavier';
            app.BLACKConnectedLamp = uilamp(app.GridLayout20_2);
            app.FireBLACKThrustersButton = uibutton(app.GridLayout20_2, 'push');
            app.FireBLACKThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireBLACKThrustersButtonPushed, true);
            app.FireBLACKThrustersButton.Tooltip = {'Fire the thrusters for BLACK in sequential order (1,2,3...).'};
            app.FireBLACKThrustersButton.Text = 'Fire Thrusters in Sequence';
            app.CyclePinsButtonBLACK = uibutton(app.GridLayout20_2, 'push');
            app.CyclePinsButtonBLACK.ButtonPushedFcn = createCallbackFcn(app, @CyclePinsButtonBLACKPushed, true);
            app.CyclePinsButtonBLACK.Tooltip = {'Turns ON then OFF all GPIO pins on BLACK. This can be useful for debugging.'};
            app.CyclePinsButtonBLACK.Text = 'Cycle GPIOs ON/OFF';
            app.OpenBLACKCMDShell = uibutton(app.GridLayout20_2, 'push');
            app.OpenBLACKCMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenBLACKCMDShellButtonPushed, true);
            app.OpenBLACKCMDShell.Tooltip = {'Open a command shell connected to the Xavier on BLACK.'};
            app.OpenBLACKCMDShell.Text = 'Open Remote SSH Command Shell';
            app.BLACKTextArea = uitextarea(app.GridLayout20_2);
            app.BLACKTextArea.Editable = 'off';
            app.BLUEPlatformLiveControlsPanel = uipanel(app.GridLayout19);
            app.BLUEPlatformLiveControlsPanel.Title = 'BLUE Platform Live Controls';
            app.GridLayout20_3 = uigridlayout(app.BLUEPlatformLiveControlsPanel);
            app.BLUEPucksOnButton = uibutton(app.GridLayout20_3, 'push');
            app.BLUEPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEPucksOnButtonPushed, true);
            app.BLUEPucksOnButton.Tooltip = {'Turn on the pucks to float the BLUE platform.'};
            app.BLUEPucksOnButton.Text = 'Pucks On';
            app.BLUEPucksOffButton = uibutton(app.GridLayout20_3, 'push');
            app.BLUEPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEPucksOffButtonPushed, true);
            app.BLUEPucksOffButton.Tooltip = {'Turn off the pucks for BLUE.'};
            app.BLUEPucksOffButton.Text = 'Pucks Off';
            app.PingBLUEButton = uibutton(app.GridLayout20_3, 'push');
            app.PingBLUEButton.ButtonPushedFcn = createCallbackFcn(app, @PingBLUEButtonPushed, true);
            app.PingBLUEButton.Tooltip = {'Send a ping to BLUE. If BLUE''s Xavier is powered on, it will indicate a successful ping.'};
            app.PingBLUEButton.Text = 'Ping Jetson Xavier';
            app.BLUEConnectedLamp = uilamp(app.GridLayout20_3);
            app.BLUETextArea = uitextarea(app.GridLayout20_3);
            app.BLUETextArea.Editable = 'off';
            app.FireBLUEThrustersButton = uibutton(app.GridLayout20_3, 'push');
            app.FireBLUEThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireBLUEThrustersButtonPushed, true);
            app.FireBLUEThrustersButton.Tooltip = {'Fire the thrusters for BLUE in sequential order (1,2,3...).'};
            app.FireBLUEThrustersButton.Text = 'Fire Thrusters in Sequence';
            app.CyclePinsButtonBLUE = uibutton(app.GridLayout20_3, 'push');
            app.CyclePinsButtonBLUE.ButtonPushedFcn = createCallbackFcn(app, @CyclePinsButtonBLUEPushed, true);
            app.CyclePinsButtonBLUE.Tooltip = {'Turns ON then OFF all GPIO pins on BLUE. This can be useful for debugging.'};
            app.CyclePinsButtonBLUE.Text = 'Cycle GPIOs ON/OFF';
            app.OpenBLUECMDShell = uibutton(app.GridLayout20_3, 'push');
            app.OpenBLUECMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenBLUECMDShellButtonPushed, true);
            app.OpenBLUECMDShell.Tooltip = {'Open a command shell connected to the Xavier on BLUE.'};
            app.OpenBLUECMDShell.Text = 'Open Remote SSH Command Shell';
            app.Step8EstablishConnectionwithSelectedPlatformsButton = uibutton(app.GridLayout19, 'push');
            app.Step8EstablishConnectionwithSelectedPlatformsButton.ButtonPushedFcn = createCallbackFcn(app, @Step8EstablishConnectionwithSelectedPlatformsButtonPushed, true);
            app.Step8EstablishConnectionwithSelectedPlatformsButton.Tooltip = {'If you have selected your active hardware, click this to establish a connection with them. If problems occur, try pinging the platforms from the DIrect Hardware Control tab.'};
            app.Step8EstablishConnectionwithSelectedPlatformsButton.Text = 'Step #8: Establish Connection with Selected Platforms';
            app.ExtrasPanel = uipanel(app.GridLayout19);
            app.ExtrasPanel.Title = 'Extras';
            app.GridLayout20_4 = uigridlayout(app.ExtrasPanel);
            app.RefreshSoftwareonPlatformsButton = uibutton(app.GridLayout20_4, 'push');
            app.RefreshSoftwareonPlatformsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSoftwareonPlatformsButtonPushed, true);
            app.RefreshSoftwareonPlatformsButton.Tooltip = {'Completely empties the MATLAB_ws folder on all active platforms to facilitate troubleshooting. Useful for troubleshooting. This will not break anything, but any data on the active hardware will be deleted.'};
            app.RefreshSoftwareonPlatformsButton.Text = {'Refresh Software'; 'on Platforms'};
            app.GridLayout33_2 = uigridlayout(app.GridLayout19);
            app.ConnectionStatus_2 = uilamp(app.GridLayout33_2);
            app.ConnectionStatus_2.Tooltip = {'Indicates the connection status of the hardware. Does not update continuously, so if you lose connection without resetting the GUI, this may be a false indication.'};
            app.ConnectionStatusLabel_2 = uilabel(app.GridLayout33_2);
            app.ConnectionStatusLabel_2.Text = {'Connection'; 'Status'};
            app.DataInspectorTab = uitab(app.TabGroup2);
            app.DataInspectorTab.Title = 'Data Inspector';
            app.GridLayout21 = uigridlayout(app.DataInspectorTab);
            app.InspectSimulationandExperimentalDataPanel = uipanel(app.GridLayout21);
            app.InspectSimulationandExperimentalDataPanel.Title = 'Inspect Simulation and Experimental Data';
            app.GridLayout9_3 = uigridlayout(app.InspectSimulationandExperimentalDataPanel);
            app.GridLayout29 = uigridlayout(app.GridLayout9_3);
            app.DataViewerPlt1 = uiaxes(app.GridLayout29);
            app.SelectDataFileLabel = uilabel(app.GridLayout29);
            app.SelectDataFileLabel.Text = 'Select Data File';
            app.LoadDataButtonPlt1 = uibutton(app.GridLayout29, 'push');
            app.LoadDataButtonPlt1.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPlt1Pushed, true);
            app.LoadDataButtonPlt1.Tooltip = {'Load a SPOT simulation or experiment data file for visualization on the left figure.'};
            app.LoadDataButtonPlt1.Text = 'Load Data';
            app.ExporttoPDFButton = uibutton(app.GridLayout29, 'push');
            app.ExporttoPDFButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoPDFButtonPushed, true);
            app.ExporttoPDFButton.Tooltip = {'Export the left figure to a PDF (name defined in the text box to the right of this button).'};
            app.ExporttoPDFButton.Text = 'Export to PDF';
            app.SelectXAxisLabel = uilabel(app.GridLayout29);
            app.SelectXAxisLabel.Text = 'Select X-Axis ';
            app.SelectXDataPlt1 = uidropdown(app.GridLayout29);
            app.SelectXDataPlt1.ValueChangedFcn = createCallbackFcn(app, @SelectXDataPlt1ValueChanged, true);
            app.SelectXDataPlt1.Tooltip = {'Select the x-axis parameter for the left figure.'};
            app.SelectYDataPlt1 = uidropdown(app.GridLayout29);
            app.SelectYDataPlt1.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt1ValueChanged, true);
            app.SelectYDataPlt1.Tooltip = {'Select the y-axis parameter for the left figure.'};
            app.SelectYAxisLabel = uilabel(app.GridLayout29);
            app.SelectYAxisLabel.Text = 'Select Y-Axis';
            app.DataViewer1PDFName = uieditfield(app.GridLayout29, 'text');
            app.DataViewer1PDFName.Tooltip = {'Enter the name for the PDF to be exported (left).'};
            app.DataViewer1PDFName.Value = 'DataViewer1Content';
            app.DataViewer1PDFName_2 = uieditfield(app.GridLayout29, 'text');
            app.DataViewer1PDFName_2.Editable = 'off';
            app.DataViewer1PDFName_2.Value = '.pdf';
            app.Image_2 = uiimage(app.GridLayout29);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.GridLayout30 = uigridlayout(app.GridLayout9_3);
            app.DataViewerPlt2 = uiaxes(app.GridLayout30);
            app.SelectDataFileLabel_2 = uilabel(app.GridLayout30);
            app.SelectDataFileLabel_2.Text = 'Select Data File';
            app.SelectXAxisLabel_2 = uilabel(app.GridLayout30);
            app.SelectXAxisLabel_2.Text = 'Select X-Axis ';
            app.SelectYAxisLabel_2 = uilabel(app.GridLayout30);
            app.SelectYAxisLabel_2.Text = 'Select Y-Axis';
            app.LoadDataButtonPlt2 = uibutton(app.GridLayout30, 'push');
            app.LoadDataButtonPlt2.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPlt2Pushed, true);
            app.LoadDataButtonPlt2.Tooltip = {'Load a SPOT simulation or experiment data file for visualization on the right figure.'};
            app.LoadDataButtonPlt2.Text = 'Load Data';
            app.SelectXDataPlt2 = uidropdown(app.GridLayout30);
            app.SelectXDataPlt2.ValueChangedFcn = createCallbackFcn(app, @SelectXDataPlt2ValueChanged, true);
            app.SelectXDataPlt2.Tooltip = {'Select the x-axis parameter for the right figure.'};
            app.SelectYDataPlt2 = uidropdown(app.GridLayout30);
            app.SelectYDataPlt2.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt2ValueChanged, true);
            app.SelectYDataPlt2.Tooltip = {'Select the y-axis parameter for the right figure.'};
            app.ExporttoPDFButton2 = uibutton(app.GridLayout30, 'push');
            app.ExporttoPDFButton2.ButtonPushedFcn = createCallbackFcn(app, @ExporttoPDFButton2Pushed, true);
            app.ExporttoPDFButton2.Tooltip = {'Export the right figure to a PDF (name defined in the text box to the right of this button).'};
            app.ExporttoPDFButton2.Text = 'Export to PDF';
            app.DataViewer2PDFName = uieditfield(app.GridLayout30, 'text');
            app.DataViewer2PDFName.Tooltip = {'Enter the name for the PDF to be exported (right).'};
            app.DataViewer2PDFName.Value = 'DataViewer2Content';
            app.DataViewer1PDFName_3 = uieditfield(app.GridLayout30, 'text');
            app.DataViewer1PDFName_3.Editable = 'off';
            app.DataViewer1PDFName_3.Value = '.pdf';
            app.Image_3 = uiimage(app.GridLayout30);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            % Show the figure after all components are created
            app.SPOT40GUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v4_0_Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SPOT40GUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @PopulateGUI)
            else

                % Focus the running singleton app
                figure(runningApp.SPOT40GUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPOT40GUIUIFigure)
        end
    end
end