classdef GUI_v4_1_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPOT41GUIUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LabLinksMenu                    matlab.ui.container.Menu
        GitHubMenu                      matlab.ui.container.Menu
        GooglePhotosMenu                matlab.ui.container.Menu
        YoutubeChannelMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        YouTubeTutorialsMenu            matlab.ui.container.Menu
        RunningaSimulationandExperimentMenu  matlab.ui.container.Menu
        RemovingtheTableCoverMenu       matlab.ui.container.Menu
        RetrievingtheIsopropanolMenu    matlab.ui.container.Menu
        TableCleaningProceduresMenu     matlab.ui.container.Menu
        SavingExperimentDataMenu        matlab.ui.container.Menu
        RemovingtheRoboticArmMenu       matlab.ui.container.Menu
        ChargingaLEDBatteryPackMenu     matlab.ui.container.Menu
        ChargingaPlatformMenu           matlab.ui.container.Menu
        InstallinganAirTankMenu         matlab.ui.container.Menu
        InstallingtheRoboticArmMenu     matlab.ui.container.Menu
        ForkingtheSPOTRepositoryMenu    matlab.ui.container.Menu
        PlacingtheTableCoverMenu        matlab.ui.container.Menu
        FillinganAirTankMenu            matlab.ui.container.Menu
        ExportingVideoswithQCViewerMenu  matlab.ui.container.Menu
        CleaningthePucksandMovingaPlatformMenu  matlab.ui.container.Menu
        SaveGUIStateMenu                matlab.ui.container.Menu
        LoadGUIStateMenu                matlab.ui.container.Menu
        ResetGUIParameterstoDefaultMenu  matlab.ui.container.Menu
        OverrideCurrentDefaultParametersMenu  matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        AddPathtoCustomLibraryMenu      matlab.ui.container.Menu
        UpdateSSHInformationMenu        matlab.ui.container.Menu
        UpdateREDInformationMenu        matlab.ui.container.Menu
        UpdateBLACKInformationMenu      matlab.ui.container.Menu
        UpdateBLUEInformationMenu       matlab.ui.container.Menu
        GridLayout16                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        InitializeParametersTab         matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        Step6ControlLawTuningPanel      matlab.ui.container.Panel
        GridLayout37                    matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        LQRControllerTab                matlab.ui.container.Tab
        GridLayout39                    matlab.ui.container.GridLayout
        GridLayout36                    matlab.ui.container.GridLayout
        ControlWeightingMatrixDiagonalsLabel  matlab.ui.control.Label
        mathrmR_F_xLabel_3              matlab.ui.control.Label
        mathrmR_F_xLabel_2              matlab.ui.control.Label
        mathrmR_F_xLabel                matlab.ui.control.Label
        BlueRFXEditField                matlab.ui.control.NumericEditField
        mathrmQ_dotxLabel_3             matlab.ui.control.Label
        BlackRFXEditField               matlab.ui.control.NumericEditField
        mathrmQ_xLabel_2                matlab.ui.control.Label
        mathrmQ_dotxLabel_2             matlab.ui.control.Label
        RedRFXEditField                 matlab.ui.control.NumericEditField
        mathrmR_F_yLabel_3              matlab.ui.control.Label
        mathrmQ_xLabel                  matlab.ui.control.Label
        mathrmQ_dotxLabel               matlab.ui.control.Label
        BlueQXDEditField                matlab.ui.control.NumericEditField
        mathrmR_F_yLabel_2              matlab.ui.control.Label
        RedQXLabel                      matlab.ui.control.Label
        BlueQXEditField                 matlab.ui.control.NumericEditField
        BlueRFYEditField                matlab.ui.control.NumericEditField
        mathrmR_F_yLabel                matlab.ui.control.Label
        BlackQXDEditField               matlab.ui.control.NumericEditField
        BlackRFYEditField               matlab.ui.control.NumericEditField
        BlackQXEditField                matlab.ui.control.NumericEditField
        mathrmR_T_zLabel_3              matlab.ui.control.Label
        mathrmQ_dotyLabel_3             matlab.ui.control.Label
        RedQXDEditField                 matlab.ui.control.NumericEditField
        RedQXEditField                  matlab.ui.control.NumericEditField
        mathrmQ_yLabel_3                matlab.ui.control.Label
        BlueRTzEditField                matlab.ui.control.NumericEditField
        RedRFYEditField                 matlab.ui.control.NumericEditField
        mathrmR_T_zLabel_2              matlab.ui.control.Label
        mathrmQ_dotyLabel_2             matlab.ui.control.Label
        BlueQYDEditField                matlab.ui.control.NumericEditField
        BlackRTzEditField               matlab.ui.control.NumericEditField
        mathrmQ_yLabel_2                matlab.ui.control.Label
        mathrmR_T_zLabel                matlab.ui.control.Label
        BlueQYEditField                 matlab.ui.control.NumericEditField
        mathrmQ_dotyLabel               matlab.ui.control.Label
        mathrmQ_yLabel                  matlab.ui.control.Label
        RedRTzEditField                 matlab.ui.control.NumericEditField
        mathrmQ_dotthetaLabel_3         matlab.ui.control.Label
        BlackQYDEditField               matlab.ui.control.NumericEditField
        BlackQYEditField                matlab.ui.control.NumericEditField
        mathrmQ_thetaLabel_2            matlab.ui.control.Label
        BlueQThetaDEditField            matlab.ui.control.NumericEditField
        StateWeightingMatrixDiagonalsLabel  matlab.ui.control.Label
        RedQYDEditField                 matlab.ui.control.NumericEditField
        mathrmQ_dotthetaLabel_2         matlab.ui.control.Label
        BlueQThetaEditField             matlab.ui.control.NumericEditField
        RedQYEditField                  matlab.ui.control.NumericEditField
        mathrmQ_thetaLabel              matlab.ui.control.Label
        BlackQThetaDEditField           matlab.ui.control.NumericEditField
        mathrmQ_dotthetaLabel           matlab.ui.control.Label
        BlackQThetaEditField            matlab.ui.control.NumericEditField
        RedQThetaLabel                  matlab.ui.control.Label
        RedQThetaDEditField             matlab.ui.control.NumericEditField
        RedQThetaEditField              matlab.ui.control.NumericEditField
        PDControllerTab                 matlab.ui.container.Tab
        GridLayout38                    matlab.ui.container.GridLayout
        Step6ControlLawTuningPanel_LQR  matlab.ui.container.Panel
        GridLayout3_8                   matlab.ui.container.GridLayout
        BlueDGainThetaEditField         matlab.ui.control.NumericEditField
        BlackDGainThetaEditField        matlab.ui.control.NumericEditField
        RedDGainThetaEditField          matlab.ui.control.NumericEditField
        mathrmK_dthetaLabel_3           matlab.ui.control.Label
        mathrmK_dthetaLabel_2           matlab.ui.control.Label
        mathrmK_dthetaLabel             matlab.ui.control.Label
        BlueDYGainEditField             matlab.ui.control.NumericEditField
        BlackDYGainEditField            matlab.ui.control.NumericEditField
        RedDYGainEditField              matlab.ui.control.NumericEditField
        mathrmK_dYLabel_3               matlab.ui.control.Label
        mathrmK_dYLabel_2               matlab.ui.control.Label
        mathrmK_dYLabel                 matlab.ui.control.Label
        BlueDXGainEditField             matlab.ui.control.NumericEditField
        BlackDXGainEditField            matlab.ui.control.NumericEditField
        RedDXGainEditField              matlab.ui.control.NumericEditField
        mathrmK_dXLabel_3               matlab.ui.control.Label
        mathrmK_dXLabel_2               matlab.ui.control.Label
        mathrmK_dXLabel                 matlab.ui.control.Label
        DerivativeGainsLabel            matlab.ui.control.Label
        ProportionalGainsLabel          matlab.ui.control.Label
        BluePGainThetaEditField         matlab.ui.control.NumericEditField
        BlackPGainThetaEditField        matlab.ui.control.NumericEditField
        RedPGainThetaEditField          matlab.ui.control.NumericEditField
        mathrmK_pthetaLabel_3           matlab.ui.control.Label
        mathrmK_pthetaLabel_2           matlab.ui.control.Label
        mathrmK_pthetaLabel             matlab.ui.control.Label
        BluePYGainEditField             matlab.ui.control.NumericEditField
        BlackPYGainEditField            matlab.ui.control.NumericEditField
        RedPYGainEditField              matlab.ui.control.NumericEditField
        mathrmK_pYLabel_3               matlab.ui.control.Label
        mathrmK_pYLabel_2               matlab.ui.control.Label
        mathrmK_pYLabel                 matlab.ui.control.Label
        BluePXGainEditField             matlab.ui.control.NumericEditField
        BlackPXGainEditField            matlab.ui.control.NumericEditField
        RedPXGainEditField              matlab.ui.control.NumericEditField
        mathrmK_pXLabel_3               matlab.ui.control.Label
        mathrmK_pXLabel_2               matlab.ui.control.Label
        mathrmK_pXLabel                 matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Step2ActiveHardwarePanel        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        BLACKCheckBox                   matlab.ui.control.CheckBox
        ARMCheckBox                     matlab.ui.control.CheckBox
        BLUECheckBox                    matlab.ui.control.CheckBox
        REDCheckBox                     matlab.ui.control.CheckBox
        Step7SetSamplingRatePanel       matlab.ui.container.Panel
        GridLayout3_7                   matlab.ui.container.GridLayout
        DurPhase0Units_51               matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        Step5SetDurationsPanel          matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Phase0Label                     matlab.ui.control.Label
        SampRateUnits_2                 matlab.ui.control.Label
        TotalTimeEditField              matlab.ui.control.NumericEditField
        TotalTimeLabel_2                matlab.ui.control.Label
        TotalTimeLabel                  matlab.ui.control.Label
        StartupPhasesLabel              matlab.ui.control.Label
        DurPhase0Units                  matlab.ui.control.Label
        DurPhase0EditField              matlab.ui.control.NumericEditField
        TerminationPhasesLabel          matlab.ui.control.Label
        SubPhase4Units                  matlab.ui.control.Label
        SubPhase4EditField              matlab.ui.control.NumericEditField
        SubPhase4Label                  matlab.ui.control.Label
        SubPhase3Units                  matlab.ui.control.Label
        SubPhase3EditField              matlab.ui.control.NumericEditField
        SubPhase3Label                  matlab.ui.control.Label
        SubPhase2Units                  matlab.ui.control.Label
        SubPhase2EditField              matlab.ui.control.NumericEditField
        SubPhase2Label                  matlab.ui.control.Label
        SubPhase1Units                  matlab.ui.control.Label
        SubPhase1EditField              matlab.ui.control.NumericEditField
        SubPhase1Label                  matlab.ui.control.Label
        Phase3SubPhasesLabel            matlab.ui.control.Label
        DurPhase5Units                  matlab.ui.control.Label
        DurPhase5EditField              matlab.ui.control.NumericEditField
        Phase5Label                     matlab.ui.control.Label
        DurPhase4Units                  matlab.ui.control.Label
        DurPhase4EditField              matlab.ui.control.NumericEditField
        Phase4Label                     matlab.ui.control.Label
        DurPhase3Units                  matlab.ui.control.Label
        DurPhase3EditField              matlab.ui.control.NumericEditField
        Phase3Label                     matlab.ui.control.Label
        DurPhase2Units                  matlab.ui.control.Label
        DurPhase2EditField              matlab.ui.control.NumericEditField
        Phase2Label                     matlab.ui.control.Label
        DurPhase1Units                  matlab.ui.control.Label
        DurPhase1EditField              matlab.ui.control.NumericEditField
        Phase1Label                     matlab.ui.control.Label
        Step4InitialConditionsPanel     matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        OpenInitialConditionAppButton   matlab.ui.control.Button
        Step3MassPropertiesPanel        matlab.ui.container.Panel
        GridLayout35                    matlab.ui.container.GridLayout
        OpenMassPropertiesAppButton     matlab.ui.control.Button
        Step1LoadSimulinkDiagramPanel   matlab.ui.container.Panel
        GridLayout3_3                   matlab.ui.container.GridLayout
        RefreshButton                   matlab.ui.control.Button
        AvailableDiagramsDropDown       matlab.ui.control.DropDown
        SetupRunSimulationsTab          matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        AnimationOptionsPanel           matlab.ui.container.Panel
        GridLayout3_5                   matlab.ui.container.GridLayout
        AnimationTree                   matlab.ui.container.CheckBoxTree
        REDNode                         matlab.ui.container.TreeNode
        ShowDockingConeNode             matlab.ui.container.TreeNode
        ShowPathTraceNode               matlab.ui.container.TreeNode
        BLACKNode                       matlab.ui.container.TreeNode
        ShowSolarPanelsNode             matlab.ui.container.TreeNode
        ShowDockingPortNode             matlab.ui.container.TreeNode
        ShowPathTraceNode_2             matlab.ui.container.TreeNode
        BLUENode                        matlab.ui.container.TreeNode
        ShowPathTraceNode_3             matlab.ui.container.TreeNode
        CreateVideoNode                 matlab.ui.container.TreeNode
        SimulationSettingsPanel         matlab.ui.container.Panel
        GridLayout3_4                   matlab.ui.container.GridLayout
        SolverNameDropDown              matlab.ui.control.DropDown
        SolverNameVarStepDropDown       matlab.ui.control.DropDown
        SolverTypeDropDown              matlab.ui.control.DropDown
        SolverTypeDropDownLabel_8       matlab.ui.control.Label
        SolverTypeDropDownLabel_2       matlab.ui.control.Label
        VarStepAbsTolEditField          matlab.ui.control.EditField
        VarStepRelTolEditField          matlab.ui.control.EditField
        VarStepInitStepSizeEditField    matlab.ui.control.EditField
        VarStepMinStepSizeEditField     matlab.ui.control.EditField
        VarStepMaxStepSizeEditField     matlab.ui.control.EditField
        DurPhase0Units_35               matlab.ui.control.Label
        DurPhase0Units_34               matlab.ui.control.Label
        DurPhase0Units_33               matlab.ui.control.Label
        DurPhase0Units_32               matlab.ui.control.Label
        DurPhase0Units_31               matlab.ui.control.Label
        SolverTypeDropDownLabel_7       matlab.ui.control.Label
        SolverTypeDropDownLabel_6       matlab.ui.control.Label
        SolverTypeDropDownLabel_5       matlab.ui.control.Label
        SolverTypeDropDownLabel_4       matlab.ui.control.Label
        VariableStepOptionsLabel        matlab.ui.control.Label
        SolverOptionsLabel              matlab.ui.control.Label
        SolverTypeDropDownLabel_3       matlab.ui.control.Label
        Step8RunSimulationPanel         matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout26_3                  matlab.ui.container.GridLayout
        AutomaticFolderNamingCheckBox   matlab.ui.control.CheckBox
        SaveDataButton                  matlab.ui.control.Button
        RunSimButton                    matlab.ui.control.Button
        SimulationControlsLabel         matlab.ui.control.Label
        GridLayout26_2                  matlab.ui.container.GridLayout
        StopButton                      matlab.ui.control.StateButton
        PauseButton                     matlab.ui.control.StateButton
        PlayButton                      matlab.ui.control.Button
        AnimationControlsLabel          matlab.ui.control.Label
        GridLayout32                    matlab.ui.container.GridLayout
        SkipToLabel                     matlab.ui.control.Label
        DurPhase0Units_52               matlab.ui.control.Label
        SkipToTimeEditField             matlab.ui.control.Spinner
        GridLayout26                    matlab.ui.container.GridLayout
        AnimationSpeedSlider            matlab.ui.control.Slider
        AnimationSpeedLabel             matlab.ui.control.Label
        GridLayout25                    matlab.ui.container.GridLayout
        AnimationScrubberSlider         matlab.ui.control.Slider
        AnimationScrubberLabel          matlab.ui.control.Label
        ExperimentPreview               matlab.ui.control.UIAxes
        SetupRunExperimentsTab          matlab.ui.container.Tab
        GridLayout19_2                  matlab.ui.container.GridLayout
        LiveAnimationOptionsPanel       matlab.ui.container.Panel
        GridLayout3_6                   matlab.ui.container.GridLayout
        AnimationTreeExp                matlab.ui.container.CheckBoxTree
        RED                             matlab.ui.container.TreeNode
        ShowDockingConeNodeLive         matlab.ui.container.TreeNode
        OverlaySimulationNodeREDLive    matlab.ui.container.TreeNode
        BLACK                           matlab.ui.container.TreeNode
        ShowDockNodeLive                matlab.ui.container.TreeNode
        ShowSolarPanelsNodeLive         matlab.ui.container.TreeNode
        OverlaySimulationNodeBLACKLive  matlab.ui.container.TreeNode
        BLUENode_2                      matlab.ui.container.TreeNode
        OverlaySimulationNodeBLUELive   matlab.ui.container.TreeNode
        GridLayout33                    matlab.ui.container.GridLayout
        ConnectionStatusLabel           matlab.ui.control.Label
        ConnectionStatus_1              matlab.ui.control.Lamp
        Step8EstablishConnectionwithSelectedPlatformsButtonRepeat  matlab.ui.control.Button
        RealTimePhaseSpaceDataPanel     matlab.ui.container.Panel
        GridLayout9_4                   matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        GridLayout27                    matlab.ui.container.GridLayout
        RealTimeTelOnLamp               matlab.ui.control.Lamp
        ToggleRealTimeTelemetrySwitch   matlab.ui.control.Switch
        EnableRealTimeTelemetryLabel    matlab.ui.control.Label
        DurPhase0Units_44               matlab.ui.control.Label
        DurPhase0Units_43               matlab.ui.control.Label
        DurPhase0Units_42               matlab.ui.control.Label
        DurPhase0Units_41               matlab.ui.control.Label
        DurPhase0Units_40               matlab.ui.control.Label
        DurPhase0Units_39               matlab.ui.control.Label
        DurPhase0Units_38               matlab.ui.control.Label
        DurPhase0Units_37               matlab.ui.control.Label
        DurPhase0Units_36               matlab.ui.control.Label
        mathrmtheta_BLUEEditField       matlab.ui.control.NumericEditField
        mathrmtheta_BLUEEditFieldLabel  matlab.ui.control.Label
        mathrmY_BLUEEditField           matlab.ui.control.NumericEditField
        mathrmY_BLUEEditFieldLabel      matlab.ui.control.Label
        mathrmX_BLUEEditField           matlab.ui.control.NumericEditField
        mathrmX_BLUEEditFieldLabel      matlab.ui.control.Label
        mathrmtheta_BLACKEditField      matlab.ui.control.NumericEditField
        mathrmtheta_BLACKEditFieldLabel  matlab.ui.control.Label
        mathrmY_BLACKEditField          matlab.ui.control.NumericEditField
        mathrmY_BLACKEditFieldLabel     matlab.ui.control.Label
        mathrmX_BLACKEditField          matlab.ui.control.NumericEditField
        mathrmX_BLACKEditFieldLabel     matlab.ui.control.Label
        mathrmtheta_REDEditField        matlab.ui.control.NumericEditField
        mathrmtheta_REDEditFieldLabel   matlab.ui.control.Label
        mathrmY_REDEditField            matlab.ui.control.NumericEditField
        mathrmY_REDEditFieldLabel       matlab.ui.control.Label
        mathrmX_REDEditField            matlab.ui.control.NumericEditField
        mathrmX_REDEditFieldLabel       matlab.ui.control.Label
        RealTimeVisualizationPanel      matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        ExperimentPreviewLive           matlab.ui.control.UIAxes
        ExperimentControlsPanel         matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        AutomaticFolderNamingCheckBoxExp  matlab.ui.control.CheckBox
        GridLayout28                    matlab.ui.container.GridLayout
        CamerasOnLamp                   matlab.ui.control.Lamp
        PhaseSpaceSwitch                matlab.ui.control.Switch
        PhaseSpaceLabel                 matlab.ui.control.Label
        BuildCode                       matlab.ui.control.Button
        SaveExperimentDataButton        matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        StartExperimentButton           matlab.ui.control.Button
        DirectHardwareControlTab        matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        GridLayout33_2                  matlab.ui.container.GridLayout
        ConnectionStatusLabel_2         matlab.ui.control.Label
        ConnectionStatus_2              matlab.ui.control.Lamp
        ExtrasPanel                     matlab.ui.container.Panel
        GridLayout20_4                  matlab.ui.container.GridLayout
        EmptyExperimentsSoftwareButton  matlab.ui.control.Button
        OpenVISIONCommandShell          matlab.ui.control.Button
        ExtrasPanelSeperator_2          matlab.ui.control.Image
        BLACKMagnetOffButton            matlab.ui.control.Button
        BLACKMagnetOnButton             matlab.ui.control.Button
        ExtrasPanelSeperator            matlab.ui.control.Image
        EmptyMATLAB_wsButton            matlab.ui.control.Button
        Step9EstablishConnectionwithSelectedPlatformsButton  matlab.ui.control.Button
        BLUEPlatformLiveControlsPanel   matlab.ui.container.Panel
        GridLayout20_3                  matlab.ui.container.GridLayout
        OpenBLUECMDShell                matlab.ui.control.Button
        CyclePinsButtonBLUE             matlab.ui.control.Button
        FireBLUEThrustersButton         matlab.ui.control.Button
        BLUETextArea                    matlab.ui.control.TextArea
        BLUEConnectedLamp               matlab.ui.control.Lamp
        PingBLUEButton                  matlab.ui.control.Button
        BLUEPucksOffButton              matlab.ui.control.Button
        BLUEPucksOnButton               matlab.ui.control.Button
        BLACKPlatformLiveControlsPanel  matlab.ui.container.Panel
        GridLayout20_2                  matlab.ui.container.GridLayout
        BLACKTextArea                   matlab.ui.control.TextArea
        OpenBLACKCMDShell               matlab.ui.control.Button
        CyclePinsButtonBLACK            matlab.ui.control.Button
        FireBLACKThrustersButton        matlab.ui.control.Button
        BLACKConnectedLamp              matlab.ui.control.Lamp
        PingBLACKButton                 matlab.ui.control.Button
        BLACKPucksOffButton             matlab.ui.control.Button
        BLACKPucksOnButton              matlab.ui.control.Button
        REDPlatformLiveControlsPanel    matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        OpenREDCMDShell                 matlab.ui.control.Button
        CyclePinsButtonRED              matlab.ui.control.Button
        FireREDThrustersButton          matlab.ui.control.Button
        REDConnectedLamp                matlab.ui.control.Lamp
        REDTextArea                     matlab.ui.control.TextArea
        PingREDButton                   matlab.ui.control.Button
        REDPucksOffButton               matlab.ui.control.Button
        REDPucksOnButton                matlab.ui.control.Button
        DataInspectorTab                matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        Plot1Tab                        matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        AllowOverplotCheckBox           matlab.ui.control.CheckBox
        IncludeLegendCheckBox           matlab.ui.control.CheckBox
        ApplyRangesButton_2             matlab.ui.control.Button
        SetYMaxPlt1                     matlab.ui.control.EditField
        SetYMinPlt1                     matlab.ui.control.EditField
        SetXMaxPlt1                     matlab.ui.control.EditField
        SetXMinPlt1                     matlab.ui.control.EditField
        FigureOptionsLabel              matlab.ui.control.Label
        FilterSignalCheckBoxPlt1_Secondary  matlab.ui.control.CheckBox
        FilterSignalCheckBoxPlt1        matlab.ui.control.CheckBox
        YAxisLabelPlt1                  matlab.ui.control.EditField
        XAxisLabelPlt1                  matlab.ui.control.EditField
        FigureExportOptionsLabel_2      matlab.ui.control.Label
        ResetFigureLabel_2              matlab.ui.control.Label
        ExportFiguretoPDFButton         matlab.ui.control.Button
        ClearFigureButtonPlt1           matlab.ui.control.Button
        SelectYDataPlt1_Secondary       matlab.ui.control.DropDown
        SelectYDataPlt1                 matlab.ui.control.DropDown
        SelectXDataPlt1_Secondary       matlab.ui.control.DropDown
        SelectXDataPlt1                 matlab.ui.control.DropDown
        LoadDataButtonPlt1_Secondary    matlab.ui.control.Button
        LoadDataButtonPlt1              matlab.ui.control.Button
        SelectDataFileLabel             matlab.ui.control.Label
        YAxisLabel                      matlab.ui.control.Label
        XAxisLabel                      matlab.ui.control.Label
        DataViewerPlt1                  matlab.ui.control.UIAxes
        Plot2Tab                        matlab.ui.container.Tab
        GridLayout30                    matlab.ui.container.GridLayout
        IncludeLegendCheckBox_2         matlab.ui.control.CheckBox
        AllowOverplotCheckBoxPlt2       matlab.ui.control.CheckBox
        ApplyRangesButton               matlab.ui.control.Button
        EditField_8                     matlab.ui.control.EditField
        EditField_7                     matlab.ui.control.EditField
        EditField_6                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        SetFigureRangesLabel_2          matlab.ui.control.Label
        FilterYSignalCheckBoxPlt2_Secondary  matlab.ui.control.CheckBox
        FilterYSignalCheckBoxPlt2       matlab.ui.control.CheckBox
        FigureExportOptionsLabel        matlab.ui.control.Label
        ResetFigureLabel                matlab.ui.control.Label
        YAxisLabel_2                    matlab.ui.control.Label
        XAxisLabel_2                    matlab.ui.control.Label
        SelectDataFileLabel_2           matlab.ui.control.Label
        ExporttoPDFButton2              matlab.ui.control.Button
        ClearFigureButtonPlt2           matlab.ui.control.Button
        LoadDataButtonPlt2_Secondary    matlab.ui.control.Button
        LoadDataButtonPlt2              matlab.ui.control.Button
        SelectYDataPlt2_Secondary       matlab.ui.control.DropDown
        SelectYDataPlt2                 matlab.ui.control.DropDown
        YAxisLabelPlt2                  matlab.ui.control.EditField
        XAxisLabelPlt2                  matlab.ui.control.EditField
        SelectXDataPlt2_Secondary       matlab.ui.control.DropDown
        SelectXDataPlt2                 matlab.ui.control.DropDown
        DataViewerPlt2                  matlab.ui.control.UIAxes
        ComputerVisionTab               matlab.ui.container.Tab
        GridLayout40                    matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
    end

    
properties (Access = public)
        REDConnection;
        BLACKConnection;
        BLUEConnection;
        RED_Process_ID;
        BLUE_Process_ID;
        BLACK_Process_ID;
        REDFigHdl;
        BLACKFigHdl;
        BLUEFigHdl;
        REDFigPatch;
        BLACKFigPatch;
        BLUEFigPatch;
        REDFigLine;
        BLACKFigLine;
        BLUEFigLine;
        REDFigLink1;
        REDFigLink2;
        REDFigLink3;
        REDFigJoin0;
        REDFigJoin1;
        REDFigJoin2;
        REDFigJoin3;
        REDPathPlot;
        BLACKPathPlot;
        BLUEPathPlot;

        REDFigLiveHdl;
        BLACKFigLiveHdl;
        BLUEFigLiveHdl;
        REDFigLivePatch;
        BLACKFigLivePatch;
        BLUEFigLivePatch;
        REDFigLiveLine;
        BLACKFigLiveLine;
        BLUEFigLiveLine;
        REDFigLiveLink1;
        REDFigLiveLink2;
        REDFigLiveLink3;
        REDFigLiveJoin0;
        REDFigLiveJoin1;
        REDFigLiveJoin2;
        REDFigLiveJoin3;
        Interruptor;
        CompileDir;
        DataPacketUp;

        CreateVideoChecked;
        ShowPathTraceRED;   
        ShowPathTraceBLACK; 
        ShowPathTraceBLUE;  
        ShowDockingConeRED; 
        ShowSolarPanelsBLACK; 
        ShowDockingPortBLACK; 

        ShowDockingConeREDLive; 
        ShowSolarPanelsBLACKLive; 
        ShowDockingPortBLACKLive; 
            
        Phase3_SubPhase1_Duration;
        Phase3_SubPhase2_Duration;
        Phase3_SubPhase3_Duration;
        Phase3_SubPhase4_Duration;

        Phase0_Duration;
        Phase1_Duration;
        Phase2_Duration;
        Phase3_Duration;
        Phase4_Duration;
        Phase5_Duration;

        Phase3_SubPhase1_End;
        Phase3_SubPhase2_End;
        Phase3_SubPhase3_End;
        Phase3_SubPhase4_End;

        Phase0_End;
        Phase1_End;
        Phase2_End;
        Phase3_End;
        Phase4_End;
        Phase5_End;

        tsim;

        Kp_xr;
        Kd_xr;
        Kp_yr;
        Kd_yr;
        Kp_tr;
        Kd_tr;
        Kp_xb;
        Kd_xb;
        Kp_yb;
        Kd_yb;
        Kp_tb;
        Kd_tb;
        Kp_xblue;
        Kd_xblue;
        Kp_yblue;
        Kd_yblue;
        Kp_tblue;
        Kd_tblue;   

        A_d_RED; 
        B_d_RED; 
        C_d_RED; 
        D_d_RED;  

        A_d_BLACK; 
        B_d_BLACK; 
        C_d_BLACK; 
        D_d_BLACK; 

        A_d_BLUE; 
        B_d_BLUE; 
        C_d_BLUE; 
        D_d_BLUE;  

        Qx_RED;
        Qy_RED;
        Qtheta_RED;
        QxD_RED;
        QyD_RED;
        QthetaD_RED;
        Qx_BLACK;
        Qy_BLACK;
        Qtheta_BLACK;
        QxD_BLACK;
        QyD_BLACK;
        QthetaD_BLACK;
        Qx_BLUE;
        Qy_BLUE;
        Qtheta_BLUE;
        QxD_BLUE;
        QyD_BLUE;
        QthetaD_BLUE;        

        RFx_RED;
        RFy_RED;
        RTz_RED;
        RFx_BLACK;
        RFy_BLACK;
        RTz_BLACK;
        RFx_BLUE;
        RFy_BLUE;
        RTz_BLUE;

        F_red_X_nominal;
        F_red_Y_nominal; 
        F_thrusters_RED;      
        F_black_X_nominal;
        F_black_Y_nominal; 
        F_thrusters_BLACK;   
        F_blue_X_nominal;
        F_blue_Y_nominal; 
        F_thrusters_BLUE;   

        baseRate;
        dataRate;
        PreviousSamplingRate;

        mRED;
        mBLACK;
        mBLUE;
        mPROX;
        mDIST;
        mEE;
        IRED;
        IBLACK;
        IBLUE;
        IPROX;
        IDIST;
        IEE;

        Phi;
        b0;
        a1;
        b1;
        a2;
        b2;
        a3;
        b3;

        xLength;
        yLength;

        varStepMaxStepSize;
        varStepMinStepSize;
        varStepInitStepSize;
        varStepRelTol;
        varStepAbsTol;

        LoadedData1;
        LoadedData2;

        LoadedData1_Secondary;
        LoadedData2_Secondary;

        DataPacket;
        DataClass;

        SubAppMassProperties;
        SubAppInitialConditions;

        isExperimentRunning;
        hudpr;

        ExternalAppState;

        checkExecutableTimer;

        RED_SSH_Address;
        BLACK_SSH_Address;
        BLUE_SSH_Address;

        RED_SSH_Name;
        BLUE_SSH_Name;
        BLACK_SSH_Name;

        RED_SSH_Password;
        BLACK_SSH_Password;
        BLUE_SSH_Password;

        MATLABVer;
        
    end
    

    methods (Access = public)

        % This function checks if an SLX file is already open in MATLAB        
        function is_open = isSLXOpen(~,file_name)
            
            % Remove the '.slx'
            file_name = strrep(file_name,".slx","");    
            
            % Get a list of all open models
            open_models = find_system('Type', 'block_diagram');
            
            % Loop through the open models and check if the file path matches
            for i = 1:length(open_models)
                [~, open_filename, ~] = fileparts(get_param(open_models{i}, 'FileName'));
                if strcmp(string(open_filename), file_name)
                    % If the file path matches, the file is already open
                    is_open = true;
                    return;
                end
            end
            
            % If we reach this point, the file is not open
            is_open = false;
            
        end

        function [] = DrawSelectedPlatforms(app,platformSelection,keyword,ax,drop_states_RED,drop_states_BLACK,drop_states_BLUE,rs,re,rw,ree)
            % This function draws the platforms based on the
            % 'platformSelection' variable. It is called by the following
            % functions:
            %    AddPlatformSim
            %    AddPlatformExternalFig
            %    AddPlatformLive
            % Inputs:
            %   keyword: "simulation", "external", or "live"
            %   ax: figure drawing object; for 'simulation' this is app.ExperimentPreview
            %       for 'external' this is ax, for 'live' this is app.ExperimentPreviewLive
            %
            
            % Draw the relevant platforms:
            if (platformSelection == 1)
                drawRED(app,keyword,drop_states_RED,ax);
            elseif (platformSelection == 2)
                drawBLACK(app,keyword,drop_states_BLACK,ax);
            elseif (platformSelection == 3)
                drawRED(app,keyword,drop_states_RED,ax);
                drawBLACK(app,keyword,drop_states_BLACK,ax);
            elseif (platformSelection == 4)
                drawRED(app,keyword,drop_states_RED,ax);
                drawBLACK(app,keyword,drop_states_BLACK,ax);
                drawARM(app,keyword,rs,re,rw,ree,ax);
            elseif (platformSelection == 5)
                drawRED(app,keyword,drop_states_RED,ax);
                drawARM(app,keyword,rs,re,rw,ree,ax);
            elseif (platformSelection == 6)
                drawBLUE(app,keyword,drop_states_BLUE,ax);
            elseif (platformSelection == 7)
                drawRED(app,keyword,drop_states_RED,ax);
                drawBLUE(app,keyword,drop_states_BLUE,ax);
            elseif (platformSelection == 8)
                drawRED(app,keyword,drop_states_RED,ax);
                drawBLACK(app,keyword,drop_states_BLACK,ax);
                drawBLUE(app,keyword,drop_states_BLUE,ax);
            elseif (platformSelection == 9)
                drawBLACK(app,keyword,drop_states_BLACK,ax);
                drawBLUE(app,keyword,drop_states_BLUE,ax);        
            elseif (platformSelection == 10)
                drawRED(app,keyword,drop_states_RED,ax);
                drawBLUE(app,keyword,drop_states_BLUE,ax);
                drawARM(app,keyword,rs,re,rw,ree,ax);
            elseif (platformSelection == 11)
                drawRED(app,keyword,drop_states_RED,ax);
                drawBLACK(app,keyword,drop_states_BLACK,ax);
                drawBLUE(app,keyword,drop_states_BLUE,ax);
                drawARM(app,keyword,rs,re,rw,ree,ax);
            end

            % Nested function for plotting RED, depending on the keyword
            function drawRED(app,keyword,param,ax)
                if keyword == "simulation"
                    [app.REDFigHdl, app.REDFigPatch] = DrawSpacecraftPrivateSimulation(app,[param(2),param(1),0.3,0.3,param(3),3],"r");
                    app.REDPathPlot = plot(app.ExperimentPreview, param(2),param(1),'Color','red');
                elseif keyword == "external"
                    [app.REDFigHdl, app.REDFigPatch] = DrawSpacecraftPrivateExternal(app,[param(2),param(1),0.3,0.3,param(3),3],"r",ax);
                    app.REDPathPlot = plot(ax, param(2),param(1),'Color','red');
                elseif keyword == "live"
                    [app.REDFigLiveHdl,app.REDFigLivePatch] = DrawSpacecraftPrivateLive(app,[param(2),param(1),0.3,0.3,param(3),3],"r");
                end
            end
            % Nested function for plotting BLACK, depending on the keyword
            % NOTE: By default, this plots solar panels. To change behaviour, 
            % switch the last input parameter from a 4 either a 1,2,or 3. 
            % See function DrawSpacecraft() for details.
            function drawBLACK(app,keyword,param,ax)
                if keyword == "simulation"
                    [app.BLACKFigHdl,app.BLACKFigPatch] = DrawSpacecraftPrivateSimulation(app,[param(2),param(1),0.3,0.3,param(3),3],"k");
                    app.BLACKPathPlot = plot(app.ExperimentPreview, param(2),param(1),'Color','black');
                elseif keyword == "external"
                    [app.BLACKFigHdl,app.BLACKFigPatch] = DrawSpacecraftPrivateExternal(app,[param(2),param(1),0.3,0.3,param(3),3],"k",ax);
                    app.BLACKPathPlot = plot(ax, param(2),param(1),'Color','black');
                elseif keyword == "live"
                    [app.BLACKFigLiveHdl,app.BLACKFigLivePatch] = DrawSpacecraftPrivateLive(app,[param(2),param(1),0.3,0.3,param(3),3],"k");
                end
            end
            % Nested function for plotting BLUE, depending on the keyword
            function drawBLUE(app,keyword,param,ax)
                if keyword == "simulation"
                    [app.BLUEFigHdl,app.BLUEFigPatch] = DrawSpacecraftPrivateSimulation(app,[param(2),param(1),0.3,0.3,param(3),3],"b");
                    app.BLUEPathPlot = plot(app.ExperimentPreview, param(2),param(1),'Color','blue');
                elseif keyword == "external"
                    [app.BLUEFigHdl,app.BLUEFigPatch] = DrawSpacecraftPrivateExternal(app,[param(2),param(1),0.3,0.3,param(3),3],"b",ax);
                    app.BLUEPathPlot = plot(ax, param(2),param(1),'Color','blue');
                elseif keyword == "live"
                    [app.BLUEFigLiveHdl,app.BLUEFigLivePatch] = DrawSpacecraftPrivateLive(app,[param(2),param(1),0.3,0.3,param(3),3],"b");
                end
            end
            % Nested function for plotting ARM, depending on the keyword
            function drawARM(app,keyword,rs,re,rw,ree,ax)
                if keyword == "live"
                    app.REDFigLiveLink1 = line(app.ExperimentPreviewLive,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                    app.REDFigLiveLink2 = line(app.ExperimentPreviewLive,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                    app.REDFigLiveLink3 = line(app.ExperimentPreviewLive,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                    app.REDFigLiveJoin0 = scatter(app.ExperimentPreviewLive,rs(1,1),rs(1,2),'filled','k');
                    app.REDFigLiveJoin1 = scatter(app.ExperimentPreviewLive,re(1,1),re(1,2),'filled','k');
                    app.REDFigLiveJoin2 = scatter(app.ExperimentPreviewLive,rw(1,1),rw(1,2),'filled','k');
                    app.REDFigLiveJoin3 = scatter(app.ExperimentPreviewLive,ree(1,1),ree(1,2),'filled','k');
                else
                    app.REDFigLink1 = line(ax,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                    app.REDFigLink2 = line(ax,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                    app.REDFigLink3 = line(ax,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                    app.REDFigJoin0 = scatter(ax,rs(1,1),rs(1,2),'filled','k');
                    app.REDFigJoin1 = scatter(ax,re(1,1),re(1,2),'filled','k');
                    app.REDFigJoin2 = scatter(ax,rw(1,1),rw(1,2),'filled','k');
                    app.REDFigJoin3 = scatter(ax,ree(1,1),ree(1,2),'filled','k');
                end

            end

        end

         % This function draws the platforms based on the
        % 'platformSelection' variable.
        function [] = AddPlatformSim(app,platformSelection)
            
            cla(app.ExperimentPreview);
            app.REDFigHdl = []; 
            app.BLACKFigHdl = [];
            app.BLUEFigHdl = [];
            app.REDFigPatch = [];
            app.BLACKFigPatch = [];
            app.BLUEFigPatch = [];
            % app.REDFigLine  = [];
            % app.BLACKFigLine= [];
            % app.BLUEFigLine = [];
            app.REDFigLink1 = [];
            app.REDFigLink2 = [];
            app.REDFigLink3 = [];
            app.REDFigJoin0 = [];
            app.REDFigJoin1 = [];
            app.REDFigJoin2 = [];
            app.REDFigJoin3 = [];
            app.REDPathPlot = [];
            app.BLUEPathPlot = [];
            app.BLACKPathPlot = [];
            
            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreview,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreview.XAxisLocation = "top";
            app.ExperimentPreview.XLim          = [-0.65 3];
            app.ExperimentPreview.YLim          = [-0.5 4];
            
            % Plot the table:  
            app.DrawTable(app.ExperimentPreview) 

            % Get the drop states for all three platforms:
            [drop_states_RED,drop_states_BLACK,drop_states_BLUE,rs,re,rw,ree] = app.GetPlatformDropStates();
            
            % Draw the selected platforms
            app.DrawSelectedPlatforms(platformSelection,"simulation",app.ExperimentPreview, ...
                                        drop_states_RED,drop_states_BLACK,drop_states_BLUE, ...
                                        rs,re,rw,ree)

        end

      % This function draws the platforms based on the
        % 'platformSelection' variable.
        function [ax] = AddPlatformExternalFig(app,platformSelection)
            
            %fig = figure();
            app.REDFigHdl = []; 
            app.BLACKFigHdl = [];
            app.BLUEFigHdl = [];
            app.REDFigPatch = [];
            app.BLACKFigPatch = [];
            app.BLUEFigPatch = [];
            % app.REDFigLine  = [];
            % app.BLACKFigLine= [];
            % app.BLUEFigLine = [];
            app.REDFigLink1 = [];
            app.REDFigLink2 = [];
            app.REDFigLink3 = [];
            app.REDFigJoin0 = [];
            app.REDFigJoin1 = [];
            app.REDFigJoin2 = [];
            app.REDFigJoin3 = [];
            app.REDPathPlot = [];
            app.BLUEPathPlot = [];
            app.BLACKPathPlot = [];

            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            ax = gca;
            axis(ax,"ij");
            
            % Set the remaining axes properties:
            ax.XAxisLocation = "top";
            ax.XLim          = [-0.65 3];
            ax.YLim          = [-0.5 4];
            set(gcf,'Position',[1000         262         560         640]);
            xlabel(ax,'Y-Position [m]');
            ylabel(ax,'X-Position [m]');
            
            % Plot the table: 
            app.DrawTable(ax) 
            
            % Get the drop states for all three platforms:
            [drop_states_RED,drop_states_BLACK,drop_states_BLUE,rs,re,rw,ree] = app.GetPlatformDropStates();

            % Draw the selected platforms
            app.DrawSelectedPlatforms(platformSelection,"external",ax, ...
                                        drop_states_RED,drop_states_BLACK,drop_states_BLUE, ...
                                        rs,re,rw,ree)

        end


        function [] = AddPlatformLive(app,platformSelection)
            
            cla(app.ExperimentPreviewLive);
            app.REDFigLiveHdl = []; 
            app.BLACKFigLiveHdl = [];
            app.BLUEFigLiveHdl = [];
            app.REDFigLivePatch = [];
            app.BLACKFigLivePatch = [];
            app.BLUEFigLivePatch = [];
            % app.REDFigLiveLine  = [];
            % app.BLACKFigLiveLine= [];
            % app.BLUEFigLiveLine = [];
            app.REDFigLiveLink1 = [];
            app.REDFigLiveLink2 = [];
            app.REDFigLiveLink3 = [];
            app.REDFigLiveJoin0 = [];
            app.REDFigLiveJoin1 = [];
            app.REDFigLiveJoin2 = [];
            app.REDFigLiveJoin3 = [];


            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreviewLive,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreviewLive.XAxisLocation = "top";
            app.ExperimentPreviewLive.XLim          = [-0.65 3];
            app.ExperimentPreviewLive.YLim          = [-0.5 4];
            
            % Plot the table: 
            app.DrawTable(app.ExperimentPreviewLive);
            
            % Get the drop states for all three platforms:
            [drop_states_RED,drop_states_BLACK,drop_states_BLUE,rs,re,rw,ree] = app.GetPlatformDropStates();                 
           
            % Draw the selected platforms
            app.DrawSelectedPlatforms(platformSelection,"live",app.ExperimentPreviewLive, ...
                                        drop_states_RED,drop_states_BLACK,drop_states_BLUE, ...
                                        rs,re,rw,ree)

        end

        function [] = DrawTable(app,ax)
            % Plot the table:
            line(ax,[0 app.yLength],[0 0],"Color","black","LineWidth",2);
            hold(ax,"on");
            line(ax,[app.yLength app.yLength],[0 app.xLength],"Color","black","LineWidth",2);
            line(ax,[app.yLength 0],[app.xLength app.xLength],"Color","black","LineWidth",2);
            line(ax,[0 0],[app.xLength 0],"Color","black","LineWidth",2);
            grid(ax,"on");   

            % Add a patch
            patch(ax, [0, app.yLength, app.yLength, 0], ...
                                        [0, 0, app.xLength, app.xLength], ...
                                        'black', 'FaceAlpha', 0.1);            
        end

        function [drop_states_RED,drop_states_BLACK,drop_states_BLUE,rs,re,rw,ree] = GetPlatformDropStates(app)
            % Get the drop states for all three platforms:
            drop_states_RED   = evalin("base","drop_states_RED");
            drop_states_ARM   = evalin("base","drop_states_ARM");
            drop_states_BLACK = evalin("base","drop_states_BLACK");
            drop_states_BLUE  = evalin("base","drop_states_BLUE");
            
            % For animations purposes, invert the angles:
            drop_states_RED(3)  = -drop_states_RED(3);
            drop_states_BLACK(3)= -drop_states_BLACK(3);
            drop_states_BLUE(3) = -drop_states_BLUE(3);
                                        
            x0 = drop_states_RED(2);
            y0 = drop_states_RED(1);
            q0 = drop_states_RED(3);
            q1 = (-drop_states_ARM(1) + 3*pi/2);
            q2 = -drop_states_ARM(2);
            q3 = -drop_states_ARM(3);         
            
            [rs,re,rw,ree] = app.CalculateArmPosition([x0,y0,q0,q1,q2,q3]);
            
        end

        function [rs,re,rw,ree] = CalculateArmPosition(app,param)
            x0 = param(1);
            y0 = param(2);
            q0 = param(3);
            q1 = param(4);
            q2 = param(5);
            q3 = param(6); 

            rs     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) ];                                    
            re     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) ];
            rw     = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) ];
            ree    = [ x0 + (app.SubAppMassProperties.b0)*cos(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*cos(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*cos(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (app.SubAppMassProperties.b0)*sin(pi/2-app.SubAppMassProperties.Phi + q0) + (app.SubAppMassProperties.a1+app.SubAppMassProperties.b1)*sin(pi/2 + q0 + q1) + (app.SubAppMassProperties.a2+app.SubAppMassProperties.b2)*sin(pi/2 + q0 + q1 + q2) + (app.SubAppMassProperties.b2+app.SubAppMassProperties.b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
            
        end   

        function [P] = DrawRectangle(~,param)
            
            [m,n] = size(param);
            
            if(m ~= 1 || n ~= 5)
                error("param should be an 1x5 array.");
            end
            
            if(param(3) <=0 || param(4) <=0)
                error("width and height must be positive values.");
            end
            
            %a = param(1);
            %b = param(2);
            w = param(3);
            h = param(4);
            theta = param(5);
            
            X = [-w/2 w/2 w/2 -w/2 -w/2];
            Y = [h/2 h/2 -h/2 -h/2 h/2];
            P = [X;Y];
            ct = cos(theta);
            st = sin(theta);
            R = [ct -st;st ct];
            P = R * P;
        end             

        % This function creates a rectangle in a plot. 
        function [FigHdl] = DrawRectanglePrivateSimulation(app,param,style)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            a = param(1);
            b = param(2);
            P = app.DrawRectangle(param);
            
            FigHdl = plot(app.ExperimentPreview,P(1,:)+a,P(2,:)+b,style);  
            
        end

        % This function creates a rectangle in a plot. 
        function [FigHdl] = DrawRectanglePrivateExternal(app,param,style,fig)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            a = param(1);
            b = param(2);
            P = app.DrawRectangle(param);
            
            FigHdl = plot(fig,P(1,:)+a,P(2,:)+b,style);  
            
        end

        % This function creates a rectangle in a plot. 
        function [FigHdl] = DrawRectanglePrivateLive(app,param,style)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            a = param(1);
            b = param(2);
            P = app.DrawRectangle(param);
            FigHdl = plot(app.ExperimentPreviewLive,P(1,:)+a,P(2,:)+b,style);  
            
        end  
        function [sc] = ShapeSpacecraft(~,param)
            % Obtain the points that make up the shape of the spacecraft,
            % the param(6) is the id that defines the shape: 
            % 1 = chaser (with docking cone along y-axis)
            % 2 = target (with docking cone along y-axis)
            % 3 = obstacle
            % 4 = target (with docking cone along y-axis & solar panels)

            % Get params
            cx = param(1);
            cy = param(2);
            r = param(3) / 2;
            id = param(6);

            % Conversion factor
            cm2m = 1/100;
            
            % Define physical constants
            excess          = 2.477*cm2m; % m
            shrink          = 1*cm2m; % m
            bracketwidth    = 6.5*cm2m; % m
            tarcyllength    = 8.07*cm2m; % m
            tarconelength   = 5.5*cm2m; % m
            tarangle        = 30*pi/180; % rads
            chaselength     = 5.5*cm2m; % m
            chaseangle      = 20*pi/180; % rads
            % SOLAR_PANEL_LENGTH = evalin("base","SOLAR_PANEL_LENGTH"); % m
            % SOLAR_PANEL_WIDTH  = evalin("base","SOLAR_PANEL_WIDTH");  % m
            % SOLAR_PANEL_HEIGHT = evalin("base","SOLAR_PANEL_HEIGHT"); % m
            SOLAR_PANEL_LENGTH = 0.45; % m
            SOLAR_PANEL_WIDTH  = 0.02;  % m
            SOLAR_PANEL_HEIGHT = 0.27; %#ok<NASGU> % m
            
            gamma = r - (excess + shrink + bracketwidth); % cm
            
            % Define the points that make-up the outline of the target spacecraft
            if id == 1 % chaser (docking grapple)
                sc = [cx,   cy;
                      cx+r, cy;
                      cx+r, cy+r;
                      cx-r, cy+r;
                      cx-r, cy-r;
                      cx+r, cy-r;
                      cx+r, cy+r;
                      cx+gamma, cy+r;
                      cx+gamma+chaselength*sin(chaseangle), cy+r+chaselength;
                      cx+gamma+bracketwidth-chaselength*sin(chaseangle), cy+r+chaselength;
                      cx+gamma+bracketwidth, cy+r;
                      cx+r, cy+r;
                      cx+r, cy];
            elseif id == 2 % target (docking cone)
                sc = [cx,   cy;
                      cx+r, cy;
                      cx+r, cy+r;
                      cx-r, cy+r;
                      cx-r, cy-r;
                      cx+r, cy-r;
                      cx+r, cy+r;
                      cx+gamma, cy+r;
                      cx+gamma, cy+r+tarcyllength;
                      cx+gamma-tarconelength*sin(tarangle), cy+r+tarcyllength+tarconelength;
                      cx+gamma+bracketwidth+tarconelength*sin(tarangle), cy+r+tarcyllength+tarconelength;
                      cx+gamma+bracketwidth, cy+r+tarcyllength;
                      cx+gamma+bracketwidth, cy+r;
                      cx+r, cy+r;
                      cx+r, cy];
            elseif id == 3 % obstacle
                sc = [cx,   cy;
                      cx+r, cy;
                      cx+r, cy+r;
                      cx-r, cy+r;
                      cx-r, cy-r;
                      cx+r, cy-r;
                      cx+r, cy];
            elseif id == 4 % target (with cone and solar panels)
                sc = [cx,   cy;
                      cx+r, cy;
                      cx+r, cy+r;
                      cx-r, cy+r;
                      cx-r, cy-SOLAR_PANEL_WIDTH/2;
                      cx-r-SOLAR_PANEL_LENGTH, cy-SOLAR_PANEL_WIDTH/2;
                      cx-r-SOLAR_PANEL_LENGTH, cy+SOLAR_PANEL_WIDTH/2;
                      cx-r, cy+SOLAR_PANEL_WIDTH/2;
                      cx-r, cy-r;
                      cx+r, cy-r;
                      cx+r, cy-SOLAR_PANEL_WIDTH/2;
                      cx+r+SOLAR_PANEL_LENGTH, cy-SOLAR_PANEL_WIDTH/2;
                      cx+r+SOLAR_PANEL_LENGTH, cy+SOLAR_PANEL_WIDTH/2;
                      cx+r, cy+SOLAR_PANEL_WIDTH/2;
                      cx+r, cy+r;
                      cx+gamma, cy+r;
                      cx+gamma, cy+r+tarcyllength;
                      cx+gamma-tarconelength*sin(tarangle), cy+r+tarcyllength+tarconelength;
                      cx+gamma+bracketwidth+tarconelength*sin(tarangle), cy+r+tarcyllength+tarconelength;
                      cx+gamma+bracketwidth, cy+r+tarcyllength;
                      cx+gamma+bracketwidth, cy+r;
                      cx+r, cy+r;
                      cx+r, cy];
            elseif id == 5 % target (with solar panels only)
                sc = [cx,   cy;
                      cx+r, cy;
                      cx+r, cy+r;
                      cx-r, cy+r;
                      cx-r, cy-SOLAR_PANEL_WIDTH/2;
                      cx-r-SOLAR_PANEL_LENGTH, cy-SOLAR_PANEL_WIDTH/2;
                      cx-r-SOLAR_PANEL_LENGTH, cy+SOLAR_PANEL_WIDTH/2;
                      cx-r, cy+SOLAR_PANEL_WIDTH/2;
                      cx-r, cy-r;
                      cx+r, cy-r;
                      cx+r, cy-SOLAR_PANEL_WIDTH/2;
                      cx+r+SOLAR_PANEL_LENGTH, cy-SOLAR_PANEL_WIDTH/2;
                      cx+r+SOLAR_PANEL_LENGTH, cy+SOLAR_PANEL_WIDTH/2;
                      cx+r, cy+SOLAR_PANEL_WIDTH/2;
                      cx+r, cy+r;
                      cx+r, cy+r;
                      cx+r, cy];
            end
        end

        function [spacecraft] = DrawSpacecraft(app,param)
            cx = param(1);
            cy = param(2);
            theta = param(5) - pi/2;

            % Obtain the points that make up the shape of the spacecraft,
            % the param(6) is the id that defines the shape: 
            % 1 = chaser (with docking cone along y-axis)
            % 2 = target (with docking cone along y-axis)
            % 3 = obstacle
            % 4 = target (with docking cone along y-axis & solar panels)
            sc = app.ShapeSpacecraft(param);
        
            % Transform the spacecraft to be at the origin in order to apply the
            % rotation matrix
            sccenter = [ones(1,length(sc))*cx; ones(1,length(sc))*cy].' ;
            scbf = sc - sccenter;
            scbf(:,1) = -scbf(:,1); % Need to flip shape across y-axis
            
            % Define a rotation matrix based on the target orientation
            rotmat = [cos(theta), -sin(theta); 
                      sin(theta), cos(theta)];

            % Apply the rotation matrix to each of the points
            scbf_rot = zeros(size(scbf));
            for i=1:length(sc)
                scbf_rot(i,:) = rotmat*scbf(i,:).';
            end
            
            % Transform the spacecraft back to its correct position at cx,cy
            spacecraft = scbf_rot + sccenter;
        end

        % This function creates a spacecraft in a plot. 
        function [FigHdl, FigPatch] = DrawSpacecraftPrivateSimulation(app,param,style)
            spacecraft = app.DrawSpacecraft(param);
            FigPatch = patch(app.ExperimentPreview,spacecraft(:,1), spacecraft(:,2),style,'FaceAlpha',0.3);
            FigHdl = plot(app.ExperimentPreview,spacecraft(:,1),spacecraft(:,2),style); 
        end

        % This function creates a spacecraft in a plot. 
        function [FigHdl, FigPatch] = DrawSpacecraftPrivateExternal(app,param,style,fig)
            spacecraft = app.DrawSpacecraft(param);
            FigPatch = patch(fig,spacecraft(:,1), spacecraft(:,2),style,'FaceAlpha',0.3);
            FigHdl = plot(fig,spacecraft(:,1),spacecraft(:,2),style);  
        end

        % This function creates a spacecraft in a plot. 
        function [FigHdl, FigPatch] = DrawSpacecraftPrivateLive(app,param,style)
            spacecraft = app.DrawSpacecraft(param);
            FigPatch = patch(app.ExperimentPreviewLive,spacecraft(:,1), spacecraft(:,2),style,'FaceAlpha',0.3);
            FigHdl = plot(app.ExperimentPreviewLive,spacecraft(:,1),spacecraft(:,2),style);  
        end  

        % This function is used for debugging. It enables all pins and then
        % cycles through them in 2 second intervals.
        function CycleAllThrusters(~, Platform)

                system(Platform,'echo 436 > /sys/class/gpio/export');
                system(Platform,'echo 445 > /sys/class/gpio/export');
                system(Platform,'echo 480 > /sys/class/gpio/export');
                system(Platform,'echo 268 > /sys/class/gpio/export');
                system(Platform,'echo 484 > /sys/class/gpio/export');
                system(Platform,'echo 483 > /sys/class/gpio/export');
                system(Platform,'echo 481 > /sys/class/gpio/export');
                system(Platform,'echo 491 > /sys/class/gpio/export');

                system(Platform,'echo out > /sys/class/gpio/gpio436/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio445/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio480/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio268/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio484/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio483/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio481/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio491/direction');

                % Start a timer
                tic;

                loopThroughTruster = 1;

                while(loopThroughTruster == 1)

                    if toc < 2
                        system(Platform,'echo 1 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio491/value');
                    elseif toc >=2 && toc < 4
                        system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 1 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio491/value');
                    elseif toc >=4 && toc < 6
                        system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 1 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio491/value');
                    elseif toc >=6 && toc < 8
                        system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 1 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio491/value');
                    elseif toc >=8 && toc < 10
                        system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 1 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio491/value'); 
                    elseif toc >=10 && toc < 12
                        system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 1 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio491/value'); 
                    elseif toc >=12 && toc < 14
                        system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 1 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio491/value');
                    elseif toc >=14 && toc < 16
                        system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 1 > /sys/class/gpio/gpio491/value'); 
                    else
                        system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                        system(Platform,'echo 0 > /sys/class/gpio/gpio491/value'); 
                        loopThroughTruster = 0;
                    end
                end

                system(Platform,'echo 436 > /sys/class/gpio/unexport');
                system(Platform,'echo 445 > /sys/class/gpio/unexport');
                system(Platform,'echo 480 > /sys/class/gpio/unexport');
                system(Platform,'echo 268 > /sys/class/gpio/unexport');
                system(Platform,'echo 484 > /sys/class/gpio/unexport');
                system(Platform,'echo 483 > /sys/class/gpio/unexport');
                system(Platform,'echo 481 > /sys/class/gpio/unexport');
                system(Platform,'echo 491 > /sys/class/gpio/unexport'); 
           
        end
        
        % This function disables all key buttons in the application.
        function [] = DisableAllKeyButtons(app)
            set(app.PlayButton,'enable','off');
            set(app.StopButton,'enable','off');
            set(app.SaveDataButton,'enable','off');
            set(app.AnimationTree,'enable','off');
            set(app.RunSimButton,'enable','off');
            set(app.BuildCode,'enable','off');
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.AnimationSpeedSlider,'enable','off');
            set(app.AnimationScrubberSlider,'enable','off');
            set(app.ToggleRealTimeTelemetrySwitch,'enable','off');
            set(app.Step9EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off');
            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off');
            set(app.EmptyMATLAB_wsButton,'enable','off');
            set(app.EmptyExperimentsSoftwareButton,'enable','off');
            set(app.BLACKMagnetOnButton,'enable','off');
            set(app.BLACKMagnetOffButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off');
            set(app.REDCheckBox,'enable','off');
            set(app.BLACKCheckBox,'enable','off');
            set(app.BLUECheckBox,'enable','off');
            set(app.ARMCheckBox,'enable','off');
            set(app.SolverNameVarStepDropDown,'enable','off');
            set(app.SolverNameDropDown,'enable','off');
            set(app.VarStepAbsTolEditField,'enable','off');
            set(app.VarStepRelTolEditField,'enable','off');
            set(app.VarStepInitStepSizeEditField,'enable','off');
            set(app.VarStepMinStepSizeEditField,'enable','off');
            set(app.VarStepMaxStepSizeEditField,'enable','off');
            set(app.SolverTypeDropDown,'enable','off');
            set(app.PauseButton, 'enable','off');
            set(app.SkipToTimeEditField,'enable','off');
            set(app.OpenInitialConditionAppButton,'enable','off');
        end
        
        % This function is used to perform a clean shutdown. It manually
        % sets all GPIO pins to low and then unexports them.
        function CleanShutdown(~, Platform)
            
            try
                system(Platform,'echo 436 > /sys/class/gpio/export');
                system(Platform,'echo 445 > /sys/class/gpio/export');
                system(Platform,'echo 480 > /sys/class/gpio/export');
                system(Platform,'echo 268 > /sys/class/gpio/export');
                system(Platform,'echo 484 > /sys/class/gpio/export');
                system(Platform,'echo 483 > /sys/class/gpio/export');
                system(Platform,'echo 481 > /sys/class/gpio/export');
                system(Platform,'echo 491 > /sys/class/gpio/export');
    
                system(Platform,'echo out > /sys/class/gpio/gpio436/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio445/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio480/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio268/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio484/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio483/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio481/direction');
                system(Platform,'echo out > /sys/class/gpio/gpio491/direction');

                system(Platform,'echo 0 > /sys/class/gpio/gpio436/value');
                system(Platform,'echo 0 > /sys/class/gpio/gpio445/value');
                system(Platform,'echo 0 > /sys/class/gpio/gpio480/value');
                system(Platform,'echo 0 > /sys/class/gpio/gpio268/value');
                system(Platform,'echo 0 > /sys/class/gpio/gpio484/value');
                system(Platform,'echo 0 > /sys/class/gpio/gpio483/value');
                system(Platform,'echo 0 > /sys/class/gpio/gpio481/value');
                system(Platform,'echo 0 > /sys/class/gpio/gpio491/value');

                system(Platform,'echo 436 > /sys/class/gpio/unexport');
                system(Platform,'echo 445 > /sys/class/gpio/unexport');
                system(Platform,'echo 480 > /sys/class/gpio/unexport');
                system(Platform,'echo 268 > /sys/class/gpio/unexport');
                system(Platform,'echo 484 > /sys/class/gpio/unexport');
                system(Platform,'echo 483 > /sys/class/gpio/unexport');
                system(Platform,'echo 481 > /sys/class/gpio/unexport');
                system(Platform,'echo 491 > /sys/class/gpio/unexport');
                    

            catch

                waitfor(warndlg('Attempted to close all GPIO pins and unexport, and did not succeed. Try power cycling the Jetson.','Failed to Complete Clean Shutdown'));

            end

            
        end
        
        % This function creates a custom waitbox to force the app to pause
        % while it is processing a user request.
        function d = CustomWaitBox(app,Message)
            fig = app.SPOT41GUIUIFigure;
            d = uiprogressdlg(fig,'Title',Message,...
                'Indeterminate','on');
            drawnow
                   
        end
       
        
        function [] = UpdateTimes(app)
            % Set the duration of each major phase in the experiment, in seconds:
            
            % Set the duration of the experiment sub-phases first. Sub-phases occur during the
            % experiment phase (Phase3_Duration). 
            
            app.Phase3_SubPhase1_Duration      = app.SubPhase1EditField.Value;        % [s]
            app.Phase3_SubPhase2_Duration      = app.SubPhase2EditField.Value;        % [s]
            app.Phase3_SubPhase3_Duration      = app.SubPhase3EditField.Value;        % [s]
            app.Phase3_SubPhase4_Duration      = app.SubPhase4EditField.Value;        % [s]
            
            app.Phase0_Duration                = app.DurPhase0EditField.Value;        % [s]
            app.Phase1_Duration                = app.DurPhase1EditField.Value;         % [s]
            app.Phase2_Duration                = app.DurPhase2EditField.Value;        % [s]
            app.Phase3_Duration                = app.Phase3_SubPhase1_Duration+...
                                                 app.Phase3_SubPhase2_Duration+...
                                                 app.Phase3_SubPhase3_Duration+...
                                                 app.Phase3_SubPhase4_Duration;        % [s]
            app.Phase4_Duration                = app.DurPhase4EditField.Value;         % [s]
            app.Phase5_Duration                = app.DurPhase5EditField.Value;         % [s]
            
            
            % Calculate other time related parameters. USERS SHOULD NOT EDIT THIS.
            
            % Determine the total experiment time from the durations:
            
            app.tsim                           = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration + app.Phase3_Duration + ...
                                                    app.Phase4_Duration + app.Phase5_Duration;        
            
            % Determine the start time of each phase based on the duration:
            
            app.Phase0_End                     = app.Phase0_Duration;
            app.Phase1_End                     = app.Phase0_Duration + app.Phase1_Duration;           
            app.Phase2_End                     = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration;         
            app.Phase3_End                     = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration + app.Phase3_Duration;      
            app.Phase4_End                     = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration + app.Phase3_Duration + ...
                                                    app.Phase4_Duration; 
            app.Phase5_End                     = app.Phase0_Duration + app.Phase1_Duration + ...
                                                    app.Phase2_Duration + app.Phase3_Duration + ...
                                                    app.Phase4_Duration + app.Phase5_Duration;                              
                                         
            % Determine the start time of each sub-phase based on the duration:  
            
            app.Phase3_SubPhase1_End           = app.Phase2_End + app.Phase3_SubPhase1_Duration;
            app.Phase3_SubPhase2_End           = app.Phase2_End + app.Phase3_SubPhase1_Duration + ...
                                                    app.Phase3_SubPhase2_Duration;
            app.Phase3_SubPhase3_End           = app.Phase2_End + app.Phase3_SubPhase1_Duration + ...
                                                    app.Phase3_SubPhase2_Duration +...
                                                    app.Phase3_SubPhase3_Duration;
            app.Phase3_SubPhase4_End           = app.Phase2_End + app.Phase3_SubPhase1_Duration + ...
                                                    app.Phase3_SubPhase2_Duration +...
                                                    app.Phase3_SubPhase3_Duration +...
                                                    app.Phase3_SubPhase4_Duration;

            assignin('base','Phase0_End',app.Phase0_End);
            assignin('base','Phase1_End',app.Phase1_End);
            assignin('base','Phase2_End',app.Phase2_End);
            assignin('base','Phase3_End',app.Phase3_End);
            assignin('base','Phase4_End',app.Phase4_End);
            assignin('base','Phase5_End',app.Phase5_End);
            assignin('base','Phase3_SubPhase1_End',app.Phase3_SubPhase1_End);
            assignin('base','Phase3_SubPhase2_End',app.Phase3_SubPhase2_End);
            assignin('base','Phase3_SubPhase3_End',app.Phase3_SubPhase3_End);
            assignin('base','Phase3_SubPhase4_End',app.Phase3_SubPhase4_End);       

            assignin('base','Phase0_Duration',app.Phase0_Duration);
            assignin('base','Phase1_Duration',app.Phase1_Duration);
            assignin('base','Phase2_Duration',app.Phase2_Duration);
            assignin('base','Phase3_Duration',app.Phase3_Duration);
            assignin('base','Phase4_Duration',app.Phase4_Duration);
            assignin('base','Phase5_Duration',app.Phase5_Duration);
            assignin('base','Phase3_SubPhase1_Duration',app.Phase3_SubPhase1_Duration);
            assignin('base','Phase3_SubPhase2_Duration',app.Phase3_SubPhase2_Duration);
            assignin('base','Phase3_SubPhase3_Duration',app.Phase3_SubPhase3_Duration);
            assignin('base','Phase3_SubPhase4_Duration',app.Phase3_SubPhase4_Duration);    
            assignin('base','tsim', app.tsim);

            app.DurPhase3EditField.Value = app.Phase3_Duration;
            app.TotalTimeEditField.Value = app.tsim;

        end
        
        function [] = UpdateAuxParameters(app)

            app.Qx_RED         = app.RedQXEditField.Value;
            app.Qy_RED         = app.RedQYEditField.Value;
            app.Qtheta_RED     = app.RedQThetaEditField.Value;
            app.QxD_RED        = app.RedQXDEditField.Value; 
            app.QyD_RED        = app.RedQYDEditField.Value;
            app.QthetaD_RED    = app.RedQThetaDEditField.Value;
            app.Qx_BLACK       = app.BlackQXEditField.Value;
            app.Qy_BLACK       = app.BlackQYEditField.Value;
            app.Qtheta_BLACK   = app.BlackQThetaEditField.Value;
            app.QxD_BLACK      = app.BlackQXDEditField.Value; 
            app.QyD_BLACK      = app.BlackQYDEditField.Value;
            app.QthetaD_BLACK  = app.BlackQThetaDEditField.Value;
            app.Qx_BLUE        = app.BlueQXEditField.Value;
            app.Qy_BLUE        = app.BlueQYEditField.Value;
            app.Qtheta_BLUE    = app.BlueQThetaEditField.Value;
            app.QxD_BLUE       = app.BlueQXDEditField.Value; 
            app.QyD_BLUE       = app.BlueQYDEditField.Value;
            app.QthetaD_BLUE   = app.BlueQThetaDEditField.Value;
            app.RFx_RED        = app.RedRFXEditField.Value;
            app.RFy_RED        = app.RedRFYEditField.Value;
            app.RTz_RED        = app.RedRTzEditField.Value;
            app.RFx_BLACK      = app.BlackRFXEditField.Value; 
            app.RFy_BLACK      = app.BlackRFYEditField.Value;
            app.RTz_BLACK      = app.BlackRTzEditField.Value;
            app.RFx_BLUE       = app.BlueRFXEditField.Value;
            app.RFy_BLUE       = app.BlueRFYEditField.Value;
            app.RTz_BLUE       = app.BlueRTzEditField.Value;
           
            % Initialize baseRate 

            app.baseRate                      = 1/app.SampleRateEditField.Value;
            app.dataRate                      = 1/(app.SampleRateEditField.Value*0.5);            

            % Assign Q values
            assignin('base', 'Qx_RED', app.Qx_RED);
            assignin('base', 'Qy_RED', app.Qy_RED);
            assignin('base', 'Qtheta_RED', app.Qtheta_RED);
            assignin('base', 'QxD_RED', app.QxD_RED);
            assignin('base', 'QyD_RED', app.QyD_RED);
            assignin('base', 'QthetaD_RED', app.QthetaD_RED);
            assignin('base', 'Qx_BLACK', app.Qx_BLACK);
            assignin('base', 'Qy_BLACK', app.Qy_BLACK);
            assignin('base', 'Qtheta_BLACK', app.Qtheta_BLACK);
            assignin('base', 'QxD_BLACK', app.QxD_BLACK);
            assignin('base', 'QyD_BLACK', app.QyD_BLACK);
            assignin('base', 'QthetaD_BLACK', app.QthetaD_BLACK);
            assignin('base', 'Qx_BLUE', app.Qx_BLUE);
            assignin('base', 'Qy_BLUE', app.Qy_BLUE);
            assignin('base', 'Qtheta_BLUE', app.Qtheta_BLUE);
            assignin('base', 'QxD_BLUE', app.QxD_BLUE);
            assignin('base', 'QyD_BLUE', app.QyD_BLUE);
            assignin('base', 'QthetaD_BLUE', app.QthetaD_BLUE);
            
            % Assign R values
            assignin('base', 'RFx_RED', app.RFx_RED);
            assignin('base', 'RFy_RED', app.RFy_RED);
            assignin('base', 'RTz_RED', app.RTz_RED);
            assignin('base', 'RFx_BLACK', app.RFx_BLACK);
            assignin('base', 'RFy_BLACK', app.RFy_BLACK);
            assignin('base', 'RTz_BLACK', app.RTz_BLACK);
            assignin('base', 'RFx_BLUE', app.RFx_BLUE);
            assignin('base', 'RFy_BLUE', app.RFy_BLUE);
            assignin('base', 'RTz_BLUE', app.RTz_BLUE);

            % Update PD gains
            assignin('base','Kp_xr',app.RedPXGainEditField.Value)
            assignin('base','Kp_yr',app.RedPYGainEditField.Value)
            assignin('base','Kp_tr',app.RedPGainThetaEditField.Value)
            assignin('base','Kp_xb',app.BlackPXGainEditField.Value)
            assignin('base','Kp_yb',app.BlackPYGainEditField.Value)
            assignin('base','Kp_tb',app.BlackPGainThetaEditField.Value)
            assignin('base','Kp_xblue',app.BluePXGainEditField.Value)
            assignin('base','Kp_yblue',app.BluePYGainEditField.Value)
            assignin('base','Kp_tblue',app.BluePGainThetaEditField.Value)
            assignin('base','Kd_xr',app.RedDXGainEditField.Value)
            assignin('base','Kd_yr',app.RedDYGainEditField.Value)            
            assignin('base','Kd_tr',app.RedDGainThetaEditField.Value)            
            assignin('base','Kd_xb',app.BlackDXGainEditField.Value)  
            assignin('base','Kd_yb',app.BlackDYGainEditField.Value)
            assignin('base','Kd_tb',app.BlackDGainThetaEditField.Value)
            assignin('base','Kd_xblue',app.BlueDXGainEditField.Value)       
            assignin('base','Kd_yblue',app.BlueDYGainEditField.Value)  
            assignin('base','Kd_tblue',app.BlueDGainThetaEditField.Value) 

            assignin('base','F_red_X_nominal',app.F_red_X_nominal);
            assignin('base','F_red_Y_nominal',app.F_red_Y_nominal);
            assignin('base','F_black_X_nominal',app.F_black_X_nominal);
            assignin('base','F_black_Y_nominal',app.F_black_Y_nominal);
            assignin('base','F_blue_X_nominal',app.F_blue_X_nominal);
            assignin('base','F_blue_Y_nominal',app.F_blue_Y_nominal);
            assignin('base','F_thrusters_RED',app.F_thrusters_RED);
            assignin('base','F_thrusters_BLACK',app.F_thrusters_BLACK);
            assignin('base','F_thrusters_BLUE',app.F_thrusters_BLUE);
            assignin('base','baseRate',app.baseRate);
            assignin('base','dataRate',app.dataRate);

        end

        function [] = UpdateSimulationParameters(app, FileName)

            app.varStepMaxStepSize = app.VarStepMaxStepSizeEditField.Value;
            app.varStepMinStepSize = app.VarStepMinStepSizeEditField.Value;
            app.varStepInitStepSize = app.VarStepInitStepSizeEditField.Value;
            app.varStepRelTol = app.VarStepRelTolEditField.Value;
            app.varStepAbsTol = app.VarStepAbsTolEditField.Value;

            if app.SolverTypeDropDown.Value == "Variable-step"
                set_param(FileName,"MaxStep",app.varStepMaxStepSize);
                set_param(FileName,"MinStep",app.varStepMinStepSize);
                set_param(FileName,"InitialStep",app.varStepInitStepSize);
                set_param(FileName,"AbsTol",app.varStepAbsTol);
                set_param(FileName,"RelTol",app.varStepRelTol);
            end

        end        
        
        function [] = ConfirmSettings(app)

            FileName = app.AvailableDiagramsDropDown.Value;

            if strcmp(FileName,'Select a Diagram') == 0
                          
                % Based on user selection in the GUI, assign variables to the workspace:
                ErrorMsg    = 0;
                
                % Assign the results to the platformSelection variable:        
                if app.REDCheckBox.Value == 1 && app.BLACKCheckBox.Value == 0 && app.BLUECheckBox.Value == 0 && app.ARMCheckBox.Value == 0
                    assignin("base","platformSelection",1);     % RED
                elseif app.REDCheckBox.Value == 0 && app.BLACKCheckBox.Value == 1 && app.BLUECheckBox.Value == 0 && app.ARMCheckBox.Value == 0
                    assignin("base","platformSelection",2);     % BLACK
                elseif app.REDCheckBox.Value == 1 && app.BLACKCheckBox.Value == 1 && app.BLUECheckBox.Value == 0 && app.ARMCheckBox.Value == 0
                    assignin("base","platformSelection",3);     % RED + BLACK
                elseif app.REDCheckBox.Value == 1 && app.BLACKCheckBox.Value == 1 && app.BLUECheckBox.Value == 0 && app.ARMCheckBox.Value == 1
                    assignin("base","platformSelection",4);     % RED + BLACK + ARM
                elseif app.REDCheckBox.Value == 1 && app.BLACKCheckBox.Value == 0 && app.BLUECheckBox.Value == 0 && app.ARMCheckBox.Value == 1
                    assignin("base","platformSelection",5);     % RED + ARM
                elseif app.REDCheckBox.Value == 0 && app.BLACKCheckBox.Value == 0 && app.BLUECheckBox.Value == 1 && app.ARMCheckBox.Value == 0
                    assignin("base","platformSelection",6);     % BLUE
                elseif app.REDCheckBox.Value == 1 && app.BLACKCheckBox.Value == 0 && app.BLUECheckBox.Value == 1 && app.ARMCheckBox.Value == 0
                    assignin("base","platformSelection",7);     % BLUE + RED
                elseif app.REDCheckBox.Value == 1 && app.BLACKCheckBox.Value == 1 && app.BLUECheckBox.Value == 1 && app.ARMCheckBox.Value == 0
                    assignin("base","platformSelection",8);     % BLUE + RED + BLACK
                elseif app.REDCheckBox.Value == 0 && app.BLACKCheckBox.Value == 1 && app.BLUECheckBox.Value == 1 && app.ARMCheckBox.Value == 0
                    assignin("base","platformSelection",9);     % BLUE + BLACK
                elseif app.REDCheckBox.Value == 1 && app.BLACKCheckBox.Value == 0 && app.BLUECheckBox.Value == 1 && app.ARMCheckBox.Value == 1
                    assignin("base","platformSelection",10);    % BLUE + RED + ARM
                elseif app.REDCheckBox.Value == 1 && app.BLACKCheckBox.Value == 1 && app.BLUECheckBox.Value == 1 && app.ARMCheckBox.Value == 1
                    assignin("base","platformSelection",11);    % BLUE + RED + BLACK + ARM
                elseif app.REDCheckBox.Value == 0 && app.BLACKCheckBox.Value == 0 && app.BLUECheckBox.Value == 0 && app.ARMCheckBox.Value == 0
                    assignin("base","platformSelection",0);    % Nothing
                else
                    ed = errordlg("The combination of platforms selected has not been implemented yet.","Error");
                    set(ed, "WindowStyle", "modal");
                    uiwait(ed);
                    app.REDCheckBox.Value = 0;
                    app.BLACKCheckBox.Value = 0;
                    app.BLUECheckBox.Value = 0;
                    app.ARMCheckBox.Value = 0;
                    ErrorMsg = 1;
                end
                
                if (ErrorMsg~=1)
                    platformSelection = evalin("base","platformSelection");
                    if platformSelection ~= 0
                        set(app.OpenInitialConditionAppButton,'enable','on');
                        AddPlatformSim(app,platformSelection);
                        AddPlatformLive(app,platformSelection);
                        set(app.RunSimButton,'enable','on');
                        set(app.Step9EstablishConnectionwithSelectedPlatformsButton,'enable','on');
                        set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','on');
                        app.AnimationScrubberSlider.MajorTicks = [0,20,40,60,80,100];
                        app.AnimationScrubberSlider.MajorTickLabels = {'0 s',...
                            [num2str(0.2*app.tsim) ' s'],[num2str(0.4*app.tsim) ' s'],...
                            [num2str(0.6*app.tsim) ' s'],[num2str(0.8*app.tsim) ' s'],[num2str(app.tsim) ' s']};
                        set(app.SolverNameVarStepDropDown,'enable','on');
                        set(app.SolverNameDropDown,'enable','on');
                        set(app.SolverTypeDropDown,'enable','on');
                        set(app.VarStepAbsTolEditField,'enable','on');
                        set(app.VarStepRelTolEditField,'enable','on');
                        set(app.VarStepInitStepSizeEditField,'enable','on');
                        set(app.VarStepMinStepSizeEditField,'enable','on');
                        set(app.VarStepMaxStepSizeEditField,'enable','on');
            
                        SolverName = get_param(FileName(1:end-4),"SolverName");
                        SolverType = get_param(FileName(1:end-4),"SolverType");
                        
                        app.SolverTypeDropDown.Value = SolverType;
            
                        if SolverType == "Variable-step"
                            app.SolverNameVarStepDropDown.Enable = "on";
                            app.SolverNameVarStepDropDown.Visible = "on";
                            app.SolverNameDropDown.Enable = "off";
                            app.SolverNameDropDown.Visible = "off";
                            app.SolverNameVarStepDropDown.Value = SolverName;
                            MaxStep = get_param(FileName(1:end-4),"MaxStep");
                            MinStep = get_param(FileName(1:end-4),"MinStep");
                            InitStep = get_param(FileName(1:end-4),"InitialStep");
                            AbsTol = get_param(FileName(1:end-4),"AbsTol");
                            RelTol = get_param(FileName(1:end-4),"RelTol");
                            app.VarStepMaxStepSizeEditField.Value = MaxStep;
                            app.VarStepMinStepSizeEditField.Value = MinStep;
                            app.VarStepInitStepSizeEditField.Value = InitStep;
                            app.VarStepAbsTolEditField.Value = AbsTol;
                            app.VarStepRelTolEditField.Value = RelTol;
                            set(app.VarStepAbsTolEditField,'enable','on');
                            set(app.VarStepRelTolEditField,'enable','on');
                            set(app.VarStepInitStepSizeEditField,'enable','on');
                            set(app.VarStepMinStepSizeEditField,'enable','on');
                            set(app.VarStepMaxStepSizeEditField,'enable','on');
                        else
                            app.SolverNameVarStepDropDown.Enable = "off";
                            app.SolverNameVarStepDropDown.Visible = "off";
                            app.SolverNameDropDown.Enable = "on";
                            app.SolverNameDropDown.Visible = "on";
                            app.SolverNameDropDown.Value = SolverName;
                            set(app.VarStepAbsTolEditField,'enable','off');
                            set(app.VarStepRelTolEditField,'enable','off');
                            set(app.VarStepInitStepSizeEditField,'enable','off');
                            set(app.VarStepMinStepSizeEditField,'enable','off');
                            set(app.VarStepMaxStepSizeEditField,'enable','off');
                        end
                        
                        app.UpdateSimulationParameters(FileName(1:end-4));
                    else
                        set(app.OpenInitialConditionAppButton,'enable','off');
                        AddPlatformSim(app,platformSelection);
                        AddPlatformLive(app,platformSelection);
                        set(app.RunSimButton,'enable','off');
                        set(app.Step9EstablishConnectionwithSelectedPlatformsButton,'enable','off');
                        set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');  
                        app.SolverNameVarStepDropDown.Enable = "off";
                        app.SolverNameVarStepDropDown.Visible = "off";
                        app.SolverNameDropDown.Enable = "off";
                        app.SolverNameDropDown.Visible = "on";
                        app.SolverTypeDropDown.Enable = "off";
                        set(app.VarStepAbsTolEditField,'enable','off');
                        set(app.VarStepRelTolEditField,'enable','off');
                        set(app.VarStepInitStepSizeEditField,'enable','off');
                        set(app.VarStepMinStepSizeEditField,'enable','off');
                        set(app.VarStepMaxStepSizeEditField,'enable','off');
                    end
                else
                    set(app.OpenInitialConditionAppButton,'enable','off');
                    set(app.RunSimButton,'enable','off');
                    set(app.Step9EstablishConnectionwithSelectedPlatformsButton,'enable','off');
                    set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');  
                    app.SolverNameVarStepDropDown.Enable = "off";
                    app.SolverNameVarStepDropDown.Visible = "off";
                    app.SolverNameDropDown.Enable = "off";
                    app.SolverNameDropDown.Visible = "on";
                    app.SolverTypeDropDown.Enable = "off";
                    set(app.VarStepAbsTolEditField,'enable','off');
                    set(app.VarStepRelTolEditField,'enable','off');
                    set(app.VarStepInitStepSizeEditField,'enable','off');
                    set(app.VarStepMinStepSizeEditField,'enable','off');
                    set(app.VarStepMaxStepSizeEditField,'enable','off');
                end            
            end

        end

        function [Derivs, Integr] = CheckForContinuousBlocks(app)

            FileName = app.AvailableDiagramsDropDown.Value;
            
            % Get a list of all blocks in the model
            blocks = find_system(FileName(1:end-4), 'FindAll', 'on', 'Type', 'Block');
            
            % Store the types of the blocks
            for i = 1:length(blocks)
                store(i) = string(get_param(blocks(i), 'BlockType')); %#ok<AGROW>
            end

            % Check if the word "Integrator" or "Derivative" is in the list
            Derivs = find(store=="Derivatives");
            Integr = find(store=="Integrator");

        end
       
        

        function [] = RunSimulationPublicFcn(app)

            app.RunSimButtonPushed();
            
        end
        
        function [] = LoadDataPublicFcn(app, pathname, filename)

            waitbox = app.CustomWaitBox('Loading GUI Parameters...');

            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else

                % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'appState');
                    
                    % Get the list of properties from the appState struct
                    properties = fieldnames(appState);
                catch
                    ed = errordlg("The file you just tried to load does not contain the variable 'appState'. Confirm you are loading a proper GUI state file.","Error");
                    set(ed, "WindowStyle", "modal");
                    uiwait(ed);
                    return
                end

                 % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'subAppState');
                    
                    % Get the list of properties from the appState struct
                    propertiesSubApp = fieldnames(subAppState);
                catch
                    subAppState = struct();
                    propertiesSubApp = fieldnames(subAppState);
                end

                 % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'subAppStateInit');
                    
                    % Get the list of properties from the appState struct
                    propertiesSubAppInit = fieldnames(subAppStateInit);
                catch
                    subAppStateInit = struct();
                    propertiesSubAppInit = fieldnames(subAppStateInit);
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(properties)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.(properties{i}).Value = appState.(properties{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(propertiesSubApp)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.SubAppMassProperties.(propertiesSubApp{i}).Value = subAppState.(propertiesSubApp{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(propertiesSubAppInit)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.SubAppInitialConditions.(propertiesSubAppInit{i}).Value = subAppStateInit.(propertiesSubAppInit{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end
                
                % Ensure that when you load any saved state, it sets the
                % diagram to default to avoid problems.
                app.AvailableDiagramsDropDown.Value = "Select a Diagram";

                app.AvailableDiagramsDropDownValueChanged();
                app.UpdateTimes();
                app.UpdateAuxParameters();
                app.UpdateSimulationParameters();
                app.SubAppMassProperties.UpdateMassProperties();
                app.SubAppInitialConditions.UpdateInitialConditions();
                app.REDCheckBoxValueChanged();
                app.BLACKCheckBoxValueChanged();
                app.BLUECheckBoxValueChanged();
                app.ARMCheckBoxValueChanged();

                close(waitbox);
            end            
        end

    end
    
    methods (Access = private)
        
        % function checkExecutableStatus(app, src, event, app)
        %     % src: Timer object
        %     % event: Event data (not used here)
        %     % app: Reference to your app object
        % 
        %     % Check if the executable is still running
        %     [~, result] = system('tasklist /FI "IMAGENAME eq StreamData.exe"');
        %     if contains(result, 'No tasks are running')
        %         % Executable has stopped, handle accordingly
        %         stop(src);
        %         delete(src);
        %         % Display error or update app state
        %         disp('Executable has stopped.');
        %         % ... additional error handling ...
        %     end
        % end
        
        function [] = CalculateStateSpaceMatrices(app)

            app.mRED = evalin('base','mRED');
            app.IRED = evalin('base','IRED');
            app.mBLACK = evalin('base','mBLACK');
            app.IBLACK = evalin('base','IBLACK');
            app.mBLUE = evalin('base','mBLUE');
            app.IBLUE = evalin('base','IBLUE');

            % The A, C, and D matrices will always be the same for all 
            % platforms.
            A = [0 0 0 1 0 0
                 0 0 0 0 1 0
                 0 0 0 0 0 1
                 0 0 0 0 0 0
                 0 0 0 0 0 0
                 0 0 0 0 0 0];

            C = eye(6);
            D = zeros(6,3);
            
            % The B matrix needs to be calculated
            B_RED = [0 0 0
                     0 0 0
                     0 0 0
                     1/app.mRED 0 0
                     0 1/app.mRED 0
                     0 0 1/app.IRED];

            B_BLACK = [0 0 0
                     0 0 0
                     0 0 0
                     1/app.mBLACK 0 0
                     0 1/app.mBLACK 0
                     0 0 1/app.IBLACK];
            
            B_BLUE = [0 0 0
                     0 0 0
                     0 0 0
                     1/app.mBLUE 0 0
                     0 1/app.mBLUE 0
                     0 0 1/app.IBLUE];
            
            % Use the ss function to get the continuous time state-space
            % models
            sys_RED      = ss(A,B_RED,C,D);
            sys_BLACK    = ss(A,B_BLACK,C,D);
            sys_BLUE     = ss(A,B_BLUE,C,D);
            
            % Convert the continuous ss models to discrete time
            sys_disc_RED = c2d(sys_RED, app.dataRate, 'zoh');
            sys_disc_BLACK = c2d(sys_BLACK, app.dataRate, 'zoh');
            sys_disc_BLUE = c2d(sys_BLUE, app.dataRate, 'zoh');
            
            % Extract the discrete state space matrices
            app.A_d_RED = sys_disc_RED.A;
            app.B_d_RED = sys_disc_RED.B;
            app.C_d_RED = sys_disc_RED.C;
            app.D_d_RED = sys_disc_RED.D;    

            app.A_d_BLACK = sys_disc_BLACK.A;
            app.B_d_BLACK = sys_disc_BLACK.B;
            app.C_d_BLACK = sys_disc_BLACK.C;
            app.D_d_BLACK = sys_disc_BLACK.D;    

            app.A_d_BLUE = sys_disc_BLUE.A;
            app.B_d_BLUE = sys_disc_BLUE.B;
            app.C_d_BLUE = sys_disc_BLUE.C;
            app.D_d_BLUE = sys_disc_BLUE.D;    

            assignin('base','A_d_RED',app.A_d_RED);
            assignin('base','B_d_RED',app.B_d_RED);
            assignin('base','C_d_RED',app.C_d_RED);
            assignin('base','D_d_RED',app.D_d_RED);

            assignin('base','A_d_BLACK',app.A_d_BLACK);
            assignin('base','B_d_BLACK',app.B_d_BLACK);
            assignin('base','C_d_BLACK',app.C_d_BLACK);
            assignin('base','D_d_BLACK',app.D_d_BLACK);

            assignin('base','A_d_BLUE',app.A_d_BLUE);
            assignin('base','B_d_BLUE',app.B_d_BLUE);
            assignin('base','C_d_BLUE',app.C_d_BLUE);
            assignin('base','D_d_BLUE',app.D_d_BLUE);
        end
        
        function [] = CalculateLQRGains(app)

            app.CalculateStateSpaceMatrices();
    
            % Retrieve Q values
            app.Qx_RED        = evalin('base', 'Qx_RED');
            app.Qy_RED        = evalin('base', 'Qy_RED');
            app.Qtheta_RED    = evalin('base', 'Qtheta_RED');
            app.QxD_RED       = evalin('base', 'QxD_RED');
            app.QyD_RED       = evalin('base', 'QyD_RED');
            app.QthetaD_RED   = evalin('base', 'QthetaD_RED');
            app.Qx_BLACK      = evalin('base', 'Qx_BLACK');
            app.Qy_BLACK      = evalin('base', 'Qy_BLACK');
            app.Qtheta_BLACK  = evalin('base', 'Qtheta_BLACK');
            app.QxD_BLACK     = evalin('base', 'QxD_BLACK');
            app.QyD_BLACK     = evalin('base', 'QyD_BLACK');
            app.QthetaD_BLACK = evalin('base', 'QthetaD_BLACK');
            app.Qx_BLUE       = evalin('base', 'Qx_BLUE');
            app.Qy_BLUE       = evalin('base', 'Qy_BLUE');
            app.Qtheta_BLUE   = evalin('base', 'Qtheta_BLUE');
            app.QxD_BLUE      = evalin('base', 'QxD_BLUE');
            app.QyD_BLUE      = evalin('base', 'QyD_BLUE');
            app.QthetaD_BLUE  = evalin('base', 'QthetaD_BLUE');
            
            % Retrieve R values
            app.RFx_RED       = evalin('base', 'RFx_RED');
            app.RFy_RED       = evalin('base', 'RFy_RED');
            app.RTz_RED       = evalin('base', 'RTz_RED');
            app.RFx_BLACK     = evalin('base', 'RFx_BLACK');
            app.RFy_BLACK     = evalin('base', 'RFy_BLACK');
            app.RTz_BLACK     = evalin('base', 'RTz_BLACK');
            app.RFx_BLUE      = evalin('base', 'RFx_BLUE');
            app.RFy_BLUE      = evalin('base', 'RFy_BLUE');
            app.RTz_BLUE      = evalin('base', 'RTz_BLUE');

            % Define R matrices
            R_d_RED = diag([app.RFx_RED, app.RFy_RED, app.RTz_RED]);
            R_d_BLACK = diag([app.RFx_BLACK, app.RFy_BLACK, app.RTz_BLACK]);
            R_d_BLUE = diag([app.RFx_BLUE, app.RFy_BLUE, app.RTz_BLUE]);
            
            % Calculate K_RED
            Q_d_RED = [app.Qx_RED 0 0 0 0 0
                       0 app.Qy_RED 0 0 0 0
                       0 0 app.Qtheta_RED 0 0 0
                       0 0 0 app.QxD_RED 0 0
                       0 0 0 0 app.QyD_RED 0
                       0 0 0 0 0 app.QthetaD_RED];
            K_RED = dlqr(app.A_d_RED, app.B_d_RED, Q_d_RED, R_d_RED);
            
            % Calculate K_BLACK
            Q_d_BLACK = [app.Qx_BLACK 0 0 0 0 0
                         0 app.Qy_BLACK 0 0 0 0
                         0 0 app.Qtheta_BLACK 0 0 0
                         0 0 0 app.QxD_BLACK 0 0
                         0 0 0 0 app.QyD_BLACK 0
                         0 0 0 0 0 app.QthetaD_BLACK];
            K_BLACK = dlqr(app.A_d_BLACK, app.B_d_BLACK, Q_d_BLACK, R_d_BLACK);
            
            % Calculate K_BLUE
            Q_d_BLUE = [app.Qx_BLUE 0 0 0 0 0
                        0 app.Qy_BLUE 0 0 0 0
                        0 0 app.Qtheta_BLUE 0 0 0
                        0 0 0 app.QxD_BLUE 0 0
                        0 0 0 0 app.QyD_BLUE 0
                        0 0 0 0 0 app.QthetaD_BLUE];
            K_BLUE = dlqr(app.A_d_BLUE, app.B_d_BLUE, Q_d_BLUE, R_d_BLUE);

            assignin('base','K_BLUE',K_BLUE);
            assignin('base','K_BLACK',K_BLACK);
            assignin('base','K_RED',K_RED);


        end
        
        function [] = CheckAnimationSelections(app)

            app.CreateVideoChecked = 0;
            app.ShowPathTraceRED   = 0;
            app.ShowPathTraceBLACK = 0;
            app.ShowPathTraceBLUE  = 0;
            app.ShowDockingConeRED = 0;
            app.ShowSolarPanelsBLACK = 0;
            app.ShowDockingPortBLACK = 0;
            for i = 1:length(app.AnimationTree.CheckedNodes)
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text, 'Create Video')
                    app.CreateVideoChecked = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'RED') 
                    app.ShowPathTraceRED = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Docking Cone') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'RED') 
                    app.ShowDockingConeRED = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'BLACK') 
                    app.ShowPathTraceBLACK = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Solar Panels') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'BLACK') 
                    app.ShowSolarPanelsBLACK = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Docking Port') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'BLACK') 
                    app.ShowDockingPortBLACK = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'BLUE') 
                    app.ShowPathTraceBLUE = 1;
                end
            
            end            
        end

        function [] = CheckAnimationSelectionsExp(app)

            %app.ShowPathTraceRED   = 0;
            %app.ShowPathTraceBLACK = 0;
            %app.ShowPathTraceBLUE  = 0;
            app.ShowDockingConeRED = 0;
            app.ShowSolarPanelsBLACK = 0;
            app.ShowDockingPortBLACK = 0;
            for i = 1:length(app.AnimationTree.CheckedNodes)
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text, 'Create Video')
                    app.CreateVideoChecked = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'RED') 
                    app.ShowPathTraceRED = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Docking Cone') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'RED') 
                    app.ShowDockingConeRED = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'BLACK') 
                    app.ShowPathTraceBLACK = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Solar Panels') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'BLACK') 
                    app.ShowSolarPanelsBLACK = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Docking Port') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'BLACK') 
                    app.ShowDockingPortBLACK = 1;
                end
            
                if strcmp(app.AnimationTree.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTree.CheckedNodes(i).Parent.Text,'BLUE') 
                    app.ShowPathTraceBLUE = 1;
                end
            
            end            
        end

        function [] = CheckAnimationSelectionsLive(app)

            %app.ShowPathTraceRED   = 0;
            %app.ShowPathTraceBLACK = 0;
            %app.ShowPathTraceBLUE  = 0;
            app.ShowDockingConeREDLive = 0;
            app.ShowSolarPanelsBLACKLive = 0;
            app.ShowDockingPortBLACKLive = 0;

            for i = 1:length(app.AnimationTreeExp.CheckedNodes)
                        
                % if strcmp(app.AnimationTreeExp.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTreeExp.CheckedNodes(i).Parent.Text,'RED') 
                %     app.ShowPathTraceREDLive = 1;
                % end
            
                if strcmp(app.AnimationTreeExp.CheckedNodes(i).Text,'Show Docking Cone') & strcmp(app.AnimationTreeExp.CheckedNodes(i).Parent.Text,'RED') 
                    app.ShowDockingConeREDLive = 1;
                end
            
                % if strcmp(app.AnimationTreeExp.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTreeExp.CheckedNodes(i).Parent.Text,'BLACK') 
                %     app.ShowPathTraceBLACKLive = 1;
                % end
            
                if strcmp(app.AnimationTreeExp.CheckedNodes(i).Text,'Show Solar Panels') & strcmp(app.AnimationTreeExp.CheckedNodes(i).Parent.Text,'BLACK') 
                    app.ShowSolarPanelsBLACKLive = 1;
                end
            
                if strcmp(app.AnimationTreeExp.CheckedNodes(i).Text,'Show Dock') & strcmp(app.AnimationTreeExp.CheckedNodes(i).Parent.Text,'BLACK') 
                    app.ShowDockingPortBLACKLive = 1;
                end
            
                % if strcmp(app.AnimationTreeExp.CheckedNodes(i).Text,'Show Path Trace') & strcmp(app.AnimationTreeExp.CheckedNodes(i).Parent.Text,'BLUE') 
                %     app.ShowPathTraceBLUELive = 1;
                % end
            
            end            
        end
        
        function [] = UpdateNominalForces(app)
            % Update the nominal force values

            app.F_red_X_nominal                = evalin('base','REDFXNominal');
            app.F_red_Y_nominal                = evalin('base','REDFYNominal');
            app.F_thrusters_RED                = [app.F_red_X_nominal; app.F_red_X_nominal;...
                            app.F_red_Y_nominal; app.F_red_Y_nominal; app.F_red_X_nominal; ...
                            app.F_red_X_nominal; app.F_red_Y_nominal; app.F_red_Y_nominal];      
            app.F_black_X_nominal              = evalin('base','BLACKFXNominal');
            app.F_black_Y_nominal              = evalin('base','BLACKFYNominal');
            app.F_thrusters_BLACK              = [app.F_black_X_nominal; app.F_black_X_nominal;...
                            app.F_black_Y_nominal; app.F_black_Y_nominal; app.F_black_X_nominal; ...
                            app.F_black_X_nominal; app.F_black_Y_nominal; app.F_black_Y_nominal];   
            app.F_blue_X_nominal               = evalin('base','BLUEFXNominal');
            app.F_blue_Y_nominal               = evalin('base','BLUEFYNominal'); 
            app.F_thrusters_BLUE               = [app.F_blue_X_nominal; app.F_blue_X_nominal;...
                            app.F_blue_Y_nominal; app.F_blue_Y_nominal; app.F_blue_X_nominal; ...
                            app.F_blue_X_nominal; app.F_blue_Y_nominal; app.F_blue_Y_nominal];

            assignin('base','F_red_X_nominal',app.F_red_X_nominal);
            assignin('base','F_red_Y_nominal',app.F_red_Y_nominal);
            assignin('base','F_black_X_nominal',app.F_black_X_nominal);
            assignin('base','F_black_Y_nominal',app.F_black_Y_nominal);
            assignin('base','F_blue_X_nominal',app.F_blue_X_nominal);
            assignin('base','F_blue_Y_nominal',app.F_blue_Y_nominal);
            assignin('base','F_thrusters_RED',app.F_thrusters_RED);
            assignin('base','F_thrusters_BLACK',app.F_thrusters_BLACK);
            assignin('base','F_thrusters_BLUE',app.F_thrusters_BLUE);
        end
        
        function [] = UpdateSSHInformation(app)

            app.RED_SSH_Address = '192.168.1.110';
            app.BLACK_SSH_Address = '192.168.1.111';
            app.BLUE_SSH_Address = '192.168.1.112';
    
            app.RED_SSH_Name = 'spot-red';
            app.BLUE_SSH_Name = 'spot-blue';
            app.BLACK_SSH_Name = 'spot-black';
    
            app.RED_SSH_Password = 'srcl2023';
            app.BLACK_SSH_Password = 'srcl2023';
            app.BLUE_SSH_Password = 'srcl2023';
            
        end
        
        function submitCallbackUpdateSSHRED(app, txt1, txt2, txt3)

            % Retrieve the inputs from the text boxes
            app.RED_SSH_Address = txt1.Value;
            app.RED_SSH_Name  = txt2.Value;
            app.RED_SSH_Password  = txt3.Value;
            
           
            % Close the dialog box after submission
            uifigureHandle = txt1.Parent;
            close(uifigureHandle);
        end

        function submitCallbackUpdateSSHBLACK(app, txt1, txt2, txt3)

            % Retrieve the inputs from the text boxes
            app.BLACK_SSH_Address = txt1.Value;
            app.BLACK_SSH_Name  = txt2.Value;
            app.BLACK_SSH_Password  = txt3.Value;
            
           
            % Close the dialog box after submission
            uifigureHandle = txt1.Parent;
            close(uifigureHandle);
        end

        function submitCallbackUpdateSSHBLUE(app, txt1, txt2, txt3)

            % Retrieve the inputs from the text boxes
            app.BLACK_SSH_Address = txt1.Value;
            app.BLACK_SSH_Name  = txt2.Value;
            app.BLACK_SSH_Password  = txt3.Value;
            
           
            % Close the dialog box after submission
            uifigureHandle = txt1.Parent;
            close(uifigureHandle);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PopulateGUI(app)
            
            % Find any instances of the main app already open and close
            % them
            
            f = findall(groot,'Type','figure','Name','SPOT 4.1 GUI');
            close(f(2:end))

            evalin('base','clear');
            evalin('base','clc');

            VersionData = ver;
            app.MATLABVer = strrep(VersionData(1).Release,')','');
            app.MATLABVer = strrep(app.MATLABVer,'(','');
            if (strcmp(VersionData(1).Release,'(R2023a)') == 0 ) && (strcmp(VersionData(1).Release,'(R2023b)') == 0 ) && (strcmp(VersionData(1).Release,'(R2024a)') == 0 && strcmp(VersionData(1).Release,'(R2024b)') == 0 )
                 waitfor(errordlg({'This version (4.1) of the GUI was built to work in MATLAB 2023a, 2023b, 2024a, and 2024b. Backwards compatibility is possible, but things may not work as expected.',...
                     'Please use the correct version of MALTAB if possible.'},'Version Error','Understood'));
            end

            % Try and add the custom library
            try
                addpath(genpath('../../Custom_Library'));
            catch
                waitfor(warndlg('Adding the custom library to the path failed. You may have to add the directory manually (under Tools).','Failed to Add Path'));
            end

            app.SubAppMassProperties = GUI_v4_1_MassProperties;     
            app.SubAppInitialConditions = GUI_v4_1_InitialConditions;   
            app.SubAppMassProperties.SPOT41MassPropertiesUIFigure.Visible = "off";
            app.SubAppInitialConditions.SPOT41InitialConditionsUIFigure.Visible = "off";

            app.SolverNameVarStepDropDown.Enable = "off";
            app.SolverNameVarStepDropDown.Visible = "off";
                
            % Obtain a list of all .slx files present in the working directory, and populate the dropdown menu:
            MyFolderInfo = dir([cd '' filesep '*.slx']);
            FileNames    = {'Select a Diagram',MyFolderInfo.name};
            app.AvailableDiagramsDropDown.Items = FileNames;
            
            app.ResetGUIParameterstoDefaultMenuSelected();

            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreview,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreview.XAxisLocation = "top";
            app.ExperimentPreview.XLim          = [-0.65 3];
            app.ExperimentPreview.YLim          = [-0.5 4];
            
            % Plot the table:
            app.DrawTable(app.ExperimentPreview);

            % Flip the axis to match the table:
            axis(app.ExperimentPreviewLive,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreviewLive.XAxisLocation = "top";
            app.ExperimentPreviewLive.XLim          = [-0.65 3];
            app.ExperimentPreviewLive.YLim          = [-0.5 4];
            
            % Plot the table:
            app.DrawTable(app.ExperimentPreviewLive);       

                        % Add a patch
            patch(app.ExperimentPreviewLive, [0, app.yLength, app.yLength, 0], ...
                                        [0, 0, app.xLength, app.xLength], ...
                                        'black', 'FaceAlpha', 0.05);
            
            % Assign the default value to be 0; this only changes temporarily when the
            % user clicks on the Run Simulation button.
            assignin("base","simMode",1);      
            assignin("base","xLength",app.xLength);
            assignin("base","yLength",app.yLength);

            % Calculate the optimal LQR gains so that there are initial
            % parameters in the workspace
            app.CalculateLQRGains();

            % Set the IP addresses for the platforms
            app.UpdateSSHInformation();

            % % Create a timer
            % app.myTimer = timer;
            % 
            % % Set the timer interval to 10 seconds
            % app.myTimer.Period = 10;
            % 
            % % Set the execution mode to fixed rate
            % app.myTimer.ExecutionMode = 'fixedRate';
            % 
            % % Define the callback function
            % app.myTimer.TimerFcn = @(~,~) app.pingHardware();
            % 
            % % Set appropriate enables and disables:
            % app.DisableAllKeyButtons();
            
        end

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)
        % Get the selected files from the drop down menu:
        waitbox = app.CustomWaitBox('Running simulation...');
        selectedFile = get(app.AvailableDiagramsDropDown,'Value');

        % Start by updating the K gains for the LQR controller:
        app.CalculateLQRGains();
        app.UpdateNominalForces();
        
        if (bdIsLoaded(selectedFile(1:(end-4)))==1)
                  
            % Set the simulation mode in Simulink:
            set_param(selectedFile(1:(end-4)),'SimulationMode','normal');
        
            % Disable hardware mode:
            activeConfigObj = getActiveConfigSet(selectedFile(1:(end-4)));
            set_param(activeConfigObj,'HardwareBoard','None');

            app.UpdateSimulationParameters(selectedFile(1:end-4));

            % Set the simMode to 1 to indicate that a simulation is being
            % performed:
            assignin('base','simMode',1);
            assignin('base','WhoAmI',0);
                
            % Open a dialog indicating tht a simulation is being performed:
                       
            options = simset('SrcWorkspace','base','DstWorkspace','base');
            if strcmp(get_param(selectedFile(1:(end-4)),'Dirty'),'on')
            	save_system(selectedFile(1:(end-4)));
            end
            simOut = sim(selectedFile(1:(end-4)),[],options);
            %set_param(selectedFile(1:(end-4)),'SimulationCommand','start')
            assignin('base','simOut',simOut);
            %SimStatus = "running";
            %while SimStatus ~= "stopped"
            %    SimStatus = get_param(selectedFile(1:(end-4)),'SimulationStatus');
                %drawnow;
            %end
            
            close(waitbox);

            set_param(activeConfigObj,'HardwareBoard','NVIDIA Jetson');
            
            assignin('base','simMode',0);
                
            set(app.PlayButton,'enable','on');
            set(app.StopButton,'enable','off');
            set(app.AnimationTree,'enable','on');
            set(app.SaveDataButton,'enable','on');
            set(app.AnimationSpeedSlider,'enable','on');
            set(app.AnimationScrubberSlider,'enable','on');
            set(app.SkipToTimeEditField,'enable','on');
            
            %simOut = evalin("base","out");
            dataClass = simOut.dataPacket;
            assignin('base','dataClass',dataClass);
            app.DataClass = dataClass;

            % Get the field names
            fields = fieldnames(dataClass);
            
            % Create empty packet
            dataPacket = [];
            
            % Convert structure to array
            for i = 1:length(fields)
                dataPacket = horzcat(dataPacket,dataClass.(fields{i}).Data); %#ok<AGROW>
            end

            assignin('base','dataPacket',dataPacket);

            % Downsample the data to reduce the number of points:
            [UniqueTime, UniqueIndex, ~] = unique(dataPacket(:,1));

            NewTimeSlow = linspace(0,UniqueTime(end),UniqueTime(end)*(app.AnimationSpeedSlider.Value/(round(0.1125*(1/app.baseRate)-1.25))+1))';

            app.DataPacket = zeros(length(NewTimeSlow),size(dataPacket,2));
            
            for j = 1:size(dataPacket,2)
                app.DataPacket(:,j) = interp1(UniqueTime,dataPacket(UniqueIndex,j),NewTimeSlow);       
            end
            
        else
            ed = errordlg("The selected Simulink diagram has not been loaded. Try selecting it again from the dropdown.","Error");
            set(ed, "WindowStyle", "modal");
            uiwait(ed);            
        end
        
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            W = evalin("base","whos"); 
            doesPacketExist = ismember('dataPacket',[W(:).name]);

            app.PlayButton.Enable = "off";
            app.SaveDataButton.Enable = "off";
            app.AnimationScrubberSlider.Enable = "off";
            app.AnimationSpeedSlider.Enable = "off";
            app.SkipToTimeEditField.Enable = "off";
            app.PauseButton.Enable = 'on';
            app.StopButton.Enable = "on";

            CheckAnimationSelections(app);
                        
            if (sum(doesPacketExist) == 10)
                
                if app.CreateVideoChecked == 1
                    fig = AddPlatformExternalFig(app,evalin("base","platformSelection"));
                else
                    AddPlatformSim(app,evalin("base","platformSelection")); 
                    fig = app.ExperimentPreview;
                end
                
                % Print time text:
                txtT = '';
                text(fig,0.1,3.75,txtT); 
                
                % Load the data packet into the GUI workspace:
                dataPacketUpWS = evalin("base","dataPacket");

                % Unwrap the angles for the animation to avoid the hitching
                dataPacketUpWS(:,10) = unwrap(dataPacketUpWS(:,10));
                dataPacketUpWS(:,52) = unwrap(dataPacketUpWS(:,52));
                dataPacketUpWS(:,78) = unwrap(dataPacketUpWS(:,78));
                
                % Downsample the data to reduce the number of points:
                [UniqueTime, UniqueIndex, ~] = unique(dataPacketUpWS(:,1));

                NewTimeSlow = linspace(0,UniqueTime(end),UniqueTime(end)*(app.AnimationSpeedSlider.Value/(round(0.1125*(1/app.baseRate)-1.25))+1))';

                dataPacket = zeros(length(NewTimeSlow),size(dataPacketUpWS,2));
                
                for j = 1:size(dataPacketUpWS,2)
                    dataPacket(:,j) = interp1(UniqueTime,dataPacketUpWS(UniqueIndex,j),NewTimeSlow);       
                end
                               
                app.DataPacketUp = dataPacket;
                app.DataPacket = dataPacketUpWS;

                if app.CreateVideoChecked == 1

                    waitbox = app.CustomWaitBox('Saving animation to SimulationVideo.avi...');

                end
                                
                for i = 1:size(dataPacket,1)
                               
                    a = dataPacket(i,9);
                    b = dataPacket(i,8);
                    theta = -unwrap(dataPacket(i,10));
                    
                    if (isempty(app.REDFigHdl) ~= 1) 

                        if app.ShowDockingConeRED == 1

                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,1]);

                        else

                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);

                        end

                        app.REDFigHdl.XData = spacecraft(:,1);
                        app.REDFigHdl.YData = spacecraft(:,2);

                        app.REDFigPatch.XData = spacecraft(:,1);
                        app.REDFigPatch.YData = spacecraft(:,2);

                        if app.ShowPathTraceRED == 1
                            app.REDPathPlot.XData = dataPacket(1:i,9);
                            app.REDPathPlot.YData = dataPacket(1:i,8);
                        end


                    end
                    
                    x0 = dataPacket(i,9);
                    y0 = dataPacket(i,8);
                    q0 = -unwrap(dataPacket(i,10));
                      
                    q1 = (-dataPacket(i,32) + 3*pi/2);
                    q2 = - dataPacket(i,33);
                    q3 = - dataPacket(i,34);
                    
                    [rs,re,rw,ree] = app.CalculateArmPosition([x0,y0,q0,q1,q2,q3]);

                    if (isempty(app.REDFigLink1) ~= 1) 
                        
                        app.REDFigLink1.XData = [rs(1,1)  re(1,1)];
                        app.REDFigLink1.YData = [rs(1,2)  re(1,2)];
                        app.REDFigLink2.XData = [re(1,1)  rw(1,1)];
                        app.REDFigLink2.YData = [re(1,2)  rw(1,2)];
                        app.REDFigLink3.XData = [rw(1,1)  ree(1,1)];
                        app.REDFigLink3.YData = [rw(1,2)  ree(1,2)];
                        app.REDFigJoin0.XData = rs(1,1);
                        app.REDFigJoin0.YData = rs(1,2);
                        app.REDFigJoin1.XData = re(1,1);
                        app.REDFigJoin1.YData = re(1,2);
                        app.REDFigJoin2.XData = rw(1,1);
                        app.REDFigJoin2.YData = rw(1,2);
                        app.REDFigJoin3.XData = ree(1,1);
                        app.REDFigJoin3.YData = ree(1,2);
                                                
                    end
                    
                    a = dataPacket(i,51);
                    b = dataPacket(i,50);
                    theta = -unwrap(dataPacket(i,52));
                    
                    if (isempty(app.BLACKFigHdl) ~= 1) 
                        
                        if app.ShowSolarPanelsBLACK == 1 && app.ShowDockingPortBLACK == 0
                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,5]);
                        elseif app.ShowSolarPanelsBLACK == 1 && app.ShowDockingPortBLACK == 1
                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,4]);
                        elseif app.ShowSolarPanelsBLACK == 0 && app.ShowDockingPortBLACK == 1
                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,2]);
                        else
                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);
                        end

                        app.BLACKFigHdl.XData = spacecraft(:,1);
                        app.BLACKFigHdl.YData = spacecraft(:,2);
                        app.BLACKFigPatch.XData = spacecraft(:,1);
                        app.BLACKFigPatch.YData = spacecraft(:,2);

                        if app.ShowPathTraceBLACK == 1
                            app.BLACKPathPlot.XData = dataPacket(1:i,51);
                            app.BLACKPathPlot.YData = dataPacket(1:i,50);
                        end                        
                    end
                    
                    a = dataPacket(i,77);
                    b = dataPacket(i,76);
                    theta = -unwrap(dataPacket(i,78));
                    
                    if (isempty(app.BLUEFigHdl) ~= 1) 

                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);

                        app.BLUEFigHdl.XData = spacecraft(:,1);
                        app.BLUEFigHdl.YData = spacecraft(:,2);
                        app.BLUEFigPatch.XData = spacecraft(:,1);
                        app.BLUEFigPatch.YData = spacecraft(:,2);

                        if app.ShowPathTraceBLUE == 1
                            app.BLUEPathPlot.XData = dataPacket(1:i,77);
                            app.BLUEPathPlot.YData = dataPacket(1:i,76);
                        end     
                        
                    end                       
          
                    fig.Children(1).String = strcat('Time: ',num2str(dataPacket(i,1)),' s');
                                           
                    if (app.StopButton.Value == 1)
                        app.StopButton.Value = 0;
                        break;
                    end

                    while app.PauseButton.Value == 1
                        app.PauseButton.Text = "Resume";
                        app.StopButton.Enable = "off";
                        drawnow;
                    end

                    app.PauseButton.Text = "Pause";
                    app.StopButton.Enable = "on";
                                        
                    if app.CreateVideoChecked == 1

                           frames{i} = getframe(fig); %#ok<AGROW>
                           %writeVideo(v,frame);
                           %exportgraphics(app.ExperimentPreview,[num2str(i) '.png'],'Resolution',100)
                    else
                           pause(0.01)
                           drawnow limitrate
                    end

                    
                end
                
                set(app.AnimationScrubberSlider,'enable','on');
                   
            else
                
                ed = errordlg("The variable dataPacket does not exist in the base workspace. Check that the simulation has been run, or ensure the variable is loaded.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                      
            end
            

            % After the animation has played, write all the frames to the video file
            if app.CreateVideoChecked == 1
                v = VideoWriter('SimulationVideo.avi','Motion JPEG AVI');
                v.Quality = 95;
                open(v);
                for i = 1:length(frames)
                    writeVideo(v, frames{i});
                end
                close(v);
                close(waitbox);
                close(gcf);
            end

            app.PlayButton.Enable = "on";
            app.SaveDataButton.Enable = "on";
            app.AnimationScrubberSlider.Enable = "on";
            app.AnimationSpeedSlider.Enable = "on";
            app.PlayButton.Enable = "on";
            app.StopButton.Enable = "off";
            app.PauseButton.Enable = "off";
            app.SkipToTimeEditField.Enable = "on";

                       
        end

        % Callback function
        function StopAnimationButtonPushed(app, event)
            app.Interruptor = 1;
        end

        % Button pushed function: REDPucksOnButton
        function REDPucksOnButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Enabling pucks on RED...');
            [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                  
                app.CleanShutdown(app.REDConnection);
                try
                    system(app.REDConnection,'echo 428 > /sys/class/gpio/unexport');
                catch
                end
                system(app.REDConnection,'echo 428 > /sys/class/gpio/export');
                system(app.REDConnection,'echo out > /sys/class/gpio/gpio428/direction');
                system(app.REDConnection,'echo 1 > /sys/class/gpio/gpio428/value');
                
                app.REDPucksOnButton.Enable = "off";
                app.REDPucksOffButton.Enable = "on";
                app.FireREDThrustersButton.Enable = "off";

            else 
                waitfor(warndlg(['There was a problem connecting to RED, could not start floating. Check that the IP address for RED is still accessible using !ping ' app.RED_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
            end
            close(waitbox);

        end

        % Button pushed function: REDPucksOffButton
        function REDPucksOffButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Stopping pucks on RED...');
            [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                try
                    system(app.REDConnection,'echo 0 > /sys/class/gpio/gpio428/value');
                    system(app.REDConnection,'echo 428 > /sys/class/gpio/unexport');
                    app.REDPucksOnButton.Enable = "on";
                    app.REDPucksOffButton.Enable = "off";
                    app.FireREDThrustersButton.Enable = "on";
                catch
                    waitfor(warndlg('There was an error stopping the float, check that the platform is currently floating. If it is, turn off the airtank and cycle the power.','Float OFF Error'));
                end
            else 
                waitfor(warndlg(['There was a problem connecting to RED, could not stop floating. Check that the IP address for RED is still accessible using !ping ' app.RED_SSH_Address ' in the MATLAB Command Window.'],'Floating Failed'));
            end 
            close(waitbox);

        end

        % Button pushed function: BLACKPucksOnButton
        function BLACKPucksOnButtonPushed(app, event)
            
            waitbox = app.CustomWaitBox('Enabling pucks on BLACK...');
            [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                   
                app.CleanShutdown(app.BLACKConnection);
                
                try
                    system(app.BLACKConnection,'echo 428 > /sys/class/gpio/unexport');
                catch
                end
                system(app.BLACKConnection,'echo 428 > /sys/class/gpio/export');
                system(app.BLACKConnection,'echo out > /sys/class/gpio/gpio428/direction');
                system(app.BLACKConnection,'echo 1 > /sys/class/gpio/gpio428/value');

                app.BLACKPucksOnButton.Enable = "off";
                app.BLACKPucksOffButton.Enable = "on";
                app.FireBLACKThrustersButton.Enable = "off";
                
            else 
                waitfor(warndlg(['There was a problem connecting to BLACK, could not start floating. Check that the IP address for BLACK is still accessible using !ping ' app.BLACK_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
            end

            close(waitbox);

        end

        % Button pushed function: BLACKPucksOffButton
        function BLACKPucksOffButtonPushed(app, event)
            
            waitbox = app.CustomWaitBox('Stopping pucks on BLACK...');
            [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                    
                try
                    system(app.BLACKConnection,'echo 0 > /sys/class/gpio/gpio428/value');
                    system(app.BLACKConnection,'echo 428 > /sys/class/gpio/unexport');

                    app.BLACKPucksOnButton.Enable = "on";
                    app.BLACKPucksOffButton.Enable = "off";
                    app.FireBLACKThrustersButton.Enable = "on";

                catch
                    waitfor(warndlg('There was an error stopping the float, check that the platform is currently floating. If it is, turn off the airtank and cycle the power.','Float OFF Error'));
                end

            else 
                waitfor(warndlg(['There was a problem connecting to BLACK, could not stop floating. Check that the IP address for BLACK is still accessible using !ping ' app.BLACK_SSH_Address ' in the MATLAB Command Window.'],'Floating Failed'));
            end 
            close(waitbox);
                     
        end

        % Button pushed function: BLUEPucksOnButton
        function BLUEPucksOnButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Enabling pucks on BLUE...');
            [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                app.CleanShutdown(app.BLUEConnection);

                try
                    system(app.BLUEConnection,'echo 428 > /sys/class/gpio/unexport');
                catch
                end
                system(app.BLUEConnection,'echo 428 > /sys/class/gpio/export');
                system(app.BLUEConnection,'echo out > /sys/class/gpio/gpio428/direction');
                system(app.BLUEConnection,'echo 1 > /sys/class/gpio/gpio428/value');

                app.BLUEPucksOffButton.Enable = "on";
                app.BLUEPucksOnButton.Enable = "off";
                app.FireBLUEThrustersButton.Enable = "off";
                
            else 
                waitfor(warndlg(['There was a problem connecting to BLUE, could not start floating. Check that the IP address for BLUE is still accessible using !ping ' app.BLUE_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
            end
            
            close(waitbox);
        end

        % Button pushed function: BLUEPucksOffButton
        function BLUEPucksOffButtonPushed(app, event)
            
            waitbox = app.CustomWaitBox('Stopping pucks on BLUE...');
            [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                try
                    system(app.BLUEConnection,'echo 0 > /sys/class/gpio/gpio428/value');
                    system(app.BLUEConnection,'echo 428 > /sys/class/gpio/unexport');
                    app.BLUEPucksOffButton.Enable = "off";
                    app.BLUEPucksOnButton.Enable = "on";
                    app.FireBLUEThrustersButton.Enable = "on";
                catch
                    waitfor(warndlg('There was an error stopping the float, check that the platform is currently floating. If it is, turn off the airtank and cycle the power.','Float OFF Error'));
                end
                
            else 
                waitfor(warndlg(['There was a problem connecting to BLUE, could not stop floating. Check that the IP address for BLUE is still accessible using !ping ' app.BLUE_SSH_Address ' in the MATLAB Command Window.'],'Floating Failed'));
            end 
            close(waitbox);
                  
        end

        % Button pushed function: BuildCode
        function BuildCodePushed(app, event)
        waitbox = app.CustomWaitBox('Compiling code, please be patient...');
        selectedFile = get(app.AvailableDiagramsDropDown,'Value');

        % Start by updating the K gains for the LQR controller:
        app.CalculateLQRGains();
        app.UpdateNominalForces();
        
        if (bdIsLoaded(selectedFile(1:(end-4)))==1)

            [Derivs, Integr] = app.CheckForContinuousBlocks;

            if ~isempty(Derivs)
                waitfor(warndlg(['There appears to be ' num2str(length(Derivs)) ' Continuous-Time derivative block in your diagram. Compiled code may not behave as expected. Replace all continuous-time blocks with their equivalent discrete-time counterpart.'],'Continuous-Time Block Error'));
            end

            if ~isempty(Integr)
                waitfor(warndlg(['There appears to be ' num2str(length(Integr)) ' Continuous-Time integrator block in your diagram. Compiled code may not behave as expected. Replace all continuous-time blocks with their equivalent discrete-time counterpart.'],'Continuous-Time Block Error'));
            end

            % Specify the block path for the models
            blockPath = [selectedFile(1:(end-4)) '/Simulate Plant Dynamics'];
            
            % Comment out the models
            set_param(blockPath, 'Commented', 'on');

            set_param(selectedFile(1:(end-4)),'SolverType',"Fixed-step");
            set_param(selectedFile(1:(end-4)),'SolverName',"ode4");

            app.CompileDir = cd;
            app.CompileDir = strrep(app.CompileDir,":","");
            app.CompileDir = strrep(app.CompileDir," ","_");
            app.CompileDir = strrep(app.CompileDir,"\","/");

            FileName = app.AvailableDiagramsDropDown.Value;

            assignin('base','simMode',0);

            activeConfigObj = getActiveConfigSet(selectedFile(1:(end-4)));
            set_param(activeConfigObj,'HardwareBoard','NVIDIA Jetson');

            % Locate a master
            if app.REDCheckBox.Value == 1
                fprintf('RED appears to be MASTER. building to RED...\n')
                [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                    prefDir = prefdir;
                    prefFilePath = fullfile(prefDir, 'matlabprefs.mat');
                    load(prefFilePath); %#ok<LOAD>
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultIpAddress = app.RED_SSH_Address;
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultUserName = app.RED_SSH_Name;
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultBuildDirPref = ['/home/' app.RED_SSH_Name '/ExperimentSoftware/'];
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaulttargetwsdirPref = ['/home/' app.RED_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/'];
                    save(prefFilePath,"Preferences",'-mat');

                    if strcmp(get_param(FileName(1:(end-4)),'Dirty'),'on')
                        save_system(selectedFile);
                    end
                    slbuild(FileName(1:(end-4)));
                    if (isModelRunning(app.REDConnection,FileName(1:(end-4))) == 1)
                        stopModel(app.REDConnection,FileName(1:(end-4)));
                        app.CleanShutdown(app.REDConnection);
                        DataDir = ['/home/' app.RED_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*']; 
                        deleteFile(app.REDConnection,DataDir);
                    end
                    set_param(blockPath, 'Commented', 'off');
                else 
                    
                    fprintf('There was a problem connecting to the designated master platform (RED), build stopped.\n')
                    
                end                
            elseif app.BLACKCheckBox.Value == 1
                fprintf('BLACK appears to be MASTER. building to BLACK...\n')
                [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                    prefDir = prefdir;
                    prefFilePath = fullfile(prefDir, 'matlabprefs.mat');
                    load(prefFilePath); %#ok<LOAD>
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultIpAddress = app.BLACK_SSH_Address;
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultUserName = app.BLACK_SSH_Name;
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultBuildDirPref = ['/home/' app.BLACK_SSH_Name '/ExperimentSoftware/'];
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaulttargetwsdirPref = ['/home/' app.BLACK_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/'];
                    save(prefFilePath,"Preferences",'-mat');
                    
                    if strcmp(get_param(FileName(1:(end-4)),'Dirty'),'on')
                        save_system(selectedFile);
                    end
                    slbuild(FileName(1:(end-4)));
                    if (isModelRunning(app.BLACKConnection,FileName(1:(end-4))) == 1)
                        stopModel(app.BLACKConnection,FileName(1:(end-4)));
                        app.CleanShutdown(app.BLACKConnection);
                        DataDir = ['/home/' app.BLACK_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*']; 
                        deleteFile(app.BLACKConnection,DataDir);
                    end
                    set_param(blockPath, 'Commented', 'off');
                else 
                    
                    fprintf('There was a problem connecting to the designated master platform (BLACK), build stopped.\n')
                    
                end   
            elseif app.BLUECheckBox.Value == 1
                fprintf('BLUE appears to be MASTER. building to BLUE...\n')
                [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                    BLUE = app.BLUEConnection;

                    prefDir = prefdir;
                    prefFilePath = fullfile(prefDir, 'matlabprefs.mat');
                    load(prefFilePath); %#ok<LOAD>
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultIpAddress = app.BLUE_SSH_Address;
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultUserName = app.BLUE_SSH_Name;
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaultBuildDirPref = ['/home/' app.BLUE_SSH_Name '/ExperimentSoftware/'];
                    Preferences.Hardware_Connectivity_Installer_NVIDIAJetson.DefaulttargetwsdirPref = ['/home/' app.BLUE_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/'];
                    save(prefFilePath,"Preferences",'-mat');
                    
                    if strcmp(get_param(FileName(1:(end-4)),'Dirty'),'on')
                        save_system(selectedFile);
                    end
                    slbuild(FileName(1:(end-4)));
                    if (isModelRunning(BLUE,FileName(1:(end-4))) == 1)
                        stopModel(BLUE,FileName(1:(end-4)));
                        app.CleanShutdown(BLUE);
                        DataDir = ['/home/' app.BLUE_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*']; 
                        deleteFile(BLUE,DataDir);
                    end
                    set_param(blockPath, 'Commented', 'off');
                else 
                    
                    fprintf('There was a problem connecting to the designated master platform (BLUE), build stopped.\n')
                    
                end
            else
                [~] = questdlg('Warning: You have not selected any platforms for code deployment! Check at least one platform.','Deployment Warning','Understood','Cancel','Cancel');
            end

            assignin('base','simMode',1);
            
            % Use the system command to copy files around as needed
            % If RED is MASTER
            if (app.REDCheckBox.Value == 1)

                if (app.BLACKCheckBox.Value == 1)

                    fprintf('Checking connection to BLACK platform...\n')
                    [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Copying files from RED to BLACK...')
                        system(app.REDConnection,['scp -r /home/' app.RED_SSH_Name '/ExperimentSoftware/ ' app.BLACK_SSH_Name '@' app.BLACK_SSH_Address ':/home/' app.BLACK_SSH_Name '/']);                        
                        system(app.BLACKConnection,['echo "' app.BLACK_SSH_Password '" | sudo -S chmod -R 755 ExperimentSoftware']);
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLACK, files not copied.\n')
                    end

                end

                if (app.BLUECheckBox.Value == 1)

                    fprintf('Checking connection to BLUE platform...\n')
                    [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n  1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Copying files from RED to BLUE...')
                        system(app.REDConnection,['scp -r /home/' app.RED_SSH_Name '/ExperimentSoftware/ ' app.BLUE_SSH_Name '@' app.BLUE_SSH_Address ':/home/' app.BLUE_SSH_Name '/']);                        
                        system(app.BLUEConnection,['echo "' app.BLUE_SSH_Password '" | sudo -S chmod -R 755 ExperimentSoftware']);
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLUE, files not copied.\n')
                    end

                end

                close(waitbox);
                return

            end
            
            % If RED is NOT MASTER, then ONLY BLACK AND BLUE ARE USED
            if (app.BLACKCheckBox.Value == 1)

                if (app.BLUECheckBox.Value == 1)

                    fprintf('Checking connection to BLUE platform...\n')
                    [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Copying files from BLACK to BLUE...')
                        system(app.BLACKConnection,['scp -r /home/' app.BLACK_SSH_Name '/ExperimentSoftware/ ' app.BLUE_SSH_Name '@' app.BLUE_SSH_Address ':/home/' app.BLUE_SSH_Name '/']);     
                        system(app.BLUEConnection,['echo "' app.BLUE_SSH_Password '" | sudo -S chmod -R 755 ExperimentSoftware']);
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLUE, files not copied.\n')
                    end

                end
                
                close(waitbox);
                return

            end


        else
            close(waitbox);
            ed = errordlg("The selected Simulink diagram has not been loaded. Try selecting it again from the dropdown.","Error");
            set(ed, "WindowStyle", "modal");
            uiwait(ed);          
        end

        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            FailedFlag = false;
            
            if isempty(app.CompileDir)
                app.CompileDir = cd;
                app.CompileDir = strrep(app.CompileDir,":","");
                app.CompileDir = strrep(app.CompileDir," ","_");
                app.CompileDir = strrep(app.CompileDir,"\","/");
            end
               
            if app.REDCheckBox.Value == 1
            
                fprintf('Checking connection to RED platform...\n')
                [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0

                    try
                        system(app.REDConnection,'echo "srcl2023" | sudo -S  chmod 666 /dev/ttyUSB0');
                        system(app.REDConnection,'echo "srcl2023" | sudo -S  chmod +x manage_pwm.sh');
                    catch
                    end
                                                  
                    if isModelRunning(app.REDConnection,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on RED!','Model Already Running'));
                    end

                    app.CleanShutdown(app.REDConnection);
                    system(app.REDConnection,'echo "srcl2023" | sudo -S  nohup ./manage_pwm.sh start > /dev/null 2>&1 &');
                    app.RED_Process_ID = runExecutable(app.REDConnection,['/home/' app.RED_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/' FileName(1:end-4) '.elf']);
                    
                else 
                    waitfor(warndlg(['There was a problem connecting to RED, could not start experiment. Check that the IP address for RED is still accessible using !ping ' app.RED_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                    FailedFlag = true;
                end        
                
            end

            if app.BLACKCheckBox.Value == 1
            
                fprintf('Checking connection to BLACK platform...\n')
                [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                           
                    try
                        system(app.BLACKConnection,'echo "srcl2023" | sudo -S  chmod 666 /dev/ttyUSB0');
                        system(app.BLACKConnection,'echo "srcl2023" | sudo -S  chmod +x manage_pwm.sh');
                    catch
                    end
                                                  
                    if isModelRunning(app.BLACKConnection,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on BLACK!','Model Already Running'));
                    end

                    app.CleanShutdown(app.BLACKConnection);
                    system(app.BLACKConnection,'echo "srcl2023" | sudo -S  nohup ./manage_pwm.sh start > /dev/null 2>&1 &');
                    app.BLACK_Process_ID = runExecutable(app.BLACKConnection,['/home/' app.BLACK_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/' FileName(1:end-4) '.elf']);
                    
                else 
                    waitfor(warndlg(['There was a problem connecting to BLACK, could not start experiment. Check that the IP address for BLACK is still accessible using !ping ' app.BLACK_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                    FailedFlag = true;
                end        
                
            end            

            if app.BLUECheckBox.Value == 1
            
                fprintf('Checking connection to BLUE platform...\n')
                [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                           
                    try
                        system(app.BLUEConnection,'echo "srcl2023" | sudo -S  chmod 666 /dev/ttyUSB0');
                        system(app.BLUEConnection,'echo "srcl2023" | sudo -S  chmod +x manage_pwm.sh');
                    catch
                    end
                            
                    if isModelRunning(app.BLUEConnection,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on BLUE!','Model Already Running'));
                    end
                    
                    app.CleanShutdown(app.BLUEConnection);
                    system(app.BLUEConnection,'echo "srcl2023" | sudo -S  nohup ./manage_pwm.sh start > /dev/null 2>&1 &');
                    app.BLUE_Process_ID = runExecutable(app.BLUEConnection,['/home/' app.BLUE_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/' FileName(1:end-4) '.elf']);

                else 
                    waitfor(warndlg(['There was a problem connecting to BLUE, could not start experiment. Check that the IP address for BLUE is still accessible using !ping ' app.BLUE_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                    FailedFlag = true;
                end        
                
            end
     
            if FailedFlag == false
                
                app.StopExperimentButton.Enable = 'on';
                app.PhaseSpaceSwitch.Enable = 'off';
                app.StartExperimentButton.Enable = 'off';
                app.isExperimentRunning = true;
                
            else
                try
                    app.StopExperimentButtonPushed();
                catch
                    errordlg('UNABLE TO STOP EXPERIMENT VIA SOFTWARE. HIT THE E-STOP.','Critical Software Failure');
                end
            end

        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            FailedFlag = false;
            if app.REDCheckBox.Value == 1
            
                fprintf('Checking connection to RED platform...\n')
                [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                    
                    try
                        system(app.REDConnection,'echo "srcl2023" | sudo -S  nohup ./manage_pwm.sh stop > /dev/null 2>&1 &');
                        system(app.REDConnection,['kill -15 ' num2str(app.RED_Process_ID)]);
                        app.CleanShutdown(app.REDConnection);
                    catch
                    end
                    
                else 
                    waitfor(warndlg(['There was a problem connecting to RED, could not stop experiment. Check that the IP address for RED is still accessible using !ping ' app.RED_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                    FailedFlag = true;
                end        
                
            end

            if app.BLACKCheckBox.Value == 1
                
                fprintf('Checking connection to BLACK platform...\n')
                [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                     
                    try
                        system(app.BLACKConnection,'echo "srcl2023" | sudo -S  nohup ./manage_pwm.sh stop > /dev/null 2>&1 &');
                        system(app.BLACKConnection,['kill -15 ' num2str(app.BLACK_Process_ID)]);
                        app.CleanShutdown(app.BLACKConnection);
                    catch
                    end
                    
                else 
                    waitfor(warndlg(['There was a problem connecting to BLACK, could not stop experiment. Check that the IP address for BLACK is still accessible using !ping ' app.BLACK_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                    FailedFlag = true;
                end         
                
            end            

            if app.BLUECheckBox.Value == 1
            
                fprintf('Checking connection to BLUE platform...\n')
                [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                     
                    try
                        system(app.BLUEConnection,'echo "srcl2023" | sudo -S  nohup ./manage_pwm.sh stop > /dev/null 2>&1 &');
                        system(app.BLUEConnection,['kill -15 ' num2str(app.BLUE_Process_ID)]);
                        app.CleanShutdown(BLUE);
                    catch
                    end
                else 
                    waitfor(warndlg(['There was a problem connecting to BLUE, could not stop experiment. Check that the IP address for BLUE is still accessible using !ping ' app.BLUE_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                    FailedFlag = true;
                end     
                
            end

            if FailedFlag == false
                app.StopExperimentButton.Enable = 'off';
                app.StartExperimentButton.Enable = 'on';
                app.PhaseSpaceSwitch.Enable = 'on';
                app.isExperimentRunning = false;
                app.PhaseSpaceSwitch.Value = "Off";
                app.PhaseSpaceSwitchValueChanged();
            else
                app.StopExperimentButton.Enable = 'on';
                errordlg('UNABLE TO STOP EXPERIMENT VIA SOFTWARE. HIT THE E-STOP.','Critical Software Failure');
            end
            
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Grab the file name from the drop down menu:
            waitbox = app.CustomWaitBox('Saving simulation data...');
            
            try
                
                dataClass      = evalin('base', 'dataClass');
                
                % Move the file to a folder for storage:
                
                DateTime       = datetime("now");
                
                if app.AutomaticFolderNamingCheckBox.Value == 1
                    foldername = ['SimulationData_' num2str(DateTime.Year) '_' ...
                        num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                    num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                else
                    % Prompt the user to enter a file name
                    foldername = inputdlg('Enter the folder name:', 'Folder Name Input', [1 50]);
                    foldername = foldername{1};
                    
                    % Check if the user entered something
                    if ~isempty(foldername)
                        % Ask the user for confirmation
                        confirm = questdlg(['Is "' foldername '" correct?'], 'Confirm Name', 'Yes', 'No', 'Yes');
                        
                        % Respond based on the user's choice
                        if strcmp(confirm, 'Yes')
                            %disp(['File name confirmed: ' fileName{1}]);
                        else
                            %disp('Please enter the correct file name.');
                        end
                    else
                        foldername = ['SimulationData_' num2str(DateTime.Year) '_' ...
                            num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                        num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                    end
                end
                
                save('dataPacket_SIM.mat','dataClass');
                
                mkdir([cd '' filesep 'Saved Data' filesep '' foldername '' filesep '']);
                
                movefile([cd '' filesep 'dataPacket_SIM.mat'],...
                [cd '' filesep 'Saved Data' filesep '' foldername '' filesep 'dataPacket_SIM.mat']);

                waitfor(warndlg(['The simulation data has been successfully saved to: ' newline ...
                cd '' filesep 'Saved Data' filesep '' foldername '' filesep 'dataPacket_SIM.mat'],'Simulation Data Saved!'));
                       
            catch 
                
                ed = errordlg("The variable 'dataClass' does not exist in the base workspace. Cannot save data.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                
            end

            close(waitbox);
        end

        % Button pushed function: SaveExperimentDataButton
        function SaveExperimentDataButtonPushed(app, event)
            
            if app.isExperimentRunning == true
                errordlg("Cannot save data while an experiment is running.","Error");
            else

                % Get dateTime:
                DateTime       = datetime("now");
    
                % Grab the file name from the drop down menu:
                Choice = questdlg('Warning: The experiment must be terminated before exporting any data. If you have not ended the experiment, select "Cancel", otherwise select "Proceed".','Data Export Warning','Proceed','Cancel','Proceed');
                FileName = app.AvailableDiagramsDropDown.Value;
                switch Choice
                    case 'Proceed'
                        try
                            waitbox = app.CustomWaitBox('Saving experimental data for selected platforms...');
                            if app.REDCheckBox.Value == 1
                    
                                [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
                                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                                                          
                                    DataDir = ['/home/' app.RED_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*'];
                                    
                                    getFile(app.REDConnection,DataDir);
        
                                    deleteFile(app.REDConnection,DataDir);
                        
                                    if app.AutomaticFolderNamingCheckBoxExp.Value == 1
                                        foldername_RED = ['ExperimentData_RED_' num2str(DateTime.Year) '_' ...
                                            num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                        num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                                    else
                                        % Prompt the user to enter a file name
                                        foldername_RED = inputdlg('Enter the folder name for RED:', 'Folder Name Input', [1 50]);
                                        foldername_RED = foldername_RED{1};
                                        
                                        % Check if the user entered something
                                        if ~isempty(foldername_RED)
                                            % Ask the user for confirmation
                                            confirm = questdlg(['Is "' foldername_RED '" correct?'], 'Confirm Name', 'Yes', 'No', 'Yes');
                                            
                                            % Respond based on the user's choice
                                            if strcmp(confirm, 'Yes')
                                                %disp(['File name confirmed: ' fileName{1}]);
                                            else
                                                %disp('Please enter the correct file name.');
                                            end
                                        else
                                            foldername_RED = ['ExperimentData_RED_' num2str(DateTime.Year) '_' ...
                                                num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                            num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                                        end
                                    end
                                    AllLoadedFiles = dir('ExpLog*');
        
                                    for index = 1:length(AllLoadedFiles)
                                        
                                        dataPacket_rt = importdata(AllLoadedFiles(index).name);
                                        blockPath = [FileName(1:end-4) '/Data Logger Subsystem/Data Logging Bus'];
                                        ph = get_param(blockPath, 'PortHandles');
                                        sh = get_param(ph.Inport, 'SignalHierarchy');
                                    
                                        % Extract the time vector from the first column
                                        timeVector = dataPacket_rt(:, 1);
                                    
                                        % Initialize a struct to store each timeseries
                                        dataClass_rt = struct();
                                        
                                        % Iterate over each signal in sh, skipping the first column which is the time vector
                                        for i = 1:numel(sh)
                                            signalName = sh{i}.SignalName;
                                            signalData = dataPacket_rt(:, i);
                                            
                                            % Create a timeseries object for this signal
                                            ts = timeseries(signalData, timeVector);
                                            ts.Name = signalName;
                                            
                                            % Add this timeseries to the struct with the field name as signalName
                                            dataClass_rt.(signalName) = ts;
                                        end
                                        
                                        % Save the final timeseries object
                                        save([foldername_RED '_' num2str(index) '.mat'], "dataClass_rt", '-mat');
                                        
                                    end
        
                                    % Move all data to new folder:
                                    movefile([cd '' filesep 'ExpLog*'],[cd '' filesep 'Saved Data' filesep '' foldername_RED]);
                                    movefile([cd '' filesep '' foldername_RED '_*'],[cd '' filesep 'Saved Data' filesep '' foldername_RED]);
                
                                else 
                                    waitfor(warndlg(['There was a problem connecting to RED, could not copy over data. Check that the IP address for RED is still accessible using !ping ' app.RED_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                                end        
                                
                            end
                
                            if app.BLACKCheckBox.Value == 1
                                
                                [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
                                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                                       
                                    DataDir = ['/home/' app.BLACK_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*'];
                                    
                                    getFile(app.BLACKConnection,DataDir);
        
                                    deleteFile(app.BLACKConnection,DataDir);
        
                                    if app.AutomaticFolderNamingCheckBoxExp.Value == 1
                                        foldername_BLACK = ['ExperimentData_BLACK_' num2str(DateTime.Year) '_' ...
                                            num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                        num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                                    else
                                        % Prompt the user to enter a file name
                                        foldername_BLACK = inputdlg('Enter the folder name for BLACK:', 'Folder Name Input', [1 50]);
                                        foldername_BLACK = foldername_BLACK{1};
                                        
                                        % Check if the user entered something
                                        if ~isempty(foldername_BLACK)
                                            % Ask the user for confirmation
                                            confirm = questdlg(['Is "' foldername_BLACK '" correct?'], 'Confirm Name', 'Yes', 'No', 'Yes');
                                            
                                            % Respond based on the user's choice
                                            if strcmp(confirm, 'Yes')
                                                %disp(['File name confirmed: ' fileName{1}]);
                                            else
                                                %disp('Please enter the correct file name.');
                                            end
                                        else
                                            foldername_BLACK = ['ExperimentData_BLACK_' num2str(DateTime.Year) '_' ...
                                                num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                            num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                                        end
                                    end
             
                                    AllLoadedFiles = dir('ExpLog*');
        
                                    for index = 1:length(AllLoadedFiles)
                                        
                                        dataPacket_rt = importdata(AllLoadedFiles(index).name);
                                        blockPath = [FileName(1:end-4) '/Data Logger Subsystem/Data Logging Bus'];
                                        ph = get_param(blockPath, 'PortHandles');
                                        sh = get_param(ph.Inport, 'SignalHierarchy');
                                    
                                        % Extract the time vector from the first column
                                        timeVector = dataPacket_rt(:, 1);
                                    
                                        % Initialize a struct to store each timeseries
                                        dataClass_rt = struct();
                                        
                                        % Iterate over each signal in sh, skipping the first column which is the time vector
                                        for i = 1:numel(sh)
                                            signalName = sh{i}.SignalName;
                                            signalData = dataPacket_rt(:, i);
                                            
                                            % Create a timeseries object for this signal
                                            ts = timeseries(signalData, timeVector);
                                            ts.Name = signalName;
                                            
                                            % Add this timeseries to the struct with the field name as signalName
                                            dataClass_rt.(signalName) = ts;
                                        end
                                        
                                        % Save the final timeseries object
                                        save([foldername_BLACK '_' num2str(index) '.mat'], "dataClass_rt", '-mat');
                                        
                                    end
                                    
                                    % Move all data to new folder:
                                    movefile([cd '' filesep 'ExpLog*'],[cd '' filesep 'Saved Data' filesep '' foldername_BLACK]);
                                    movefile([cd '' filesep '' foldername_BLACK '_*'],[cd '' filesep 'Saved Data' filesep '' foldername_BLACK]);
        
                                else 
                                    waitfor(warndlg(['There was a problem connecting to BLACK, could not copy over data. Check that the IP address for BLACK is still accessible using !ping ' app.BLACK_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                                end         
                                
                            end            
                
                            if app.BLUECheckBox.Value == 1
                            
                                [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
                                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                       
                                    DataDir = ['/home/' app.BLUE_SSH_Name '/ExperimentSoftware/MATLAB_ws/' app.MATLABVer '/' convertStringsToChars(strrep(app.CompileDir,'\','/')) '/ExpLog*'];
                                    
                                    getFile(app.BLUEConnection,DataDir);
        
                                    deleteFile(app.BLUEConnection,DataDir);
        
                                    if app.AutomaticFolderNamingCheckBoxExp.Value == 1
                                        foldername_BLUE = ['ExperimentData_BLUE_' num2str(DateTime.Year) '_' ...
                                            num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                        num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                                    else
                                        % Prompt the user to enter a file name
                                        foldername_BLUE = inputdlg('Enter the folder name for BLUE:', 'Folder Name Input', [1 50]);
                                        foldername_BLUE = foldername_BLUE{1};
                                        
                                        % Check if the user entered something
                                        if ~isempty(foldername_BLUE)
                                            % Ask the user for confirmation
                                            confirm = questdlg(['Is "' foldername_BLUE '" correct?'], 'Confirm Name', 'Yes', 'No', 'Yes');
                                            
                                            % Respond based on the user's choice
                                            if strcmp(confirm, 'Yes')
                                                %disp(['File name confirmed: ' fileName{1}]);
                                            else
                                                %disp('Please enter the correct file name.');
                                            end
                                        else
                                            foldername_BLUE = ['ExperimentData_BLUE_' num2str(DateTime.Year) '_' ...
                                                num2str(DateTime.Month) '_' num2str(DateTime.Day) '_'...
                                            num2str(DateTime.Hour) '_' num2str(DateTime.Minute) '_' strrep(num2str(DateTime.Second),'.','_')];
                                        end
                                    end     
        
                                    AllLoadedFiles = dir('ExpLog*');
        
                                    for index = 1:length(AllLoadedFiles)
                                        
                                        dataPacket_rt = importdata(AllLoadedFiles(index).name);
                                        blockPath = [FileName(1:end-4) '/Data Logger Subsystem/Data Logging Bus'];
                                        ph = get_param(blockPath, 'PortHandles');
                                        sh = get_param(ph.Inport, 'SignalHierarchy');
                                    
                                        % Extract the time vector from the first column
                                        timeVector = dataPacket_rt(:, 1);
                                    
                                        % Initialize a struct to store each timeseries
                                        dataClass_rt = struct();
                                        
                                        % Iterate over each signal in sh, skipping the first column which is the time vector
                                        for i = 1:numel(sh)
                                            signalName = sh{i}.SignalName;
                                            signalData = dataPacket_rt(:, i);
                                            
                                            % Create a timeseries object for this signal
                                            ts = timeseries(signalData, timeVector);
                                            ts.Name = signalName;
                                            
                                            % Add this timeseries to the struct with the field name as signalName
                                            dataClass_rt.(signalName) = ts;
                                        end
                                        
                                        % Save the final timeseries object
                                        save([foldername_BLUE '_' num2str(index) '.mat'], "dataClass_rt", '-mat');
                                        
                                    end
        
                                    % Move all data to new folder:
                                    movefile([cd '' filesep 'ExpLog*'],[cd '' filesep 'Saved Data' filesep '' foldername_BLUE]);
                                    movefile([cd '' filesep '' foldername_BLUE '_*'],[cd '' filesep 'Saved Data' filesep '' foldername_BLUE]);                           
        
                                    
                                else 
                                    waitfor(warndlg(['There was a problem connecting to BLUE, could not copy over data. Check that the IP address for BLUE is still accessible using !ping ' app.BLUE_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
                                end     
                                
                            end
                            close(waitbox);
                        catch
                            waitbox = app.CustomWaitBox('Saving experimental data for selected platforms...');
                            waitfor(warndlg('There was a problem saving the data. Remote into the platforms and check that the data file was correctly generated. You are looking for files which start with "ExpLog".','Failed to Save Data'));
                            close(waitbox);
                        end
    
                    case 'Cancel'
                        % Do nothing.
                end
            end

            
        end

        % Button pushed function: PingREDButton
        function PingREDButtonPushed(app, event)
         
            trytime = char(datetime("now"));

            [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               app.REDConnectedLamp.Color = [0.00,1.00,0.00];
               app.REDTextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP: ' app.RED_SSH_Address ''];
            else 
               app.REDConnectedLamp.Color = [1.00,0.00,0.00];
               app.REDTextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end

        end

        % Button pushed function: PingBLACKButton
        function PingBLACKButtonPushed(app, event)
            
            trytime = char(datetime("now"));

            [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               app.BLACKConnectedLamp.Color = [0.00,1.00,0.00];
               app.BLACKTextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP: ' app.BLACK_SSH_Address];
            else 
               app.BLACKConnectedLamp.Color = [1.00,0.00,0.00];
               app.BLACKTextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end

        end

        % Button pushed function: PingBLUEButton
        function PingBLUEButtonPushed(app, event)
            
            trytime = char(datetime("now"));

            [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               app.BLUEConnectedLamp.Color = [0.00,1.00,0.00];
               app.BLUETextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP: ' app.BLUE_SSH_Address];
            else 
               app.BLUEConnectedLamp.Color = [1.00,0.00,0.00];
               app.BLUETextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end
            
        end

        % Callback function
        function SoftwareInstallationMenuSelected(app, event)
            web('https://youtu.be/d77dXB41VeI','-browser' );
        end

        % Callback function
        function ConnectingtoaRaspberryPiRemotelyMenuSelected(app, event)
            web('https://youtu.be/1Ly6PqwiL1w','-browser' );
        end

        % Menu selected function: RemovingtheTableCoverMenu
        function RemovingtheTableCoverMenuSelected(app, event)
            web('https://youtu.be/0DmQvlUzcDc','-browser' );
        end

        % Menu selected function: RetrievingtheIsopropanolMenu
        function RetrievingtheIsopropanolMenuSelected(app, event)
            web('https://youtu.be/nVwOGg06n6c','-browser' );
        end

        % Menu selected function: TableCleaningProceduresMenu
        function TableCleaningProceduresMenuSelected(app, event)
            web('https://youtu.be/xqzN2uYfsFM','-browser' );
        end

        % Menu selected function: SavingExperimentDataMenu
        function SavingExperimentDataMenuSelected(app, event)
            web('https://youtu.be/kHlCK_5decQ','-browser' );
        end

        % Menu selected function: RemovingtheRoboticArmMenu
        function RemovingtheRoboticArmMenuSelected(app, event)
            web('https://youtu.be/KlgUwF5Puzk','-browser' );
        end

        % Button pushed function: FireREDThrustersButton
        function FireREDThrustersButtonPushed(app, event)
            fprintf('Checking connection to RED platform...\n')
            [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run turn on thrusters in order...\n');

                app.CleanShutdown(app.REDConnection);
                
                waitbox = app.CustomWaitBox('Cycling all thrusters on RED...');
                app.CycleAllThrusters(app.REDConnection);
                close(waitbox);
                       
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg(['There was a problem connecting to RED, could not cycle thrusters. Check that the IP address for RED is still accessible using !ping ' app.RED_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
            end  
        end

        % Button pushed function: OpenREDCMDShell
        function OpenREDCMDShellButtonPushed(app, event)
            openShell(app.REDConnection);             
        end

        % Button pushed function: FireBLACKThrustersButton
        function FireBLACKThrustersButtonPushed(app, event)
            fprintf('Checking connection to BLACK platform...\n')
            [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run turn on thrusters in order...\n');

                app.CleanShutdown(app.BLACKConnection);

                waitbox = app.CustomWaitBox('Cycling all thrusters on BLACK...');
                app.CycleAllThrusters(app.BLACKConnection);
                close(waitbox);
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg(['There was a problem connecting to BLACK, could not cycle thrusters. Check that the IP address for BLACK is still accessible using !ping ' app.BLACK_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
            end             
        end

        % Button pushed function: OpenBLACKCMDShell
        function OpenBLACKCMDShellButtonPushed(app, event)
            openShell(app.BLACKConnection);              
        end

        % Button pushed function: OpenBLUECMDShell
        function OpenBLUECMDShellButtonPushed(app, event)
            openShell(app.BLUEConnection);              
        end

        % Button pushed function: FireBLUEThrustersButton
        function FireBLUEThrustersButtonPushed(app, event)
            fprintf('Checking connection to BLUE platform...\n')
            [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run turn on thrusters in order...\n');

                BLUE = app.BLUEConnection;
                app.CleanShutdown(BLUE);

                waitbox = app.CustomWaitBox('Cycling all thrusters on BLUE...');
                app.CycleAllThrusters(BLUE);
                close(waitbox);
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg(['There was a problem connecting to BLUE, could not cycle thrusters. Check that the IP address for BLUE is still accessible using !ping ' app.BLUE_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
            end            
        end

        % Value changed function: REDCheckBox
        function REDCheckBoxValueChanged(app, event)
                  
            set(app.RunSimButton,'enable','off');
            set(app.BuildCode,'enable','off');             
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.Step9EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off');    

            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off');

            set(app.EmptyMATLAB_wsButton,'enable','off');
            set(app.EmptyExperimentsSoftwareButton,'enable','off');
            set(app.BLACKMagnetOnButton,'enable','off');
            set(app.BLACKMagnetOffButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off');
            

            app.ConfirmSettings();
        end

        % Value changed function: BLACKCheckBox
        function BLACKCheckBoxValueChanged(app, event)
            set(app.RunSimButton,'enable','off');
            set(app.BuildCode,'enable','off');             
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.Step9EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off'); 

            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off'); 

            set(app.EmptyMATLAB_wsButton,'enable','off');
            set(app.EmptyExperimentsSoftwareButton,'enable','off');
            set(app.BLACKMagnetOnButton,'enable','off');
            set(app.BLACKMagnetOffButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off'); 
            

            app.ConfirmSettings();
        end

        % Value changed function: BLUECheckBox
        function BLUECheckBoxValueChanged(app, event)
            set(app.RunSimButton,'enable','off');
            set(app.BuildCode,'enable','off');             
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.Step9EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off');
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off');       

            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off'); 

            set(app.EmptyMATLAB_wsButton,'enable','off');
            set(app.EmptyExperimentsSoftwareButton,'enable','off');
            set(app.BLACKMagnetOnButton,'enable','off');
            set(app.BLACKMagnetOffButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off');   
            

            app.ConfirmSettings();
        end

        % Value changed function: ARMCheckBox
        function ARMCheckBoxValueChanged(app, event)
            
            % Can't have the arm without RED
            if app.REDCheckBox.Value == 0 && app.ARMCheckBox.Value == 1
                app.REDCheckBox.Value = 1;
            end
            
            set(app.RunSimButton,'enable','off');
            set(app.BuildCode,'enable','off');             
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
            set(app.Step9EstablishConnectionwithSelectedPlatformsButton,'enable','off');
            set(app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat,'enable','off');
            set(app.REDPucksOnButton,'enable','off');
            set(app.REDPucksOffButton,'enable','off');
            set(app.BLACKPucksOnButton,'enable','off');
            set(app.BLACKPucksOffButton,'enable','off'); 
            set(app.BLUEPucksOnButton,'enable','off');
            set(app.BLUEPucksOffButton,'enable','off');
            set(app.FireREDThrustersButton,'enable','off');
            set(app.FireBLACKThrustersButton,'enable','off');
            set(app.FireBLUEThrustersButton,'enable','off');
            set(app.OpenREDCMDShell,'enable','off');
            set(app.OpenBLACKCMDShell,'enable','off');
            set(app.OpenBLUECMDShell,'enable','off');    

            set(app.CyclePinsButtonBLACK,'enable','off');
            set(app.CyclePinsButtonRED,'enable','off');
            set(app.CyclePinsButtonBLUE,'enable','off');  

            set(app.EmptyMATLAB_wsButton,'enable','off');
            set(app.EmptyExperimentsSoftwareButton,'enable','off');
            set(app.BLACKMagnetOnButton,'enable','off');
            set(app.BLACKMagnetOffButton,'enable','off');
            set(app.SaveExperimentDataButton,'enable','off');  
            

            app.ConfirmSettings();
        end

        % Menu selected function: GitHubMenu
        function GitHubMenuSelected(app, event)
            web('https://github.com/Carleton-SRCL/SPOT/wiki','-browser' );
        end

        % Menu selected function: GooglePhotosMenu
        function GooglePhotosMenuSelected(app, event)
           web('https://photos.google.com/share/AF1QipNfJT6JaXArg4jsdNeEDJND8l6JiP75N8nS3p4yscl8leAFTLd5wW7gAhIgfqgyiQ?key=SmpJNUhQNE05OGh2NUhSWXc0UmFkZnJVbHdGZmJ3','-browser' );
        end

        % Menu selected function: YoutubeChannelMenu
        function YoutubeChannelMenuSelected(app, event)
            web('https://www.youtube.com/user/SRCLabCarleton','-browser' );
        end

        % Menu selected function: RunningaSimulationandExperimentMenu
        function RunningaSimulationandExperimentMenuSelected(app, event)
            web('https://youtu.be/N2YYbz6UxEw','-browser');
        end

        % Menu selected function: ChargingaLEDBatteryPackMenu
        function ChargingaLEDBatteryPackMenuSelected(app, event)
            web('https://youtu.be/_GZzT4T4Xq8','-browser');
        end

        % Menu selected function: ChargingaPlatformMenu
        function ChargingaPlatformMenuSelected(app, event)
            web('https://youtu.be/wDpUcpgEBgA','-browser');
        end

        % Menu selected function: InstallinganAirTankMenu
        function InstallinganAirTankMenuSelected(app, event)
            web('https://youtu.be/4xL3vRoF7FE','-browser');
        end

        % Menu selected function: InstallingtheRoboticArmMenu
        function InstallingtheRoboticArmMenuSelected(app, event)
            web('https://youtu.be/ZjW5lB-b85M','-browser');
        end

        % Menu selected function: ForkingtheSPOTRepositoryMenu
        function ForkingtheSPOTRepositoryMenuSelected(app, event)
            web('https://youtu.be/Vu77edOiv6o','-browser');
        end

        % Menu selected function: PlacingtheTableCoverMenu
        function PlacingtheTableCoverMenuSelected(app, event)
            web('https://youtu.be/UVv9MAwmUHA','-browser');
        end

        % Menu selected function: FillinganAirTankMenu
        function FillinganAirTankMenuSelected(app, event)
            web('https://youtu.be/2G6YD_iBmaA','-browser');
        end

        % Menu selected function: ExportingVideoswithQCViewerMenu
        function ExportingVideoswithQCViewerMenuSelected(app, event)
            web('https://youtu.be/H8Ht0rh1U1E','-browser');
        end

        % Menu selected function: CleaningthePucksandMovingaPlatformMenu
        function CleaningthePucksandMovingaPlatformMenuSelected(app, event)
            web('https://youtu.be/eQQ0q6dXufQ','-browser');
        end

        % Value changing function: AnimationScrubberSlider
        function AnimationScrubberSliderValueChanging(app, event)
            changingValue = event.Value/100*app.tsim;
            changingValue(changingValue<1)=1;

            CheckAnimationSelections(app);

            if (isempty(app.DataPacket)~=1)

                TimeVec = app.DataPacket(:,1);

                closestIndex = find(abs(TimeVec - changingValue) == min(abs(TimeVec - changingValue)), 1);

                AddPlatformSim(app,evalin("base","platformSelection")); 
                
                % Print time text:
                txtT = '';
                text(app.ExperimentPreview,0.1,3.75,txtT); 
                                        
                a = app.DataPacket(closestIndex,9);
                b = app.DataPacket(closestIndex,8);
                theta = -unwrap(app.DataPacket(closestIndex,10));
                
                if (isempty(app.REDFigHdl) ~= 1) 
                        
                    if app.ShowDockingConeRED == 1
        
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,1]);
        
                    else
        
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);
        
                    end
        
                    app.REDFigHdl.XData = spacecraft(:,1);
                    app.REDFigHdl.YData = spacecraft(:,2);
        
                    app.REDFigPatch.XData = spacecraft(:,1);
                    app.REDFigPatch.YData = spacecraft(:,2);
        
                    if app.ShowPathTraceRED == 1
                        app.REDPathPlot.XData = app.DataPacket(1:closestIndex,9);
                        app.REDPathPlot.YData = app.DataPacket(1:closestIndex,8);
                    end
                end
                
                x0 = app.DataPacket(closestIndex,9);
                y0 = app.DataPacket(closestIndex,8);
                q0 = -unwrap(app.DataPacket(closestIndex,10));
                  
                q1 = (-app.DataPacket(closestIndex,32)+3*pi/2);
                q2 = -app.DataPacket(closestIndex,33);
                q3 = -(app.DataPacket(closestIndex,34));  

                [rs,re,rw,ree] = app.CalculateArmPosition([x0,y0,q0,q1,q2,q3]);

                if (isempty(app.REDFigLink1) ~= 1) 
                    
                    app.REDFigLink1.XData = [rs(1,1)  re(1,1)];
                    app.REDFigLink1.YData = [rs(1,2)  re(1,2)];
                    app.REDFigLink2.XData = [re(1,1)  rw(1,1)];
                    app.REDFigLink2.YData = [re(1,2)  rw(1,2)];
                    app.REDFigLink3.XData = [rw(1,1)  ree(1,1)];
                    app.REDFigLink3.YData = [rw(1,2)  ree(1,2)];
                    app.REDFigJoin0.XData = rs(1,1);
                    app.REDFigJoin0.YData = rs(1,2);
                    app.REDFigJoin1.XData = re(1,1);
                    app.REDFigJoin1.YData = re(1,2);
                    app.REDFigJoin2.XData = rw(1,1);
                    app.REDFigJoin2.YData = rw(1,2);
                    app.REDFigJoin3.XData = ree(1,1);
                    app.REDFigJoin3.YData = ree(1,2);
                                            
                end
                
                a = app.DataPacket(closestIndex,51);
                b = app.DataPacket(closestIndex,50);
                theta = -unwrap(app.DataPacket(closestIndex,52));
                
                if (isempty(app.BLACKFigHdl) ~= 1) 
                    if app.ShowSolarPanelsBLACK == 1 && app.ShowDockingPortBLACK == 0
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,5]);
                    elseif app.ShowSolarPanelsBLACK == 1 && app.ShowDockingPortBLACK == 1
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,4]);
                    elseif app.ShowSolarPanelsBLACK == 0 && app.ShowDockingPortBLACK == 1
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,2]);
                    else
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);
                    end
        
                    app.BLACKFigHdl.XData = spacecraft(:,1);
                    app.BLACKFigHdl.YData = spacecraft(:,2);
                    app.BLACKFigPatch.XData = spacecraft(:,1);
                    app.BLACKFigPatch.YData = spacecraft(:,2);
        
                    if app.ShowPathTraceBLACK == 1
                        app.BLACKPathPlot.XData = app.DataPacket(1:closestIndex,45);
                        app.BLACKPathPlot.YData = app.DataPacket(1:closestIndex,44);
                    end  
                    
                end
                
                a = app.DataPacket(closestIndex,77);
                b = app.DataPacket(closestIndex,76);
                theta = -unwrap(app.DataPacket(closestIndex,78));
                
                if (isempty(app.BLUEFigHdl) ~= 1) 

                    spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);
        
                    app.BLUEFigHdl.XData = spacecraft(:,1);
                    app.BLUEFigHdl.YData = spacecraft(:,2);
                    app.BLUEFigPatch.XData = spacecraft(:,1);
                    app.BLUEFigPatch.YData = spacecraft(:,2);
        
                    if app.ShowPathTraceBLUE == 1
                        app.BLUEPathPlot.XData = app.DataPacket(1:closestIndex,77);
                        app.BLUEPathPlot.YData = app.DataPacket(1:closestIndex,76);
                    end 
                    
                end                    
      
                app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(app.DataPacket(closestIndex,1)),' s');
            end

        end

        % Button pushed function: EmptyMATLAB_wsButton
        function EmptyMATLAB_wsButtonPushed(app, event)
             
            answer = questdlg('Warning: You are about to PERMANENTLY DELETE all files under /ExperimentSoftware/MATLAB_ws/. This action cannot be undone','Software Cleanup Warning','Understood','Cancel','Cancel');
            
            if answer ~= "Cancel"
                            
                if (app.REDCheckBox.Value == 1)
                    
                    fprintf('Checking connection to RED platform...\n')
                    [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out ExperimentSoftware/MATLAB_ws/ on RED...')
                        
                        try                  
                            system(app.REDConnection, 'rm -r ExperimentSoftware/MATLAB_ws/');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to RED, files not deleted.\n')
                    end
               
                end
                
                if (app.BLACKCheckBox.Value == 1)
                    
                    fprintf('Checking connection to BLACK platform...\n')
                    [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out ExperimentSoftware/MATLAB_ws/ on BLACK...')
                        
                        try                 
                            system(app.BLACKConnection, 'rm -r ExperimentSoftware/MATLAB_ws/');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLACK, files not deleted.\n')
                    end
               
                end
    
                if (app.BLUECheckBox.Value == 1)
                    
                    fprintf('Checking connection to BLUE platform...\n')
                    [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out ExperimentSoftware/MATLAB_ws/ on BLUE...')
                        try                 
                            system(app.BLUEConnection, 'rm -r ExperimentSoftware/MATLAB_ws/');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLUE, files not deleted.\n')
                    end
               
                end
                                
            else
                
                %Do Nothing
                
            end
            
        end

        % Button pushed function: 
        % Step8EstablishConnectionwithSelectedPlatformsButtonRepeat, 
        % ...and 1 other component
        function Step8EstablishConnectionwithSelectedPlatformsButtonPushed(app, event)
            %waitbox = app.CustomDialogBox('Establishing connection with selected platform. Wait for dialog to close before proceeding to any hardware control.', 'Connecting to hardware...')

            waitbox = app.CustomWaitBox('Connecting to hardware...');
            errFlag = 0;
            if ((app.REDCheckBox.Value == 1) && ~isempty(app.REDConnection)) || ((app.BLACKCheckBox.Value == 1) && ~isempty(app.BLACKConnection)) || ((app.BLUECheckBox.Value == 1) && ~isempty(app.BLUEConnection))
                Choice = questdlg('Warning: A connection between the groundstation and the platforms already exists. If you are attempting a re-connection, select "Proceed".','Connection Warning','Proceed','Cancel','Proceed');
            else
                Choice = 'Proceed';
            end
            
            switch Choice
                case 'Proceed'

                    app.REDConnection = [];
                    app.BLACKConnection = [];
                    app.BLUEConnection = [];
                    try
                        if (app.REDCheckBox.Value == 1)

                            [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
                            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                    
                                app.REDConnection = jetson(app.RED_SSH_Address,app.RED_SSH_Name,app.RED_SSH_Password);
                
                                set(app.REDPucksOnButton,'enable','on');
                                set(app.REDPucksOffButton,'enable','off');
                                set(app.FireREDThrustersButton,'enable','on');
                                set(app.OpenREDCMDShell,'enable','on');
                                set(app.CyclePinsButtonRED,'enable','on');
                
                            else
                                errFlag = 1;
                            end
 
                        end

                        if (app.BLACKCheckBox.Value == 1)

                            [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
                            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                   
                                app.BLACKConnection = jetson(app.BLACK_SSH_Address,app.BLACK_SSH_Name,app.BLACK_SSH_Password);
                                
                                set(app.BLACKPucksOnButton,'enable','on');
                                set(app.BLACKPucksOffButton,'enable','off');
                                set(app.FireBLACKThrustersButton,'enable','on');
                                set(app.OpenBLACKCMDShell,'enable','on');
                                set(app.CyclePinsButtonBLACK,'enable','on');
                                
                                set(app.BLACKMagnetOnButton,'enable','on');
                                set(app.BLACKMagnetOffButton,'enable','off');

                            else
                                errFlag = 1;
                            end
                            
                        end

                        if (app.BLUECheckBox.Value == 1)
                            [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
                            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                   
                                app.BLUEConnection = jetson(app.BLUE_SSH_Address,app.BLUE_SSH_Name,app.BLUE_SSH_Password);
                
                                set(app.BLUEPucksOnButton,'enable','on');
                                set(app.BLUEPucksOffButton,'enable','off');
                                set(app.FireBLUEThrustersButton,'enable','on');
                                set(app.OpenBLUECMDShell,'enable','on');
                                set(app.CyclePinsButtonBLUE,'enable','on'); 
                            else
                                errFlag = 1;
                            end
                             
                        end 

                        if errFlag == 0
                            app.ConnectionStatus_1.Color = [0.00,1.00,0.00];
                            app.ConnectionStatus_2.Color = [0.00,1.00,0.00];
                            set(app.BuildCode,'enable','on');
                            set(app.PhaseSpaceSwitch,'enable','on');
                            set(app.EmptyMATLAB_wsButton,'enable','on');
                            set(app.EmptyExperimentsSoftwareButton,'enable','on');
                            set(app.SaveExperimentDataButton,'enable','on');
                        else
                            app.ConnectionStatus_1.Color = [1.00,0.00,0.00];
                            app.ConnectionStatus_2.Color = [1.00,0.00,0.00];
                            set(app.BuildCode,'enable','off');
                            set(app.EmptyMATLAB_wsButton,'enable','off');
                            set(app.EmptyExperimentsSoftwareButton,'enable','off');
                            set(app.SaveExperimentDataButton,'enable','off');
                            ed = errordlg("There was a problem connecting to one of the selected platforms. Ensure the platforms are all ON.","Connection Error");
                            set(ed, "WindowStyle", "modal");
                            uiwait(ed);
                        end
                    catch
                        ed = errordlg("There was a problem connecting to one of the selected platforms. Ensure the platforms are all ON.","Connection Error");
                        set(ed, "WindowStyle", "modal");
                        uiwait(ed);
                    end
                case 'Cancel'
                    % Do Nothing
            end

            close(waitbox); 
        end

        % Value changed function: PhaseSpaceSwitch
        function PhaseSpaceSwitchValueChanged(app, event)
            
            value = app.PhaseSpaceSwitch.Value;
            
            if value == "On"
                
                try
                    app.baseRate = evalin("base","baseRate");
                    app.dataRate = evalin("base","dataRate");
                    CurrentDir = cd();
                    system(['cd "' CurrentDir '' filesep 'Resources' filesep '' '" & start StreamData.exe ' num2str(round((1/app.dataRate))) ' &']);
                    %system(['cd "' CurrentDir '' filesep 'Resources' filesep '' '" & start StreamData.exe ' num2str(round((1/app.baseRate))) ' &']);

                    pause(5)

                    %app.ToggleRealTimeTelemetrySwitchValueChanged();
                    [~, result] = system('tasklist /FI "IMAGENAME eq StreamData.exe"');

                    if contains(result, 'No tasks are running')                    
                        waitfor(warndlg('Could not start cameras.','Cameras Failed'));
                        app.PhaseSpaceSwitch.Value = 'Off';
                        %app.ToggleRealTimeTelemetrySwitch.Value = 'Off';
                        %app.ToggleRealTimeTelemetrySwitch.Enable = 'Off';
                        app.StartExperimentButton.Enable = 'Off';
                        %app.StopExperimentButton.Enable = 'Off';
                        %app.ToggleRealTimeTelemetrySwitchValueChanged();
                    else
                        app.ToggleRealTimeTelemetrySwitch.Enable = 'On';
                        %app.ToggleRealTimeTelemetrySwitch.Value = 'On';
                        if (app.REDCheckBox.Value == 1 && isempty(app.REDConnection)) || (app.BLACKCheckBox.Value == 1 && isempty(app.BLACKConnection)) || (app.BLUECheckBox.Value == 1 && isempty(app.BLUEConnection)) || (app.REDCheckBox.Value == 0 && app.BLACKCheckBox.Value == 0 && app.BLUECheckBox.Value == 0)
                            waitfor(warndlg('Cameras were started, but you cannot start an experiment unless you are connected to the active hardware.','No Hardware Connection'));
                        else
                            app.StartExperimentButton.Enable = 'On';
                        end
                        %app.StopExperimentButton.Enable = 'On';
                        AddPlatformLive(app,evalin("base","platformSelection"));
                        app.CamerasOnLamp.Color = [0.00,1.00,0.00];
                    end 

                catch
                    waitfor(warndlg('Could not start cameras.','Cameras Failed'));
                    %app.ToggleRealTimeTelemetrySwitch.Value = 'Off';
                    %app.ToggleRealTimeTelemetrySwitch.Enable = 'Off';
                    app.StartExperimentButton.Enable = 'Off';
                    %app.StopExperimentButton.Enable = 'Off';

                    %app.ToggleRealTimeTelemetrySwitchValueChanged();
                end
                
            else
                app.CamerasOnLamp.Color = [1.00,0.00,0.00];
                app.ToggleRealTimeTelemetrySwitch.Value = 'Off';
                app.ToggleRealTimeTelemetrySwitch.Enable = 'Off';
                system("taskkill/im StreamData.exe /F");
                app.StartExperimentButton.Enable = 'Off';
                %app.StopExperimentButton.Enable = 'Off';
                
                app.ToggleRealTimeTelemetrySwitchValueChanged();

            end

        end

        % Button pushed function: CyclePinsButtonBLACK
        function CyclePinsButtonBLACKPushed(app, event)
            
            waitbox = app.CustomWaitBox('Closing all BLACK GPIO Pins...');
            app.CleanShutdown(app.BLACKConnection);
            close(waitbox);

        end

        % Button pushed function: CyclePinsButtonBLUE
        function CyclePinsButtonBLUEPushed(app, event)
            
            waitbox = app.CustomWaitBox('Closing all BLUE GPIO Pins...');
            app.CleanShutdown(app.BLUEConnection);
            close(waitbox);

        end

        % Button pushed function: CyclePinsButtonRED
        function CyclePinsButtonREDPushed(app, event)
            
            waitbox = app.CustomWaitBox('Closing all RED GPIO Pins...');
            app.CleanShutdown(app.REDConnection);   
            close(waitbox);

        end

        % Value changed function: AvailableDiagramsDropDown
        function AvailableDiagramsDropDownValueChanged(app, event)
            
            if app.AvailableDiagramsDropDown.Value == "Select a Diagram"
                return;
            end
            
            waitbox = app.CustomWaitBox('Loading selected Simulink diagram...');

            FileName = app.AvailableDiagramsDropDown.Value;

            open(FileName); 

            is_open = app.isSLXOpen(app.AvailableDiagramsDropDown.Value);

            while is_open == 0
                is_open = app.isSLXOpen(app.AvailableDiagramsDropDown.Value);
            end
            
            set(app.REDCheckBox,'enable','on');
            set(app.BLACKCheckBox,'enable','on');
            set(app.BLUECheckBox,'enable','on');
            set(app.ARMCheckBox,'enable','on');

            close(waitbox); 

        end

        % Value changed function: DurPhase0EditField
        function DurPhase0EditFieldValueChanged(app, event)
            
            app.Phase0_Duration = app.DurPhase0EditField.Value;
            app.UpdateTimes();

        end

        % Value changed function: DurPhase1EditField
        function DurPhase1EditFieldValueChanged(app, event)
            
            app.Phase1_Duration = app.DurPhase1EditField.Value;
            app.UpdateTimes();
           
        end

        % Value changed function: DurPhase2EditField
        function DurPhase2EditFieldValueChanged(app, event)

            app.Phase2_Duration = app.DurPhase2EditField.Value;
            app.UpdateTimes();

        end

        % Value changed function: SubPhase1EditField
        function SubPhase1EditFieldValueChanged(app, event)
            
            app.Phase3_SubPhase1_Duration = app.SubPhase1EditField.Value;
            app.UpdateTimes();
            
        end

        % Value changed function: SubPhase2EditField
        function SubPhase2EditFieldValueChanged(app, event)
            app.Phase3_SubPhase2_Duration = app.SubPhase2EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: SubPhase3EditField
        function SubPhase3EditFieldValueChanged(app, event)
            app.Phase3_SubPhase3_Duration = app.SubPhase3EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: SubPhase4EditField
        function SubPhase4EditFieldValueChanged(app, event)
            app.Phase3_SubPhase4_Duration = app.SubPhase4EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: DurPhase4EditField
        function DurPhase4EditFieldValueChanged(app, event)
            app.Phase4_Duration = app.DurPhase4EditField.Value;
            app.UpdateTimes();
        end

        % Value changed function: DurPhase5EditField
        function DurPhase5EditFieldValueChanged(app, event)
            app.Phase5_Duration = app.DurPhase5EditField.Value;
            app.UpdateTimes();
        end

        % Callback function
        function AuxiliaryEditFieldValueChanged(app, event)
            app.UpdateAuxParameters();
        end

        % Value changed function: SolverTypeDropDown
        function SolverTypeDropDownValueChanged(app, event)

            FileName = app.AvailableDiagramsDropDown.Value;
            set_param(FileName(1:(end-4)),'SolverType',app.SolverTypeDropDown.Value);

            if app.SolverTypeDropDown.Value == "Variable-step"
                app.SolverNameVarStepDropDown.Enable = "on";
                app.SolverNameVarStepDropDown.Visible = "on";
                app.SolverNameDropDown.Enable = "off";
                app.SolverNameDropDown.Visible = "off";
                set(app.VarStepAbsTolEditField,'enable','on');
                set(app.VarStepRelTolEditField,'enable','on');
                set(app.VarStepInitStepSizeEditField,'enable','on');
                set(app.VarStepMinStepSizeEditField,'enable','on');
                set(app.VarStepMaxStepSizeEditField,'enable','on');
            else
                app.SolverNameVarStepDropDown.Enable = "off";
                app.SolverNameVarStepDropDown.Visible = "off";
                app.SolverNameDropDown.Enable = "on";
                app.SolverNameDropDown.Visible = "on";
                set(app.VarStepAbsTolEditField,'enable','off');
                set(app.VarStepRelTolEditField,'enable','off');
                set(app.VarStepInitStepSizeEditField,'enable','off');
                set(app.VarStepMinStepSizeEditField,'enable','off');
                set(app.VarStepMaxStepSizeEditField,'enable','off');
            end            

            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Menu selected function: SaveGUIStateMenu
        function SaveGUIStateMenuSelected(app, event)
            waitbox = app.CustomWaitBox('Saving GUI Parameters...');
           
            [filename, pathname] = uiputfile('*.mat', 'Save As');

            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)

                return

            else

                % Initialize an empty struct
                appState = struct();
                              
                % Get the list of properties from the app object
                properties = fieldnames(app);
                
                % Loop through the properties
                for i = 1:length(properties)
                     % Try to save the 'Value' property to the struct
                    try
                        appState.(properties{i}) = app.(properties{i}).Value;
                    catch
                        % If the 'Value' property cannot be saved, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end
                
                subAppState = struct();
                try
                    propertiesSubApp = fieldnames(app.SubAppMassProperties);
    
                    % Loop through the properties
                    for i = 1:length(propertiesSubApp)
                         % Try to save the 'Value' property to the struct
                        try
                            subAppState.(propertiesSubApp{i}) = app.SubAppMassProperties.(propertiesSubApp{i}).Value;
                        catch
                            % If the 'Value' property cannot be saved, skip it
                            %fprintf('Skipping property %s\n', properties{i});
                        end
                    end
                catch
                end

                subAppStateInit = struct();
                try
                    propertiesSubApp = fieldnames(app.SubAppInitialConditions);
    
                    % Loop through the properties
                    for i = 1:length(propertiesSubApp)
                         % Try to save the 'Value' property to the struct
                        try
                            subAppStateInit.(propertiesSubApp{i}) = app.SubAppInitialConditions.(propertiesSubApp{i}).Value;
                        catch
                            % If the 'Value' property cannot be saved, skip it
                            %fprintf('Skipping property %s\n', properties{i});
                        end
                    end
                catch
                end
                % Save the appState variable to a .mat file
                save(fullfile(pathname, filename), 'appState', 'subAppState', 'subAppStateInit');

            end

            close(waitbox);

        end

        % Menu selected function: LoadGUIStateMenu
        function LoadGUIStateMenuSelected(app, event)
            
            waitbox = app.CustomWaitBox('Loading GUI Parameters...');

            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file');
            
            % Call the public load function
            app.LoadDataPublicFcn(pathname,filename);

            % Close the waitbox
            close(waitbox);


        end

        % Menu selected function: ResetGUIParameterstoDefaultMenu
        function ResetGUIParameterstoDefaultMenuSelected(app, event)
            
            waitbox = app.CustomWaitBox('Loading default GUI parameters...');

            % Open a file browser and let the user select a .mat file
            filename = 'SPOTGUI_DEFAULT.mat';
            pathname = [cd '/Resources/'];
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else

                % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'appState');
                    
                    % Get the list of properties from the appState struct
                    properties = fieldnames(appState);
                catch
                    ed = errordlg("The file you just tried to load does not contain the variable 'appState'. Confirm you are loading a proper GUI state file.","Error");
                    set(ed, "WindowStyle", "modal");
                    uiwait(ed);
                    return
                end

                 % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'subAppState');
                    
                    % Get the list of properties from the appState struct
                    propertiesSubApp = fieldnames(subAppState);
                catch
                    subAppState = struct();
                    propertiesSubApp = fieldnames(subAppState);
                end

                 % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'subAppStateInit');
                    
                    % Get the list of properties from the appState struct
                    propertiesSubAppInit = fieldnames(subAppStateInit);
                catch
                    subAppStateInit = struct();
                    propertiesSubAppInit = fieldnames(subAppStateInit);
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(properties)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.(properties{i}).Value = appState.(properties{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(propertiesSubApp)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.SubAppMassProperties.(propertiesSubApp{i}).Value = subAppState.(propertiesSubApp{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                % Loop through the properties and restore their values to the app
                for i = 1:length(propertiesSubAppInit)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.SubAppInitialConditions.(propertiesSubAppInit{i}).Value = subAppStateInit.(propertiesSubAppInit{i});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                app.AvailableDiagramsDropDownValueChanged();
                app.UpdateTimes();
                app.UpdateAuxParameters();
                app.UpdateSimulationParameters();
                app.SubAppMassProperties.UpdateMassProperties();
                app.SubAppInitialConditions.UpdateInitialConditions();
                app.REDCheckBoxValueChanged();
                app.BLACKCheckBoxValueChanged();
                app.BLUECheckBoxValueChanged();
                app.ARMCheckBoxValueChanged();
                app.DisableAllKeyButtons();

                close(waitbox);
            end
        end

        % Value changed function: SolverNameDropDown
        function SolverNameDropDownValueChanged(app, event)
            value = app.SolverNameDropDown.Value;
            FileName = app.AvailableDiagramsDropDown.Value;
            set_param(FileName(1:(end-4)),'SolverName',value);
        end

        % Callback function
        function StopSimulationButtonPushed(app, event)
            selectedFile = get(app.AvailableDiagramsDropDown,'Value');
            set_param(selectedFile(1:(end-4)),'SimulationCommand','stop')
            %assignin('base','simOut',simOut);
        end

        % Button pushed function: LoadDataButtonPlt1
        function LoadDataButtonPlt1Pushed(app, event)
            % Check if there's an existing figure (your app's main window)
            currentFig = app.SPOT41GUIUIFigure;
            
            % Create a waitbar for the data loading
            waitbox = app.CustomWaitBox('Loading Data...');
            
            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file');
            
            % Bring your app's window back to the forefront only if currentFig is not empty
            if ~isempty(currentFig)
                figure(currentFig);
            end
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                tmp   = load([pathname filename]);
                tmpfn = fieldnames(tmp);
                app.LoadedData1 = tmp.(tmpfn{1});
            
                if isstruct(app.LoadedData1)
                    app.SelectXDataPlt1.Items = fieldnames(app.LoadedData1);
                    app.SelectYDataPlt1.Items = fieldnames(app.LoadedData1);
                else
                    waitfor(warndlg('Could not load data file, please ensure the data file is a struct.','Load Failed'));
                end
            end
            
            close(waitbox);

        end

        % Value changed function: SelectXDataPlt1
        function SelectXDataPlt1ValueChanged(app, event)
            dataX = app.LoadedData1.(app.SelectXDataPlt1.Value);
            dataY = app.LoadedData1.(app.SelectYDataPlt1.Value);
            if isa(dataX,'timeseries') && isa(dataY,'timeseries')
                dataX = dataX.data;
                dataY = dataY.data;
                % Handle overplotting logic
                if app.AllowOverplotCheckBox.Value == 1
                    hold(app.DataViewerPlt1,"on");
                else
                    hold(app.DataViewerPlt1,"off");
                end
                plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt1,"on");
                %xaxis(app.DataViewerPlt1,"xy");
                axis(app.DataViewerPlt1,"tight");
                %xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1.Value,'_',' '));
                %ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1.Value,'_',' '));
            elseif isa(dataX,'struct') && isa(dataY,'struct')
                % Handle overplotting logic
                if app.AllowOverplotCheckBox.Value == 1
                    hold(app.DataViewerPlt1,"on");
                else
                    hold(app.DataViewerPlt1,"off");
                end
                % do nothing for x and y
                plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt1,"on");
                %xaxis(app.DataViewerPlt1,"xy");
                axis(app.DataViewerPlt1,"tight");
                %xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1.Value,'_',' '));
                %ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1.Value,'_',' '));
            else
                waitfor(warndlg('Warning: The data file you are trying to load is not valid. Only data Timeseries and Structs are supported.','Invalid Data Type'));
            end

        end

        % Value changed function: FilterSignalCheckBoxPlt1, SelectYDataPlt1
        function SelectYDataPlt1ValueChanged(app, event)
            dataX = app.LoadedData1.(app.SelectXDataPlt1.Value);
            dataY = app.LoadedData1.(app.SelectYDataPlt1.Value);
            if isa(dataX,'timeseries') && isa(dataY,'timeseries')
                dataX = dataX.data;
                dataY = dataY.data;
                % Handle overplotting logic
                if app.AllowOverplotCheckBox.Value == 1
                    hold(app.DataViewerPlt1,"on");
                else
                    hold(app.DataViewerPlt1,"off");
                end
                plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt1,"on");
                %xaxis(app.DataViewerPlt1,"xy");
                axis(app.DataViewerPlt1,"tight");
                %xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1.Value,'_',' '));
                %ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1.Value,'_',' '));
            elseif isa(dataX,'struct') && isa(dataY,'struct')
                % Handle overplotting logic
                if app.AllowOverplotCheckBox.Value == 1
                    hold(app.DataViewerPlt1,"on");
                else
                    hold(app.DataViewerPlt1,"off");
                end
                % do nothing for x and y
                plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt1,"on");
                %xaxis(app.DataViewerPlt1,"xy");
                axis(app.DataViewerPlt1,"tight");
                %xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1.Value,'_',' '));
                %ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1.Value,'_',' '));
            else
                waitfor(warndlg('Warning: The data file you are trying to load is not valid. Only data Timeseries and Structs are supported.','Invalid Data Type'));
            end

        end

        % Button pushed function: LoadDataButtonPlt2
        function LoadDataButtonPlt2Pushed(app, event)
            % Check if there's an existing figure (your app's main window)
            currentFig = app.SPOT41GUIUIFigure;
            
            % Create a waitbar for the data loading
            waitbox = app.CustomWaitBox('Loading Data...');
            
            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file');
            
            % Bring your app's window back to the forefront only if currentFig is not empty
            if ~isempty(currentFig)
                figure(currentFig);
            end
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                tmp   = load([pathname filename]);
                tmpfn = fieldnames(tmp);
                app.LoadedData2 = tmp.(tmpfn{1});
                
                if isstruct(app.LoadedData2)

                    app.SelectXDataPlt2.Items = fieldnames(app.LoadedData2);
                    app.SelectYDataPlt2.Items = fieldnames(app.LoadedData2);
                
                else

                    waitfor(warndlg('Could not load data file, please ensure the data file is a struct.','Load Failed'));

                end


            end     
            close(waitbox);
        end

        % Value changed function: SelectXDataPlt2
        function SelectXDataPlt2ValueChanged(app, event)
            dataX = app.LoadedData2.(app.SelectXDataPlt2.Value);
            dataY = app.LoadedData2.(app.SelectYDataPlt2.Value);
            if isa(dataX,'timeseries') && isa(dataY,'timeseries')
                dataX = dataX.data;
                dataY = dataY.data;
                % Handle overplotting logic
                if app.AllowOverplotCheckBoxPlt2.Value == 1
                    hold(app.DataViewerPlt2,"on");
                else
                    hold(app.DataViewerPlt2,"off");
                end
                plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0), 'k');
                grid(app.DataViewerPlt2,"on");
                %xaxis(app.DataViewerPlt2,"xy");
                axis(app.DataViewerPlt2,"tight");
                %xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2.Value,'_',' '));
                %ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2.Value,'_',' ')); 
            elseif isa(dataX,'struct') && isa(dataY,'struct')
                % do nothing for x and y
                % Handle overplotting logic
                if app.AllowOverplotCheckBoxPlt2.Value == 1
                    hold(app.DataViewerPlt2,"on");
                else
                    hold(app.DataViewerPlt2,"off");
                end
                plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0), 'k');
                grid(app.DataViewerPlt2,"on");
                %xaxis(app.DataViewerPlt2,"xy");
                axis(app.DataViewerPlt2,"tight");
                %xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2.Value,'_',' '));
                %ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2.Value,'_',' ')); 
            else
                waitfor(warndlg('Warning: The data file you are trying to load is not valid. Only data Timeseries and Structs are supported.','Invalid Data Type'));
            end
        end

        % Value changed function: FilterYSignalCheckBoxPlt2, 
        % ...and 1 other component
        function SelectYDataPlt2ValueChanged(app, event)

            dataX = app.LoadedData2.(app.SelectXDataPlt2.Value);
            dataY = app.LoadedData2.(app.SelectYDataPlt2.Value);
            if isa(dataX,'timeseries') && isa(dataY,'timeseries')
                dataX = dataX.data;
                dataY = dataY.data;
                if app.FilterYSignalCheckBoxPlt2.Value == 1
                    Time = app.LoadedData2.('Time_s').data;
                    dataY = lowpass(dataY,0.01,1/mean(diff(Time)));
                end
                % Handle overplotting logic
                if app.AllowOverplotCheckBoxPlt2.Value == 1
                    hold(app.DataViewerPlt2,"on");
                else
                    hold(app.DataViewerPlt2,"off");
                end
            
                plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt2,"on");
                %xaxis(app.DataViewerPlt2,"xy");
                axis(app.DataViewerPlt2,"tight");
                %xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2.Value,'_',' '));
                %ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2.Value,'_',' ')); 
            elseif isa(dataX,'struct') && isa(dataY,'struct')
                if app.FilterYSignalCheckBoxPlt2.Value == 1
                    Time = app.LoadedData2.('Time_s');
                    dataY = lowpass(dataY,0.01,1/mean(diff(Time)));
                end
                % do nothing for x and y
                % Handle overplotting logic
                if app.AllowOverplotCheckBoxPlt2.Value == 1
                    hold(app.DataViewerPlt2,"on");
                else
                    hold(app.DataViewerPlt2,"off");
                end
                plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt2,"on");
                %xaxis(app.DataViewerPlt2,"xy");
                axis(app.DataViewerPlt2,"tight");
                %xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2.Value,'_',' '));
                %ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2.Value,'_',' ')); 
            else
                waitfor(warndlg('Warning: The data file you are trying to load is not valid. Only data Timeseries and Structs are supported.','Invalid Data Type'));
            end
        end

        % Button pushed function: ExportFiguretoPDFButton
        function ExportFiguretoPDFButtonPushed(app, event)
            % Set the default figure name
            DefaultFigureName = 'DataViewerWindow_Plot1';
            % Get all PDF files in the current directory
            fileNameExists = dir('*.pdf');
            
            % Function to generate the new filename
            newFileName = DefaultFigureName;
            counter = 1;
            
            % Check if the default filename exists and find a unique name
            while any(strcmp({fileNameExists.name}, [newFileName '.pdf']))
                newFileName = [DefaultFigureName '_' num2str(counter)];
                counter = counter + 1;
            end
            
            % Display the saving message
            waitbox = app.CustomWaitBox(['Saving figure to ' newFileName '.pdf...']);
            
            % Try to export the figure
            try
                exportgraphics(app.DataViewerPlt1, [newFileName '.pdf'], 'ContentType', 'vector');
            catch
                waitfor(warndlg('Could not export to PDF. Please confirm a file with the same name is not currently open.', 'Save to PDF Failed'));
            end
            
            % Close the wait box
            close(waitbox);
        end

        % Button pushed function: ExporttoPDFButton2
        function ExporttoPDFButton2Pushed(app, event)
            % Set the default figure name
            DefaultFigureName = 'DataViewerWindow_Plot2';
            % Get all PDF files in the current directory
            fileNameExists = dir('*.pdf');
            
            % Function to generate the new filename
            newFileName = DefaultFigureName;
            counter = 1;
            
            % Check if the default filename exists and find a unique name
            while any(strcmp({fileNameExists.name}, [newFileName '.pdf']))
                newFileName = [DefaultFigureName '_' num2str(counter)];
                counter = counter + 1;
            end
            
            % Display the saving message
            waitbox = app.CustomWaitBox(['Saving figure to ' newFileName '.pdf...']);
            
            % Try to export the figure
            try
                exportgraphics(app.DataViewerPlt1, [newFileName '.pdf'], 'ContentType', 'vector');
            catch
                waitfor(warndlg('Could not export to PDF. Please confirm a file with the same name is not currently open.', 'Save to PDF Failed'));
            end
            
            % Close the wait box
            close(waitbox);
        end

        % Value changed function: VarStepMaxStepSizeEditField
        function VarStepMaxStepSizeEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepMaxStepSize = app.VarStepMaxStepSizeEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: VarStepMinStepSizeEditField
        function VarStepMinStepSizeEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepMinStepSize = app.VarStepMinStepSizeEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: VarStepInitStepSizeEditField
        function VarStepInitStepSizeEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepInitStepSize = app.VarStepInitStepSizeEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: VarStepRelTolEditField
        function VarStepRelTolEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepRelTol = app.VarStepRelTolEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: VarStepAbsTolEditField
        function VarStepAbsTolEditFieldValueChanged(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            app.varStepAbsTol = app.VarStepAbsTolEditField.Value;
            app.UpdateSimulationParameters(FileName(1:end-4));
        end

        % Value changed function: SolverNameVarStepDropDown
        function SolverNameVarStepDropDownValueChanged(app, event)
            value = app.SolverNameVarStepDropDown.Value;
            FileName = app.AvailableDiagramsDropDown.Value;
            set_param(FileName(1:(end-4)),'SolverName',value);
        end

        % Value changed function: ToggleRealTimeTelemetrySwitch
        function ToggleRealTimeTelemetrySwitchValueChanged(app, event)
            value = app.ToggleRealTimeTelemetrySwitch.Value;
            

            app.hudpr = dsp.UDPReceiver('LocalIPPort',31534,'ReceiveBufferSize' ,8192,'MessageDataType','double');

            %AddPlatformLive(app,evalin("base","platformSelection")); 

            while value == "On"  
                %cla(app.ExperimentPreviewLive);
                app.CheckAnimationSelectionsLive();
                value = app.ToggleRealTimeTelemetrySwitch.Value;
                dataR = app.hudpr();
                                
                % Get latest data packet:
               
                if isempty(dataR)

                    continue;

                end

                if size(dataR) ~= 10

                    continue;

                end

                % Update the indicator color               
                app.RealTimeTelOnLamp.Color = [0.00,1.00,0.00];

                % Update the text boxes
                app.mathrmX_REDEditField.Value = dataR(2)/1000;
                app.mathrmY_REDEditField.Value = dataR(3)/1000;
                app.mathrmtheta_REDEditField.Value = dataR(4)*180/pi;

                app.mathrmX_BLACKEditField.Value = dataR(5)/1000;
                app.mathrmY_BLACKEditField.Value = dataR(6)/1000;
                app.mathrmtheta_BLACKEditField.Value = dataR(7)*180/pi;

                app.mathrmX_BLUEEditField.Value = dataR(8)/1000;
                app.mathrmY_BLUEEditField.Value = dataR(9)/1000;
                app.mathrmtheta_BLUEEditField.Value = dataR(10)*180/pi;

                if app.REDCheckBox.Value

                    % Draw the platforms:
                    a = dataR(3)/1000;
                    b = dataR(2)/1000;
                    theta = -dataR(4);

                    if (isempty(app.REDFigLiveHdl) ~= 1) 

                        if app.ShowDockingConeREDLive == 1

                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,1]);

                        else

                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);

                        end
                                                      
                        app.REDFigLiveHdl.XData = spacecraft(:,1);
                        app.REDFigLiveHdl.YData = spacecraft(:,2);

                        app.REDFigLivePatch.XData = spacecraft(:,1);
                        app.REDFigLivePatch.YData = spacecraft(:,2);

                    end

                end

                if app.BLACKCheckBox.Value == 1

                    a = dataR(6)/1000;
                    b = dataR(5)/1000;
                    theta = -dataR(7);

                    if (isempty(app.BLACKFigLiveHdl) ~= 1) 

                        if app.ShowSolarPanelsBLACKLive == 1 && app.ShowDockingPortBLACKLive == 0
                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,5]);
                        elseif app.ShowSolarPanelsBLACKLive == 1 && app.ShowDockingPortBLACKLive == 1
                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,4]);
                        elseif app.ShowSolarPanelsBLACKLive == 0 && app.ShowDockingPortBLACKLive == 1
                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,2]);
                        else
                            spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);
                        end
                                                      
                        app.BLACKFigLiveHdl.XData = spacecraft(:,1);
                        app.BLACKFigLiveHdl.YData = spacecraft(:,2);

                        app.BLACKFigLivePatch.XData = spacecraft(:,1);
                        app.BLACKFigLivePatch.YData = spacecraft(:,2);

                    end

                end

                if app.BLUECheckBox.Value == 1

                    a = dataR(9)/1000;
                    b = dataR(8)/1000;
                    theta = -dataR(10);

                    if (isempty(app.BLUEFigLiveHdl) ~= 1) 

                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);

                        app.BLUEFigLiveHdl.XData = spacecraft(:,1);
                        app.BLUEFigLiveHdl.YData = spacecraft(:,2);

                        app.BLUEFigLivePatch.XData = spacecraft(:,1);
                        app.BLUEFigLivePatch.YData = spacecraft(:,2);

                    end       
                end

                %app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(dataR(1)),' s');

                %pause(0.01)
                drawnow limitrate

            end   

            if value == "Off"
                release(app.hudpr);
                app.RealTimeTelOnLamp.Color = [1.00,0.00,0.00];
            end
            
        end

        % Value changed function: SkipToTimeEditField
        function SkipToTimeEditFieldValueChanged(app, event)

            changingValue = app.SkipToTimeEditField.Value;
            changingValue(changingValue<1)=1;

            CheckAnimationSelections(app);

            if (isempty(app.DataPacket)~=1)

                TimeVec = app.DataPacket(:,1);

                closestIndex = find(abs(TimeVec - changingValue) == min(abs(TimeVec - changingValue)), 1);

                AddPlatformSim(app,evalin("base","platformSelection")); 
                
                % Print time text:
                txtT = '';
                text(app.ExperimentPreview,0.1,3.75,txtT); 
                                        
                a = app.DataPacket(closestIndex,9);
                b = app.DataPacket(closestIndex,8);
                theta = -unwrap(app.DataPacket(closestIndex,10));
                
                
                if (isempty(app.REDFigHdl) ~= 1) 
                    
                                                          
                    if app.ShowDockingConeRED == 1
        
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,1]);
        
                    else
        
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);
        
                    end
                                                      
                    app.REDFigHdl.XData = spacecraft(:,1);
                    app.REDFigHdl.YData = spacecraft(:,2);

                    app.REDFigPatch.XData = spacecraft(:,1);
                    app.REDFigPatch.YData = spacecraft(:,2);
                
                    if app.ShowPathTraceRED == 1
                        app.REDPathPlot.XData = app.DataPacket(1:closestIndex,9);
                        app.REDPathPlot.YData = app.DataPacket(1:closestIndex,8);
                    end

                end
                
                x0 = app.DataPacket(closestIndex,9);
                y0 = app.DataPacket(closestIndex,8);
                q0 = -unwrap(app.DataPacket(closestIndex,10));
                  
                q1 = (-app.DataPacket(closestIndex,32) + 3*pi/2);
                q2 = -app.DataPacket(closestIndex,33);
                q3 = -(app.DataPacket(closestIndex,34));   

                [rs,re,rw,ree] = app.CalculateArmPosition([x0,y0,q0,q1,q2,q3]);

                if (isempty(app.REDFigLink1) ~= 1) 
                    
                    app.REDFigLink1.XData = [rs(1,1)  re(1,1)];
                    app.REDFigLink1.YData = [rs(1,2)  re(1,2)];
                    app.REDFigLink2.XData = [re(1,1)  rw(1,1)];
                    app.REDFigLink2.YData = [re(1,2)  rw(1,2)];
                    app.REDFigLink3.XData = [rw(1,1)  ree(1,1)];
                    app.REDFigLink3.YData = [rw(1,2)  ree(1,2)];
                    app.REDFigJoin0.XData = rs(1,1);
                    app.REDFigJoin0.YData = rs(1,2);
                    app.REDFigJoin1.XData = re(1,1);
                    app.REDFigJoin1.YData = re(1,2);
                    app.REDFigJoin2.XData = rw(1,1);
                    app.REDFigJoin2.YData = rw(1,2);
                    app.REDFigJoin3.XData = ree(1,1);
                    app.REDFigJoin3.YData = ree(1,2);
                                            
                end
                
                a = app.DataPacket(closestIndex,51);
                b = app.DataPacket(closestIndex,50);
                theta = -unwrap(app.DataPacket(closestIndex,52));
                
                if (isempty(app.BLACKFigHdl) ~= 1) 
                    
                    if app.ShowSolarPanelsBLACK == 1 && app.ShowDockingPortBLACK == 0
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,5]);
                    elseif app.ShowSolarPanelsBLACK == 1 && app.ShowDockingPortBLACK == 1
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,4]);
                    elseif app.ShowSolarPanelsBLACK == 0 && app.ShowDockingPortBLACK == 1
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,2]);
                    else
                        spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);
                    end

                    app.BLACKFigHdl.XData = spacecraft(:,1);
                    app.BLACKFigHdl.YData = spacecraft(:,2);

                    app.BLACKFigPatch.XData = spacecraft(:,1);
                    app.BLACKFigPatch.YData = spacecraft(:,2);
        
                    if app.ShowPathTraceBLACK == 1
                        app.BLACKPathPlot.XData = app.DataPacket(1:closestIndex,51);
                        app.BLACKPathPlot.YData = app.DataPacket(1:closestIndex,50);
                    end                      
                end
                
                a = app.DataPacket(closestIndex,77);
                b = app.DataPacket(closestIndex,76);
                theta = -unwrap(app.DataPacket(closestIndex,78));
                
                if (isempty(app.BLUEFigHdl) ~= 1) 

                    spacecraft = app.DrawSpacecraft([a,b,0.3,0.3,theta,3]);

                    app.BLUEFigHdl.XData = spacecraft(:,1);
                    app.BLUEFigHdl.YData = spacecraft(:,2);

                    app.BLUEFigPatch.XData = spacecraft(:,1);
                    app.BLUEFigPatch.YData = spacecraft(:,2);
        
                    if app.ShowPathTraceBLUE == 1
                        app.BLUEPathPlot.XData = app.DataPacket(1:closestIndex,77);
                        app.BLUEPathPlot.YData = app.DataPacket(1:closestIndex,76);
                    end                       
                end                  
      
                app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(app.DataPacket(closestIndex,1)),' s');
            end
        end

        % Callback function
        function EstablishConnectionwithSelectedPlatformsButtonValueChanged(app, event)
            value = app.Step9EstablishConnectionwithSelectedPlatformsButton.Value;
            if value == 1 && isempty(app.REDConnection)
            end
            
        end

        % Button pushed function: OpenMassPropertiesAppButton
        function OpenMassPropertiesAppButtonPushed(app, event)
            % Instantiate the sub-application
            app.SubAppMassProperties.SPOT41MassPropertiesUIFigure.Visible = "on";
        end

        % Close request function: SPOT41GUIUIFigure
        function SPOT41GUIUIFigureCloseRequest(app, event)
            if isempty(app.SubAppMassProperties) && isempty(app.SubAppInitialConditions)
                delete(app);
            elseif ~isempty(app.SubAppMassProperties) && isempty(app.SubAppInitialConditions)
                delete(app.SubAppMassProperties);
                delete(app);
            elseif isempty(app.SubAppMassProperties) && ~isempty(app.SubAppInitialConditions)
                delete(app.SubAppInitialConditions);
                delete(app);
            else
                delete(app.SubAppInitialConditions);
                delete(app.SubAppMassProperties);
                delete(app);
            end
            
        end

        % Menu selected function: AddPathtoCustomLibraryMenu
        function AddPathtoCustomLibraryMenuSelected(app, event)
            % Warn the user about adding custom libraries:
            Choice = questdlg('Warning: You are about to add a new custom library to your path. If you have more then one custom library on the path, you will encounter problems. If you want to proceed, select "proceed".','Adding Custom Library','Proceed','Cancel','Proceed');
            switch Choice
                case 'Proceed'
                    
                    % Get user to select a folder 
                    selpath = uigetdir();
        
                    pathCell = regexp(path, pathsep, 'split');
                    if ispc  % Windows is not case-sensitive
                      onPath = any(strcmpi(selpath, pathCell));
                    else
                      onPath = any(strcmp(selpath, pathCell));
                    end
        
                    if onPath == 1
                        ed = errordlg("The path you selected is already on the MATLAB path.","Error");
                        set(ed, "WindowStyle", "modal");
                        uiwait(ed);
                    else
                        addpath(genpath(selpath));
                        savepath matlab/myfiles/pathdef.m
                    end

                case 'Cancel'

            end
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            app.PreviousSamplingRate      = app.SampleRateEditField.Value;
            app.baseRate                  = 1/app.SampleRateEditField.Value;
            app.dataRate                  = 1/(app.SampleRateEditField.Value*0.5);
            app.UpdateAuxParameters();
        end

        % Button pushed function: OpenInitialConditionAppButton
        function OpenInitialConditionAppButtonPushed(app, event)
            % Instantiate the sub-application
            app.SubAppInitialConditions.SPOT41InitialConditionsUIFigure.Visible = "on";
        end

        % Menu selected function: OverrideCurrentDefaultParametersMenu
        function OverrideCurrentDefaultParametersMenuSelected(app, event)
                % Grab the file name from the drop down menu:
                Choice = questdlg('Warning: If you overwrite the default values you will not be able to revert back to the original defaults. If you want to proceed, select "proceed".','Overwritting Defaults','Proceed','Cancel','Proceed');
                switch Choice
                    case 'Proceed'

                        waitbox = app.CustomWaitBox('Overwriting Defaults Parameters...');
                       
                        filename = 'SPOTGUI_DEFAULT';
                        pathname = [cd '/Resources/'];
            
                        % Check if the user clicked 'Cancel'
                        if isequal(filename, 0) || isequal(pathname, 0)
            
                            return
            
                        else
            
                            % Initialize an empty struct
                            appState = struct();
                                          
                            % Get the list of properties from the app object
                            properties = fieldnames(app);
                            
                            % Loop through the properties
                            for i = 1:length(properties)
                                 % Try to save the 'Value' property to the struct
                                try
                                    appState.(properties{i}) = app.(properties{i}).Value;
                                catch
                                    % If the 'Value' property cannot be saved, skip it
                                    %fprintf('Skipping property %s\n', properties{i});
                                end
                            end
                            
                            subAppState = struct();
                            try
                                propertiesSubApp = fieldnames(app.SubAppMassProperties);
                
                                % Loop through the properties
                                for i = 1:length(propertiesSubApp)
                                     % Try to save the 'Value' property to the struct
                                    try
                                        subAppState.(propertiesSubApp{i}) = app.SubAppMassProperties.(propertiesSubApp{i}).Value;
                                    catch
                                        % If the 'Value' property cannot be saved, skip it
                                        %fprintf('Skipping property %s\n', properties{i});
                                    end
                                end
                            catch
                            end
            
                            subAppStateInit = struct();
                            try
                                propertiesSubApp = fieldnames(app.SubAppInitialConditions);
                
                                % Loop through the properties
                                for i = 1:length(propertiesSubApp)
                                     % Try to save the 'Value' property to the struct
                                    try
                                        subAppStateInit.(propertiesSubApp{i}) = app.SubAppInitialConditions.(propertiesSubApp{i}).Value;
                                    catch
                                        % If the 'Value' property cannot be saved, skip it
                                        %fprintf('Skipping property %s\n', properties{i});
                                    end
                                end
                            catch
                            end
                            % Save the appState variable to a .mat file
                            save(fullfile(pathname, filename), 'appState', 'subAppState', 'subAppStateInit');
            
                        end
            
                        close(waitbox);

                    case 'Cancel'

                end


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            MyFolderInfo = dir([cd '' filesep '*.slx']);
            FileNames    = {'Select a Diagram',MyFolderInfo.name};
            app.AvailableDiagramsDropDown.Items = FileNames;
        end

        % Button pushed function: BLACKMagnetOnButton
        function BLACKMagnetOnButtonPushed(app, event)
            % NOTE: This function currently controls pucks. Need to assign
            % a pin permanently to the magnet and then update function.
            waitbox = app.CustomWaitBox('Enabling magnet on BLACK...');
            [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                    
                app.CleanShutdown(app.BLACKConnection);
                
                try
                    system(app.BLACKConnection,'echo 482 > /sys/class/gpio/unexport');
                catch
                end
                system(app.BLACKConnection,'echo 482 > /sys/class/gpio/export');
                system(app.BLACKConnection,'echo out > /sys/class/gpio/gpio482/direction');
                system(app.BLACKConnection,'echo 1 > /sys/class/gpio/gpio482/value');

                app.BLACKMagnetOnButton.Enable = "off";
                app.BLACKMagnetOffButton.Enable = "on";
                
            else 
                waitfor(warndlg(['There was a problem connecting to BLACK, could not enable magnet. Check that the IP address for BLACK is still accessible using !ping ' app.BLACK_SSH_Address ' in the MATLAB Command Window.'],'Connection Failed'));
            end

            close(waitbox);
        end

        % Button pushed function: BLACKMagnetOffButton
        function BLACKMagnetOffButtonPushed(app, event)
            
            waitbox = app.CustomWaitBox('Disabling magnet on BLACK...');
            [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                                                    
                try
                    system(app.BLACKConnection,'echo 0 > /sys/class/gpio/gpio482/value');
                    system(app.BLACKConnection,'echo 482 > /sys/class/gpio/unexport');

                    app.BLACKMagnetOnButton.Enable = "on";
                    app.BLACKMagnetOffButton.Enable = "off";
                    
                catch
                    waitfor(warndlg('There was an error turning off the magnet. Try again, and if this error appears you may need to power cycle.','Magnet OFF Error'));
                end

            else 
                waitfor(warndlg(['There was a problem connecting to BLACK, could not disable the magnet. Check that the IP address for BLACK is still accessible using !ping ' app.BLACK_SSH_Address ' in the MATLAB Command Window.'],'Floating Failed'));
            end 
            close(waitbox);
        end

        % Button pushed function: LoadDataButtonPlt1_Secondary
        function LoadDataButtonPlt1_SecondaryPushed(app, event)
            % Check if there's an existing figure (your app's main window)
            currentFig = app.SPOT41GUIUIFigure;
            
            % Create a waitbar for the data loading
            waitbox = app.CustomWaitBox('Loading Data...');
            
            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file');
            
            % Bring your app's window back to the forefront only if currentFig is not empty
            if ~isempty(currentFig)
                figure(currentFig);
            end
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                tmp   = load([pathname filename]);
                tmpfn = fieldnames(tmp);
                app.LoadedData1_Secondary = tmp.(tmpfn{1});
            
                if isstruct(app.LoadedData1_Secondary)
                    app.SelectXDataPlt1_Secondary.Items = fieldnames(app.LoadedData1_Secondary);
                    app.SelectYDataPlt1_Secondary.Items = fieldnames(app.LoadedData1_Secondary);
                else
                    waitfor(warndlg('Could not load data file, please ensure the data file is a struct.','Load Failed'));
                end
            end
            
            close(waitbox);
        end

        % Value changed function: SelectXDataPlt1_Secondary
        function SelectXDataPlt1_SecondaryValueChanged(app, event)
            dataX = app.LoadedData1_Secondary.(app.SelectXDataPlt1_Secondary.Value);
            dataY = app.LoadedData1_Secondary.(app.SelectYDataPlt1_Secondary.Value);
            if isa(dataX,'timeseries') && isa(dataY,'timeseries')
                dataX = dataX.data;
                dataY = dataY.data;
                % Handle overplotting logic
                if app.AllowOverplotCheckBox.Value == 1
                    hold(app.DataViewerPlt1,"on");
                else
                    hold(app.DataViewerPlt1,"off");
                end
                plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0), 'k');
                grid(app.DataViewerPlt1,"on");
                %xaxis(app.DataViewerPlt1,"xy");
                axis(app.DataViewerPlt1,"tight");
                %xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1_Secondary.Value,'_',' '));
                %ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1_Secondary.Value,'_',' '));
            elseif isa(dataX,'struct') && isa(dataY,'struct')
                % do nothing for x and y
                % Handle overplotting logic
                if app.AllowOverplotCheckBox.Value == 1
                    hold(app.DataViewerPlt1,"on");
                else
                    hold(app.DataViewerPlt1,"off");
                end
                plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0), 'k');
                grid(app.DataViewerPlt1,"on");
                %xaxis(app.DataViewerPlt1,"xy");
                axis(app.DataViewerPlt1,"tight");
                %xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1_Secondary.Value,'_',' '));
                %ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1_Secondary.Value,'_',' '));
            else
                waitfor(warndlg('Warning: The data file you are trying to load is not valid. Only data Timeseries and Structs are supported.','Invalid Data Type'));
            end
        end

        % Value changed function: FilterSignalCheckBoxPlt1_Secondary, 
        % ...and 1 other component
        function SelectYDataPlt1_SecondaryValueChanged(app, event)
            dataX = app.LoadedData1_Secondary.(app.SelectXDataPlt1_Secondary.Value);
            dataY = app.LoadedData1_Secondary.(app.SelectYDataPlt1_Secondary.Value);
            if isa(dataX,'timeseries') && isa(dataY,'timeseries')
                dataX = dataX.data;
                dataY = dataY.data;

                if app.FilterSignalCheckBoxPlt1_Secondary.Value == 1
                    Time = app.LoadedData1_Secondary.('Time_s').data;
                    dataY = lowpass(dataY,0.01,1/mean(diff(Time)));
                end
                if app.AllowOverplotCheckBox.Value == 1
                    hold(app.DataViewerPlt1,"on");
                else
                    hold(app.DataViewerPlt1,"off");
                end
                plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0));
                %legend(app.DataViewerPlt1, app.SelectXDataPlt1_Secondary.Value,'Visible','off');
                grid(app.DataViewerPlt1,"on");
                %xaxis(app.DataViewerPlt1,"xy");
                axis(app.DataViewerPlt1,"tight");
                %xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1_Secondary.Value,'_',' '));
                %ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1_Secondary.Value,'_',' '));
            elseif isa(dataX,'struct') && isa(dataY,'struct')
                if app.FilterSignalCheckBoxPlt1_Secondary.Value == 1
                    Time = app.LoadedData1_Secondary.('Time_s');
                    dataY = lowpass(dataY,0.01,1/mean(diff(Time)));
                end
                % do nothing for x and y
                if app.AllowOverplotCheckBox.Value == 1
                    hold(app.DataViewerPlt1,"on");
                else
                    hold(app.DataViewerPlt1,"off");
                end
                plot(app.DataViewerPlt1, dataX(dataX~=0), dataY(dataX~=0));
                %legend(app.DataViewerPlt1, app.SelectXDataPlt1_Secondary.Value,'Visible','off');
                grid(app.DataViewerPlt1,"on");
                %xaxis(app.DataViewerPlt1,"xy");
                axis(app.DataViewerPlt1,"tight");
                %xlabel(app.DataViewerPlt1,strrep(app.SelectXDataPlt1_Secondary.Value,'_',' '));
                %ylabel(app.DataViewerPlt1,strrep(app.SelectYDataPlt1_Secondary.Value,'_',' '));
            else
                waitfor(warndlg('Warning: The data file you are trying to load is not valid. Only data Timeseries and Structs are supported.','Invalid Data Type'));
            end
        end

        % Button pushed function: ClearFigureButtonPlt1
        function ClearFigureButtonPlt1Pushed(app, event)
            cla(app.DataViewerPlt1);
        end

        % Button pushed function: ClearFigureButtonPlt2
        function ClearFigureButtonPlt2Pushed(app, event)
           cla(app.DataViewerPlt2); 
        end

        % Button pushed function: LoadDataButtonPlt2_Secondary
        function LoadDataButtonPlt2_SecondaryPushed(app, event)
            % Check if there's an existing figure (your app's main window)
            currentFig = app.SPOT41GUIUIFigure;
            
            % Create a waitbar for the data loading
            waitbox = app.CustomWaitBox('Loading Data...');
            
            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file');
            
            % Bring your app's window back to the forefront only if currentFig is not empty
            if ~isempty(currentFig)
                figure(currentFig);
            end
            
            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                tmp   = load([pathname filename]);
                tmpfn = fieldnames(tmp);
                app.LoadedData2_Secondary = tmp.(tmpfn{1});
                
                if isstruct(app.LoadedData2_Secondary)

                    app.SelectXDataPlt2_Secondary.Items = fieldnames(app.LoadedData2_Secondary);
                    app.SelectYDataPlt2_Secondary.Items = fieldnames(app.LoadedData2_Secondary);
                
                else

                    waitfor(warndlg('Could not load data file, please ensure the data file is a struct.','Load Failed'));

                end


            end     
            close(waitbox);
        end

        % Value changed function: SelectXDataPlt2_Secondary
        function SelectXDataPlt2_SecondaryValueChanged(app, event)
            dataX = app.LoadedData2_Secondary.(app.SelectXDataPlt2_Secondary.Value);
            dataY = app.LoadedData2_Secondary.(app.SelectYDataPlt2_Secondary.Value);
            if isa(dataX,'timeseries') && isa(dataY,'timeseries')
                dataX = dataX.data;
                dataY = dataY.data;
                % Handle overplotting logic
                if app.AllowOverplotCheckBoxPlt2.Value == 1
                    hold(app.DataViewerPlt2,"on");
                else
                    hold(app.DataViewerPlt2,"off");
                end
                plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt2,"on");
                %xaxis(app.DataViewerPlt2,"xy");
                axis(app.DataViewerPlt2,"tight");
                %xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2_Secondary.Value,'_',' '));
                %ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2_Secondary.Value,'_',' ')); 
            elseif isa(dataX,'struct') && isa(dataY,'struct')
                % do nothing for x and y
                % Handle overplotting logic
                if app.AllowOverplotCheckBoxPlt2.Value == 1
                    hold(app.DataViewerPlt2,"on");
                else
                    hold(app.DataViewerPlt2,"off");
                end
                plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt2,"on");
                %xaxis(app.DataViewerPlt2,"xy");
                axis(app.DataViewerPlt2,"tight");
                %xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2_Secondary.Value,'_',' '));
                %ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2_Secondary.Value,'_',' ')); 
            else
                waitfor(warndlg('Warning: The data file you are trying to load is not valid. Only data Timeseries and Structs are supported.','Invalid Data Type'));
            end
        end

        % Value changed function: FilterYSignalCheckBoxPlt2_Secondary, 
        % ...and 1 other component
        function SelectYDataPlt2_SecondaryValueChanged(app, event)
            dataX = app.LoadedData2_Secondary.(app.SelectXDataPlt2_Secondary.Value);
            dataY = app.LoadedData2_Secondary.(app.SelectYDataPlt2_Secondary.Value);
            if isa(dataX,'timeseries') && isa(dataY,'timeseries')
                dataX = dataX.data;
                dataY = dataY.data;
                if app.FilterYSignalCheckBoxPlt2_Secondary.Value == 1
                    Time = app.LoadedData2_Secondary.('Time_s').data;
                    dataY = lowpass(dataY,0.01,1/mean(diff(Time)));
                end
                %hold(app.DataViewerPlt2,"on");
                % Handle overplotting logic
                if app.AllowOverplotCheckBoxPlt2.Value == 1
                    hold(app.DataViewerPlt2,"on");
                else
                    hold(app.DataViewerPlt2,"off");
                end
                plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt2,"on");
                %xaxis(app.DataViewerPlt2,"xy");
                axis(app.DataViewerPlt2,"tight");
                %xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2_Secondary.Value,'_',' '));
                %ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2_Secondary.Value,'_',' ')); 
            elseif isa(dataX,'struct') && isa(dataY,'struct')
                % do nothing for x and y
                if app.FilterYSignalCheckBoxPlt2_Secondary.Value == 1
                    Time = app.LoadedData2_Secondary.('Time_s');
                    dataY = lowpass(dataY,0.01,1/mean(diff(Time)));
                end
                % Handle overplotting logic
                if app.AllowOverplotCheckBoxPlt2.Value == 1
                    hold(app.DataViewerPlt2,"on");
                else
                    hold(app.DataViewerPlt2,"off");
                end
                %hold(app.DataViewerPlt2,"on");
                plot(app.DataViewerPlt2, dataX(dataX~=0), dataY(dataX~=0));
                grid(app.DataViewerPlt2,"on");
                %xaxis(app.DataViewerPlt2,"xy");
                axis(app.DataViewerPlt2,"tight");
                %xlabel(app.DataViewerPlt2,strrep(app.SelectXDataPlt2_Secondary.Value,'_',' '));
                %ylabel(app.DataViewerPlt2,strrep(app.SelectYDataPlt2_Secondary.Value,'_',' ')); 
            else
                waitfor(warndlg('Warning: The data file you are trying to load is not valid. Only data Timeseries and Structs are supported.','Invalid Data Type'));
            end
        end

        % Value changed function: XAxisLabelPlt1
        function XAxisLabelPlt1ValueChanged(app, event)
            xlabel(app.DataViewerPlt1,app.XAxisLabelPlt1.Value);
        end

        % Value changed function: YAxisLabelPlt1
        function YAxisLabelPlt1ValueChanged(app, event)
            ylabel(app.DataViewerPlt1,app.YAxisLabelPlt1.Value);
        end

        % Value changed function: XAxisLabelPlt2
        function XAxisLabelPlt2ValueChanged(app, event)
            xlabel(app.DataViewerPlt2,app.XAxisLabelPlt2.Value);
        end

        % Value changed function: YAxisLabelPlt2
        function YAxisLabelPlt2ValueChanged(app, event)
            ylabel(app.DataViewerPlt2,app.YAxisLabelPlt2.Value);
        end

        % Button pushed function: OpenVISIONCommandShell
        function OpenVISIONCommandShellButtonPushed(app, event)

            try
                VISION = jetson('192.168.1.115','spot-vision','srcl2023');
                openShell(VISION); 
            catch
                Choice = questdlg('Warning: Unable to connect to spot-jetson @ 192.168.1.115. Confirm it is powered on and the correct WiFi dongle is connected. If you want to connect to spot-jetson-spare instead, select "proceed".','Unable to Locate spot-jetson','Proceed','Cancel','Proceed');
                    switch Choice
                        case 'Proceed'
                            VISION2 = jetson('192.168.1.115','spot-vision-spare','srcl2023');
                            openShell(VISION2);
                        case 'Cancel'
                            % Do nothing
                    end
            end
        end

        % Clicked callback: SelectYDataPlt2
        function SelectYDataPlt2Clicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item) == 1
                % Do nothing
            else
                app.SelectYDataPlt2ValueChanged;
            end
            
        end

        % Clicked callback: SelectYDataPlt2_Secondary
        function SelectYDataPlt2_SecondaryClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item) == 1
                % Do nothing
            else
                app.SelectYDataPlt2_SecondaryValueChanged;
            end            
        end

        % Clicked callback: SelectYDataPlt1
        function SelectYDataPlt1Clicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item) == 1
                % Do nothing
            else
                app.SelectYDataPlt1ValueChanged;
            end               
        end

        % Clicked callback: SelectYDataPlt1_Secondary
        function SelectYDataPlt1_SecondaryClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item) == 1
                % Do nothing
            else
                app.SelectYDataPlt1_SecondaryValueChanged;
            end                   
        end

        % Button pushed function: ApplyRangesButton_2
        function ApplyRangesButton_2Pushed(app, event)
            try
                if (strcmp(app.SetXMaxPlt1.Value, 'Set X-Axis (Max)') == 1 ||  strcmp(app.SetXMinPlt1.Value, 'Set X-Axis (Min)') == 1)
                    % Do Nothing
                else
                    XMax = str2double(app.SetXMaxPlt1.Value);
                    XMin = str2double(app.SetXMinPlt1.Value);
                    app.DataViewerPlt1.XLim(1) = XMin;
                    app.DataViewerPlt1.XLim(2) = XMax;
                end
            catch
                waitfor(warndlg('Warning: There was a problem converting your desired X-range into doubles. Ensure you have not used text.','Conversion Failure'));
            end

            try
                if (strcmp(app.SetYMaxPlt1.Value, 'Set Y-Axis (Max)') == 1 ||  strcmp(app.SetYMinPlt1.Value, 'Set Y-Axis (Min)') == 1)
                    % Do Nothing
                else
                    YMax = str2double(app.SetYMaxPlt1.Value);
                    YMin = str2double(app.SetYMinPlt1.Value);
                    app.DataViewerPlt1.YLim(1) = YMin;
                    app.DataViewerPlt1.YLim(2) = YMax;
                end
            catch
                waitfor(warndlg('Warning: There was a problem converting your desired Y-range into doubles. Ensure you have not used text.','Conversion Failure'));
            end            
            
            
        end

        % Value changed function: RedPXGainEditField
        function RedPXGainEditFieldValueChanged(app, event)
            assignin('base','Kp_xr',app.RedPXGainEditField.Value)
        end

        % Value changed function: RedPYGainEditField
        function RedPYGainEditFieldValueChanged2(app, event)
            assignin('base','Kp_yr',app.RedPYGainEditField.Value)
        end

        % Value changed function: RedPGainThetaEditField
        function RedPGainThetaEditFieldValueChanged(app, event)
            assignin('base','Kp_tr',app.RedPGainThetaEditField.Value)
        end

        % Value changed function: BlackPXGainEditField
        function BlackPXGainEditFieldValueChanged(app, event)
            assignin('base','Kp_xb',app.BlackPXGainEditField.Value)
        end

        % Value changed function: BlackPYGainEditField
        function BlackPYGainEditFieldValueChanged2(app, event)
            assignin('base','Kp_yb',app.BlackPYGainEditField.Value)
        end

        % Value changed function: BlackPGainThetaEditField
        function BlackPGainThetaEditFieldValueChanged(app, event)
            assignin('base','Kp_tb',app.BlackPGainThetaEditField.Value)
        end

        % Value changed function: BluePXGainEditField
        function BluePXGainEditFieldValueChanged(app, event)
            assignin('base','Kp_xblue',app.BluePXGainEditField.Value)
        end

        % Value changed function: BluePYGainEditField
        function BluePYGainEditFieldValueChanged2(app, event)
            assignin('base','Kp_yblue',app.BluePYGainEditField.Value)
        end

        % Value changed function: BluePGainThetaEditField
        function BluePGainThetaEditFieldValueChanged(app, event)
            assignin('base','Kp_tblue',app.BluePGainThetaEditField.Value)
        end

        % Value changed function: RedDXGainEditField
        function RedDXGainEditFieldValueChanged(app, event)
            assignin('base','Kd_xr',app.RedDXGainEditField.Value)
        end

        % Value changed function: RedDYGainEditField
        function RedDYGainEditFieldValueChanged2(app, event)
            assignin('base','Kd_yr',app.RedDYGainEditField.Value)            
        end

        % Value changed function: RedDGainThetaEditField
        function RedDGainThetaEditFieldValueChanged(app, event)
            assignin('base','Kd_tr',app.RedDGainThetaEditField.Value)            
        end

        % Value changed function: BlackDXGainEditField
        function BlackDXGainEditFieldValueChanged(app, event)
            assignin('base','Kd_xb',app.BlackDXGainEditField.Value)  
        end

        % Value changed function: BlackDYGainEditField
        function BlackDYGainEditFieldValueChanged2(app, event)
            assignin('base','Kd_yb',app.BlackDYGainEditField.Value)
        end

        % Value changed function: BlackDGainThetaEditField
        function BlackDGainThetaEditFieldValueChanged(app, event)
            assignin('base','Kd_tb',app.BlackDGainThetaEditField.Value)
        end

        % Value changed function: BlueDXGainEditField
        function BlueDXGainEditFieldValueChanged(app, event)
            assignin('base','Kd_xblue',app.BlueDXGainEditField.Value)       
        end

        % Value changed function: BlueDYGainEditField
        function BlueDYGainEditFieldValueChanged2(app, event)
            assignin('base','Kd_yblue',app.BlueDYGainEditField.Value)  
        end

        % Value changed function: BlueDGainThetaEditField
        function BlueDGainThetaEditFieldValueChanged(app, event)
            assignin('base','Kd_tblue',app.BlueDGainThetaEditField.Value)  
        end

        % Button pushed function: EmptyExperimentsSoftwareButton
        function EmptyExperimentsSoftwareButtonPushed(app, event)
            answer = questdlg('Warning: You are about to PERMANENTLY DELETE all files under /ExperimentSoftware, INCLUDING EXPERIMENT DATA. This action cannot be undone. Make sure you have saved your data!','Software Cleanup Warning','Understood','Cancel','Cancel');
            if answer ~= "Cancel"
                            
                if (app.REDCheckBox.Value == 1)
                    
                    fprintf('Checking connection to RED platform...\n')
                    [~,results] = system(['ping ' app.RED_SSH_Address ' -w 2000 -n 1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on RED...')
                        
                        try                  
                            system(app.REDConnection, 'rm -r ExperimentSoftware');
                            system(app.REDConnection, 'mkdir ExperimentSoftware');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to RED, files not deleted.\n')
                    end
               
                end
                
                if (app.BLACKCheckBox.Value == 1)
                    
                    fprintf('Checking connection to BLACK platform...\n')
                    [~,results] = system(['ping ' app.BLACK_SSH_Address ' -w 2000 -n 1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on BLACK...')
                        
                        try                
                            system(app.BLACKConnection, 'rm -r ExperimentSoftware');
                            system(app.BLACKConnection, 'mkdir ExperimentSoftware');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLACK, files not deleted.\n')
                    end
               
                end
    
                if (app.BLUECheckBox.Value == 1)
                    
                    fprintf('Checking connection to BLUE platform...\n')
                    [~,results] = system(['ping ' app.BLUE_SSH_Address ' -w 2000 -n 1']);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on BLUE...')
                        try                    
                            system(app.BLUEConnection, 'rm -r ExperimentSoftware');
                            system(app.BLUEConnection, 'mkdir ExperimentSoftware');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLUE, files not deleted.\n')
                    end
               
                end
                                
            else
                
                %Do Nothing
                
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
        
           % Create the dialog window
            fig = uifigure('Name', 'About', 'Position', [100 100 500 450]);
            
            % Create label for the title with word wrapping
            [~] = uilabel(fig, ...
                'Text', 'About the Spacecraft Proximity Operations Testbed (SPOT) Software', ...
                'FontSize', 18, ...
                'FontWeight', 'bold', ...
                'Position', [20 370 460 40], ... % Wider width for better wrapping
                'HorizontalAlignment', 'center', ...
                'WordWrap', 'on');
            
            % License information
            [~] = uilabel(fig, ...
                'Text', 'License:', ...
                'Position', [20 320 100 22], ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left');
            
            [~] = uilabel(fig, ...
                'Text', ['MIT License: Permission is hereby granted, free of charge, to any person obtaining a copy of this (SPOT) software ' ...
                'and associated documentation files (the "Software"), to deal in the Software without restriction, including without limitation ' ...
                'the rights to use, copy, modify, merge, publish, distribute, sublicense, and/or sell copies of the Software, and to permit ' ...
                'persons to whom the Software is furnished to do so, subject to no additional conditions.'], ...
                'Position', [20 230 460 80], ...
                'FontSize', 10, ...
                'HorizontalAlignment', 'left', ...
                'WordWrap', 'on');
            
            % Author and contributors info
            [~] = uilabel(fig, ...
                'Text', 'Primary Author: Crain, A.', ...
                'Position', [20 200 460 22], ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left');

            % Author and contributors info
            [~] = uilabel(fig, ...
                'Text', 'Contact Information: alexcrain@cmail.carleton.ca', ...
                'Position', [20 170 460 22], ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left');
            
            [~] = uilabel(fig, ...
                'Text', 'Contributors: Goodridge, R., Savytska, C., Vigneron, A., Hovell, K.', ...
                'Position', [20 140 480 22], ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left', ...
                'WordWrap', 'on');
            
            % Version and release date info
            [~] = uilabel(fig, ...
                'Text', 'Version: 4.1.0 Pre-Release.2', ...
                'Position', [20 110 460 22], ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left');
            
            [~] = uilabel(fig, ...
                'Text', 'Release Date: September 24, 2024', ...
                'Position', [20 80 460 22], ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left');
            
            % Close button
            [~] = uibutton(fig, 'Text', 'Close', ...
                'Position', [200 40 100 30], ...
                'ButtonPushedFcn', @(btn, event) close(fig));

        end

        % Menu selected function: UpdateREDInformationMenu
        function UpdateREDInformationMenuSelected(app, event)
            % Create a dialog figure
            fig = uifigure('Name', 'Update RED SSH Information', 'Position', [100 100 400 250]);
            
            % Create label and text box 1
            [~] = uilabel(fig, 'Text', 'IP Address:', 'Position', [20 180 100 22]);
            txt1 = uieditfield(fig, 'text', 'Position', [120 180 250 22],'Value',app.RED_SSH_Address);
            
            % Create label and text box 2
            [~] = uilabel(fig, 'Text', 'Name:', 'Position', [20 130 100 22]);
            txt2 = uieditfield(fig, 'text', 'Position', [120 130 250 22],'Value',app.RED_SSH_Name);
            
            % Create label and text box 3
            [~] = uilabel(fig, 'Text', 'Password:', 'Position', [20 80 100 22]);
            txt3 = uieditfield(fig, 'text', 'Position', [120 80 250 22],'Value',app.RED_SSH_Password);
            
            % Create a submit button
            [~] = uibutton(fig, 'Text', 'Submit', 'Position', [150 30 100 30], ...
                           'ButtonPushedFcn', @(btn,event) submitCallbackUpdateSSHRED(app, txt1, txt2, txt3));            
        end

        % Menu selected function: UpdateBLACKInformationMenu
        function UpdateBLACKInformationMenuSelected(app, event)
            % Create a dialog figure
            fig = uifigure('Name', 'Update BLACK SSH Information', 'Position', [100 100 400 250]);
            
            % Create label and text box 1
            [~] = uilabel(fig, 'Text', 'IP Address:', 'Position', [20 180 100 22]);
            txt1 = uieditfield(fig, 'text', 'Position', [120 180 250 22],'Value',app.BLACK_SSH_Address);
            
            % Create label and text box 2
            [~] = uilabel(fig, 'Text', 'Name:', 'Position', [20 130 100 22]);
            txt2 = uieditfield(fig, 'text', 'Position', [120 130 250 22],'Value',app.BLACK_SSH_Name);
            
            % Create label and text box 3
            [~] = uilabel(fig, 'Text', 'Password:', 'Position', [20 80 100 22]);
            txt3 = uieditfield(fig, 'text', 'Position', [120 80 250 22],'Value',app.BLACK_SSH_Password);
            
            % Create a submit button
            [~] = uibutton(fig, 'Text', 'Submit', 'Position', [150 30 100 30], ...
                           'ButtonPushedFcn', @(btn,event) submitCallbackUpdateSSHBLACK(app,txt1, txt2, txt3));  
        end

        % Menu selected function: UpdateBLUEInformationMenu
        function UpdateBLUEInformationMenuSelected(app, event)
            % Create a dialog figure
            fig = uifigure('Name', 'Update BLUE SSH Information', 'Position', [100 100 400 250]);
            
            % Create label and text box 1
            [~] = uilabel(fig, 'Text', 'IP Address:', 'Position', [20 180 100 22]);
            txt1 = uieditfield(fig, 'text', 'Position', [120 180 250 22],'Value',app.BLUE_SSH_Address);
            
            % Create label and text box 2
            [~] = uilabel(fig, 'Text', 'Name:', 'Position', [20 130 100 22]);
            txt2 = uieditfield(fig, 'text', 'Position', [120 130 250 22],'Value',app.BLUE_SSH_Name);
            
            % Create label and text box 3
            [~] = uilabel(fig, 'Text', 'Password:', 'Position', [20 80 100 22]);
            txt3 = uieditfield(fig, 'text', 'Position', [120 80 250 22],'Value',app.BLUE_SSH_Password);
            
            % Create a submit button
            [~] = uibutton(fig, 'Text', 'Submit', 'Position', [150 30 100 30], ...
                           'ButtonPushedFcn', @(btn,event) submitCallbackUpdateSSHBLUE(app,txt1, txt2, txt3));  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SPOT41GUIUIFigure = uifigure('Visible', 'off');
            app.SPOT41GUIUIFigure.Name = 'SPOT 4.1 GUI';
            app.SPOT41GUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @SPOT41GUIUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.SPOT41GUIUIFigure);
            app.FileMenu.Text = 'File';
            app.LabLinksMenu = uimenu(app.FileMenu);
            app.LabLinksMenu.Text = 'Lab Links';
            app.GitHubMenu = uimenu(app.LabLinksMenu);
            app.GitHubMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubMenuSelected, true);
            app.GitHubMenu.Text = 'GitHub';
            app.GooglePhotosMenu = uimenu(app.LabLinksMenu);
            app.GooglePhotosMenu.MenuSelectedFcn = createCallbackFcn(app, @GooglePhotosMenuSelected, true);
            app.GooglePhotosMenu.Text = 'Google Photos';
            app.YoutubeChannelMenu = uimenu(app.LabLinksMenu);
            app.YoutubeChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @YoutubeChannelMenuSelected, true);
            app.YoutubeChannelMenu.Text = 'Youtube Channel';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.Text = 'Help';
            app.YouTubeTutorialsMenu = uimenu(app.HelpMenu);
            app.YouTubeTutorialsMenu.Text = 'YouTube Tutorials';
            app.RunningaSimulationandExperimentMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RunningaSimulationandExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @RunningaSimulationandExperimentMenuSelected, true);
            app.RunningaSimulationandExperimentMenu.Text = 'Running a Simulation and Experiment';
            app.RemovingtheTableCoverMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RemovingtheTableCoverMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovingtheTableCoverMenuSelected, true);
            app.RemovingtheTableCoverMenu.Text = 'Removing the Table Cover';
            app.RetrievingtheIsopropanolMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RetrievingtheIsopropanolMenu.MenuSelectedFcn = createCallbackFcn(app, @RetrievingtheIsopropanolMenuSelected, true);
            app.RetrievingtheIsopropanolMenu.Text = 'Retrieving the Isopropanol';
            app.TableCleaningProceduresMenu = uimenu(app.YouTubeTutorialsMenu);
            app.TableCleaningProceduresMenu.MenuSelectedFcn = createCallbackFcn(app, @TableCleaningProceduresMenuSelected, true);
            app.TableCleaningProceduresMenu.Text = 'Table Cleaning Procedures';
            app.SavingExperimentDataMenu = uimenu(app.YouTubeTutorialsMenu);
            app.SavingExperimentDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavingExperimentDataMenuSelected, true);
            app.SavingExperimentDataMenu.Text = 'Saving Experiment Data';
            app.RemovingtheRoboticArmMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RemovingtheRoboticArmMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovingtheRoboticArmMenuSelected, true);
            app.RemovingtheRoboticArmMenu.Text = 'Removing the Robotic Arm';
            app.ChargingaLEDBatteryPackMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ChargingaLEDBatteryPackMenu.MenuSelectedFcn = createCallbackFcn(app, @ChargingaLEDBatteryPackMenuSelected, true);
            app.ChargingaLEDBatteryPackMenu.Text = 'Charging a LED Battery Pack';
            app.ChargingaPlatformMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ChargingaPlatformMenu.MenuSelectedFcn = createCallbackFcn(app, @ChargingaPlatformMenuSelected, true);
            app.ChargingaPlatformMenu.Text = 'Charging a Platform';
            app.InstallinganAirTankMenu = uimenu(app.YouTubeTutorialsMenu);
            app.InstallinganAirTankMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallinganAirTankMenuSelected, true);
            app.InstallinganAirTankMenu.Text = 'Installing an Air Tank';
            app.InstallingtheRoboticArmMenu = uimenu(app.YouTubeTutorialsMenu);
            app.InstallingtheRoboticArmMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallingtheRoboticArmMenuSelected, true);
            app.InstallingtheRoboticArmMenu.Text = 'Installing the Robotic Arm';
            app.ForkingtheSPOTRepositoryMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ForkingtheSPOTRepositoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ForkingtheSPOTRepositoryMenuSelected, true);
            app.ForkingtheSPOTRepositoryMenu.Text = 'Forking the SPOT Repository';
            app.PlacingtheTableCoverMenu = uimenu(app.YouTubeTutorialsMenu);
            app.PlacingtheTableCoverMenu.MenuSelectedFcn = createCallbackFcn(app, @PlacingtheTableCoverMenuSelected, true);
            app.PlacingtheTableCoverMenu.Text = 'Placing the Table Cover';
            app.FillinganAirTankMenu = uimenu(app.YouTubeTutorialsMenu);
            app.FillinganAirTankMenu.MenuSelectedFcn = createCallbackFcn(app, @FillinganAirTankMenuSelected, true);
            app.FillinganAirTankMenu.Text = 'Filling an Air Tank';
            app.ExportingVideoswithQCViewerMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ExportingVideoswithQCViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportingVideoswithQCViewerMenuSelected, true);
            app.ExportingVideoswithQCViewerMenu.Text = 'Exporting Videos with QC Viewer';
            app.CleaningthePucksandMovingaPlatformMenu = uimenu(app.YouTubeTutorialsMenu);
            app.CleaningthePucksandMovingaPlatformMenu.MenuSelectedFcn = createCallbackFcn(app, @CleaningthePucksandMovingaPlatformMenuSelected, true);
            app.CleaningthePucksandMovingaPlatformMenu.Text = 'Cleaning the Pucks and Moving a Platform';
            app.SaveGUIStateMenu = uimenu(app.FileMenu);
            app.SaveGUIStateMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveGUIStateMenuSelected, true);
            app.SaveGUIStateMenu.Tooltip = {'Save the current state of the GUI to a MAT file so that you can load it later.'};
            app.SaveGUIStateMenu.Text = 'Save GUI State';
            app.LoadGUIStateMenu = uimenu(app.FileMenu);
            app.LoadGUIStateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadGUIStateMenuSelected, true);
            app.LoadGUIStateMenu.Tooltip = {'Load a MAT file containing a saved GUI state. '};
            app.LoadGUIStateMenu.Text = 'Load GUI State';
            app.ResetGUIParameterstoDefaultMenu = uimenu(app.FileMenu);
            app.ResetGUIParameterstoDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetGUIParameterstoDefaultMenuSelected, true);
            app.ResetGUIParameterstoDefaultMenu.Tooltip = {'Reset the GUI to it''s default state. Default states are saved in the Resources folder.'};
            app.ResetGUIParameterstoDefaultMenu.Text = 'Reset GUI Parameters to Default';
            app.OverrideCurrentDefaultParametersMenu = uimenu(app.FileMenu);
            app.OverrideCurrentDefaultParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @OverrideCurrentDefaultParametersMenuSelected, true);
            app.OverrideCurrentDefaultParametersMenu.Tooltip = {'Allows you to override the default GUI states (stored in the Resources folder) with the current GUI state. This is permanent.'};
            app.OverrideCurrentDefaultParametersMenu.Text = 'Override Current Default Parameters';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ToolsMenu = uimenu(app.SPOT41GUIUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.AddPathtoCustomLibraryMenu = uimenu(app.ToolsMenu);
            app.AddPathtoCustomLibraryMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPathtoCustomLibraryMenuSelected, true);
            app.AddPathtoCustomLibraryMenu.Tooltip = {'Allows you to add any folder to the matlab path. It''s designed to be used to add a new custom library, but you could add any path you want.'};
            app.AddPathtoCustomLibraryMenu.Text = 'Add Path to Custom Library';
            app.UpdateSSHInformationMenu = uimenu(app.ToolsMenu);
            app.UpdateSSHInformationMenu.Text = 'Update SSH Information';
            app.UpdateREDInformationMenu = uimenu(app.UpdateSSHInformationMenu);
            app.UpdateREDInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateREDInformationMenuSelected, true);
            app.UpdateREDInformationMenu.Text = 'Update RED Information';
            app.UpdateBLACKInformationMenu = uimenu(app.UpdateSSHInformationMenu);
            app.UpdateBLACKInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateBLACKInformationMenuSelected, true);
            app.UpdateBLACKInformationMenu.Text = 'Update BLACK Information';
            app.UpdateBLUEInformationMenu = uimenu(app.UpdateSSHInformationMenu);
            app.UpdateBLUEInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateBLUEInformationMenuSelected, true);
            app.UpdateBLUEInformationMenu.Text = 'Update BLUE Information';
            app.GridLayout16 = uigridlayout(app.SPOT41GUIUIFigure);
            app.TabGroup2 = uitabgroup(app.GridLayout16);
            app.InitializeParametersTab = uitab(app.TabGroup2);
            app.InitializeParametersTab.Title = 'Initialize Parameters';
            app.GridLayout17 = uigridlayout(app.InitializeParametersTab);
            app.Step1LoadSimulinkDiagramPanel = uipanel(app.GridLayout17);
            app.Step1LoadSimulinkDiagramPanel.Title = 'Step #1: Load Simulink Diagram ';
            app.GridLayout3_3 = uigridlayout(app.Step1LoadSimulinkDiagramPanel);
            app.AvailableDiagramsDropDown = uidropdown(app.GridLayout3_3);
            app.AvailableDiagramsDropDown.Items = {'Select a Diagram'};
            app.AvailableDiagramsDropDown.ValueChangedFcn = createCallbackFcn(app, @AvailableDiagramsDropDownValueChanged, true);
            app.AvailableDiagramsDropDown.Tooltip = {'Select and load the Simulink diagram to run.'};
            app.AvailableDiagramsDropDown.Value = 'Select a Diagram';
            app.RefreshButton = uibutton(app.GridLayout3_3, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.Step3MassPropertiesPanel = uipanel(app.GridLayout17);
            app.Step3MassPropertiesPanel.Title = 'Step #3: Mass Properties';
            app.GridLayout35 = uigridlayout(app.Step3MassPropertiesPanel);
            app.OpenMassPropertiesAppButton = uibutton(app.GridLayout35, 'push');
            app.OpenMassPropertiesAppButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMassPropertiesAppButtonPushed, true);
            app.OpenMassPropertiesAppButton.Tooltip = {'Open a sub-application containing the mass properties (and geometric properties) of the platforms. Allows you to calculate new COG and inertia values.'};
            app.OpenMassPropertiesAppButton.Text = {'Open Mass '; 'Properties App'};
            app.Step4InitialConditionsPanel = uipanel(app.GridLayout17);
            app.Step4InitialConditionsPanel.Title = 'Step #4: Initial Conditions';
            app.GridLayout34 = uigridlayout(app.Step4InitialConditionsPanel);
            app.OpenInitialConditionAppButton = uibutton(app.GridLayout34, 'push');
            app.OpenInitialConditionAppButton.ButtonPushedFcn = createCallbackFcn(app, @OpenInitialConditionAppButtonPushed, true);
            app.OpenInitialConditionAppButton.Tooltip = {'Opens a sub-application to tune the initial conditions for any simulation or experiment. Allows you to quickly see what the drop, initial, and home locations are for the simulation and experiment.'};
            app.OpenInitialConditionAppButton.Text = {'Open Initial '; 'Condition App'};
            app.Step5SetDurationsPanel = uipanel(app.GridLayout17);
            app.Step5SetDurationsPanel.Title = 'Step #5: Set Durations';
            app.GridLayout3 = uigridlayout(app.Step5SetDurationsPanel);
            app.Phase1Label = uilabel(app.GridLayout3);
            app.Phase1Label.Text = 'Phase #1:';
            app.DurPhase1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase1EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase1EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase1EditFieldValueChanged, true);
            app.DurPhase1EditField.Tooltip = {'Set the duration for Phase #1 (start floating).'};
            app.DurPhase1EditField.Value = 5;
            app.DurPhase1Units = uilabel(app.GridLayout3);
            app.DurPhase1Units.Text = 's';
            app.Phase2Label = uilabel(app.GridLayout3);
            app.Phase2Label.Text = 'Phase #2:';
            app.DurPhase2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase2EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase2EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase2EditFieldValueChanged, true);
            app.DurPhase2EditField.Tooltip = {'Set the duration for Phase #2 (go to initial location as defined in the Initial Conditions App).'};
            app.DurPhase2EditField.Value = 20;
            app.DurPhase2Units = uilabel(app.GridLayout3);
            app.DurPhase2Units.Text = 's';
            app.Phase3Label = uilabel(app.GridLayout3);
            app.Phase3Label.Text = 'Phase #3:';
            app.DurPhase3EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase3EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase3EditField.Editable = 'off';
            app.DurPhase3EditField.Tooltip = {'Displays the duration for Phase #3 (run experiment).'};
            app.DurPhase3EditField.Value = 170;
            app.DurPhase3Units = uilabel(app.GridLayout3);
            app.DurPhase3Units.Text = 's';
            app.Phase4Label = uilabel(app.GridLayout3);
            app.Phase4Label.Text = 'Phase #4:';
            app.DurPhase4EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase4EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase4EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase4EditFieldValueChanged, true);
            app.DurPhase4EditField.Tooltip = {'Set the duration for Phase #4 (head to home location as defined in the Initial Conditions App).'};
            app.DurPhase4EditField.Value = 30;
            app.DurPhase4Units = uilabel(app.GridLayout3);
            app.DurPhase4Units.Text = 's';
            app.Phase5Label = uilabel(app.GridLayout3);
            app.Phase5Label.Text = 'Phase #5:';
            app.DurPhase5EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase5EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase5EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase5EditFieldValueChanged, true);
            app.DurPhase5EditField.Tooltip = {'Set the duration for Phase #5 (hold the home position until the end of the experiment).'};
            app.DurPhase5EditField.Value = 20;
            app.DurPhase5Units = uilabel(app.GridLayout3);
            app.DurPhase5Units.Text = 's';
            app.Phase3SubPhasesLabel = uilabel(app.GridLayout3);
            app.Phase3SubPhasesLabel.Text = 'Phase #3 - Sub-Phases';
            app.SubPhase1Label = uilabel(app.GridLayout3);
            app.SubPhase1Label.Text = 'Sub-Phase #1:';
            app.SubPhase1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubPhase1EditField.ValueDisplayFormat = '%.1f';
            app.SubPhase1EditField.ValueChangedFcn = createCallbackFcn(app, @SubPhase1EditFieldValueChanged, true);
            app.SubPhase1EditField.Tooltip = {'Set the duration for Phase #3 - Sub-Phase #1.'};
            app.SubPhase1EditField.Value = 10;
            app.SubPhase1Units = uilabel(app.GridLayout3);
            app.SubPhase1Units.Text = 's';
            app.SubPhase2Label = uilabel(app.GridLayout3);
            app.SubPhase2Label.Text = 'Sub-Phase #2:';
            app.SubPhase2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubPhase2EditField.ValueDisplayFormat = '%.1f';
            app.SubPhase2EditField.ValueChangedFcn = createCallbackFcn(app, @SubPhase2EditFieldValueChanged, true);
            app.SubPhase2EditField.Tooltip = {'Set the duration for Phase #3 - Sub-Phase #2.'};
            app.SubPhase2EditField.Value = 10;
            app.SubPhase2Units = uilabel(app.GridLayout3);
            app.SubPhase2Units.Text = 's';
            app.SubPhase3Label = uilabel(app.GridLayout3);
            app.SubPhase3Label.Text = 'Sub-Phase #3:';
            app.SubPhase3EditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubPhase3EditField.ValueDisplayFormat = '%.1f';
            app.SubPhase3EditField.ValueChangedFcn = createCallbackFcn(app, @SubPhase3EditFieldValueChanged, true);
            app.SubPhase3EditField.Tooltip = {'Set the duration for Phase #3 - Sub-Phase #3.'};
            app.SubPhase3EditField.Value = 30;
            app.SubPhase3Units = uilabel(app.GridLayout3);
            app.SubPhase3Units.Text = 's';
            app.SubPhase4Label = uilabel(app.GridLayout3);
            app.SubPhase4Label.Text = 'Sub-Phase #4:';
            app.SubPhase4EditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubPhase4EditField.ValueDisplayFormat = '%.1f';
            app.SubPhase4EditField.ValueChangedFcn = createCallbackFcn(app, @SubPhase4EditFieldValueChanged, true);
            app.SubPhase4EditField.Tooltip = {'Set the duration for Phase #3 - Sub-Phase #4.'};
            app.SubPhase4EditField.Value = 120;
            app.SubPhase4Units = uilabel(app.GridLayout3);
            app.SubPhase4Units.Text = 's';
            app.TerminationPhasesLabel = uilabel(app.GridLayout3);
            app.TerminationPhasesLabel.Text = 'Termination Phases ';
            app.DurPhase0EditField = uieditfield(app.GridLayout3, 'numeric');
            app.DurPhase0EditField.ValueDisplayFormat = '%.1f';
            app.DurPhase0EditField.ValueChangedFcn = createCallbackFcn(app, @DurPhase0EditFieldValueChanged, true);
            app.DurPhase0EditField.Tooltip = {'Set the duration for Phase #0 (waiting for data).'};
            app.DurPhase0EditField.Value = 10;
            app.DurPhase0Units = uilabel(app.GridLayout3);
            app.DurPhase0Units.Text = 's';
            app.StartupPhasesLabel = uilabel(app.GridLayout3);
            app.StartupPhasesLabel.Text = 'Startup Phases';
            app.TotalTimeLabel = uilabel(app.GridLayout3);
            app.TotalTimeLabel.Text = 'Total Time';
            app.TotalTimeLabel_2 = uilabel(app.GridLayout3);
            app.TotalTimeLabel_2.Text = 'Total Time:';
            app.TotalTimeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TotalTimeEditField.ValueDisplayFormat = '%.1f';
            app.TotalTimeEditField.Editable = 'off';
            app.TotalTimeEditField.Tooltip = {'Display the total time for the experiment. This is automatically updated based on the phase durations.'};
            app.TotalTimeEditField.Value = 255;
            app.SampRateUnits_2 = uilabel(app.GridLayout3);
            app.SampRateUnits_2.Text = 's';
            app.Phase0Label = uilabel(app.GridLayout3);
            app.Phase0Label.Text = 'Phase #0:';
            app.Step7SetSamplingRatePanel = uipanel(app.GridLayout17);
            app.Step7SetSamplingRatePanel.Title = 'Step #7: Set Sampling Rate';
            app.GridLayout3_7 = uigridlayout(app.Step7SetSamplingRatePanel);
            app.SampleRateEditField = uieditfield(app.GridLayout3_7, 'numeric');
            app.SampleRateEditField.ValueDisplayFormat = '%.1f';
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.SampleRateEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. If you change this value, you will need to retune the gain and possibly change the PWM period values.'};
            app.SampleRateEditField.Value = 20;
            app.DurPhase0Units_51 = uilabel(app.GridLayout3_7);
            app.DurPhase0Units_51.Text = 'Hz';
            app.Step2ActiveHardwarePanel = uipanel(app.GridLayout17);
            app.Step2ActiveHardwarePanel.Title = 'Step #2: Active Hardware';
            app.GridLayout4 = uigridlayout(app.Step2ActiveHardwarePanel);
            app.REDCheckBox = uicheckbox(app.GridLayout4);
            app.REDCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDCheckBoxValueChanged, true);
            app.REDCheckBox.Tooltip = {'Select to enable RED for the simulation and experiment.'};
            app.REDCheckBox.Text = 'RED ';
            app.BLUECheckBox = uicheckbox(app.GridLayout4);
            app.BLUECheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUECheckBoxValueChanged, true);
            app.BLUECheckBox.Tooltip = {'Select to enable BLUE for the simulation and experiment.'};
            app.BLUECheckBox.Text = 'BLUE ';
            app.ARMCheckBox = uicheckbox(app.GridLayout4);
            app.ARMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ARMCheckBoxValueChanged, true);
            app.ARMCheckBox.Tooltip = {'Select to enable the ARM for the simulation and experiment.'};
            app.ARMCheckBox.Text = 'ARM';
            app.BLACKCheckBox = uicheckbox(app.GridLayout4);
            app.BLACKCheckBox.ValueChangedFcn = createCallbackFcn(app, @BLACKCheckBoxValueChanged, true);
            app.BLACKCheckBox.Tooltip = {'Select to enable BLACK for the simulation and experiment.'};
            app.BLACKCheckBox.Text = 'BLACK ';
            app.Image3 = uiimage(app.GridLayout17);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Resources', 'SRCLLogoFinal_Version2.png');
            app.Step6ControlLawTuningPanel = uipanel(app.GridLayout17);
            app.Step6ControlLawTuningPanel.Title = 'Step #6: Control Law Tuning';
            app.GridLayout37 = uigridlayout(app.Step6ControlLawTuningPanel);
            app.TabGroup4 = uitabgroup(app.GridLayout37);
            app.LQRControllerTab = uitab(app.TabGroup4);
            app.LQRControllerTab.Title = 'LQR Controller';
            app.GridLayout39 = uigridlayout(app.LQRControllerTab);
            app.GridLayout36 = uigridlayout(app.GridLayout39);
            app.RedQThetaEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedQThetaEditField.ValueDisplayFormat = '%.3f';
            app.RedQThetaEditField.Tooltip = {'Set the proportial gain for RED (attitude error),'};
            app.RedQThetaEditField.Value = 100;
            app.RedQThetaDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedQThetaDEditField.ValueDisplayFormat = '%.3f';
            app.RedQThetaDEditField.Tooltip = {'Set the proportial gain for RED (attitude error),'};
            app.RedQThetaDEditField.Value = 100;
            app.RedQThetaLabel = uilabel(app.GridLayout36);
            app.RedQThetaLabel.Interpreter = 'latex';
            app.RedQThetaLabel.Text = '$\mathrm{Q_{\theta}}$';
            app.BlackQThetaEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackQThetaEditField.ValueDisplayFormat = '%.3f';
            app.BlackQThetaEditField.Tooltip = {'Set the proportial gain for BLACK (attitude error),'};
            app.BlackQThetaEditField.Value = 100;
            app.mathrmQ_dotthetaLabel = uilabel(app.GridLayout36);
            app.mathrmQ_dotthetaLabel.Interpreter = 'latex';
            app.mathrmQ_dotthetaLabel.Text = '$\mathrm{Q_{\dot{\theta}}}$';
            app.BlackQThetaDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackQThetaDEditField.ValueDisplayFormat = '%.3f';
            app.BlackQThetaDEditField.Tooltip = {'Set the proportial gain for RED (attitude error),'};
            app.BlackQThetaDEditField.Value = 100;
            app.mathrmQ_thetaLabel = uilabel(app.GridLayout36);
            app.mathrmQ_thetaLabel.Interpreter = 'latex';
            app.mathrmQ_thetaLabel.Text = '$\mathrm{Q_{\theta}}$';
            app.RedQYEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedQYEditField.ValueDisplayFormat = '%.3f';
            app.RedQYEditField.Tooltip = {'Set the proportial gain for RED (y-position error),'};
            app.RedQYEditField.Value = 100;
            app.BlueQThetaEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueQThetaEditField.ValueDisplayFormat = '%.3f';
            app.BlueQThetaEditField.Tooltip = {'Set the proportial gain for BLUE (attitude error),'};
            app.BlueQThetaEditField.Value = 100;
            app.mathrmQ_dotthetaLabel_2 = uilabel(app.GridLayout36);
            app.mathrmQ_dotthetaLabel_2.Interpreter = 'latex';
            app.mathrmQ_dotthetaLabel_2.Text = '$\mathrm{Q_{\dot{\theta}}}$';
            app.RedQYDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedQYDEditField.ValueDisplayFormat = '%.3f';
            app.RedQYDEditField.Tooltip = {'Set the proportial gain for RED (y-position error),'};
            app.RedQYDEditField.Value = 100;
            app.StateWeightingMatrixDiagonalsLabel = uilabel(app.GridLayout36);
            app.StateWeightingMatrixDiagonalsLabel.Text = 'State Weighting Matrix Diagonals';
            app.BlueQThetaDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueQThetaDEditField.ValueDisplayFormat = '%.3f';
            app.BlueQThetaDEditField.Tooltip = {'Set the proportial gain for RED (attitude error),'};
            app.BlueQThetaDEditField.Value = 100;
            app.mathrmQ_thetaLabel_2 = uilabel(app.GridLayout36);
            app.mathrmQ_thetaLabel_2.Interpreter = 'latex';
            app.mathrmQ_thetaLabel_2.Text = '$\mathrm{Q_{\theta}}$';
            app.BlackQYEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackQYEditField.ValueDisplayFormat = '%.3f';
            app.BlackQYEditField.Tooltip = {'Set the proportial gain for BLACK (y-position  error),'};
            app.BlackQYEditField.Value = 100;
            app.BlackQYDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackQYDEditField.ValueDisplayFormat = '%.3f';
            app.BlackQYDEditField.Tooltip = {'Set the proportial gain for RED (y-position error),'};
            app.BlackQYDEditField.Value = 100;
            app.mathrmQ_dotthetaLabel_3 = uilabel(app.GridLayout36);
            app.mathrmQ_dotthetaLabel_3.Interpreter = 'latex';
            app.mathrmQ_dotthetaLabel_3.Text = '$\mathrm{Q_{\dot{\theta}}}$';
            app.RedRTzEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedRTzEditField.ValueDisplayFormat = '%.3f';
            app.RedRTzEditField.Tooltip = {'Set the derivative gain for RED (attitude error),'};
            app.RedRTzEditField.Value = 10;
            app.mathrmQ_yLabel = uilabel(app.GridLayout36);
            app.mathrmQ_yLabel.Interpreter = 'latex';
            app.mathrmQ_yLabel.Text = '$\mathrm{Q_{y}}$';
            app.mathrmQ_dotyLabel = uilabel(app.GridLayout36);
            app.mathrmQ_dotyLabel.Interpreter = 'latex';
            app.mathrmQ_dotyLabel.Text = '$\mathrm{Q_{\dot{y}}}$';
            app.BlueQYEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueQYEditField.ValueDisplayFormat = '%.3f';
            app.BlueQYEditField.Tooltip = {'Set the proportial gain for BLUE (y-position error),'};
            app.BlueQYEditField.Value = 100;
            app.mathrmR_T_zLabel = uilabel(app.GridLayout36);
            app.mathrmR_T_zLabel.Interpreter = 'latex';
            app.mathrmR_T_zLabel.Text = '$\mathrm{R_{T_{z}}}$';
            app.mathrmQ_yLabel_2 = uilabel(app.GridLayout36);
            app.mathrmQ_yLabel_2.Interpreter = 'latex';
            app.mathrmQ_yLabel_2.Text = '$\mathrm{Q_{y}}$';
            app.BlackRTzEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackRTzEditField.ValueDisplayFormat = '%.3f';
            app.BlackRTzEditField.Tooltip = {'Set the derivative gain for BLACK (attitude error),'};
            app.BlackRTzEditField.Value = 10;
            app.BlueQYDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueQYDEditField.ValueDisplayFormat = '%.3f';
            app.BlueQYDEditField.Tooltip = {'Set the proportial gain for RED (y-position error),'};
            app.BlueQYDEditField.Value = 100;
            app.mathrmQ_dotyLabel_2 = uilabel(app.GridLayout36);
            app.mathrmQ_dotyLabel_2.Interpreter = 'latex';
            app.mathrmQ_dotyLabel_2.Text = '$\mathrm{Q_{\dot{y}}}$';
            app.mathrmR_T_zLabel_2 = uilabel(app.GridLayout36);
            app.mathrmR_T_zLabel_2.Interpreter = 'latex';
            app.mathrmR_T_zLabel_2.Text = '$\mathrm{R_{T_{z}}}$';
            app.RedRFYEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedRFYEditField.ValueDisplayFormat = '%.3f';
            app.RedRFYEditField.Tooltip = {'Set the derivative gain for RED (y-position error),'};
            app.RedRFYEditField.Value = 10;
            app.BlueRTzEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueRTzEditField.ValueDisplayFormat = '%.3f';
            app.BlueRTzEditField.Tooltip = {'Set the derivative gain for BLUE (attitude error),'};
            app.BlueRTzEditField.Value = 10;
            app.mathrmQ_yLabel_3 = uilabel(app.GridLayout36);
            app.mathrmQ_yLabel_3.Interpreter = 'latex';
            app.mathrmQ_yLabel_3.Text = '$\mathrm{Q_{y}}$';
            app.RedQXEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedQXEditField.ValueDisplayFormat = '%.3f';
            app.RedQXEditField.Tooltip = {'Set the proportial gain for RED (x-position error),'};
            app.RedQXEditField.Value = 100;
            app.RedQXDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedQXDEditField.ValueDisplayFormat = '%.3f';
            app.RedQXDEditField.Tooltip = {'Set the proportial gain for RED (x-position error),'};
            app.RedQXDEditField.Value = 100;
            app.mathrmQ_dotyLabel_3 = uilabel(app.GridLayout36);
            app.mathrmQ_dotyLabel_3.Interpreter = 'latex';
            app.mathrmQ_dotyLabel_3.Text = '$\mathrm{Q_{\dot{y}}}$';
            app.mathrmR_T_zLabel_3 = uilabel(app.GridLayout36);
            app.mathrmR_T_zLabel_3.Interpreter = 'latex';
            app.mathrmR_T_zLabel_3.Text = '$\mathrm{R_{T_{z}}}$';
            app.BlackQXEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackQXEditField.ValueDisplayFormat = '%.3f';
            app.BlackQXEditField.Tooltip = {'Set the proportial gain for BLACK (x-position error),'};
            app.BlackQXEditField.Value = 100;
            app.BlackRFYEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackRFYEditField.ValueDisplayFormat = '%.3f';
            app.BlackRFYEditField.Tooltip = {'Set the derivative gain for BLACK (y-position error),'};
            app.BlackRFYEditField.Value = 10;
            app.BlackQXDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackQXDEditField.ValueDisplayFormat = '%.3f';
            app.BlackQXDEditField.Tooltip = {'Set the proportial gain for RED (x-position error),'};
            app.BlackQXDEditField.Value = 100;
            app.mathrmR_F_yLabel = uilabel(app.GridLayout36);
            app.mathrmR_F_yLabel.Interpreter = 'latex';
            app.mathrmR_F_yLabel.Text = '$\mathrm{R_{F_{y}}}$';
            app.BlueRFYEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueRFYEditField.ValueDisplayFormat = '%.3f';
            app.BlueRFYEditField.Tooltip = {'Set the derivative gain for BLUE (y-position error),'};
            app.BlueRFYEditField.Value = 10;
            app.BlueQXEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueQXEditField.ValueDisplayFormat = '%.3f';
            app.BlueQXEditField.Tooltip = {'Set the proportial gain for BLUE (x-position error),'};
            app.BlueQXEditField.Value = 100;
            app.RedQXLabel = uilabel(app.GridLayout36);
            app.RedQXLabel.Interpreter = 'latex';
            app.RedQXLabel.Text = '$\mathrm{Q_{x}}$';
            app.mathrmR_F_yLabel_2 = uilabel(app.GridLayout36);
            app.mathrmR_F_yLabel_2.Interpreter = 'latex';
            app.mathrmR_F_yLabel_2.Text = '$\mathrm{R_{F_{y}}}$';
            app.BlueQXDEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueQXDEditField.ValueDisplayFormat = '%.3f';
            app.BlueQXDEditField.Tooltip = {'Set the proportial gain for RED (x-position error),'};
            app.BlueQXDEditField.Value = 100;
            app.mathrmQ_dotxLabel = uilabel(app.GridLayout36);
            app.mathrmQ_dotxLabel.Interpreter = 'latex';
            app.mathrmQ_dotxLabel.Text = '$\mathrm{Q_{\dot{x}}}$';
            app.mathrmQ_xLabel = uilabel(app.GridLayout36);
            app.mathrmQ_xLabel.Interpreter = 'latex';
            app.mathrmQ_xLabel.Text = '$\mathrm{Q_{x}}$';
            app.mathrmR_F_yLabel_3 = uilabel(app.GridLayout36);
            app.mathrmR_F_yLabel_3.Interpreter = 'latex';
            app.mathrmR_F_yLabel_3.Text = '$\mathrm{R_{F_{y}}}$';
            app.RedRFXEditField = uieditfield(app.GridLayout36, 'numeric');
            app.RedRFXEditField.ValueDisplayFormat = '%.3f';
            app.RedRFXEditField.Tooltip = {'Set the derivative gain for RED (x-position error),'};
            app.RedRFXEditField.Value = 10;
            app.mathrmQ_dotxLabel_2 = uilabel(app.GridLayout36);
            app.mathrmQ_dotxLabel_2.Interpreter = 'latex';
            app.mathrmQ_dotxLabel_2.Text = '$\mathrm{Q_{\dot{x}}}$';
            app.mathrmQ_xLabel_2 = uilabel(app.GridLayout36);
            app.mathrmQ_xLabel_2.Interpreter = 'latex';
            app.mathrmQ_xLabel_2.Text = '$\mathrm{Q_{x}}$';
            app.BlackRFXEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlackRFXEditField.ValueDisplayFormat = '%.3f';
            app.BlackRFXEditField.Tooltip = {'Set the derivative gain for BLACK (x-position error),'};
            app.BlackRFXEditField.Value = 10;
            app.mathrmQ_dotxLabel_3 = uilabel(app.GridLayout36);
            app.mathrmQ_dotxLabel_3.Interpreter = 'latex';
            app.mathrmQ_dotxLabel_3.Text = '$\mathrm{Q_{\dot{x}}}$';
            app.BlueRFXEditField = uieditfield(app.GridLayout36, 'numeric');
            app.BlueRFXEditField.ValueDisplayFormat = '%.3f';
            app.BlueRFXEditField.Tooltip = {'Set the derivative gain for BLUE (x-position error),'};
            app.BlueRFXEditField.Value = 10;
            app.mathrmR_F_xLabel = uilabel(app.GridLayout36);
            app.mathrmR_F_xLabel.Interpreter = 'latex';
            app.mathrmR_F_xLabel.Text = '$\mathrm{R_{F_{x}}}$';
            app.mathrmR_F_xLabel_2 = uilabel(app.GridLayout36);
            app.mathrmR_F_xLabel_2.Interpreter = 'latex';
            app.mathrmR_F_xLabel_2.Text = '$\mathrm{R_{F_{x}}}$';
            app.mathrmR_F_xLabel_3 = uilabel(app.GridLayout36);
            app.mathrmR_F_xLabel_3.Interpreter = 'latex';
            app.mathrmR_F_xLabel_3.Text = '$\mathrm{R_{F_{x}}}$';
            app.ControlWeightingMatrixDiagonalsLabel = uilabel(app.GridLayout36);
            app.ControlWeightingMatrixDiagonalsLabel.Text = 'Control Weighting Matrix Diagonals';
            app.PDControllerTab = uitab(app.TabGroup4);
            app.PDControllerTab.Title = 'PD Controller';
            app.GridLayout38 = uigridlayout(app.PDControllerTab);
            app.Step6ControlLawTuningPanel_LQR = uipanel(app.GridLayout38);
            app.GridLayout3_8 = uigridlayout(app.Step6ControlLawTuningPanel_LQR);
            app.mathrmK_pXLabel = uilabel(app.GridLayout3_8);
            app.mathrmK_pXLabel.Interpreter = 'latex';
            app.mathrmK_pXLabel.Text = '$\mathrm{K_{pX}}$';
            app.mathrmK_pXLabel_2 = uilabel(app.GridLayout3_8);
            app.mathrmK_pXLabel_2.Interpreter = 'latex';
            app.mathrmK_pXLabel_2.Text = '$\mathrm{K_{pX}}$';
            app.mathrmK_pXLabel_3 = uilabel(app.GridLayout3_8);
            app.mathrmK_pXLabel_3.Interpreter = 'latex';
            app.mathrmK_pXLabel_3.Text = '$\mathrm{K_{pX}}$';
            app.RedPXGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.RedPXGainEditField.ValueDisplayFormat = '%.1f';
            app.RedPXGainEditField.ValueChangedFcn = createCallbackFcn(app, @RedPXGainEditFieldValueChanged, true);
            app.RedPXGainEditField.Tooltip = {'Set the proportial gain for RED (x-position error),'};
            app.RedPXGainEditField.Value = 1;
            app.BlackPXGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlackPXGainEditField.ValueDisplayFormat = '%.1f';
            app.BlackPXGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlackPXGainEditFieldValueChanged, true);
            app.BlackPXGainEditField.Tooltip = {'Set the proportial gain for BLACK (x-position error),'};
            app.BlackPXGainEditField.Value = 1;
            app.BluePXGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BluePXGainEditField.ValueDisplayFormat = '%.1f';
            app.BluePXGainEditField.ValueChangedFcn = createCallbackFcn(app, @BluePXGainEditFieldValueChanged, true);
            app.BluePXGainEditField.Tooltip = {'Set the proportial gain for BLUE (x-position error),'};
            app.BluePXGainEditField.Value = 1;
            app.mathrmK_pYLabel = uilabel(app.GridLayout3_8);
            app.mathrmK_pYLabel.Interpreter = 'latex';
            app.mathrmK_pYLabel.Text = '$\mathrm{K_{pY}}$';
            app.mathrmK_pYLabel_2 = uilabel(app.GridLayout3_8);
            app.mathrmK_pYLabel_2.Interpreter = 'latex';
            app.mathrmK_pYLabel_2.Text = '$\mathrm{K_{pY}}$';
            app.mathrmK_pYLabel_3 = uilabel(app.GridLayout3_8);
            app.mathrmK_pYLabel_3.Interpreter = 'latex';
            app.mathrmK_pYLabel_3.Text = '$\mathrm{K_{pY}}$';
            app.RedPYGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.RedPYGainEditField.ValueDisplayFormat = '%.1f';
            app.RedPYGainEditField.ValueChangedFcn = createCallbackFcn(app, @RedPYGainEditFieldValueChanged2, true);
            app.RedPYGainEditField.Tooltip = {'Set the proportial gain for RED (y-position error),'};
            app.RedPYGainEditField.Value = 1;
            app.BlackPYGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlackPYGainEditField.ValueDisplayFormat = '%.1f';
            app.BlackPYGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlackPYGainEditFieldValueChanged2, true);
            app.BlackPYGainEditField.Tooltip = {'Set the proportial gain for BLACK (y-position  error),'};
            app.BlackPYGainEditField.Value = 1;
            app.BluePYGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BluePYGainEditField.ValueDisplayFormat = '%.1f';
            app.BluePYGainEditField.ValueChangedFcn = createCallbackFcn(app, @BluePYGainEditFieldValueChanged2, true);
            app.BluePYGainEditField.Tooltip = {'Set the proportial gain for BLUE (y-position error),'};
            app.BluePYGainEditField.Value = 1;
            app.mathrmK_pthetaLabel = uilabel(app.GridLayout3_8);
            app.mathrmK_pthetaLabel.Interpreter = 'latex';
            app.mathrmK_pthetaLabel.Text = '$\mathrm{K_{p\theta}}$';
            app.mathrmK_pthetaLabel_2 = uilabel(app.GridLayout3_8);
            app.mathrmK_pthetaLabel_2.Interpreter = 'latex';
            app.mathrmK_pthetaLabel_2.Text = '$\mathrm{K_{p\theta}}$';
            app.mathrmK_pthetaLabel_3 = uilabel(app.GridLayout3_8);
            app.mathrmK_pthetaLabel_3.Interpreter = 'latex';
            app.mathrmK_pthetaLabel_3.Text = '$\mathrm{K_{p\theta}}$';
            app.RedPGainThetaEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.RedPGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.RedPGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @RedPGainThetaEditFieldValueChanged, true);
            app.RedPGainThetaEditField.Tooltip = {'Set the proportial gain for RED (attitude error),'};
            app.RedPGainThetaEditField.Value = 0.5;
            app.BlackPGainThetaEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlackPGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.BlackPGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @BlackPGainThetaEditFieldValueChanged, true);
            app.BlackPGainThetaEditField.Tooltip = {'Set the proportial gain for BLACK (attitude error),'};
            app.BlackPGainThetaEditField.Value = 0.5;
            app.BluePGainThetaEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BluePGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.BluePGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @BluePGainThetaEditFieldValueChanged, true);
            app.BluePGainThetaEditField.Tooltip = {'Set the proportial gain for BLUE (attitude error),'};
            app.BluePGainThetaEditField.Value = 0.5;
            app.ProportionalGainsLabel = uilabel(app.GridLayout3_8);
            app.ProportionalGainsLabel.Text = 'Proportional Gains';
            app.DerivativeGainsLabel = uilabel(app.GridLayout3_8);
            app.DerivativeGainsLabel.Text = 'Derivative Gains';
            app.mathrmK_dXLabel = uilabel(app.GridLayout3_8);
            app.mathrmK_dXLabel.Interpreter = 'latex';
            app.mathrmK_dXLabel.Text = '$\mathrm{K_{dX}}$';
            app.mathrmK_dXLabel_2 = uilabel(app.GridLayout3_8);
            app.mathrmK_dXLabel_2.Interpreter = 'latex';
            app.mathrmK_dXLabel_2.Text = '$\mathrm{K_{dX}}$';
            app.mathrmK_dXLabel_3 = uilabel(app.GridLayout3_8);
            app.mathrmK_dXLabel_3.Interpreter = 'latex';
            app.mathrmK_dXLabel_3.Text = '$\mathrm{K_{dX}}$';
            app.RedDXGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.RedDXGainEditField.ValueDisplayFormat = '%.1f';
            app.RedDXGainEditField.ValueChangedFcn = createCallbackFcn(app, @RedDXGainEditFieldValueChanged, true);
            app.RedDXGainEditField.Tooltip = {'Set the derivative gain for RED (x-position error),'};
            app.RedDXGainEditField.Value = 2.6;
            app.BlackDXGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlackDXGainEditField.ValueDisplayFormat = '%.1f';
            app.BlackDXGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlackDXGainEditFieldValueChanged, true);
            app.BlackDXGainEditField.Tooltip = {'Set the derivative gain for BLACK (x-position error),'};
            app.BlackDXGainEditField.Value = 2.6;
            app.BlueDXGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlueDXGainEditField.ValueDisplayFormat = '%.1f';
            app.BlueDXGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlueDXGainEditFieldValueChanged, true);
            app.BlueDXGainEditField.Tooltip = {'Set the derivative gain for BLUE (x-position error),'};
            app.BlueDXGainEditField.Value = 2.6;
            app.mathrmK_dYLabel = uilabel(app.GridLayout3_8);
            app.mathrmK_dYLabel.Interpreter = 'latex';
            app.mathrmK_dYLabel.Text = '$\mathrm{K_{dY}}$';
            app.mathrmK_dYLabel_2 = uilabel(app.GridLayout3_8);
            app.mathrmK_dYLabel_2.Interpreter = 'latex';
            app.mathrmK_dYLabel_2.Text = '$\mathrm{K_{dY}}$';
            app.mathrmK_dYLabel_3 = uilabel(app.GridLayout3_8);
            app.mathrmK_dYLabel_3.Interpreter = 'latex';
            app.mathrmK_dYLabel_3.Text = '$\mathrm{K_{dY}}$';
            app.RedDYGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.RedDYGainEditField.ValueDisplayFormat = '%.1f';
            app.RedDYGainEditField.ValueChangedFcn = createCallbackFcn(app, @RedDYGainEditFieldValueChanged2, true);
            app.RedDYGainEditField.Tooltip = {'Set the derivative gain for RED (y-position error),'};
            app.RedDYGainEditField.Value = 2.6;
            app.BlackDYGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlackDYGainEditField.ValueDisplayFormat = '%.1f';
            app.BlackDYGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlackDYGainEditFieldValueChanged2, true);
            app.BlackDYGainEditField.Tooltip = {'Set the derivative gain for BLACK (y-position error),'};
            app.BlackDYGainEditField.Value = 2.6;
            app.BlueDYGainEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlueDYGainEditField.ValueDisplayFormat = '%.1f';
            app.BlueDYGainEditField.ValueChangedFcn = createCallbackFcn(app, @BlueDYGainEditFieldValueChanged2, true);
            app.BlueDYGainEditField.Tooltip = {'Set the derivative gain for BLUE (y-position error),'};
            app.BlueDYGainEditField.Value = 2.6;
            app.mathrmK_dthetaLabel = uilabel(app.GridLayout3_8);
            app.mathrmK_dthetaLabel.Interpreter = 'latex';
            app.mathrmK_dthetaLabel.Text = '$\mathrm{K_{d\theta}}$';
            app.mathrmK_dthetaLabel_2 = uilabel(app.GridLayout3_8);
            app.mathrmK_dthetaLabel_2.Interpreter = 'latex';
            app.mathrmK_dthetaLabel_2.Text = '$\mathrm{K_{d\theta}}$';
            app.mathrmK_dthetaLabel_3 = uilabel(app.GridLayout3_8);
            app.mathrmK_dthetaLabel_3.Interpreter = 'latex';
            app.mathrmK_dthetaLabel_3.Text = '$\mathrm{K_{d\theta}}$';
            app.RedDGainThetaEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.RedDGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.RedDGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @RedDGainThetaEditFieldValueChanged, true);
            app.RedDGainThetaEditField.Tooltip = {'Set the derivative gain for RED (attitude error),'};
            app.RedDGainThetaEditField.Value = 1.2;
            app.BlackDGainThetaEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlackDGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.BlackDGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @BlackDGainThetaEditFieldValueChanged, true);
            app.BlackDGainThetaEditField.Tooltip = {'Set the derivative gain for BLACK (attitude error),'};
            app.BlackDGainThetaEditField.Value = 1.2;
            app.BlueDGainThetaEditField = uieditfield(app.GridLayout3_8, 'numeric');
            app.BlueDGainThetaEditField.ValueDisplayFormat = '%.1f';
            app.BlueDGainThetaEditField.ValueChangedFcn = createCallbackFcn(app, @BlueDGainThetaEditFieldValueChanged, true);
            app.BlueDGainThetaEditField.Tooltip = {'Set the derivative gain for BLUE (attitude error),'};
            app.BlueDGainThetaEditField.Value = 1.2;
            app.SetupRunSimulationsTab = uitab(app.TabGroup2);
            app.SetupRunSimulationsTab.Title = 'Setup & Run Simulations';
            app.GridLayout18 = uigridlayout(app.SetupRunSimulationsTab);
            app.Step8RunSimulationPanel = uipanel(app.GridLayout18);
            app.Step8RunSimulationPanel.Title = 'Step #8: Run Simulation';
            app.GridLayout7 = uigridlayout(app.Step8RunSimulationPanel);
            app.ExperimentPreview = uiaxes(app.GridLayout7);
            xlabel(app.ExperimentPreview, 'Y-Coordinate (m)')
            ylabel(app.ExperimentPreview, 'X-Coordinate (m)')
            app.GridLayout25 = uigridlayout(app.GridLayout7);
            app.AnimationScrubberLabel = uilabel(app.GridLayout25);
            app.AnimationScrubberLabel.Text = 'Animation Scrubber';
            app.AnimationScrubberSlider = uislider(app.GridLayout25);
            app.AnimationScrubberSlider.MajorTicks = [0 50 100];
            app.AnimationScrubberSlider.MajorTickLabels = {''};
            app.AnimationScrubberSlider.ValueChangingFcn = createCallbackFcn(app, @AnimationScrubberSliderValueChanging, true);
            app.AnimationScrubberSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.AnimationScrubberSlider.Tooltip = {'After a simulation has been performed, use this to scrub to a specific point in time.'};
            app.AnimationScrubberSlider.Value = 46.4919793801229;
            app.GridLayout26 = uigridlayout(app.GridLayout7);
            app.AnimationSpeedLabel = uilabel(app.GridLayout26);
            app.AnimationSpeedLabel.Text = 'Animation Speed';
            app.AnimationSpeedSlider = uislider(app.GridLayout26);
            app.AnimationSpeedSlider.MajorTicks = [0 100];
            app.AnimationSpeedSlider.MajorTickLabels = {'Fast', 'Slow'};
            app.AnimationSpeedSlider.Tooltip = {'Change the speed of the animation. '};
            app.AnimationSpeedSlider.Value = 56.333251953125;
            app.GridLayout32 = uigridlayout(app.GridLayout7);
            app.SkipToTimeEditField = uispinner(app.GridLayout32);
            app.SkipToTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SkipToTimeEditFieldValueChanged, true);
            app.SkipToTimeEditField.Tooltip = {'After a simulation has been performed, use this to jump to a specific point in time.'};
            app.DurPhase0Units_52 = uilabel(app.GridLayout32);
            app.DurPhase0Units_52.Text = 's';
            app.SkipToLabel = uilabel(app.GridLayout32);
            app.SkipToLabel.Text = 'Skip To:';
            app.GridLayout26_2 = uigridlayout(app.GridLayout7);
            app.AnimationControlsLabel = uilabel(app.GridLayout26_2);
            app.AnimationControlsLabel.Text = 'Animation Controls';
            app.PlayButton = uibutton(app.GridLayout26_2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Tooltip = {'Play the animation for the simulation.'};
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.GridLayout26_2, 'state');
            app.PauseButton.Tooltip = {'While the animation is playing, pause the animation.'};
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.GridLayout26_2, 'state');
            app.StopButton.Tooltip = {'Stop the animation.'};
            app.StopButton.Text = 'Stop';
            app.GridLayout26_3 = uigridlayout(app.GridLayout7);
            app.SimulationControlsLabel = uilabel(app.GridLayout26_3);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.RunSimButton = uibutton(app.GridLayout26_3, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Tooltip = {'Click to run the simulation for the currently loaded (and selected) diagram in the Available Diagrams dropdown menu.'};
            app.RunSimButton.Text = 'Run Sim.';
            app.SaveDataButton = uibutton(app.GridLayout26_3, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'Save the simulation data to the Saved Data folder.'};
            app.SaveDataButton.Text = 'Save Data';
            app.AutomaticFolderNamingCheckBox = uicheckbox(app.GridLayout26_3);
            app.AutomaticFolderNamingCheckBox.Text = 'Automatic Folder Naming';
            app.AutomaticFolderNamingCheckBox.Value = true;
            app.SimulationSettingsPanel = uipanel(app.GridLayout18);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.GridLayout3_4 = uigridlayout(app.SimulationSettingsPanel);
            app.SolverTypeDropDownLabel_3 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_3.Text = 'Max Step Size:';
            app.SolverOptionsLabel = uilabel(app.GridLayout3_4);
            app.SolverOptionsLabel.Text = 'Solver Options';
            app.VariableStepOptionsLabel = uilabel(app.GridLayout3_4);
            app.VariableStepOptionsLabel.Text = 'Variable-Step Options';
            app.SolverTypeDropDownLabel_4 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_4.Text = 'Min Step Size:';
            app.SolverTypeDropDownLabel_5 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_5.Text = 'Init. Step Size:';
            app.SolverTypeDropDownLabel_6 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_6.Text = 'Rel. Tolerance:';
            app.SolverTypeDropDownLabel_7 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_7.Text = 'Abs. Tolerance:';
            app.DurPhase0Units_31 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_31.Text = 's';
            app.DurPhase0Units_32 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_32.Text = 's';
            app.DurPhase0Units_33 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_33.Text = 's';
            app.DurPhase0Units_34 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_34.Text = '-';
            app.DurPhase0Units_35 = uilabel(app.GridLayout3_4);
            app.DurPhase0Units_35.Text = '-';
            app.VarStepMaxStepSizeEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepMaxStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepMaxStepSizeEditFieldValueChanged, true);
            app.VarStepMaxStepSizeEditField.Tooltip = {'If using a variable-step solver, set the max step size.'};
            app.VarStepMinStepSizeEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepMinStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepMinStepSizeEditFieldValueChanged, true);
            app.VarStepMinStepSizeEditField.Tooltip = {'If using a variable-step solver, set the min step size.'};
            app.VarStepInitStepSizeEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepInitStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepInitStepSizeEditFieldValueChanged, true);
            app.VarStepInitStepSizeEditField.Tooltip = {'If using a variable-step solver, set the initial step size.'};
            app.VarStepRelTolEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepRelTolEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepRelTolEditFieldValueChanged, true);
            app.VarStepRelTolEditField.Tooltip = {'If using a variable-step solver, set the relative tolerance.'};
            app.VarStepAbsTolEditField = uieditfield(app.GridLayout3_4, 'text');
            app.VarStepAbsTolEditField.ValueChangedFcn = createCallbackFcn(app, @VarStepAbsTolEditFieldValueChanged, true);
            app.VarStepAbsTolEditField.Tooltip = {'If using a variable-step solver, set the absolute tolerance.'};
            app.SolverTypeDropDownLabel_2 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_2.Text = 'Solver Name:';
            app.SolverTypeDropDownLabel_8 = uilabel(app.GridLayout3_4);
            app.SolverTypeDropDownLabel_8.Text = 'Solver Type:';
            app.SolverTypeDropDown = uidropdown(app.GridLayout3_4);
            app.SolverTypeDropDown.Items = {'Fixed-step', 'Variable-step'};
            app.SolverTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverTypeDropDownValueChanged, true);
            app.SolverTypeDropDown.Tooltip = {'Set the solver type for the simulation. Note that when running an experiment, the type is set to be fixed-step.'};
            app.SolverTypeDropDown.Value = 'Fixed-step';
            app.SolverNameVarStepDropDown = uidropdown(app.GridLayout3_4);
            app.SolverNameVarStepDropDown.Items = {'VariableStepAuto', 'VariableStepDiscrete', 'ode45', 'ode23', 'ode113', 'ode15s', 'ode23s', 'ode23t', 'ode23tb', 'odeN', 'daessc'};
            app.SolverNameVarStepDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverNameVarStepDropDownValueChanged, true);
            app.SolverNameVarStepDropDown.Tooltip = {'Choose the solver algorithm for the variable-step solver. In experiment, this will be forced to be ODE4.'};
            app.SolverNameVarStepDropDown.Value = 'VariableStepAuto';
            app.SolverNameDropDown = uidropdown(app.GridLayout3_4);
            app.SolverNameDropDown.Items = {'FixedStepAuto', 'FixedStepDiscrete', 'ode8', 'ode5', 'ode4', 'ode3', 'ode2', 'ode1', 'ode14x', 'ode1be'};
            app.SolverNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverNameDropDownValueChanged, true);
            app.SolverNameDropDown.Tooltip = {'Choose the solver algorithm for the fixed-step solver. In experiment, this will be forced to be ODE4.'};
            app.SolverNameDropDown.Value = 'FixedStepAuto';
            app.AnimationOptionsPanel = uipanel(app.GridLayout18);
            app.AnimationOptionsPanel.Title = 'Animation Options';
            app.GridLayout3_5 = uigridlayout(app.AnimationOptionsPanel);
            app.AnimationTree = uitree(app.GridLayout3_5, 'checkbox');
            app.REDNode = uitreenode(app.AnimationTree);
            app.REDNode.Text = 'RED';
            app.ShowDockingConeNode = uitreenode(app.REDNode);
            app.ShowDockingConeNode.Text = 'Show Docking Cone';
            app.ShowPathTraceNode = uitreenode(app.REDNode);
            app.ShowPathTraceNode.Text = 'Show Path Trace';
            app.BLACKNode = uitreenode(app.AnimationTree);
            app.BLACKNode.Text = 'BLACK';
            app.ShowSolarPanelsNode = uitreenode(app.BLACKNode);
            app.ShowSolarPanelsNode.Text = 'Show Solar Panels';
            app.ShowDockingPortNode = uitreenode(app.BLACKNode);
            app.ShowDockingPortNode.Text = 'Show Docking Port';
            app.ShowPathTraceNode_2 = uitreenode(app.BLACKNode);
            app.ShowPathTraceNode_2.Text = 'Show Path Trace';
            app.BLUENode = uitreenode(app.AnimationTree);
            app.BLUENode.Text = 'BLUE';
            app.ShowPathTraceNode_3 = uitreenode(app.BLUENode);
            app.ShowPathTraceNode_3.Text = 'Show Path Trace';
            app.CreateVideoNode = uitreenode(app.AnimationTree);
            app.CreateVideoNode.Text = 'Create Video';
            app.SetupRunExperimentsTab = uitab(app.TabGroup2);
            app.SetupRunExperimentsTab.Title = 'Setup & Run Experiments';
            app.GridLayout19_2 = uigridlayout(app.SetupRunExperimentsTab);
            app.ExperimentControlsPanel = uipanel(app.GridLayout19_2);
            app.ExperimentControlsPanel.Title = 'Experiment Controls';
            app.GridLayout9 = uigridlayout(app.ExperimentControlsPanel);
            app.StartExperimentButton = uibutton(app.GridLayout9, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Tooltip = {'Start the selected experiment (cameras must be on before starting).'};
            app.StartExperimentButton.Text = {'Start '; 'Experiment'};
            app.StopExperimentButton = uibutton(app.GridLayout9, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Tooltip = {'Stop the selected experiment. This is only enabled after you have started an experiment.'};
            app.StopExperimentButton.Text = {'Stop '; 'Experiment'};
            app.SaveExperimentDataButton = uibutton(app.GridLayout9, 'push');
            app.SaveExperimentDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExperimentDataButtonPushed, true);
            app.SaveExperimentDataButton.Tooltip = {'Copy the experiment data from all active hardware and save it to the Saved Data folder.'};
            app.SaveExperimentDataButton.Text = {'Save Experiment'; 'Data'};
            app.BuildCode = uibutton(app.GridLayout9, 'push');
            app.BuildCode.ButtonPushedFcn = createCallbackFcn(app, @BuildCodePushed, true);
            app.BuildCode.Tooltip = {'Build the code for the selected diagram to the designated "Master Platform". Then, copy the built code to the other active platforms.'};
            app.BuildCode.Text = 'Build Code';
            app.GridLayout28 = uigridlayout(app.GridLayout9);
            app.PhaseSpaceLabel = uilabel(app.GridLayout28);
            app.PhaseSpaceLabel.Text = 'PhaseSpace:';
            app.PhaseSpaceSwitch = uiswitch(app.GridLayout28, 'slider');
            app.PhaseSpaceSwitch.ValueChangedFcn = createCallbackFcn(app, @PhaseSpaceSwitchValueChanged, true);
            app.PhaseSpaceSwitch.Tooltip = {'Toggle to ON to turn on the PhaseSpace cameras. This relies on streamdata.exe located in the Resources folder.'};
            app.CamerasOnLamp = uilamp(app.GridLayout28);
            app.AutomaticFolderNamingCheckBoxExp = uicheckbox(app.GridLayout9);
            app.AutomaticFolderNamingCheckBoxExp.Text = 'Automatic Folder Naming';
            app.AutomaticFolderNamingCheckBoxExp.Value = true;
            app.RealTimeVisualizationPanel = uipanel(app.GridLayout19_2);
            app.RealTimeVisualizationPanel.Title = 'Real-Time Visualization';
            app.GridLayout9_2 = uigridlayout(app.RealTimeVisualizationPanel);
            app.ExperimentPreviewLive = uiaxes(app.GridLayout9_2);
            xlabel(app.ExperimentPreviewLive, 'Y-Coordinate (m)')
            ylabel(app.ExperimentPreviewLive, 'X-Coordinate (m)')
            app.RealTimePhaseSpaceDataPanel = uipanel(app.GridLayout19_2);
            app.RealTimePhaseSpaceDataPanel.Title = 'Real-Time PhaseSpace Data';
            app.GridLayout9_4 = uigridlayout(app.RealTimePhaseSpaceDataPanel);
            app.mathrmX_REDEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmX_REDEditFieldLabel.Interpreter = 'latex';
            app.mathrmX_REDEditFieldLabel.Text = '$\mathrm{X_{RED}}$';
            app.mathrmX_REDEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmX_REDEditField.Editable = 'off';
            app.mathrmX_REDEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the RED platform (x-position).'};
            app.mathrmY_REDEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmY_REDEditFieldLabel.Interpreter = 'latex';
            app.mathrmY_REDEditFieldLabel.Text = '$\mathrm{Y_{RED}}$';
            app.mathrmY_REDEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmY_REDEditField.Editable = 'off';
            app.mathrmY_REDEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the RED platform (y-position).'};
            app.mathrmtheta_REDEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmtheta_REDEditFieldLabel.Interpreter = 'latex';
            app.mathrmtheta_REDEditFieldLabel.Text = '$\mathrm{\theta_{RED}}$';
            app.mathrmtheta_REDEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmtheta_REDEditField.Editable = 'off';
            app.mathrmtheta_REDEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time attitude of the RED platform.'};
            app.mathrmX_BLACKEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmX_BLACKEditFieldLabel.Interpreter = 'latex';
            app.mathrmX_BLACKEditFieldLabel.Text = '$\mathrm{X_{BLACK}}$';
            app.mathrmX_BLACKEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmX_BLACKEditField.Editable = 'off';
            app.mathrmX_BLACKEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the BLACK platform (x-position).'};
            app.mathrmY_BLACKEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmY_BLACKEditFieldLabel.Interpreter = 'latex';
            app.mathrmY_BLACKEditFieldLabel.Text = '$\mathrm{Y_{BLACK}}$';
            app.mathrmY_BLACKEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmY_BLACKEditField.Editable = 'off';
            app.mathrmY_BLACKEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the BLACK platform (y-position).'};
            app.mathrmtheta_BLACKEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmtheta_BLACKEditFieldLabel.Interpreter = 'latex';
            app.mathrmtheta_BLACKEditFieldLabel.Text = '$\mathrm{\theta_{BLACK}}$';
            app.mathrmtheta_BLACKEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmtheta_BLACKEditField.Editable = 'off';
            app.mathrmtheta_BLACKEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time attitude of the BLACK platform.'};
            app.mathrmX_BLUEEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmX_BLUEEditFieldLabel.Interpreter = 'latex';
            app.mathrmX_BLUEEditFieldLabel.Text = '$\mathrm{X_{BLUE}}$';
            app.mathrmX_BLUEEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmX_BLUEEditField.Editable = 'off';
            app.mathrmX_BLUEEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the BLUE platform (x-position).'};
            app.mathrmY_BLUEEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmY_BLUEEditFieldLabel.Interpreter = 'latex';
            app.mathrmY_BLUEEditFieldLabel.Text = '$\mathrm{Y_{BLUE}}$';
            app.mathrmY_BLUEEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmY_BLUEEditField.Editable = 'off';
            app.mathrmY_BLUEEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time position of the BLUE platform (y-position).'};
            app.mathrmtheta_BLUEEditFieldLabel = uilabel(app.GridLayout9_4);
            app.mathrmtheta_BLUEEditFieldLabel.Interpreter = 'latex';
            app.mathrmtheta_BLUEEditFieldLabel.Text = '$\mathrm{\theta_{BLUE}}$';
            app.mathrmtheta_BLUEEditField = uieditfield(app.GridLayout9_4, 'numeric');
            app.mathrmtheta_BLUEEditField.Editable = 'off';
            app.mathrmtheta_BLUEEditField.Tooltip = {'If real-time telemetry is ON, displays the real-time attitude of the BLUE platform.'};
            app.DurPhase0Units_36 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_36.Text = 'm';
            app.DurPhase0Units_37 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_37.Text = 'm';
            app.DurPhase0Units_38 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_38.Text = 'm';
            app.DurPhase0Units_39 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_39.Text = 'm';
            app.DurPhase0Units_40 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_40.Text = 'm';
            app.DurPhase0Units_41 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_41.Text = 'm';
            app.DurPhase0Units_42 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_42.Interpreter = 'latex';
            app.DurPhase0Units_42.Text = '$\degree$';
            app.DurPhase0Units_43 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_43.Interpreter = 'latex';
            app.DurPhase0Units_43.Text = '$\degree$';
            app.DurPhase0Units_44 = uilabel(app.GridLayout9_4);
            app.DurPhase0Units_44.Interpreter = 'latex';
            app.DurPhase0Units_44.Text = '$\degree$';
            app.GridLayout27 = uigridlayout(app.GridLayout9_4);
            app.EnableRealTimeTelemetryLabel = uilabel(app.GridLayout27);
            app.EnableRealTimeTelemetryLabel.Text = 'Enable Real-Time Telemetry';
            app.ToggleRealTimeTelemetrySwitch = uiswitch(app.GridLayout27, 'slider');
            app.ToggleRealTimeTelemetrySwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleRealTimeTelemetrySwitchValueChanged, true);
            app.ToggleRealTimeTelemetrySwitch.Tooltip = {'Toggle to ON to enable real-time telemetry. This will automatically turn on when you turn on the cameras, but it can be independently turned off.'};
            app.RealTimeTelOnLamp = uilamp(app.GridLayout27);
            app.Image = uiimage(app.GridLayout9_4);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat = uibutton(app.GridLayout19_2, 'push');
            app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat.ButtonPushedFcn = createCallbackFcn(app, @Step8EstablishConnectionwithSelectedPlatformsButtonPushed, true);
            app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat.Tooltip = {'If you have selected your active hardware, click this to establish a connection with them. If problems occur, try pinging the platforms from the DIrect Hardware Control tab.'};
            app.Step8EstablishConnectionwithSelectedPlatformsButtonRepeat.Text = 'Step #9: Establish Connection with Selected Platforms';
            app.GridLayout33 = uigridlayout(app.GridLayout19_2);
            app.ConnectionStatus_1 = uilamp(app.GridLayout33);
            app.ConnectionStatus_1.Tooltip = {'Indicates the connection status of the hardware. Does not update continuously, so if you lose connection without resetting the GUI, this may be a false indication.'};
            app.ConnectionStatusLabel = uilabel(app.GridLayout33);
            app.ConnectionStatusLabel.Text = {'Connection'; 'Status'};
            app.LiveAnimationOptionsPanel = uipanel(app.GridLayout19_2);
            app.LiveAnimationOptionsPanel.Title = 'Live Animation Options';
            app.GridLayout3_6 = uigridlayout(app.LiveAnimationOptionsPanel);
            app.AnimationTreeExp = uitree(app.GridLayout3_6, 'checkbox');
            app.RED = uitreenode(app.AnimationTreeExp);
            app.RED.Text = 'RED';
            app.ShowDockingConeNodeLive = uitreenode(app.RED);
            app.ShowDockingConeNodeLive.Text = 'Show Docking Cone';
            app.OverlaySimulationNodeREDLive = uitreenode(app.RED);
            app.OverlaySimulationNodeREDLive.Text = 'Overlay Simulation';
            app.BLACK = uitreenode(app.AnimationTreeExp);
            app.BLACK.Text = 'BLACK';
            app.ShowDockNodeLive = uitreenode(app.BLACK);
            app.ShowDockNodeLive.Text = 'Show Dock';
            app.ShowSolarPanelsNodeLive = uitreenode(app.BLACK);
            app.ShowSolarPanelsNodeLive.Text = 'Show Solar Panels';
            app.OverlaySimulationNodeBLACKLive = uitreenode(app.BLACK);
            app.OverlaySimulationNodeBLACKLive.Text = 'Overlay Simulation';
            app.BLUENode_2 = uitreenode(app.AnimationTreeExp);
            app.BLUENode_2.Text = 'BLUE';
            app.OverlaySimulationNodeBLUELive = uitreenode(app.BLUENode_2);
            app.OverlaySimulationNodeBLUELive.Text = 'Overlay Simulation';
            app.DirectHardwareControlTab = uitab(app.TabGroup2);
            app.DirectHardwareControlTab.Title = 'Direct Hardware Control';
            app.GridLayout19 = uigridlayout(app.DirectHardwareControlTab);
            app.REDPlatformLiveControlsPanel = uipanel(app.GridLayout19);
            app.REDPlatformLiveControlsPanel.Title = 'RED Platform Live Controls';
            app.GridLayout20 = uigridlayout(app.REDPlatformLiveControlsPanel);
            app.REDPucksOnButton = uibutton(app.GridLayout20, 'push');
            app.REDPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @REDPucksOnButtonPushed, true);
            app.REDPucksOnButton.Tooltip = {'Turn on the pucks to float the RED platform.'};
            app.REDPucksOnButton.Text = 'Pucks On';
            app.REDPucksOffButton = uibutton(app.GridLayout20, 'push');
            app.REDPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @REDPucksOffButtonPushed, true);
            app.REDPucksOffButton.Tooltip = {'Turn off the pucks for RED.'};
            app.REDPucksOffButton.Text = 'Pucks Off';
            app.PingREDButton = uibutton(app.GridLayout20, 'push');
            app.PingREDButton.ButtonPushedFcn = createCallbackFcn(app, @PingREDButtonPushed, true);
            app.PingREDButton.Tooltip = {'Send a ping to RED. If RED''s Xavier is powered on, it will indicate a successful ping.'};
            app.PingREDButton.Text = 'Ping Jetson Xavier';
            app.REDTextArea = uitextarea(app.GridLayout20);
            app.REDTextArea.Editable = 'off';
            app.REDConnectedLamp = uilamp(app.GridLayout20);
            app.FireREDThrustersButton = uibutton(app.GridLayout20, 'push');
            app.FireREDThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireREDThrustersButtonPushed, true);
            app.FireREDThrustersButton.Tooltip = {'Fire the thrusters for RED in sequential order (1,2,3...).'};
            app.FireREDThrustersButton.Text = 'Fire Thrusters in Sequence';
            app.CyclePinsButtonRED = uibutton(app.GridLayout20, 'push');
            app.CyclePinsButtonRED.ButtonPushedFcn = createCallbackFcn(app, @CyclePinsButtonREDPushed, true);
            app.CyclePinsButtonRED.Tooltip = {'Turns ON then OFF all GPIO pins on RED. This can be useful for debugging.'};
            app.CyclePinsButtonRED.Text = 'Cycle GPIOs ON/OFF';
            app.OpenREDCMDShell = uibutton(app.GridLayout20, 'push');
            app.OpenREDCMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenREDCMDShellButtonPushed, true);
            app.OpenREDCMDShell.Tooltip = {'Open a command shell connected to the Xavier on RED.'};
            app.OpenREDCMDShell.Text = 'Open Remote SSH Command Shell (spot-red)';
            app.BLACKPlatformLiveControlsPanel = uipanel(app.GridLayout19);
            app.BLACKPlatformLiveControlsPanel.Title = 'BLACK Platform Live Controls';
            app.GridLayout20_2 = uigridlayout(app.BLACKPlatformLiveControlsPanel);
            app.BLACKPucksOnButton = uibutton(app.GridLayout20_2, 'push');
            app.BLACKPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKPucksOnButtonPushed, true);
            app.BLACKPucksOnButton.Tooltip = {'Turn on the pucks to float the BLACK platform.'};
            app.BLACKPucksOnButton.Text = 'Pucks On';
            app.BLACKPucksOffButton = uibutton(app.GridLayout20_2, 'push');
            app.BLACKPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKPucksOffButtonPushed, true);
            app.BLACKPucksOffButton.Tooltip = {'Turn off the pucks for BLACK.'};
            app.BLACKPucksOffButton.Text = 'Pucks Off';
            app.PingBLACKButton = uibutton(app.GridLayout20_2, 'push');
            app.PingBLACKButton.ButtonPushedFcn = createCallbackFcn(app, @PingBLACKButtonPushed, true);
            app.PingBLACKButton.Tooltip = {'Send a ping to BLACK. If BLACK''s Xavier is powered on, it will indicate a successful ping.'};
            app.PingBLACKButton.Text = 'Ping Jetson Xavier';
            app.BLACKConnectedLamp = uilamp(app.GridLayout20_2);
            app.FireBLACKThrustersButton = uibutton(app.GridLayout20_2, 'push');
            app.FireBLACKThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireBLACKThrustersButtonPushed, true);
            app.FireBLACKThrustersButton.Tooltip = {'Fire the thrusters for BLACK in sequential order (1,2,3...).'};
            app.FireBLACKThrustersButton.Text = 'Fire Thrusters in Sequence';
            app.CyclePinsButtonBLACK = uibutton(app.GridLayout20_2, 'push');
            app.CyclePinsButtonBLACK.ButtonPushedFcn = createCallbackFcn(app, @CyclePinsButtonBLACKPushed, true);
            app.CyclePinsButtonBLACK.Tooltip = {'Turns ON then OFF all GPIO pins on BLACK. This can be useful for debugging.'};
            app.CyclePinsButtonBLACK.Text = 'Cycle GPIOs ON/OFF';
            app.OpenBLACKCMDShell = uibutton(app.GridLayout20_2, 'push');
            app.OpenBLACKCMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenBLACKCMDShellButtonPushed, true);
            app.OpenBLACKCMDShell.Tooltip = {'Open a command shell connected to the Xavier on BLACK.'};
            app.OpenBLACKCMDShell.Text = 'Open Remote SSH Command Shell (spot-black)';
            app.BLACKTextArea = uitextarea(app.GridLayout20_2);
            app.BLACKTextArea.Editable = 'off';
            app.BLUEPlatformLiveControlsPanel = uipanel(app.GridLayout19);
            app.BLUEPlatformLiveControlsPanel.Title = 'BLUE Platform Live Controls';
            app.GridLayout20_3 = uigridlayout(app.BLUEPlatformLiveControlsPanel);
            app.BLUEPucksOnButton = uibutton(app.GridLayout20_3, 'push');
            app.BLUEPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEPucksOnButtonPushed, true);
            app.BLUEPucksOnButton.Tooltip = {'Turn on the pucks to float the BLUE platform.'};
            app.BLUEPucksOnButton.Text = 'Pucks On';
            app.BLUEPucksOffButton = uibutton(app.GridLayout20_3, 'push');
            app.BLUEPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEPucksOffButtonPushed, true);
            app.BLUEPucksOffButton.Tooltip = {'Turn off the pucks for BLUE.'};
            app.BLUEPucksOffButton.Text = 'Pucks Off';
            app.PingBLUEButton = uibutton(app.GridLayout20_3, 'push');
            app.PingBLUEButton.ButtonPushedFcn = createCallbackFcn(app, @PingBLUEButtonPushed, true);
            app.PingBLUEButton.Tooltip = {'Send a ping to BLUE. If BLUE''s Xavier is powered on, it will indicate a successful ping.'};
            app.PingBLUEButton.Text = 'Ping Jetson Xavier';
            app.BLUEConnectedLamp = uilamp(app.GridLayout20_3);
            app.BLUETextArea = uitextarea(app.GridLayout20_3);
            app.BLUETextArea.Editable = 'off';
            app.FireBLUEThrustersButton = uibutton(app.GridLayout20_3, 'push');
            app.FireBLUEThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireBLUEThrustersButtonPushed, true);
            app.FireBLUEThrustersButton.Tooltip = {'Fire the thrusters for BLUE in sequential order (1,2,3...).'};
            app.FireBLUEThrustersButton.Text = 'Fire Thrusters in Sequence';
            app.CyclePinsButtonBLUE = uibutton(app.GridLayout20_3, 'push');
            app.CyclePinsButtonBLUE.ButtonPushedFcn = createCallbackFcn(app, @CyclePinsButtonBLUEPushed, true);
            app.CyclePinsButtonBLUE.Tooltip = {'Turns ON then OFF all GPIO pins on BLUE. This can be useful for debugging.'};
            app.CyclePinsButtonBLUE.Text = 'Cycle GPIOs ON/OFF';
            app.OpenBLUECMDShell = uibutton(app.GridLayout20_3, 'push');
            app.OpenBLUECMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenBLUECMDShellButtonPushed, true);
            app.OpenBLUECMDShell.Tooltip = {'Open a command shell connected to the Xavier on BLUE.'};
            app.OpenBLUECMDShell.Text = 'Open Remote SSH Command Shell (spot-blue)';
            app.Step9EstablishConnectionwithSelectedPlatformsButton = uibutton(app.GridLayout19, 'push');
            app.Step9EstablishConnectionwithSelectedPlatformsButton.ButtonPushedFcn = createCallbackFcn(app, @Step8EstablishConnectionwithSelectedPlatformsButtonPushed, true);
            app.Step9EstablishConnectionwithSelectedPlatformsButton.Tooltip = {'If you have selected your active hardware, click this to establish a connection with them. If problems occur, try pinging the platforms from the DIrect Hardware Control tab.'};
            app.Step9EstablishConnectionwithSelectedPlatformsButton.Text = 'Step #9: Establish Connection with Selected Platforms';
            app.ExtrasPanel = uipanel(app.GridLayout19);
            app.ExtrasPanel.Title = 'Extras';
            app.GridLayout20_4 = uigridlayout(app.ExtrasPanel);
            app.EmptyMATLAB_wsButton = uibutton(app.GridLayout20_4, 'push');
            app.EmptyMATLAB_wsButton.ButtonPushedFcn = createCallbackFcn(app, @EmptyMATLAB_wsButtonPushed, true);
            app.EmptyMATLAB_wsButton.Tooltip = {'Completely empties the MATLAB_ws folder on all active platforms to facilitate troubleshooting. Useful for troubleshooting. This will not break anything, but any data on the active hardware will be deleted.'};
            app.EmptyMATLAB_wsButton.Text = 'Empty /MATLAB_ws/';
            app.ExtrasPanelSeperator = uiimage(app.GridLayout20_4);
            app.ExtrasPanelSeperator.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.BLACKMagnetOnButton = uibutton(app.GridLayout20_4, 'push');
            app.BLACKMagnetOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKMagnetOnButtonPushed, true);
            app.BLACKMagnetOnButton.Tooltip = {'Turn on the pucks to float the BLACK platform.'};
            app.BLACKMagnetOnButton.Text = 'Magnet On';
            app.BLACKMagnetOffButton = uibutton(app.GridLayout20_4, 'push');
            app.BLACKMagnetOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKMagnetOffButtonPushed, true);
            app.BLACKMagnetOffButton.Tooltip = {'Turn on the pucks to float the BLACK platform.'};
            app.BLACKMagnetOffButton.Text = 'Magnet Off';
            app.ExtrasPanelSeperator_2 = uiimage(app.GridLayout20_4);
            app.ExtrasPanelSeperator_2.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.OpenVISIONCommandShell = uibutton(app.GridLayout20_4, 'push');
            app.OpenVISIONCommandShell.ButtonPushedFcn = createCallbackFcn(app, @OpenVISIONCommandShellButtonPushed, true);
            app.OpenVISIONCommandShell.Tooltip = {'Open a command shell connected to the Xavier on BLACK.'};
            app.OpenVISIONCommandShell.Text = 'Open Remote SSH Command Shell (spot-vision-spare)';
            app.EmptyExperimentsSoftwareButton = uibutton(app.GridLayout20_4, 'push');
            app.EmptyExperimentsSoftwareButton.ButtonPushedFcn = createCallbackFcn(app, @EmptyExperimentsSoftwareButtonPushed, true);
            app.EmptyExperimentsSoftwareButton.Tooltip = {'Completely empties the MATLAB_ws folder on all active platforms to facilitate troubleshooting. Useful for troubleshooting. This will not break anything, but any data on the active hardware will be deleted.'};
            app.EmptyExperimentsSoftwareButton.Text = 'Empty /ExperimentsSoftware/';
            app.GridLayout33_2 = uigridlayout(app.GridLayout19);
            app.ConnectionStatus_2 = uilamp(app.GridLayout33_2);
            app.ConnectionStatus_2.Tooltip = {'Indicates the connection status of the hardware. Does not update continuously, so if you lose connection without resetting the GUI, this may be a false indication.'};
            app.ConnectionStatusLabel_2 = uilabel(app.GridLayout33_2);
            app.ConnectionStatusLabel_2.Text = {'Connection'; 'Status'};
            app.DataInspectorTab = uitab(app.TabGroup2);
            app.DataInspectorTab.Title = 'Data Inspector';
            app.GridLayout21 = uigridlayout(app.DataInspectorTab);

            app.TabGroup3 = uitabgroup(app.GridLayout21);
            app.Plot1Tab = uitab(app.TabGroup3);
            app.Plot1Tab.Title = 'Plot #1';
            app.GridLayout29 = uigridlayout(app.Plot1Tab);
            app.DataViewerPlt1 = uiaxes(app.GridLayout29);
            xlabel(app.DataViewerPlt1, 'X-Axis Parameter')
            ylabel(app.DataViewerPlt1, 'Y-Axis Parameter')
            app.XAxisLabel = uilabel(app.GridLayout29);
            app.XAxisLabel.Text = 'X-Axis ';
            app.YAxisLabel = uilabel(app.GridLayout29);
            app.YAxisLabel.Text = 'Y-Axis';
            app.SelectDataFileLabel = uilabel(app.GridLayout29);
            app.SelectDataFileLabel.Text = 'Select Data File';
            app.LoadDataButtonPlt1 = uibutton(app.GridLayout29, 'push');
            app.LoadDataButtonPlt1.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPlt1Pushed, true);
            app.LoadDataButtonPlt1.Tooltip = {'Load a SPOT simulation or experiment data file for visualization on the left figure.'};
            app.LoadDataButtonPlt1.Text = 'Load Dataset #1';
            app.LoadDataButtonPlt1_Secondary = uibutton(app.GridLayout29, 'push');
            app.LoadDataButtonPlt1_Secondary.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPlt1_SecondaryPushed, true);
            app.LoadDataButtonPlt1_Secondary.Tooltip = {'Load a SPOT simulation or experiment data file for visualization on the left figure.'};
            app.LoadDataButtonPlt1_Secondary.Text = 'Load Dataset #2';
            app.SelectXDataPlt1 = uidropdown(app.GridLayout29);
            app.SelectXDataPlt1.ValueChangedFcn = createCallbackFcn(app, @SelectXDataPlt1ValueChanged, true);
            app.SelectXDataPlt1.Tooltip = {'Select the x-axis parameter for the left figure.'};
            app.SelectXDataPlt1_Secondary = uidropdown(app.GridLayout29);
            app.SelectXDataPlt1_Secondary.ValueChangedFcn = createCallbackFcn(app, @SelectXDataPlt1_SecondaryValueChanged, true);
            app.SelectXDataPlt1_Secondary.Tooltip = {'Select the x-axis parameter for the left figure.'};
            app.SelectYDataPlt1 = uidropdown(app.GridLayout29);
            app.SelectYDataPlt1.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt1ValueChanged, true);
            app.SelectYDataPlt1.Tooltip = {'Select the y-axis parameter for the left figure.'};
            app.SelectYDataPlt1.ClickedFcn = createCallbackFcn(app, @SelectYDataPlt1Clicked, true);
            app.SelectYDataPlt1_Secondary = uidropdown(app.GridLayout29);
            app.SelectYDataPlt1_Secondary.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt1_SecondaryValueChanged, true);
            app.SelectYDataPlt1_Secondary.Tooltip = {'Select the y-axis parameter for the left figure.'};
            app.SelectYDataPlt1_Secondary.ClickedFcn = createCallbackFcn(app, @SelectYDataPlt1_SecondaryClicked, true);
            app.ClearFigureButtonPlt1 = uibutton(app.GridLayout29, 'push');
            app.ClearFigureButtonPlt1.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPlt1Pushed, true);
            app.ClearFigureButtonPlt1.Text = 'Clear Figure';
            app.ExportFiguretoPDFButton = uibutton(app.GridLayout29, 'push');
            app.ExportFiguretoPDFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFiguretoPDFButtonPushed, true);
            app.ExportFiguretoPDFButton.Tooltip = {'Export the left figure to a PDF (name defined in the text box to the right of this button).'};
            app.ExportFiguretoPDFButton.Text = 'Export Figure to PDF';
            app.ResetFigureLabel_2 = uilabel(app.GridLayout29);
            app.ResetFigureLabel_2.Text = 'Reset Figure';
            app.FigureExportOptionsLabel_2 = uilabel(app.GridLayout29);
            app.FigureExportOptionsLabel_2.Text = 'Figure Export Options';
            app.XAxisLabelPlt1 = uieditfield(app.GridLayout29, 'text');
            app.XAxisLabelPlt1.ValueChangedFcn = createCallbackFcn(app, @XAxisLabelPlt1ValueChanged, true);
            app.XAxisLabelPlt1.Placeholder = 'Enter Label';
            app.YAxisLabelPlt1 = uieditfield(app.GridLayout29, 'text');
            app.YAxisLabelPlt1.ValueChangedFcn = createCallbackFcn(app, @YAxisLabelPlt1ValueChanged, true);
            app.YAxisLabelPlt1.Placeholder = 'Enter Label';
            app.FilterSignalCheckBoxPlt1 = uicheckbox(app.GridLayout29);
            app.FilterSignalCheckBoxPlt1.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt1ValueChanged, true);
            app.FilterSignalCheckBoxPlt1.Text = 'Filter Dataset #1';
            app.FilterSignalCheckBoxPlt1_Secondary = uicheckbox(app.GridLayout29);
            app.FilterSignalCheckBoxPlt1_Secondary.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt1_SecondaryValueChanged, true);
            app.FilterSignalCheckBoxPlt1_Secondary.Text = 'Filter Dataset #2';
            app.FigureOptionsLabel = uilabel(app.GridLayout29);
            app.FigureOptionsLabel.Text = 'Figure Options';
            app.SetXMinPlt1 = uieditfield(app.GridLayout29, 'text');
            app.SetXMinPlt1.Placeholder = 'Set X-Axis (Min)';
            app.SetXMaxPlt1 = uieditfield(app.GridLayout29, 'text');
            app.SetXMaxPlt1.Placeholder = 'Set X-Axis (Max)';
            app.SetYMinPlt1 = uieditfield(app.GridLayout29, 'text');
            app.SetYMinPlt1.Placeholder = 'Set Y-Axis (Min)';
            app.SetYMaxPlt1 = uieditfield(app.GridLayout29, 'text');
            app.SetYMaxPlt1.Placeholder = 'Set Y-Axis (Max)';
            app.ApplyRangesButton_2 = uibutton(app.GridLayout29, 'push');
            app.ApplyRangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyRangesButton_2Pushed, true);
            app.ApplyRangesButton_2.Text = 'Apply Ranges';
            app.IncludeLegendCheckBox = uicheckbox(app.GridLayout29);
            app.IncludeLegendCheckBox.Enable = 'off';
            app.IncludeLegendCheckBox.Text = 'Include Legend';
            app.AllowOverplotCheckBox = uicheckbox(app.GridLayout29);
            app.AllowOverplotCheckBox.Text = 'Allow Overplot';
            app.Plot2Tab = uitab(app.TabGroup3);
            app.Plot2Tab.Title = 'Plot #2';
            app.GridLayout30 = uigridlayout(app.Plot2Tab);
            app.DataViewerPlt2 = uiaxes(app.GridLayout30);
            xlabel(app.DataViewerPlt2, 'X-Axis Parameter')
            ylabel(app.DataViewerPlt2, 'Y-Axis Parameter')
            app.SelectXDataPlt2 = uidropdown(app.GridLayout30);
            app.SelectXDataPlt2.ValueChangedFcn = createCallbackFcn(app, @SelectXDataPlt2ValueChanged, true);
            app.SelectXDataPlt2.Tooltip = {'Select the x-axis parameter for the right figure.'};
            app.SelectXDataPlt2_Secondary = uidropdown(app.GridLayout30);
            app.SelectXDataPlt2_Secondary.ValueChangedFcn = createCallbackFcn(app, @SelectXDataPlt2_SecondaryValueChanged, true);
            app.SelectXDataPlt2_Secondary.Tooltip = {'Select the x-axis parameter for the right figure.'};
            app.XAxisLabelPlt2 = uieditfield(app.GridLayout30, 'text');
            app.XAxisLabelPlt2.ValueChangedFcn = createCallbackFcn(app, @XAxisLabelPlt2ValueChanged, true);
            app.XAxisLabelPlt2.Placeholder = 'Enter Label';
            app.YAxisLabelPlt2 = uieditfield(app.GridLayout30, 'text');
            app.YAxisLabelPlt2.ValueChangedFcn = createCallbackFcn(app, @YAxisLabelPlt2ValueChanged, true);
            app.YAxisLabelPlt2.Placeholder = 'Enter Label';
            app.SelectYDataPlt2 = uidropdown(app.GridLayout30);
            app.SelectYDataPlt2.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt2ValueChanged, true);
            app.SelectYDataPlt2.Tooltip = {'Select the y-axis parameter for the right figure.'};
            app.SelectYDataPlt2.ClickedFcn = createCallbackFcn(app, @SelectYDataPlt2Clicked, true);
            app.SelectYDataPlt2_Secondary = uidropdown(app.GridLayout30);
            app.SelectYDataPlt2_Secondary.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt2_SecondaryValueChanged, true);
            app.SelectYDataPlt2_Secondary.Tooltip = {'Select the y-axis parameter for the right figure.'};
            app.SelectYDataPlt2_Secondary.ClickedFcn = createCallbackFcn(app, @SelectYDataPlt2_SecondaryClicked, true);
            app.LoadDataButtonPlt2 = uibutton(app.GridLayout30, 'push');
            app.LoadDataButtonPlt2.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPlt2Pushed, true);
            app.LoadDataButtonPlt2.Tooltip = {'Load a SPOT simulation or experiment data file for visualization on the right figure.'};
            app.LoadDataButtonPlt2.Text = 'Load Dataset #1';
            app.LoadDataButtonPlt2_Secondary = uibutton(app.GridLayout30, 'push');
            app.LoadDataButtonPlt2_Secondary.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPlt2_SecondaryPushed, true);
            app.LoadDataButtonPlt2_Secondary.Tooltip = {'Load a SPOT simulation or experiment data file for visualization on the right figure.'};
            app.LoadDataButtonPlt2_Secondary.Text = 'Load Dataset #2';
            app.ClearFigureButtonPlt2 = uibutton(app.GridLayout30, 'push');
            app.ClearFigureButtonPlt2.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPlt2Pushed, true);
            app.ClearFigureButtonPlt2.Text = 'Clear Figure';
            app.ExporttoPDFButton2 = uibutton(app.GridLayout30, 'push');
            app.ExporttoPDFButton2.ButtonPushedFcn = createCallbackFcn(app, @ExporttoPDFButton2Pushed, true);
            app.ExporttoPDFButton2.Tooltip = {'Export the right figure to a PDF (name defined in the text box to the right of this button).'};
            app.ExporttoPDFButton2.Text = 'Export Figure to PDF';
            app.SelectDataFileLabel_2 = uilabel(app.GridLayout30);
            app.SelectDataFileLabel_2.Text = 'Select Data File';
            app.XAxisLabel_2 = uilabel(app.GridLayout30);
            app.XAxisLabel_2.Text = 'X-Axis ';
            app.YAxisLabel_2 = uilabel(app.GridLayout30);
            app.YAxisLabel_2.Text = 'Y-Axis';
            app.ResetFigureLabel = uilabel(app.GridLayout30);
            app.ResetFigureLabel.Text = 'Reset Figure';
            app.FigureExportOptionsLabel = uilabel(app.GridLayout30);
            app.FigureExportOptionsLabel.Text = 'Figure Export Options';
            app.FilterYSignalCheckBoxPlt2 = uicheckbox(app.GridLayout30);
            app.FilterYSignalCheckBoxPlt2.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt2ValueChanged, true);
            app.FilterYSignalCheckBoxPlt2.Text = 'Filter Dataset #1';
            app.FilterYSignalCheckBoxPlt2_Secondary = uicheckbox(app.GridLayout30);
            app.FilterYSignalCheckBoxPlt2_Secondary.ValueChangedFcn = createCallbackFcn(app, @SelectYDataPlt2_SecondaryValueChanged, true);
            app.FilterYSignalCheckBoxPlt2_Secondary.Text = 'Filter Dataset #2';
            app.SetFigureRangesLabel_2 = uilabel(app.GridLayout30);
            app.SetFigureRangesLabel_2.Text = 'Set Figure Ranges';
            app.EditField_5 = uieditfield(app.GridLayout30, 'text');
            app.EditField_5.Placeholder = 'Set X-Axis (Min)';
            app.EditField_6 = uieditfield(app.GridLayout30, 'text');
            app.EditField_6.Placeholder = 'Set X-Axis (Max)';
            app.EditField_7 = uieditfield(app.GridLayout30, 'text');
            app.EditField_7.Placeholder = 'Set Y-Axis (Min)';
            app.EditField_8 = uieditfield(app.GridLayout30, 'text');
            app.EditField_8.Placeholder = 'Set Y-Axis (Max)';
            app.ApplyRangesButton = uibutton(app.GridLayout30, 'push');
            app.ApplyRangesButton.Text = 'Apply Ranges';
            app.AllowOverplotCheckBoxPlt2 = uicheckbox(app.GridLayout30);
            app.AllowOverplotCheckBoxPlt2.Text = 'Allow Overplot';
            app.IncludeLegendCheckBox_2 = uicheckbox(app.GridLayout30);
            app.IncludeLegendCheckBox_2.Enable = 'off';
            app.IncludeLegendCheckBox_2.Text = 'Include Legend';
            app.ComputerVisionTab = uitab(app.TabGroup2);
            app.ComputerVisionTab.Title = 'Computer Vision';
            app.GridLayout40 = uigridlayout(app.ComputerVisionTab);
            app.Image4 = uiimage(app.GridLayout40);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Resources', 'ComingSoon.png');
            % Show the figure after all components are created
            app.SPOT41GUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v4_1_Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SPOT41GUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @PopulateGUI)
            else

                % Focus the running singleton app
                figure(runningApp.SPOT41GUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPOT41GUIUIFigure)
        end
    end
end