classdef GUI_v5_0_MassProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPOT50MassPropertiesUIFigure    matlab.ui.Figure
        GridLayout25                    matlab.ui.container.GridLayout
        InertiaEstimatorPanel           matlab.ui.container.Panel
        GridLayout24_3                  matlab.ui.container.GridLayout
        CalculateInertiausingBifilarPendulumTechniqueButton  matlab.ui.control.Button
        DurPhase0Units_52               matlab.ui.control.Label
        TBLUEMeasurement                matlab.ui.control.NumericEditField
        mathrmTLabel_3                  matlab.ui.control.Label
        TBLACKMeasurement               matlab.ui.control.NumericEditField
        mathrmTLabel_2                  matlab.ui.control.Label
        TREDMeasurement                 matlab.ui.control.NumericEditField
        mathrmTLabel                    matlab.ui.control.Label
        DurPhase0Units_51               matlab.ui.control.Label
        DurPhase0Units_50               matlab.ui.control.Label
        LBLUEMeasurement                matlab.ui.control.NumericEditField
        DBLUEMeasurement                matlab.ui.control.NumericEditField
        mathrmLLabel_3                  matlab.ui.control.Label
        mathrmDLabel_3                  matlab.ui.control.Label
        LBLACKMeasurement               matlab.ui.control.NumericEditField
        DBLACKMeasurement               matlab.ui.control.NumericEditField
        mathrmLLabel_2                  matlab.ui.control.Label
        mathrmDLabel_2                  matlab.ui.control.Label
        LREDScaleMeasurement            matlab.ui.control.NumericEditField
        DREDMeasurement                 matlab.ui.control.NumericEditField
        mathrmLLabel                    matlab.ui.control.Label
        mathrmDLabel                    matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        SummaryofSalientDataPanel       matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        OverridePropertiesCheckBox      matlab.ui.control.CheckBox
        DurPhase0Units_38               matlab.ui.control.Label
        DurPhase0Units_37               matlab.ui.control.Label
        DurPhase0Units_36               matlab.ui.control.Label
        DurPhase0Units_35               matlab.ui.control.Label
        BLACKXCGCalculation             matlab.ui.control.NumericEditField
        mathrmX_cgLabel_2               matlab.ui.control.Label
        DurPhase0Units_34               matlab.ui.control.Label
        BLUEYCGCalculation              matlab.ui.control.NumericEditField
        mathrmY_cgLabel_3               matlab.ui.control.Label
        DurPhase0Units_33               matlab.ui.control.Label
        BLUEXCGCalculation              matlab.ui.control.NumericEditField
        mathrmX_cgLabel_3               matlab.ui.control.Label
        mathrmY_cgLabel_2               matlab.ui.control.Label
        BLACKYCGCalculation             matlab.ui.control.NumericEditField
        mathrmY_cgLabel                 matlab.ui.control.Label
        REDYCGCalculation               matlab.ui.control.NumericEditField
        REDXCGCalculation               matlab.ui.control.NumericEditField
        mathrmX_cgLabel                 matlab.ui.control.Label
        DurPhase0Units_32               matlab.ui.control.Label
        ArmPhiEditField                 matlab.ui.control.NumericEditField
        mathrmphiLabel                  matlab.ui.control.Label
        DurPhase0Units_27               matlab.ui.control.Label
        DurPhase0Units_26               matlab.ui.control.Label
        DurPhase0Units_25               matlab.ui.control.Label
        DurPhase0Units_24               matlab.ui.control.Label
        DurPhase0Units_23               matlab.ui.control.Label
        DurPhase0Units_22               matlab.ui.control.Label
        DurPhase0Units_21               matlab.ui.control.Label
        InertiaEndEffectorLinkEditField  matlab.ui.control.NumericEditField
        DurPhase0Units_20               matlab.ui.control.Label
        InertiaDistalLinkEditField      matlab.ui.control.NumericEditField
        DurPhase0Units_19               matlab.ui.control.Label
        InertiaProximalLinkEditField    matlab.ui.control.NumericEditField
        DurPhase0Units_18               matlab.ui.control.Label
        MassEndEffectorLinkEditField    matlab.ui.control.NumericEditField
        DurPhase0Units_17               matlab.ui.control.Label
        MassDistalLinkEditField         matlab.ui.control.NumericEditField
        DurPhase0Units_16               matlab.ui.control.Label
        MassProximalLinkEditField       matlab.ui.control.NumericEditField
        mathrmI_EELabel                 matlab.ui.control.Label
        mathrmI_DISTLabel               matlab.ui.control.Label
        mathrmI_PROXLabel               matlab.ui.control.Label
        mathrmm_EELabel                 matlab.ui.control.Label
        mathrmm_DISTLabel               matlab.ui.control.Label
        mathrmm_PROXLabel               matlab.ui.control.Label
        ShowRoboticArmParameterDefinitionsButton  matlab.ui.control.Button
        ArmB3EditField                  matlab.ui.control.NumericEditField
        mathrmb_3Label                  matlab.ui.control.Label
        ArmA3EditField                  matlab.ui.control.NumericEditField
        mathrma_3Label                  matlab.ui.control.Label
        ArmB2EditField                  matlab.ui.control.NumericEditField
        mathrmb_2Label                  matlab.ui.control.Label
        ArmA2EditField                  matlab.ui.control.NumericEditField
        mathrma_2Label                  matlab.ui.control.Label
        ArmB1EditField                  matlab.ui.control.NumericEditField
        mathrmb_1Label                  matlab.ui.control.Label
        ArmA1EditField                  matlab.ui.control.NumericEditField
        mathrma_1Label                  matlab.ui.control.Label
        DurPhase0Units_9                matlab.ui.control.Label
        ArmB0EditField                  matlab.ui.control.NumericEditField
        mathrmb_0Label                  matlab.ui.control.Label
        RoboticManipulatorDimensionsLabel  matlab.ui.control.Label
        MassPropertiesLabel             matlab.ui.control.Label
        DurPhase0Units_7                matlab.ui.control.Label
        InertiaBlueEditField            matlab.ui.control.NumericEditField
        mathrmI_BLUELabel               matlab.ui.control.Label
        DurPhase0Units_6                matlab.ui.control.Label
        InertiaBlackEditField           matlab.ui.control.NumericEditField
        mathrmI_BLACKLabel              matlab.ui.control.Label
        DurPhase0Units_5                matlab.ui.control.Label
        InertiaRedEditField             matlab.ui.control.NumericEditField
        mathrmI_REDLabel                matlab.ui.control.Label
        DurPhase0Units_4                matlab.ui.control.Label
        MassBlueEditField               matlab.ui.control.NumericEditField
        mathrmm_BLUELabel               matlab.ui.control.Label
        DurPhase0Units_3                matlab.ui.control.Label
        MassBlackEditField              matlab.ui.control.NumericEditField
        mathrmm_BLACKLabel              matlab.ui.control.Label
        DurPhase0Units_2                matlab.ui.control.Label
        MassRedEditField                matlab.ui.control.NumericEditField
        mathrmm_REDLabel                matlab.ui.control.Label
        CenterofGravityEstimatorPanel   matlab.ui.container.Panel
        GridLayout24_2                  matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        ShowThrusterLocationDefinitionsButton  matlab.ui.control.Button
        ShowCenterofGravityExperimentalSetupButton  matlab.ui.control.Button
        DurPhase0Units_46               matlab.ui.control.Label
        DurPhase0Units_45               matlab.ui.control.Label
        DurPhase0Units_44               matlab.ui.control.Label
        DurPhase0Units_43               matlab.ui.control.Label
        DurPhase0Units_42               matlab.ui.control.Label
        DurPhase0Units_41               matlab.ui.control.Label
        DurPhase0Units_40               matlab.ui.control.Label
        DurPhase0Units_39               matlab.ui.control.Label
        mathrmT_8Label_3                matlab.ui.control.Label
        mathrmT_7Label_3                matlab.ui.control.Label
        mathrmT_6Label_3                matlab.ui.control.Label
        mathrmT_5Label_3                matlab.ui.control.Label
        mathrmT_4Label_3                matlab.ui.control.Label
        mathrmT_3Label_3                matlab.ui.control.Label
        mathrmT_2Label_3                matlab.ui.control.Label
        mathrmT_1Label_3                matlab.ui.control.Label
        CREDScaleMeasurement_25         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_24         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_23         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_22         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_21         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_20         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_19         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_18         matlab.ui.control.NumericEditField
        mathrmT_8Label_2                matlab.ui.control.Label
        mathrmT_7Label_2                matlab.ui.control.Label
        mathrmT_6Label_2                matlab.ui.control.Label
        mathrmT_5Label_2                matlab.ui.control.Label
        mathrmT_4Label_2                matlab.ui.control.Label
        mathrmT_3Label_2                matlab.ui.control.Label
        mathrmT_2Label_2                matlab.ui.control.Label
        mathrmT_1Label_2                matlab.ui.control.Label
        CREDScaleMeasurement_17         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_16         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_15         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_14         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_13         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_12         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_11         matlab.ui.control.NumericEditField
        CREDScaleMeasurement_10         matlab.ui.control.NumericEditField
        ShowPlotsCheckBox               matlab.ui.control.CheckBox
        CalculateCenterofGravityButton  matlab.ui.control.Button
        mathrmT_8Label                  matlab.ui.control.Label
        mathrmT_7Label                  matlab.ui.control.Label
        mathrmT_6Label                  matlab.ui.control.Label
        mathrmT_5Label                  matlab.ui.control.Label
        mathrmT_4Label                  matlab.ui.control.Label
        mathrmT_3Label                  matlab.ui.control.Label
        mathrmT_2Label                  matlab.ui.control.Label
        mathrmT_1Label                  matlab.ui.control.Label
        CREDScaleMeasurement_9          matlab.ui.control.NumericEditField
        CREDScaleMeasurement_8          matlab.ui.control.NumericEditField
        CREDScaleMeasurement_7          matlab.ui.control.NumericEditField
        CREDScaleMeasurement_6          matlab.ui.control.NumericEditField
        CREDScaleMeasurement_5          matlab.ui.control.NumericEditField
        CREDScaleMeasurement_4          matlab.ui.control.NumericEditField
        CREDScaleMeasurement_3          matlab.ui.control.NumericEditField
        CREDScaleMeasurement_2          matlab.ui.control.NumericEditField
        DurPhase0Units_29               matlab.ui.control.Label
        DurPhase0Units_28               matlab.ui.control.Label
        DurPhase0Units_8                matlab.ui.control.Label
        CBLACKScaleMeasurement          matlab.ui.control.NumericEditField
        BBLACKScaleMeasurement          matlab.ui.control.NumericEditField
        ABLACKScaleMeasurement          matlab.ui.control.NumericEditField
        mathrmCLabel_3                  matlab.ui.control.Label
        mathrmBLabel_3                  matlab.ui.control.Label
        mathrmALabel_3                  matlab.ui.control.Label
        CBLUEScaleMeasurement           matlab.ui.control.NumericEditField
        BBLUEScaleMeasurement           matlab.ui.control.NumericEditField
        ABLUEScaleMeasurement           matlab.ui.control.NumericEditField
        mathrmCLabel_2                  matlab.ui.control.Label
        mathrmBLabel_2                  matlab.ui.control.Label
        mathrmALabel_2                  matlab.ui.control.Label
        CREDScaleMeasurement            matlab.ui.control.NumericEditField
        BREDScaleMeasurement            matlab.ui.control.NumericEditField
        mathrmCLabel                    matlab.ui.control.Label
        mathrmBLabel                    matlab.ui.control.Label
        mathrmALabel                    matlab.ui.control.Label
        AREDScaleMeasurement            matlab.ui.control.NumericEditField
    end

    
properties (Access = public)
            
        mRED;
        mBLACK;
        mBLUE;
        mPROX;
        mDIST;
        mEE;
        IRED;
        IBLACK;
        IBLUE;
        IPROX;
        IDIST;
        IEE;

        Phi;
        b0;
        a1;
        b1;
        a2;
        b2;
        a3;
        b3;

    end

    methods (Access = public)

        % This function creates a custom waitbox to force the app to pause
        % while it is processing a user request.
        function d = CustomWaitBox(app,Message)
            fig = app.SPOT50MassPropertiesUIFigure;
            d = uiprogressdlg(fig,'Title',Message,...
                'Indeterminate','on');
            drawnow
                   
        end

        function [] = UpdateMassProperties(app)


            app.mRED     = app.MassRedEditField.Value;
            app.IRED     = app.InertiaRedEditField.Value;
            app.mBLACK   = app.MassBlackEditField.Value;
            app.IBLACK   = app.InertiaBlackEditField.Value;
            app.mBLUE    = app.MassBlueEditField.Value; 
            app.IBLUE    = app.InertiaBlueEditField.Value;
            app.Phi      = app.ArmPhiEditField.Value; 
            app.b0       = app.ArmB0EditField.Value; 
            app.a1       = app.ArmA1EditField.Value;
            app.b1       = app.ArmB1EditField.Value;
            app.a2       = app.ArmA2EditField.Value;
            app.b2       = app.ArmB2EditField.Value;
            app.a3       = app.ArmA3EditField.Value; 
            app.b3       = app.ArmB3EditField.Value;
            app.mPROX    = app.MassProximalLinkEditField.Value; 
            app.mDIST    = app.MassDistalLinkEditField.Value;
            app.mEE      = app.MassEndEffectorLinkEditField.Value;
            app.IPROX    = app.InertiaProximalLinkEditField.Value;
            app.IDIST    = app.InertiaDistalLinkEditField.Value;
            app.IEE      = app.InertiaEndEffectorLinkEditField.Value;

            assignin('base', 'mRED', app.mRED);
            assignin('base', 'IRED', app.IRED);
            assignin('base', 'mBLACK', app.mBLACK);
            assignin('base', 'IBLACK', app.IBLACK);
            assignin('base', 'mBLUE', app.mBLUE);
            assignin('base', 'IBLUE', app.IBLUE);
            assignin('base', 'Phi', app.Phi);
            assignin('base', 'b0', app.b0);
            assignin('base', 'a1', app.a1);
            assignin('base', 'b1', app.b1);
            assignin('base', 'a2', app.a2);
            assignin('base', 'b2', app.b2);
            assignin('base', 'a3', app.a3);
            assignin('base', 'b3', app.b3);
            assignin('base', 'mPROX', app.mPROX);
            assignin('base', 'mDIST', app.mDIST);
            assignin('base', 'mEE', app.mEE);
            assignin('base', 'IPROX', app.IPROX);
            assignin('base', 'IDIST', app.IDIST);
            assignin('base', 'IEE', app.IEE);  

            %app.ConfirmSettings();
        end        
        
        function [] = CalculateCOG(app, showPlots)

            % Red Configuration #1: NO panels; YES Air tank; NO arm; YES shoulder motor; NO wheel
            Ar = app.AREDScaleMeasurement.Value; % [kg] Left-middle-edge
            Br = app.BREDScaleMeasurement.Value; % [kg] Right-back corner
            Cr = app.CREDScaleMeasurement.Value; % [kg] Right-front corner

            Mr = Ar+Br+Cr;

            app.MassRedEditField.Value = Mr;

            % Location of scales is assumed to be consistant, and relative
            % to the center of geometry and reference frame defined on the
            % Wiki
            x1 = 0;
            y1 = 0.15;

            x2 = -0.15;
            y2 = -0.15;

            x3 = 0.15;
            y3 = -0.15;

            % New equations on 2025-08-29: Configuration of scales modified
            Xr = (Ar*x1 + Br*x2 + Cr*x3)/Mr;
            Yr = (Ar*y1 + Br*y2 + Cr*y3)/Mr;

            % Convert 
            app.REDXCGCalculation.Value = Xr*100;
            app.REDYCGCalculation.Value = Yr*100;
    
            % Place LEDs (Measured on 2025-08-29)
            LED6x =  0.15-Xr-0.013;
            LED6y =  0.15-Yr-0.013;
            
            LED4x =  0.15-Xr-0.013;
            LED4y =  -0.15-Yr+0.013;
            
            LED2x =  -0.15-Xr+0.013;
            LED2y =  -0.15-Yr+0.013;
            
            LED0x =  -0.15-Xr+0.013;
            LED0y =  0.15-Yr-0.013;

            if showPlots == true
    
                figure(1);
                rectangle('Position',[-0.15 -0.15 0.3 0.3],'EdgeColor','r')
                hold on;
                grid on;
                axis([-0.2 0.2 -0.2 0.2]);
                axis square;
                scatter(Yr,Xr, 'filled','k');
                line([0 0],[0 0.15],'Color','red');
                set(gca,'xdir','reverse') 
                xlabel('Y - Body Frame (m)');
                ylabel('X - Body Frame (m)');
                text(Yr, Xr+0.025, 'CG')
                text(0.15, 0.16, 'LED #6')
                text(-0.1, 0.16, 'LED #4')
                text(-0.1, -0.16, 'LED #2')
                text(0.15, -0.16, 'LED #0')

    
                scatter(-(LED2x+Xr), -(LED2y+Yr), 'filled','b');
                scatter(LED0x+Xr, LED0y+Yr, 'filled','o');
                scatter(-(LED6x+Xr),-(LED6y+Yr), 'filled','m');
                scatter(LED4x+Xr,LED4y+Yr, 'filled','g');
    
                set(gca,'FontSize',12,'FontName','Times');  
                width = 4;                                                                        
                height = 4;                                                                       
                pos = get(gcf, 'Position');                                               
                set(gcf, 'Position', [pos(1) pos(2) width*100, height*100]);
                set(gcf, 'Paperposition', [0 0 width height])        
                set(gcf,'papersize',[width height])   

            end
            
            % Thruster offsets from centre of mass
            % Measured on 2025-08-29
            Thr1R = 0.15-0.081-Yr;
            Thr2R = -(0.15-0.081+Yr);
            Thr3R = 0.15-0.081-Xr;
            Thr4R = -(0.15-0.081+Xr);
            Thr5R = 0.15-0.081+Yr;
            Thr6R = -(0.15-0.081-Yr);
            Thr7R = 0.15-0.081+Xr;
            Thr8R = -(0.15-0.081-Xr);

            thruster_dist2CG_RED   = [ Thr1R
                                       Thr2R
                                       Thr3R
                                       Thr4R
                                       Thr5R
                                       Thr6R
                                       Thr7R
                                       Thr8R ].*1000;

            % Black with panels and an air tank 
            Ab = app.ABLACKScaleMeasurement.Value; % [kg] Left-middle-edge
            Bb = app.BBLACKScaleMeasurement.Value; % [kg] Right-back corner
            Cb = app.CBLACKScaleMeasurement.Value; % [kg] Right-front corner

            Mb = Ab+Bb+Cb;

            app.MassBlackEditField.Value = Mb;

            % Location of scales is assumed to be consistant, and relative
            % to the center of geometry and reference frame defined on the
            % Wiki
            x1 = 0;
            y1 = 0.15;

            x2 = -0.15;
            y2 = -0.15;

            x3 = 0.15;
            y3 = -0.15;

            % New equations on 2025-08-29: Configuration of scales modified
            Xb = (Ab*x1 + Bb*x2 + Cb*x3)/Mr;
            Yb = (Ab*y1 + Bb*y2 + Cb*y3)/Mr;

            app.BLACKXCGCalculation.Value = Xb*100;
            app.BLACKYCGCalculation.Value = Yb*100;

            % Place Black's LEDs 
            LED13x =  0.15-Xb-0.013;
            LED13y =  0.15-Yb-0.013;

            LED11x =  0.15-Xb-0.013;
            LED11y =  -0.15-Yb+0.013;

            LED9x =  -0.15-Xb+0.013;
            LED9y =  -0.15-Yb+0.013;

            LED15x =  -0.15-Xb+0.013;
            LED15y =  0.15-Yb-0.013;

            if showPlots == true

                figure(2);
                rectangle('Position',[-0.15 -0.15 0.3 0.3])
                hold on;
                grid on;
                axis([-0.2 0.2 -0.2 0.2]);
                axis square;
                scatter(Yb, Xb,'filled','k');
                line([0 0],[0 0.15],'Color','black');
                set(gca,'xdir','reverse') 
                xlabel('Y - Body Frame (m)');
                ylabel('X - Body Frame (m)');
                text(Yb, Xb+0.025, 'CG')
                text(0.15, 0.16, 'LED #13')
                text(-0.1, 0.16, 'LED #11')
                text(-0.1, -0.16, 'LED #9')
                text(0.15, -0.16, 'LED #15')

                scatter(-(LED9x+Xb), -(LED9y+Yb), 'filled','b');
                scatter(LED15x+Xb, LED15y+Yb, 'filled','o');
                scatter(-(LED13x+Xb),-(LED13y+Yb), 'filled','m');
                scatter(LED11x+Xb,LED11y+Yb, 'filled','g');
    
                set(gca,'FontSize',12,'FontName','Times');  
                width = 4;                                                                            
                height = 4;                                                                         
                pos = get(gcf, 'Position');                                               
                set(gcf, 'Position', [pos(1) pos(2) width*100, height*100]); 
                set(gcf, 'Paperposition', [0 0 width height])        
                set(gcf,'papersize',[width height])   

            end
           
            % Calculate the thruster locations relative to the CG:
            Thr1B = 0.15-0.081-Yb;
            Thr2B = -(0.15-0.081+Yb);
            Thr3B = 0.15-0.081-Xb;
            Thr4B = -(0.15-0.081+Xb);
            Thr5B = 0.15-0.081+Yb;
            Thr6B = -(0.15-0.081-Yb);
            Thr7B = 0.15-0.081+Xb;
            Thr8B = -(0.15-0.081-Xb);

            thruster_dist2CG_BLACK = [ Thr1B
                                       Thr2B
                                       Thr3B
                                       Thr4B
                                       Thr5B
                                       Thr6B
                                       Thr7B
                                       Thr8B ].*1000;

            % Blue with panels and an air tank 
            Ab = app.ABLUEScaleMeasurement.Value; % [kg ] Left-middle-edge
            Bb = app.BBLUEScaleMeasurement.Value; % [kg] Right-back corner
            Cb = app.CBLUEScaleMeasurement.Value; % [kg] Right-front corner
            
            % Calculate total mass and update field
            Mblue = Ab+Bb+Cb;
            
            app.MassBlueEditField.Value = Mblue;
            
            % Location of scales is assumed to be consistant, and relative
            % to the center of geometry and reference frame defined on the
            % Wiki
            x1 = 0;
            y1 = 0.15;

            x2 = -0.15;
            y2 = -0.15;

            x3 = 0.15;
            y3 = -0.15;

            % New equations on 2025-08-29: Configuration of scales modified
            Xblue = (Ab*x1 + Bb*x2 + Cb*x3)/Mblue;
            Yblue = (Ab*y1 + Bb*y2 + Cb*y3)/Mblue;

            app.BLUEXCGCalculation.Value = Xblue*100;
            app.BLUEYCGCalculation.Value = Yblue*100;

            % Place Blue's LEDs * USER ENTERED OFFSETS
            LED8x =  0.15-Xblue-0.013;
            LED8y =  0.15-Yblue-0.013;
            
            LED14x =  0.15-Xblue-0.013;
            LED14y =  -0.15-Yblue+0.013;
            
            LED12x =  -0.15-Xblue+0.013;
            LED12y =  -0.15-Yblue+0.013;
            
            LED10x =  -0.15-Xblue+0.013;
            LED10y =  0.15-Yblue-0.013;

            if showPlots == true
                figure(3);
                rectangle('Position',[-0.15 -0.15 0.3 0.3],'EdgeColor','b')
                hold on;
                grid on;
                axis([-0.2 0.2 -0.2 0.2]);
                axis square;
                scatter(Yblue, Xblue,'filled','k');
                line([0 0],[0 0.15],'Color','blue');
                set(gca,'xdir','reverse') 
                xlabel('Y - Body Frame (m)');
                ylabel('X - Body Frame (m)');
                text(0.15, 0.16, 'LED #8')
                text(Yblue, Xblue+0.025, 'CG')
                text(-0.1, 0.16, 'LED #14')
                text(-0.1, -0.16, 'LED #12')
                text(0.15, -0.16, 'LED #10')
                                
                scatter((LED8x+Xblue), (LED8y+Yblue), 'filled','b');
                scatter(-(LED14x+Xblue), -(LED14y+Yblue), 'filled','o');
                scatter(-(LED10x+Xblue),-(LED10y+Yblue), 'filled','m');
                scatter((LED12x+Xblue),(LED12y+Yblue), 'filled','g');
                
                set(gca,'FontSize',12,'FontName','Times');  
                width = 4;                                                                            
                height = 4;                                                                         
                pos = get(gcf, 'Position');                                               
                set(gcf, 'Position', [pos(1) pos(2) width*100, height*100]); 
                set(gcf, 'Paperposition', [0 0 width height])        
                set(gcf,'papersize',[width height])     
            end
            
            % Calculate the thruster locations relative to the CG:
            Thr1B = 0.15-0.081-Yblue;
            Thr2B = -(0.15-0.081+Yblue);
            Thr3B = 0.15-0.081-Xblue;
            Thr4B = -(0.15-0.081+Xblue);
            Thr5B = 0.15-0.081+Yblue;
            Thr6B = -(0.15-0.081-Yblue);
            Thr7B = 0.15-0.081+Xblue;
            Thr8B = -(0.15-0.081-Xblue);

            thruster_dist2CG_BLUE  = [ Thr1B
                                       Thr2B
                                       Thr3B
                                       Thr4B
                                       Thr5B
                                       Thr6B
                                       Thr7B
                                       Thr8B ].*1000;

            app.CREDScaleMeasurement_2.Value = thruster_dist2CG_RED(1);
            app.CREDScaleMeasurement_3.Value = thruster_dist2CG_RED(2);
            app.CREDScaleMeasurement_4.Value = thruster_dist2CG_RED(3);
            app.CREDScaleMeasurement_5.Value = thruster_dist2CG_RED(4);
            app.CREDScaleMeasurement_6.Value = thruster_dist2CG_RED(5);
            app.CREDScaleMeasurement_7.Value = thruster_dist2CG_RED(6);
            app.CREDScaleMeasurement_8.Value = thruster_dist2CG_RED(7);
            app.CREDScaleMeasurement_9.Value = thruster_dist2CG_RED(8);

            app.CREDScaleMeasurement_10.Value = thruster_dist2CG_BLACK(1);
            app.CREDScaleMeasurement_11.Value = thruster_dist2CG_BLACK(2);
            app.CREDScaleMeasurement_12.Value = thruster_dist2CG_BLACK(3);
            app.CREDScaleMeasurement_13.Value = thruster_dist2CG_BLACK(4);
            app.CREDScaleMeasurement_14.Value = thruster_dist2CG_BLACK(5);
            app.CREDScaleMeasurement_15.Value = thruster_dist2CG_BLACK(6);
            app.CREDScaleMeasurement_16.Value = thruster_dist2CG_BLACK(7);
            app.CREDScaleMeasurement_17.Value = thruster_dist2CG_BLACK(8);
            
            app.CREDScaleMeasurement_18.Value = thruster_dist2CG_BLUE(1);
            app.CREDScaleMeasurement_19.Value = thruster_dist2CG_BLUE(2);
            app.CREDScaleMeasurement_20.Value = thruster_dist2CG_BLUE(3);
            app.CREDScaleMeasurement_21.Value = thruster_dist2CG_BLUE(4);
            app.CREDScaleMeasurement_22.Value = thruster_dist2CG_BLUE(5);
            app.CREDScaleMeasurement_23.Value = thruster_dist2CG_BLUE(6);
            app.CREDScaleMeasurement_24.Value = thruster_dist2CG_BLUE(7);
            app.CREDScaleMeasurement_25.Value = thruster_dist2CG_BLUE(8);

            assignin('base','thruster_dist2CG_BLUE',thruster_dist2CG_BLUE);
            assignin('base','thruster_dist2CG_BLACK',thruster_dist2CG_BLACK);
            assignin('base','thruster_dist2CG_RED',thruster_dist2CG_RED);
            
            fileID = fopen([cd '/Resources/tracker_positions_phasespace.txt'],'w');
            fprintf(fileID,'tracker_id_RED_5_pos_string,%f,%f,0\n',[LED6x*1000,LED6y*1000]);
            fprintf(fileID,'tracker_id_RED_3_pos_string,%f,%f,0\n',[LED4x*1000,LED4y*1000]);
            fprintf(fileID,'tracker_id_RED_7_pos_string,%f,%f,0\n',[LED2x*1000,LED2y*1000]);
            fprintf(fileID,'tracker_id_RED_4_pos_string,%f,%f,0\n',[LED0x*1000,LED0y*1000]);
            fprintf(fileID,'tracker_id_BLACK_13_pos_string,%f,%f,0\n',[LED13x*1000,LED13y*1000]);
            fprintf(fileID,'tracker_id_BLACK_11_pos_string,%f,%f,0\n',[LED11x*1000,LED11y*1000]);
            fprintf(fileID,'tracker_id_BLACK_9_pos_string,%f,%f,0\n',[LED9x*1000,LED9y*1000]);
            fprintf(fileID,'tracker_id_BLACK_15_pos_string,%f,%f,0\n',[LED15x*1000,LED15y*1000]);
            fprintf(fileID,'tracker_id_BLUE_8_pos_string,%f,%f,0\n',[LED8x*1000,LED8y*1000]);
            fprintf(fileID,'tracker_id_BLUE_14_pos_string,%f,%f,0\n',[LED14x*1000,LED14y*1000]);
            fprintf(fileID,'tracker_id_BLUE_12_pos_string,%f,%f,0\n',[LED12x*1000,LED12y*1000]);
            fprintf(fileID,'tracker_id_BLUE_10_pos_string,%f,%f,0\n',[LED10x*1000,LED10y*1000]);
            
            app.UpdateMassProperties();
        end
        
        function [] = CalculateInertia(app)
  
            % % Calculating Black's inertia using the bifilar pendulum * USER ENTERED VALUES
            
            app.InertiaBlueEditField.Value = app.TBLUEMeasurement.Value^2 * app.MassBlueEditField.Value * 9.81 * app.DBLUEMeasurement.Value^2 / (16*pi^2 * app.LBLUEMeasurement.Value);
            app.InertiaBlackEditField.Value = app.TBLACKMeasurement.Value^2 * app.MassBlackEditField.Value * 9.81 * app.DBLACKMeasurement.Value^2 / (16*pi^2 * app.LBLACKMeasurement.Value);
            app.InertiaRedEditField.Value = app.TREDMeasurement.Value^2 * app.MassRedEditField.Value * 9.81 * app.DREDMeasurement.Value^2 / (16*pi^2 * app.LREDScaleMeasurement.Value);
         
            app.UpdateMassProperties();

        end
    end
    
    methods (Access = public)
        
        function [] = ExternalGUIReset(app)

            app.PopulateGUI();
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PopulateGUI(app)

            % Initialize the mass properties for the platforms:

            app.AREDScaleMeasurement.Value = 6.17;
            app.BREDScaleMeasurement.Value = 3.12;
            app.CREDScaleMeasurement.Value = 4.46;

            app.ABLACKScaleMeasurement.Value = 7.37; % 7.37 with solar panels; 6.14, no solar panels
            app.BBLACKScaleMeasurement.Value = 3.98; % 3.98 with solar panels; 3.07, no solar panels
            app.CBLACKScaleMeasurement.Value = 4.86; % 4.86 with solar panels; 4.01, no solar panels

            app.ABLUEScaleMeasurement.Value = 5.65;
            app.BBLUEScaleMeasurement.Value = 3.08;
            app.CBLUEScaleMeasurement.Value = 4.04;

            app.DREDMeasurement.Value = 0.28;
            app.LREDScaleMeasurement.Value = 2.565;
            app.TREDMeasurement.Value = 2.997;

            app.DBLACKMeasurement.Value = 0.28;
            app.LBLACKMeasurement.Value = 2.565;
            app.TBLACKMeasurement.Value = 3.109;

            app.DBLUEMeasurement.Value = 0.28;
            app.LBLUEMeasurement.Value = 2.565;
            app.TBLUEMeasurement.Value = 3.021;

            app.CalculateCOG(false);
            app.CalculateInertia();

            %app.MassRedEditField.Value                  = 13.0720;     % RED Mass
            %app.InertiaRedEditField.Value               = 0.2405;      % RED Inertia;
            %app.MassBlackEditField.Value                = 12.0390;     % BLACK Mass
            %app.InertiaBlackEditField.Value             = 0.225692;    % BLACK Inertia
            %app.MassBlueEditField.Value                 = 11.2670;     % BLUE Mass
            %app.InertiaBlueEditField.Value              = 0.204245;    % BLUE Inertia
            app.ArmPhiEditField.Value                   = 73.2749*pi/180; % ARM pi/2-app.Phi
            app.ArmB0EditField.Value                    = 0.25;        % ARM app.b0
            app.ArmA1EditField.Value                    = 0.196822;    % ARM app.a1
            app.ArmB1EditField.Value                    = 0.107678;    % ARM app.b1
            app.ArmA2EditField.Value                    = 0.198152;    % ARM app.a2
            app.ArmB2EditField.Value                    = 0.106348;    % ARM app.b2
            app.ArmA3EditField.Value                    = 0.062097;    % ARM app.a3
            app.ArmB3EditField.Value                    = 0.025153;    % ARM app.b3
            app.MassProximalLinkEditField.Value         = 0.345;       % ARM m1
            app.MassDistalLinkEditField.Value           = 0.335;       % ARM m2
            app.MassEndEffectorLinkEditField.Value      = 0.111;       % ARM m3
            app.InertiaProximalLinkEditField.Value      = 0.003704;    % ARM I1
            app.InertiaDistalLinkEditField.Value        = 0.003506;    % ARM I2
            app.InertiaEndEffectorLinkEditField.Value   = 0.000106;    % ARM I3

            % Update GUI times and send to workspace
            app.UpdateMassProperties();
          
        end

        % Callback function
        function MassREDEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Callback function
        function InertiaREDEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Callback function
        function MassBLACKEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Callback function
        function InertiaBLACKEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Callback function
        function MassBLUEEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');  
        end

        % Callback function
        function InertiaBLUEEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.StartSimulationButton,'enable','off');
            set(app.BuildCode,'enable','off');
            
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Button pushed function: ShowRoboticArmParameterDefinitionsButton
        function ShowRoboticArmParameterDefinitionsButtonPushed(app, event)
            PictureofArm = imread([cd '/Resources/ArmConfig.png']);
            imshow(PictureofArm);         
        end

        % Value changed function: MassRedEditField
        function MassRedEditFieldValueChanged(app, event)
            app.mRED = app.MassRedEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: MassBlackEditField
        function MassBlackEditFieldValueChanged(app, event)
            app.mBLACK = app.MassBlueEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: MassBlueEditField
        function MassBlueEditFieldValueChanged(app, event)
            app.mBLUE = app.MassBlueEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: InertiaRedEditField
        function InertiaRedEditFieldValueChanged(app, event)
            app.IRED = app.InertiaRedEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: InertiaBlackEditField
        function InertiaBlackEditFieldValueChanged(app, event)
            app.IBLACK = app.InertiaBlackEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: InertiaBlueEditField
        function InertiaBlueEditFieldValueChanged(app, event)
            app.IBLUE = app.InertiaBlueEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: MassProximalLinkEditField
        function MassProximalLinkEditFieldValueChanged(app, event)
            app.mPROX = app.MassProximalLinkEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: MassDistalLinkEditField
        function MassDistalLinkEditFieldValueChanged(app, event)
            app.mDIST = app.MassDistalLinkEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: MassEndEffectorLinkEditField
        function MassEndEffectorLinkEditFieldValueChanged(app, event)
            app.mEE = app.MassEndEffectorLinkEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: InertiaProximalLinkEditField
        function InertiaProximalLinkEditFieldValueChanged(app, event)
            app.IPROX = app.InertiaProximalLinkEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: InertiaDistalLinkEditField
        function InertiaDistalLinkEditFieldValueChanged(app, event)
            app.IDIST = app.InertiaDistalLinkEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: InertiaEndEffectorLinkEditField
        function InertiaEndEffectorLinkEditFieldValueChanged(app, event)
            app.IEE = app.InertiaEndEffectorLinkEditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: AREDScaleMeasurement
        function AREDScaleMeasurementValueChanged(app, event)
            app.Phi = app.AREDScaleMeasurement.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: ArmB0EditField
        function ArmB0EditFieldValueChanged(app, event)
            app.b0 = app.ArmB0EditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: ArmA1EditField
        function ArmA1EditFieldValueChanged(app, event)
            app.a1 = app.ArmA1EditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: ArmB1EditField
        function ArmB1EditFieldValueChanged(app, event)
            app.b1 = app.ArmB1EditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: ArmA2EditField
        function ArmA2EditFieldValueChanged(app, event)
            app.a2 = app.ArmA2EditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: ArmB2EditField
        function ArmB2EditFieldValueChanged(app, event)
            app.b2 = app.ArmB2EditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: ArmA3EditField
        function ArmA3EditFieldValueChanged(app, event)
            app.a3 = app.ArmA3EditField.Value;
            app.UpdateMassProperties();
        end

        % Value changed function: ArmB3EditField
        function ArmB3EditFieldValueChanged(app, event)
            app.b3 = app.ArmB3EditField.Value;
            app.UpdateMassProperties();
        end

        % Button pushed function: CalculateCenterofGravityButton
        function CalculateCenterofGravityButtonPushed(app, event)
            if app.ShowPlotsCheckBox.Value == 1
                app.CalculateCOG(true);
            else
                app.CalculateCOG(false);
            end

            app.UpdateMassProperties();
        end

        % Button pushed function: 
        % CalculateInertiausingBifilarPendulumTechniqueButton
        function CalculateInertiausingBifilarPendulumTechniqueButtonPushed(app, event)
            app.CalculateInertia();
            app.UpdateMassProperties();
        end

        % Value changed function: OverridePropertiesCheckBox
        function OverridePropertiesCheckBoxValueChanged(app, event)
            value = app.OverridePropertiesCheckBox.Value;
            if value == 1
                app.MassRedEditField.Editable = "on";
                app.MassBlackEditField.Editable = "on";
                app.MassBlueEditField.Editable = "on";
                app.InertiaRedEditField.Editable = "on";
                app.InertiaBlackEditField.Editable = "on";
                app.InertiaBlueEditField.Editable = "on";
            else
                app.MassRedEditField.Editable = "off";
                app.MassBlackEditField.Editable = "off";
                app.MassBlueEditField.Editable = "off";
                app.InertiaRedEditField.Editable = "off";
                app.InertiaBlackEditField.Editable = "off";
                app.InertiaBlueEditField.Editable = "off";
            end
        end

        % Close request function: SPOT50MassPropertiesUIFigure
        function SPOT50MassPropertiesUIFigureCloseRequest(app, event)
            %delete(app)   
            app.SPOT50MassPropertiesUIFigure.Visible = "off";
        end

        % Button pushed function: 
        % ShowCenterofGravityExperimentalSetupButton
        function ShowCenterofGravityExperimentalSetupButtonPushed(app, event)
            figure()
            PictureOfCM = imread([cd '/Resources/COMConfig.png']);
            imshow(PictureOfCM);
            set(gca, 'Color', 'white');
        end

        % Button pushed function: ShowThrusterLocationDefinitionsButton
        function ShowThrusterLocationDefinitionsButtonPushed(app, event)
            figure()
            PictureOfT = imread([cd '/Resources/ThrusterConfig.png']);
            imshow(PictureOfT);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SPOT50MassPropertiesUIFigure = uifigure('Visible', 'off');
            app.SPOT50MassPropertiesUIFigure.Name = 'SPOT 5.0 Mass Properties';
            app.SPOT50MassPropertiesUIFigure.CloseRequestFcn = createCallbackFcn(app, @SPOT50MassPropertiesUIFigureCloseRequest, true);
            app.GridLayout25 = uigridlayout(app.SPOT50MassPropertiesUIFigure);
            app.CenterofGravityEstimatorPanel = uipanel(app.GridLayout25);
            app.CenterofGravityEstimatorPanel.Title = 'Center of Gravity Estimator';
            app.GridLayout24_2 = uigridlayout(app.CenterofGravityEstimatorPanel);
            app.AREDScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.AREDScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.AREDScaleMeasurement.ValueChangedFcn = createCallbackFcn(app, @AREDScaleMeasurementValueChanged, true);
            app.AREDScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location A (RED).'};
            app.AREDScaleMeasurement.Value = 100;
            app.mathrmALabel = uilabel(app.GridLayout24_2);
            app.mathrmALabel.Interpreter = 'latex';
            app.mathrmALabel.Text = '$\mathrm{A}$';
            app.mathrmBLabel = uilabel(app.GridLayout24_2);
            app.mathrmBLabel.Interpreter = 'latex';
            app.mathrmBLabel.Text = '$\mathrm{B}$';
            app.mathrmCLabel = uilabel(app.GridLayout24_2);
            app.mathrmCLabel.Interpreter = 'latex';
            app.mathrmCLabel.Text = '$\mathrm{C}$';
            app.BREDScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.BREDScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.BREDScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location B (RED).'};
            app.BREDScaleMeasurement.Value = 100;
            app.CREDScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location C (RED).'};
            app.CREDScaleMeasurement.Value = 100;
            app.mathrmALabel_2 = uilabel(app.GridLayout24_2);
            app.mathrmALabel_2.Interpreter = 'latex';
            app.mathrmALabel_2.Text = '$\mathrm{A}$';
            app.mathrmBLabel_2 = uilabel(app.GridLayout24_2);
            app.mathrmBLabel_2.Interpreter = 'latex';
            app.mathrmBLabel_2.Text = '$\mathrm{B}$';
            app.mathrmCLabel_2 = uilabel(app.GridLayout24_2);
            app.mathrmCLabel_2.Interpreter = 'latex';
            app.mathrmCLabel_2.Text = '$\mathrm{C}$';
            app.ABLUEScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.ABLUEScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.ABLUEScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location A (BLUE).'};
            app.ABLUEScaleMeasurement.Value = 100;
            app.BBLUEScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.BBLUEScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.BBLUEScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location B (BLUE).'};
            app.BBLUEScaleMeasurement.Value = 100;
            app.CBLUEScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.CBLUEScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.CBLUEScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location C (BLUE).'};
            app.CBLUEScaleMeasurement.Value = 100;
            app.mathrmALabel_3 = uilabel(app.GridLayout24_2);
            app.mathrmALabel_3.Interpreter = 'latex';
            app.mathrmALabel_3.Text = '$\mathrm{A}$';
            app.mathrmBLabel_3 = uilabel(app.GridLayout24_2);
            app.mathrmBLabel_3.Interpreter = 'latex';
            app.mathrmBLabel_3.Text = '$\mathrm{B}$';
            app.mathrmCLabel_3 = uilabel(app.GridLayout24_2);
            app.mathrmCLabel_3.Interpreter = 'latex';
            app.mathrmCLabel_3.Text = '$\mathrm{C}$';
            app.ABLACKScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.ABLACKScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.ABLACKScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location A (BLACK).'};
            app.ABLACKScaleMeasurement.Value = 100;
            app.BBLACKScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.BBLACKScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.BBLACKScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location B (BLACK).'};
            app.BBLACKScaleMeasurement.Value = 100;
            app.CBLACKScaleMeasurement = uieditfield(app.GridLayout24_2, 'numeric');
            app.CBLACKScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.CBLACKScaleMeasurement.Tooltip = {'Mass measured using a scale placed at location C (BLACK).'};
            app.CBLACKScaleMeasurement.Value = 100;
            app.DurPhase0Units_8 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_8.Interpreter = 'latex';
            app.DurPhase0Units_8.Text = 'kg';
            app.DurPhase0Units_28 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_28.Interpreter = 'latex';
            app.DurPhase0Units_28.Text = 'kg';
            app.DurPhase0Units_29 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_29.Interpreter = 'latex';
            app.DurPhase0Units_29.Text = 'kg';
            app.CREDScaleMeasurement_2 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_2.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_2.Editable = 'off';
            app.CREDScaleMeasurement_2.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_2.Value = 100;
            app.CREDScaleMeasurement_3 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_3.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_3.Editable = 'off';
            app.CREDScaleMeasurement_3.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_3.Value = 100;
            app.CREDScaleMeasurement_4 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_4.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_4.Editable = 'off';
            app.CREDScaleMeasurement_4.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_4.Value = 100;
            app.CREDScaleMeasurement_5 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_5.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_5.Editable = 'off';
            app.CREDScaleMeasurement_5.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_5.Value = 100;
            app.CREDScaleMeasurement_6 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_6.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_6.Editable = 'off';
            app.CREDScaleMeasurement_6.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_6.Value = 100;
            app.CREDScaleMeasurement_7 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_7.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_7.Editable = 'off';
            app.CREDScaleMeasurement_7.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_7.Value = 100;
            app.CREDScaleMeasurement_8 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_8.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_8.Editable = 'off';
            app.CREDScaleMeasurement_8.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_8.Value = 100;
            app.CREDScaleMeasurement_9 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_9.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_9.Editable = 'off';
            app.CREDScaleMeasurement_9.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_9.Value = 100;
            app.mathrmT_1Label = uilabel(app.GridLayout24_2);
            app.mathrmT_1Label.Interpreter = 'latex';
            app.mathrmT_1Label.Text = '$\mathrm{T_1}$';
            app.mathrmT_2Label = uilabel(app.GridLayout24_2);
            app.mathrmT_2Label.Interpreter = 'latex';
            app.mathrmT_2Label.Text = '$\mathrm{T_2}$';
            app.mathrmT_3Label = uilabel(app.GridLayout24_2);
            app.mathrmT_3Label.Interpreter = 'latex';
            app.mathrmT_3Label.Text = '$\mathrm{T_3}$';
            app.mathrmT_4Label = uilabel(app.GridLayout24_2);
            app.mathrmT_4Label.Interpreter = 'latex';
            app.mathrmT_4Label.Text = '$\mathrm{T_4}$';
            app.mathrmT_5Label = uilabel(app.GridLayout24_2);
            app.mathrmT_5Label.Interpreter = 'latex';
            app.mathrmT_5Label.Text = '$\mathrm{T_5}$';
            app.mathrmT_6Label = uilabel(app.GridLayout24_2);
            app.mathrmT_6Label.Interpreter = 'latex';
            app.mathrmT_6Label.Text = '$\mathrm{T_6}$';
            app.mathrmT_7Label = uilabel(app.GridLayout24_2);
            app.mathrmT_7Label.Interpreter = 'latex';
            app.mathrmT_7Label.Text = '$\mathrm{T_7}$';
            app.mathrmT_8Label = uilabel(app.GridLayout24_2);
            app.mathrmT_8Label.Interpreter = 'latex';
            app.mathrmT_8Label.Text = '$\mathrm{T_8}$';
            app.CalculateCenterofGravityButton = uibutton(app.GridLayout24_2, 'push');
            app.CalculateCenterofGravityButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCenterofGravityButtonPushed, true);
            app.CalculateCenterofGravityButton.Tooltip = {'Calculate the COG for each platform using the measurements from the scales.'};
            app.CalculateCenterofGravityButton.Text = 'Calculate Center of Gravity';
            app.ShowPlotsCheckBox = uicheckbox(app.GridLayout24_2);
            app.ShowPlotsCheckBox.Tooltip = {'If this is checked when the Calculate Center of Gravity button is pressed, then plots showing the LEDs and the COG for each platform will be generated.'};
            app.ShowPlotsCheckBox.Text = 'Show Plots';
            app.CREDScaleMeasurement_10 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_10.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_10.Editable = 'off';
            app.CREDScaleMeasurement_10.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_10.Value = 100;
            app.CREDScaleMeasurement_11 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_11.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_11.Editable = 'off';
            app.CREDScaleMeasurement_11.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_11.Value = 100;
            app.CREDScaleMeasurement_12 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_12.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_12.Editable = 'off';
            app.CREDScaleMeasurement_12.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_12.Value = 100;
            app.CREDScaleMeasurement_13 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_13.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_13.Editable = 'off';
            app.CREDScaleMeasurement_13.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_13.Value = 100;
            app.CREDScaleMeasurement_14 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_14.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_14.Editable = 'off';
            app.CREDScaleMeasurement_14.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_14.Value = 100;
            app.CREDScaleMeasurement_15 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_15.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_15.Editable = 'off';
            app.CREDScaleMeasurement_15.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_15.Value = 100;
            app.CREDScaleMeasurement_16 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_16.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_16.Editable = 'off';
            app.CREDScaleMeasurement_16.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_16.Value = 100;
            app.CREDScaleMeasurement_17 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_17.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_17.Editable = 'off';
            app.CREDScaleMeasurement_17.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_17.Value = 100;
            app.mathrmT_1Label_2 = uilabel(app.GridLayout24_2);
            app.mathrmT_1Label_2.Interpreter = 'latex';
            app.mathrmT_1Label_2.Text = '$\mathrm{T_1}$';
            app.mathrmT_2Label_2 = uilabel(app.GridLayout24_2);
            app.mathrmT_2Label_2.Interpreter = 'latex';
            app.mathrmT_2Label_2.Text = '$\mathrm{T_2}$';
            app.mathrmT_3Label_2 = uilabel(app.GridLayout24_2);
            app.mathrmT_3Label_2.Interpreter = 'latex';
            app.mathrmT_3Label_2.Text = '$\mathrm{T_3}$';
            app.mathrmT_4Label_2 = uilabel(app.GridLayout24_2);
            app.mathrmT_4Label_2.Interpreter = 'latex';
            app.mathrmT_4Label_2.Text = '$\mathrm{T_4}$';
            app.mathrmT_5Label_2 = uilabel(app.GridLayout24_2);
            app.mathrmT_5Label_2.Interpreter = 'latex';
            app.mathrmT_5Label_2.Text = '$\mathrm{T_5}$';
            app.mathrmT_6Label_2 = uilabel(app.GridLayout24_2);
            app.mathrmT_6Label_2.Interpreter = 'latex';
            app.mathrmT_6Label_2.Text = '$\mathrm{T_6}$';
            app.mathrmT_7Label_2 = uilabel(app.GridLayout24_2);
            app.mathrmT_7Label_2.Interpreter = 'latex';
            app.mathrmT_7Label_2.Text = '$\mathrm{T_7}$';
            app.mathrmT_8Label_2 = uilabel(app.GridLayout24_2);
            app.mathrmT_8Label_2.Interpreter = 'latex';
            app.mathrmT_8Label_2.Text = '$\mathrm{T_8}$';
            app.CREDScaleMeasurement_18 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_18.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_18.Editable = 'off';
            app.CREDScaleMeasurement_18.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_18.Value = 100;
            app.CREDScaleMeasurement_19 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_19.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_19.Editable = 'off';
            app.CREDScaleMeasurement_19.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_19.Value = 100;
            app.CREDScaleMeasurement_20 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_20.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_20.Editable = 'off';
            app.CREDScaleMeasurement_20.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_20.Value = 100;
            app.CREDScaleMeasurement_21 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_21.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_21.Editable = 'off';
            app.CREDScaleMeasurement_21.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_21.Value = 100;
            app.CREDScaleMeasurement_22 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_22.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_22.Editable = 'off';
            app.CREDScaleMeasurement_22.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_22.Value = 100;
            app.CREDScaleMeasurement_23 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_23.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_23.Editable = 'off';
            app.CREDScaleMeasurement_23.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_23.Value = 100;
            app.CREDScaleMeasurement_24 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_24.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_24.Editable = 'off';
            app.CREDScaleMeasurement_24.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_24.Value = 100;
            app.CREDScaleMeasurement_25 = uieditfield(app.GridLayout24_2, 'numeric');
            app.CREDScaleMeasurement_25.ValueDisplayFormat = '%.2f';
            app.CREDScaleMeasurement_25.Editable = 'off';
            app.CREDScaleMeasurement_25.Tooltip = {'Location of thruster relative to COG.'};
            app.CREDScaleMeasurement_25.Value = 100;
            app.mathrmT_1Label_3 = uilabel(app.GridLayout24_2);
            app.mathrmT_1Label_3.Interpreter = 'latex';
            app.mathrmT_1Label_3.Text = '$\mathrm{T_1}$';
            app.mathrmT_2Label_3 = uilabel(app.GridLayout24_2);
            app.mathrmT_2Label_3.Interpreter = 'latex';
            app.mathrmT_2Label_3.Text = '$\mathrm{T_2}$';
            app.mathrmT_3Label_3 = uilabel(app.GridLayout24_2);
            app.mathrmT_3Label_3.Interpreter = 'latex';
            app.mathrmT_3Label_3.Text = '$\mathrm{T_3}$';
            app.mathrmT_4Label_3 = uilabel(app.GridLayout24_2);
            app.mathrmT_4Label_3.Interpreter = 'latex';
            app.mathrmT_4Label_3.Text = '$\mathrm{T_4}$';
            app.mathrmT_5Label_3 = uilabel(app.GridLayout24_2);
            app.mathrmT_5Label_3.Interpreter = 'latex';
            app.mathrmT_5Label_3.Text = '$\mathrm{T_5}$';
            app.mathrmT_6Label_3 = uilabel(app.GridLayout24_2);
            app.mathrmT_6Label_3.Interpreter = 'latex';
            app.mathrmT_6Label_3.Text = '$\mathrm{T_6}$';
            app.mathrmT_7Label_3 = uilabel(app.GridLayout24_2);
            app.mathrmT_7Label_3.Interpreter = 'latex';
            app.mathrmT_7Label_3.Text = '$\mathrm{T_7}$';
            app.mathrmT_8Label_3 = uilabel(app.GridLayout24_2);
            app.mathrmT_8Label_3.Interpreter = 'latex';
            app.mathrmT_8Label_3.Text = '$\mathrm{T_8}$';
            app.DurPhase0Units_39 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_39.Interpreter = 'latex';
            app.DurPhase0Units_39.Text = 'mm';
            app.DurPhase0Units_40 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_40.Interpreter = 'latex';
            app.DurPhase0Units_40.Text = 'mm';
            app.DurPhase0Units_41 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_41.Interpreter = 'latex';
            app.DurPhase0Units_41.Text = 'mm';
            app.DurPhase0Units_42 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_42.Interpreter = 'latex';
            app.DurPhase0Units_42.Text = 'mm';
            app.DurPhase0Units_43 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_43.Interpreter = 'latex';
            app.DurPhase0Units_43.Text = 'mm';
            app.DurPhase0Units_44 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_44.Interpreter = 'latex';
            app.DurPhase0Units_44.Text = 'mm';
            app.DurPhase0Units_45 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_45.Interpreter = 'latex';
            app.DurPhase0Units_45.Text = 'mm';
            app.DurPhase0Units_46 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_46.Interpreter = 'latex';
            app.DurPhase0Units_46.Text = 'mm';
            app.ShowCenterofGravityExperimentalSetupButton = uibutton(app.GridLayout24_2, 'push');
            app.ShowCenterofGravityExperimentalSetupButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCenterofGravityExperimentalSetupButtonPushed, true);
            app.ShowCenterofGravityExperimentalSetupButton.Text = {'Show Center of Gravity '; 'Experimental Setup'};
            app.ShowThrusterLocationDefinitionsButton = uibutton(app.GridLayout24_2, 'push');
            app.ShowThrusterLocationDefinitionsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowThrusterLocationDefinitionsButtonPushed, true);
            app.ShowThrusterLocationDefinitionsButton.Text = 'Show Thruster Location Definitions';
            app.Image3 = uiimage(app.GridLayout24_2);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.SummaryofSalientDataPanel = uipanel(app.GridLayout25);
            app.SummaryofSalientDataPanel.Title = 'Summary of Salient Data';
            app.GridLayout24 = uigridlayout(app.SummaryofSalientDataPanel);
            app.mathrmm_REDLabel = uilabel(app.GridLayout24);
            app.mathrmm_REDLabel.Interpreter = 'latex';
            app.mathrmm_REDLabel.Text = '$\mathrm{m_{RED}}$';
            app.MassRedEditField = uieditfield(app.GridLayout24, 'numeric');
            app.MassRedEditField.ValueDisplayFormat = '%.3f';
            app.MassRedEditField.ValueChangedFcn = createCallbackFcn(app, @MassRedEditFieldValueChanged, true);
            app.MassRedEditField.Editable = 'off';
            app.MassRedEditField.Tooltip = {'Mass of RED.'};
            app.MassRedEditField.Value = 100;
            app.DurPhase0Units_2 = uilabel(app.GridLayout24);
            app.DurPhase0Units_2.Interpreter = 'latex';
            app.DurPhase0Units_2.Text = 'kg';
            app.mathrmm_BLACKLabel = uilabel(app.GridLayout24);
            app.mathrmm_BLACKLabel.Interpreter = 'latex';
            app.mathrmm_BLACKLabel.Text = '$\mathrm{m_{BLACK}}$';
            app.MassBlackEditField = uieditfield(app.GridLayout24, 'numeric');
            app.MassBlackEditField.ValueDisplayFormat = '%.3f';
            app.MassBlackEditField.ValueChangedFcn = createCallbackFcn(app, @MassBlackEditFieldValueChanged, true);
            app.MassBlackEditField.Editable = 'off';
            app.MassBlackEditField.Tooltip = {'Mass of BLACK.'};
            app.MassBlackEditField.Value = 100;
            app.DurPhase0Units_3 = uilabel(app.GridLayout24);
            app.DurPhase0Units_3.Interpreter = 'latex';
            app.DurPhase0Units_3.Text = 'kg';
            app.mathrmm_BLUELabel = uilabel(app.GridLayout24);
            app.mathrmm_BLUELabel.Interpreter = 'latex';
            app.mathrmm_BLUELabel.Text = '$\mathrm{m_{BLUE}}$';
            app.MassBlueEditField = uieditfield(app.GridLayout24, 'numeric');
            app.MassBlueEditField.ValueDisplayFormat = '%.3f';
            app.MassBlueEditField.ValueChangedFcn = createCallbackFcn(app, @MassBlueEditFieldValueChanged, true);
            app.MassBlueEditField.Editable = 'off';
            app.MassBlueEditField.Tooltip = {'Mass of BLUE.'};
            app.MassBlueEditField.Value = 100;
            app.DurPhase0Units_4 = uilabel(app.GridLayout24);
            app.DurPhase0Units_4.Interpreter = 'latex';
            app.DurPhase0Units_4.Text = 'kg';
            app.mathrmI_REDLabel = uilabel(app.GridLayout24);
            app.mathrmI_REDLabel.Interpreter = 'latex';
            app.mathrmI_REDLabel.Text = '$\mathrm{I_{RED}}$';
            app.InertiaRedEditField = uieditfield(app.GridLayout24, 'numeric');
            app.InertiaRedEditField.ValueDisplayFormat = '%.5f';
            app.InertiaRedEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaRedEditFieldValueChanged, true);
            app.InertiaRedEditField.Editable = 'off';
            app.InertiaRedEditField.Tooltip = {'Inertia of RED.'};
            app.InertiaRedEditField.Value = 100;
            app.DurPhase0Units_5 = uilabel(app.GridLayout24);
            app.DurPhase0Units_5.Interpreter = 'latex';
            app.DurPhase0Units_5.Text = '$\mathrm{kg.m^2}$';
            app.mathrmI_BLACKLabel = uilabel(app.GridLayout24);
            app.mathrmI_BLACKLabel.Interpreter = 'latex';
            app.mathrmI_BLACKLabel.Text = '$\mathrm{I_{BLACK}}$';
            app.InertiaBlackEditField = uieditfield(app.GridLayout24, 'numeric');
            app.InertiaBlackEditField.ValueDisplayFormat = '%.5f';
            app.InertiaBlackEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaBlackEditFieldValueChanged, true);
            app.InertiaBlackEditField.Editable = 'off';
            app.InertiaBlackEditField.Tooltip = {'Inertia of BLACK.'};
            app.InertiaBlackEditField.Value = 100;
            app.DurPhase0Units_6 = uilabel(app.GridLayout24);
            app.DurPhase0Units_6.Interpreter = 'latex';
            app.DurPhase0Units_6.Text = '$\mathrm{kg.m^2}$';
            app.mathrmI_BLUELabel = uilabel(app.GridLayout24);
            app.mathrmI_BLUELabel.Interpreter = 'latex';
            app.mathrmI_BLUELabel.Text = '$\mathrm{I_{BLUE}}$';
            app.InertiaBlueEditField = uieditfield(app.GridLayout24, 'numeric');
            app.InertiaBlueEditField.ValueDisplayFormat = '%.5f';
            app.InertiaBlueEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaBlueEditFieldValueChanged, true);
            app.InertiaBlueEditField.Editable = 'off';
            app.InertiaBlueEditField.Tooltip = {'Inertia of BLUE.'};
            app.InertiaBlueEditField.Value = 100;
            app.DurPhase0Units_7 = uilabel(app.GridLayout24);
            app.DurPhase0Units_7.Interpreter = 'latex';
            app.DurPhase0Units_7.Text = '$\mathrm{kg.m^2}$';
            app.MassPropertiesLabel = uilabel(app.GridLayout24);
            app.MassPropertiesLabel.Text = 'Mass Properties';
            app.RoboticManipulatorDimensionsLabel = uilabel(app.GridLayout24);
            app.RoboticManipulatorDimensionsLabel.Text = 'Robotic Manipulator Dimensions';
            app.mathrmb_0Label = uilabel(app.GridLayout24);
            app.mathrmb_0Label.Interpreter = 'latex';
            app.mathrmb_0Label.Text = '$\mathrm{b_0}$';
            app.ArmB0EditField = uieditfield(app.GridLayout24, 'numeric');
            app.ArmB0EditField.ValueDisplayFormat = '%.5f';
            app.ArmB0EditField.ValueChangedFcn = createCallbackFcn(app, @ArmB0EditFieldValueChanged, true);
            app.ArmB0EditField.Tooltip = {'Distance between the center of geometry and the first joint.'};
            app.ArmB0EditField.Value = 100;
            app.DurPhase0Units_9 = uilabel(app.GridLayout24);
            app.DurPhase0Units_9.Interpreter = 'latex';
            app.DurPhase0Units_9.Text = 'm';
            app.mathrma_1Label = uilabel(app.GridLayout24);
            app.mathrma_1Label.Interpreter = 'latex';
            app.mathrma_1Label.Text = '$\mathrm{a_1}$';
            app.ArmA1EditField = uieditfield(app.GridLayout24, 'numeric');
            app.ArmA1EditField.ValueDisplayFormat = '%.5f';
            app.ArmA1EditField.ValueChangedFcn = createCallbackFcn(app, @ArmA1EditFieldValueChanged, true);
            app.ArmA1EditField.Tooltip = {'Distance from the first joint to the COG of the proximal link.'};
            app.ArmA1EditField.Value = 100;
            app.mathrmb_1Label = uilabel(app.GridLayout24);
            app.mathrmb_1Label.Interpreter = 'latex';
            app.mathrmb_1Label.Text = '$\mathrm{b_1}$';
            app.ArmB1EditField = uieditfield(app.GridLayout24, 'numeric');
            app.ArmB1EditField.ValueDisplayFormat = '%.5f';
            app.ArmB1EditField.ValueChangedFcn = createCallbackFcn(app, @ArmB1EditFieldValueChanged, true);
            app.ArmB1EditField.Tooltip = {'Distance between the COG of the proximal link and the second joint.'};
            app.ArmB1EditField.Value = 100;
            app.mathrma_2Label = uilabel(app.GridLayout24);
            app.mathrma_2Label.Interpreter = 'latex';
            app.mathrma_2Label.Text = '$\mathrm{a_2}$';
            app.ArmA2EditField = uieditfield(app.GridLayout24, 'numeric');
            app.ArmA2EditField.ValueDisplayFormat = '%.5f';
            app.ArmA2EditField.ValueChangedFcn = createCallbackFcn(app, @ArmA2EditFieldValueChanged, true);
            app.ArmA2EditField.Tooltip = {'Distance between the second joint and the COG of the distal link.'};
            app.ArmA2EditField.Value = 100;
            app.mathrmb_2Label = uilabel(app.GridLayout24);
            app.mathrmb_2Label.Interpreter = 'latex';
            app.mathrmb_2Label.Text = '$\mathrm{b_2}$';
            app.ArmB2EditField = uieditfield(app.GridLayout24, 'numeric');
            app.ArmB2EditField.ValueDisplayFormat = '%.5f';
            app.ArmB2EditField.ValueChangedFcn = createCallbackFcn(app, @ArmB2EditFieldValueChanged, true);
            app.ArmB2EditField.Tooltip = {'Distance between the COG of the distal ink and the third joint.'};
            app.ArmB2EditField.Value = 100;
            app.mathrma_3Label = uilabel(app.GridLayout24);
            app.mathrma_3Label.Interpreter = 'latex';
            app.mathrma_3Label.Text = '$\mathrm{a_3}$';
            app.ArmA3EditField = uieditfield(app.GridLayout24, 'numeric');
            app.ArmA3EditField.ValueDisplayFormat = '%.5f';
            app.ArmA3EditField.ValueChangedFcn = createCallbackFcn(app, @ArmA3EditFieldValueChanged, true);
            app.ArmA3EditField.Tooltip = {'Distance between the third joint and the COG of the end-effector.'};
            app.ArmA3EditField.Value = 100;
            app.mathrmb_3Label = uilabel(app.GridLayout24);
            app.mathrmb_3Label.Interpreter = 'latex';
            app.mathrmb_3Label.Text = '$\mathrm{b_3}$';
            app.ArmB3EditField = uieditfield(app.GridLayout24, 'numeric');
            app.ArmB3EditField.ValueDisplayFormat = '%.5f';
            app.ArmB3EditField.ValueChangedFcn = createCallbackFcn(app, @ArmB3EditFieldValueChanged, true);
            app.ArmB3EditField.Tooltip = {'Distance between the COG of the end-effector and the tip of the end-effector.'};
            app.ArmB3EditField.Value = 100;
            app.ShowRoboticArmParameterDefinitionsButton = uibutton(app.GridLayout24, 'push');
            app.ShowRoboticArmParameterDefinitionsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRoboticArmParameterDefinitionsButtonPushed, true);
            app.ShowRoboticArmParameterDefinitionsButton.Tooltip = {'This opens a figure providing a visual for the manipulator dimensions.'};
            app.ShowRoboticArmParameterDefinitionsButton.Text = {'Show Robotic Arm'; 'Parameter Definitions'};
            app.mathrmm_PROXLabel = uilabel(app.GridLayout24);
            app.mathrmm_PROXLabel.Interpreter = 'latex';
            app.mathrmm_PROXLabel.Text = '$\mathrm{m_{PROX}}$';
            app.mathrmm_DISTLabel = uilabel(app.GridLayout24);
            app.mathrmm_DISTLabel.Interpreter = 'latex';
            app.mathrmm_DISTLabel.Text = '$\mathrm{m_{DIST}}$';
            app.mathrmm_EELabel = uilabel(app.GridLayout24);
            app.mathrmm_EELabel.Interpreter = 'latex';
            app.mathrmm_EELabel.Text = '$\mathrm{m_{EE}}$';
            app.mathrmI_PROXLabel = uilabel(app.GridLayout24);
            app.mathrmI_PROXLabel.Interpreter = 'latex';
            app.mathrmI_PROXLabel.Text = '$\mathrm{I_{PROX}}$';
            app.mathrmI_DISTLabel = uilabel(app.GridLayout24);
            app.mathrmI_DISTLabel.Interpreter = 'latex';
            app.mathrmI_DISTLabel.Text = '$\mathrm{I_{DIST}}$';
            app.mathrmI_EELabel = uilabel(app.GridLayout24);
            app.mathrmI_EELabel.Interpreter = 'latex';
            app.mathrmI_EELabel.Text = '$\mathrm{I_{EE}}$';
            app.MassProximalLinkEditField = uieditfield(app.GridLayout24, 'numeric');
            app.MassProximalLinkEditField.ValueDisplayFormat = '%.3f';
            app.MassProximalLinkEditField.ValueChangedFcn = createCallbackFcn(app, @MassProximalLinkEditFieldValueChanged, true);
            app.MassProximalLinkEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. Can be edited here or in the initialization script. Does not affect the sampling rate of the PhaseSpace Server.'};
            app.MassProximalLinkEditField.Value = 100;
            app.DurPhase0Units_16 = uilabel(app.GridLayout24);
            app.DurPhase0Units_16.Interpreter = 'latex';
            app.DurPhase0Units_16.Text = 'kg';
            app.MassDistalLinkEditField = uieditfield(app.GridLayout24, 'numeric');
            app.MassDistalLinkEditField.ValueDisplayFormat = '%.3f';
            app.MassDistalLinkEditField.ValueChangedFcn = createCallbackFcn(app, @MassDistalLinkEditFieldValueChanged, true);
            app.MassDistalLinkEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. Can be edited here or in the initialization script. Does not affect the sampling rate of the PhaseSpace Server.'};
            app.MassDistalLinkEditField.Value = 100;
            app.DurPhase0Units_17 = uilabel(app.GridLayout24);
            app.DurPhase0Units_17.Interpreter = 'latex';
            app.DurPhase0Units_17.Text = 'kg';
            app.MassEndEffectorLinkEditField = uieditfield(app.GridLayout24, 'numeric');
            app.MassEndEffectorLinkEditField.ValueDisplayFormat = '%.3f';
            app.MassEndEffectorLinkEditField.ValueChangedFcn = createCallbackFcn(app, @MassEndEffectorLinkEditFieldValueChanged, true);
            app.MassEndEffectorLinkEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. Can be edited here or in the initialization script. Does not affect the sampling rate of the PhaseSpace Server.'};
            app.MassEndEffectorLinkEditField.Value = 100;
            app.DurPhase0Units_18 = uilabel(app.GridLayout24);
            app.DurPhase0Units_18.Interpreter = 'latex';
            app.DurPhase0Units_18.Text = 'kg';
            app.InertiaProximalLinkEditField = uieditfield(app.GridLayout24, 'numeric');
            app.InertiaProximalLinkEditField.ValueDisplayFormat = '%.5f';
            app.InertiaProximalLinkEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaProximalLinkEditFieldValueChanged, true);
            app.InertiaProximalLinkEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. Can be edited here or in the initialization script. Does not affect the sampling rate of the PhaseSpace Server.'};
            app.InertiaProximalLinkEditField.Value = 100;
            app.DurPhase0Units_19 = uilabel(app.GridLayout24);
            app.DurPhase0Units_19.Interpreter = 'latex';
            app.DurPhase0Units_19.Text = '$\mathrm{kg.m^2}$';
            app.InertiaDistalLinkEditField = uieditfield(app.GridLayout24, 'numeric');
            app.InertiaDistalLinkEditField.ValueDisplayFormat = '%.5f';
            app.InertiaDistalLinkEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaDistalLinkEditFieldValueChanged, true);
            app.InertiaDistalLinkEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. Can be edited here or in the initialization script. Does not affect the sampling rate of the PhaseSpace Server.'};
            app.InertiaDistalLinkEditField.Value = 100;
            app.DurPhase0Units_20 = uilabel(app.GridLayout24);
            app.DurPhase0Units_20.Interpreter = 'latex';
            app.DurPhase0Units_20.Text = '$\mathrm{kg.m^2}$';
            app.InertiaEndEffectorLinkEditField = uieditfield(app.GridLayout24, 'numeric');
            app.InertiaEndEffectorLinkEditField.ValueDisplayFormat = '%.5f';
            app.InertiaEndEffectorLinkEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaEndEffectorLinkEditFieldValueChanged, true);
            app.InertiaEndEffectorLinkEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. Can be edited here or in the initialization script. Does not affect the sampling rate of the PhaseSpace Server.'};
            app.InertiaEndEffectorLinkEditField.Value = 100;
            app.DurPhase0Units_21 = uilabel(app.GridLayout24);
            app.DurPhase0Units_21.Interpreter = 'latex';
            app.DurPhase0Units_21.Text = '$\mathrm{kg.m^2}$';
            app.DurPhase0Units_22 = uilabel(app.GridLayout24);
            app.DurPhase0Units_22.Interpreter = 'latex';
            app.DurPhase0Units_22.Text = 'm';
            app.DurPhase0Units_23 = uilabel(app.GridLayout24);
            app.DurPhase0Units_23.Interpreter = 'latex';
            app.DurPhase0Units_23.Text = 'm';
            app.DurPhase0Units_24 = uilabel(app.GridLayout24);
            app.DurPhase0Units_24.Interpreter = 'latex';
            app.DurPhase0Units_24.Text = 'm';
            app.DurPhase0Units_25 = uilabel(app.GridLayout24);
            app.DurPhase0Units_25.Interpreter = 'latex';
            app.DurPhase0Units_25.Text = 'm';
            app.DurPhase0Units_26 = uilabel(app.GridLayout24);
            app.DurPhase0Units_26.Interpreter = 'latex';
            app.DurPhase0Units_26.Text = 'm';
            app.DurPhase0Units_27 = uilabel(app.GridLayout24);
            app.DurPhase0Units_27.Interpreter = 'latex';
            app.DurPhase0Units_27.Text = 'm';
            app.mathrmphiLabel = uilabel(app.GridLayout24);
            app.mathrmphiLabel.Interpreter = 'latex';
            app.mathrmphiLabel.Text = '$\mathrm{\phi}$';
            app.ArmPhiEditField = uieditfield(app.GridLayout24, 'numeric');
            app.ArmPhiEditField.ValueDisplayFormat = '%.2f';
            app.ArmPhiEditField.Tooltip = {'Angle of the line drawn between the center of gemotry and the first joint.'};
            app.ArmPhiEditField.Value = 100;
            app.DurPhase0Units_32 = uilabel(app.GridLayout24);
            app.DurPhase0Units_32.Interpreter = 'latex';
            app.DurPhase0Units_32.Text = 'rad';
            app.mathrmX_cgLabel = uilabel(app.GridLayout24);
            app.mathrmX_cgLabel.Interpreter = 'latex';
            app.mathrmX_cgLabel.Text = '$\mathrm{X_{cg}}$';
            app.REDXCGCalculation = uieditfield(app.GridLayout24, 'numeric');
            app.REDXCGCalculation.ValueDisplayFormat = '%.2f';
            app.REDXCGCalculation.Editable = 'off';
            app.REDXCGCalculation.Tooltip = {'X-CG of RED (relative to center of geometry).'};
            app.REDXCGCalculation.Value = 100;
            app.REDYCGCalculation = uieditfield(app.GridLayout24, 'numeric');
            app.REDYCGCalculation.ValueDisplayFormat = '%.2f';
            app.REDYCGCalculation.Editable = 'off';
            app.REDYCGCalculation.Tooltip = {'Y-CG of RED (relative to center of geometry).'};
            app.REDYCGCalculation.Value = 100;
            app.mathrmY_cgLabel = uilabel(app.GridLayout24);
            app.mathrmY_cgLabel.Interpreter = 'latex';
            app.mathrmY_cgLabel.Text = '$\mathrm{Y_{cg}}$';
            app.BLACKYCGCalculation = uieditfield(app.GridLayout24, 'numeric');
            app.BLACKYCGCalculation.ValueDisplayFormat = '%.2f';
            app.BLACKYCGCalculation.Editable = 'off';
            app.BLACKYCGCalculation.Tooltip = {'Y-CG of BLACK (relative to center of geometry).'};
            app.BLACKYCGCalculation.Value = 100;
            app.mathrmY_cgLabel_2 = uilabel(app.GridLayout24);
            app.mathrmY_cgLabel_2.Interpreter = 'latex';
            app.mathrmY_cgLabel_2.Text = '$\mathrm{Y_{cg}}$';
            app.mathrmX_cgLabel_3 = uilabel(app.GridLayout24);
            app.mathrmX_cgLabel_3.Interpreter = 'latex';
            app.mathrmX_cgLabel_3.Text = '$\mathrm{X_{cg}}$';
            app.BLUEXCGCalculation = uieditfield(app.GridLayout24, 'numeric');
            app.BLUEXCGCalculation.ValueDisplayFormat = '%.2f';
            app.BLUEXCGCalculation.Editable = 'off';
            app.BLUEXCGCalculation.Tooltip = {'X-CG of BLUE (relative to center of geometry).'};
            app.BLUEXCGCalculation.Value = 100;
            app.DurPhase0Units_33 = uilabel(app.GridLayout24);
            app.DurPhase0Units_33.Interpreter = 'latex';
            app.DurPhase0Units_33.Text = 'cm';
            app.mathrmY_cgLabel_3 = uilabel(app.GridLayout24);
            app.mathrmY_cgLabel_3.Interpreter = 'latex';
            app.mathrmY_cgLabel_3.Text = '$\mathrm{Y_{cg}}$';
            app.BLUEYCGCalculation = uieditfield(app.GridLayout24, 'numeric');
            app.BLUEYCGCalculation.ValueDisplayFormat = '%.2f';
            app.BLUEYCGCalculation.Editable = 'off';
            app.BLUEYCGCalculation.Tooltip = {'Y-CG of BLUE (relative to center of geometry).'};
            app.BLUEYCGCalculation.Value = 100;
            app.DurPhase0Units_34 = uilabel(app.GridLayout24);
            app.DurPhase0Units_34.Interpreter = 'latex';
            app.DurPhase0Units_34.Text = 'cm';
            app.mathrmX_cgLabel_2 = uilabel(app.GridLayout24);
            app.mathrmX_cgLabel_2.Interpreter = 'latex';
            app.mathrmX_cgLabel_2.Text = '$\mathrm{X_{cg}}$';
            app.BLACKXCGCalculation = uieditfield(app.GridLayout24, 'numeric');
            app.BLACKXCGCalculation.ValueDisplayFormat = '%.2f';
            app.BLACKXCGCalculation.Editable = 'off';
            app.BLACKXCGCalculation.Tooltip = {'X-CG of BLACK (relative to center of geometry).'};
            app.BLACKXCGCalculation.Value = 100;
            app.DurPhase0Units_35 = uilabel(app.GridLayout24);
            app.DurPhase0Units_35.Interpreter = 'latex';
            app.DurPhase0Units_35.Text = 'cm';
            app.DurPhase0Units_36 = uilabel(app.GridLayout24);
            app.DurPhase0Units_36.Interpreter = 'latex';
            app.DurPhase0Units_36.Text = 'cm';
            app.DurPhase0Units_37 = uilabel(app.GridLayout24);
            app.DurPhase0Units_37.Interpreter = 'latex';
            app.DurPhase0Units_37.Text = 'cm';
            app.DurPhase0Units_38 = uilabel(app.GridLayout24);
            app.DurPhase0Units_38.Interpreter = 'latex';
            app.DurPhase0Units_38.Text = 'cm';
            app.OverridePropertiesCheckBox = uicheckbox(app.GridLayout24);
            app.OverridePropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverridePropertiesCheckBoxValueChanged, true);
            app.OverridePropertiesCheckBox.Tooltip = {'Checking this box allows you to override the mass properties for the platforms.'};
            app.OverridePropertiesCheckBox.Text = 'Override Properties';
            app.InertiaEstimatorPanel = uipanel(app.GridLayout25);
            app.InertiaEstimatorPanel.Title = 'Inertia Estimator';
            app.GridLayout24_3 = uigridlayout(app.InertiaEstimatorPanel);
            app.Image2 = uiimage(app.GridLayout24_3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Resources', 'config_for_inertia.png');
            app.mathrmDLabel = uilabel(app.GridLayout24_3);
            app.mathrmDLabel.Interpreter = 'latex';
            app.mathrmDLabel.Text = '$\mathrm{D}$';
            app.mathrmLLabel = uilabel(app.GridLayout24_3);
            app.mathrmLLabel.Interpreter = 'latex';
            app.mathrmLLabel.Text = '$\mathrm{L}$';
            app.DREDMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.DREDMeasurement.ValueDisplayFormat = '%.2f';
            app.DREDMeasurement.Tooltip = {'Distance between both mounting strings (RED).'};
            app.DREDMeasurement.Value = 100;
            app.LREDScaleMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.LREDScaleMeasurement.ValueDisplayFormat = '%.2f';
            app.LREDScaleMeasurement.Tooltip = {'Length of the mounting strings (RED).'};
            app.LREDScaleMeasurement.Value = 100;
            app.mathrmDLabel_2 = uilabel(app.GridLayout24_3);
            app.mathrmDLabel_2.Interpreter = 'latex';
            app.mathrmDLabel_2.Text = '$\mathrm{D}$';
            app.mathrmLLabel_2 = uilabel(app.GridLayout24_3);
            app.mathrmLLabel_2.Interpreter = 'latex';
            app.mathrmLLabel_2.Text = '$\mathrm{L}$';
            app.DBLACKMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.DBLACKMeasurement.ValueDisplayFormat = '%.2f';
            app.DBLACKMeasurement.Tooltip = {'Distance between both mounting strings (BLACK).'};
            app.DBLACKMeasurement.Value = 100;
            app.LBLACKMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.LBLACKMeasurement.ValueDisplayFormat = '%.2f';
            app.LBLACKMeasurement.Tooltip = {'Length of the mounting strings (BLACK).'};
            app.LBLACKMeasurement.Value = 100;
            app.mathrmDLabel_3 = uilabel(app.GridLayout24_3);
            app.mathrmDLabel_3.Interpreter = 'latex';
            app.mathrmDLabel_3.Text = '$\mathrm{D}$';
            app.mathrmLLabel_3 = uilabel(app.GridLayout24_3);
            app.mathrmLLabel_3.Interpreter = 'latex';
            app.mathrmLLabel_3.Text = '$\mathrm{L}$';
            app.DBLUEMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.DBLUEMeasurement.ValueDisplayFormat = '%.2f';
            app.DBLUEMeasurement.Tooltip = {'Distance between both mounting strings (BLUE).'};
            app.DBLUEMeasurement.Value = 100;
            app.LBLUEMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.LBLUEMeasurement.ValueDisplayFormat = '%.2f';
            app.LBLUEMeasurement.Tooltip = {'Length of the mounting strings (BLUE).'};
            app.LBLUEMeasurement.Value = 100;
            app.DurPhase0Units_50 = uilabel(app.GridLayout24_3);
            app.DurPhase0Units_50.Text = 'm';
            app.DurPhase0Units_51 = uilabel(app.GridLayout24_3);
            app.DurPhase0Units_51.Text = 'm';
            app.mathrmTLabel = uilabel(app.GridLayout24_3);
            app.mathrmTLabel.Interpreter = 'latex';
            app.mathrmTLabel.Text = '$\mathrm{T}$';
            app.TREDMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.TREDMeasurement.ValueDisplayFormat = '%.2f';
            app.TREDMeasurement.Tooltip = {'Time to complete one full oscillation (RED).'};
            app.TREDMeasurement.Value = 100;
            app.mathrmTLabel_2 = uilabel(app.GridLayout24_3);
            app.mathrmTLabel_2.Interpreter = 'latex';
            app.mathrmTLabel_2.Text = '$\mathrm{T}$';
            app.TBLACKMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.TBLACKMeasurement.ValueDisplayFormat = '%.2f';
            app.TBLACKMeasurement.Tooltip = {'Time to complete one full oscillation (BLACK).'};
            app.TBLACKMeasurement.Value = 100;
            app.mathrmTLabel_3 = uilabel(app.GridLayout24_3);
            app.mathrmTLabel_3.Interpreter = 'latex';
            app.mathrmTLabel_3.Text = '$\mathrm{T}$';
            app.TBLUEMeasurement = uieditfield(app.GridLayout24_3, 'numeric');
            app.TBLUEMeasurement.ValueDisplayFormat = '%.2f';
            app.TBLUEMeasurement.Tooltip = {'Time to complete one full oscillation (BLUE).'};
            app.TBLUEMeasurement.Value = 100;
            app.DurPhase0Units_52 = uilabel(app.GridLayout24_3);
            app.DurPhase0Units_52.Text = 's';
            app.CalculateInertiausingBifilarPendulumTechniqueButton = uibutton(app.GridLayout24_3, 'push');
            app.CalculateInertiausingBifilarPendulumTechniqueButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateInertiausingBifilarPendulumTechniqueButtonPushed, true);
            app.CalculateInertiausingBifilarPendulumTechniqueButton.Tooltip = {'Calculate the inertia of the platforms using the bifilar pendulum technique.'};
            app.CalculateInertiausingBifilarPendulumTechniqueButton.Text = {'Calculate Inertia using '; 'Bifilar Pendulum Technique '};
            % Show the figure after all components are created
            app.SPOT50MassPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v5_0_MassProperties

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SPOT50MassPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @PopulateGUI)
            else

                % Focus the running singleton app
                figure(runningApp.SPOT50MassPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPOT50MassPropertiesUIFigure)
        end
    end
end